classdef AerofoilSpecs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        RestoreallButton                matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        StaticaerofoilpolarsPanel       matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        ImportpolartableButton          matlab.ui.control.Button
        ExportpolartableButton          matlab.ui.control.Button
        PolarTable                      matlab.ui.control.Table
        AerofoilgeometryPanel           matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        ImportgeometrytableButton       matlab.ui.control.Button
        ExportgeometrytableButton       matlab.ui.control.Button
        GeometryTable                   matlab.ui.control.Table
        GridLayout1                     matlab.ui.container.GridLayout
        DynamicstallPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Criticalc_natnegEditField       matlab.ui.control.NumericEditField
        Criticalc_natnegEditFieldLabel  matlab.ui.control.Label
        Criticalc_natposEditField       matlab.ui.control.NumericEditField
        Criticalc_natposEditFieldLabel  matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        StallangleatnegEditField        matlab.ui.control.NumericEditField
        StallangleatnegEditFieldLabel   matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        StallangleatposEditField        matlab.ui.control.NumericEditField
        StallangleatposEditFieldLabel   matlab.ui.control.Label
        radsup1supLabel                 matlab.ui.control.Label
        dc_ndslopeEditField             matlab.ui.control.NumericEditField
        dc_ndslopeEditFieldLabel        matlab.ui.control.Label
        AerofoilcollectionPanel         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        DeleteButton                    matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
        CollectionListBox               matlab.ui.control.ListBox
    end

    properties (Access = private)
        WindTurbine
        mainApp
        specsChanged
        variableNamesGeometry
        variableNamesPolar
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function resetUI(comp)
            comp.CollectionListBox.ValueIndex = [];
        end

        function selectedAerofoilIndex = setSpecs(comp, Data)
            comp.WindTurbine.Airfoil = Data.Airfoil;
            
            % Remember previously selected aerofoil. If that doesn't exist
            % in the next dataset, the selection defaults to the first one
            currentAerofoil = comp.CollectionListBox.Value;
            comp.CollectionListBox.Items = string(Data.Airfoil.Name);
            if ~isempty(currentAerofoil)
                if sum(strcmp(comp.CollectionListBox.Items, currentAerofoil))
                    comp.CollectionListBox.Value = currentAerofoil;
                end
            else
                comp.CollectionListBox.ValueIndex = 1;
            end
            selectedAerofoilIndex = comp.CollectionListBox.ValueIndex;

            i = comp.CollectionListBox.ValueIndex;
            comp.dc_ndslopeEditField.Value = Data.Airfoil.CnSlope(i);
            comp.StallangleatposEditField.Value = Data.Airfoil.StallAngle1(i);
            comp.StallangleatnegEditField.Value = Data.Airfoil.StallAngle2(i);
            comp.Criticalc_natposEditField.Value = Data.Airfoil.CritCn1(i);
            comp.Criticalc_natnegEditField.Value = Data.Airfoil.CritCn2(i);

            Aerofoil.Geometry = Data.Airfoil.Geometry{i};
            Aerofoil.Alpha = Data.Airfoil.Alpha{i};
            Aerofoil.Cl = Data.Airfoil.Cl{i};
            Aerofoil.Cd = Data.Airfoil.Cd{i};
            Aerofoil.Cm = Data.Airfoil.Cm{i};
            setPolarTable(comp, Aerofoil);
            setGeometryTable(comp, Aerofoil);

            applyEdits(comp);
        end
        
        % FASTTool.updateForChangedAerofoilSelection requests an update of
        % the list of aerofoil names. This is done when a new aerofoil name
        % is used in the table on the Rotor specifications tab.
        % The user cannot add an aerofoil name directly in the table, but
        % it can be imported through the import of an Excel file. The
        % aerofoil data for the new aerofoil has been generated in
        % WindTurbineClass, following a request from the Rotor tab.
        function setAerofoilList(comp)
            comp.CollectionListBox.Items = string(comp.WindTurbine.Airfoil.Name);
            comp.specsChanged = true;
        end

        function processImport(comp)
            % Parse the imported data and then reload and reapply  the
            % table
            parseAerofoil(comp.WindTurbine, comp.CollectionListBox.Value);
            i = comp.CollectionListBox.ValueIndex;
            comp.dc_ndslopeEditField.Value = comp.WindTurbine.Airfoil.CnSlope(i);
            comp.StallangleatposEditField.Value = comp.WindTurbine.Airfoil.StallAngle1(i);
            comp.StallangleatnegEditField.Value = comp.WindTurbine.Airfoil.StallAngle2(i);
            comp.Criticalc_natposEditField.Value = comp.WindTurbine.Airfoil.CritCn1(i);
            comp.Criticalc_natnegEditField.Value = comp.WindTurbine.Airfoil.CritCn2(i);
            Aerofoil.Geometry = comp.WindTurbine.Airfoil.Geometry{i};
            Aerofoil.Alpha = comp.WindTurbine.Airfoil.Alpha{i};
            Aerofoil.Cl = comp.WindTurbine.Airfoil.Cl{i};
            Aerofoil.Cd = comp.WindTurbine.Airfoil.Cd{i};
            Aerofoil.Cm = comp.WindTurbine.Airfoil.Cm{i};
            setPolarTable(comp, Aerofoil);
            setGeometryTable(comp, Aerofoil);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end
    end

    methods (Access = private)

        %% Managing the display of data on the tab

        function setPolarTable(comp, Aerofoil)
            comp.PolarTable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric'};
            comp.PolarTable.Data = [...
                num2cell(Aerofoil.Alpha), ...
                num2cell(Aerofoil.Cl), ...
                num2cell(Aerofoil.Cd), ...
                num2cell(Aerofoil.Cm)];
        end
        
        function setGeometryTable(comp, Aerofoil)
            comp.GeometryTable.ColumnFormat = {'numeric', 'numeric'};
            comp.GeometryTable.Data = [...
                num2cell(Aerofoil.Geometry(1,:)'), ...
                num2cell(Aerofoil.Geometry(2,:)')];
        end

        %% Extracting WindTurbine data from tab information

        function applyEdits(comp)
            i = comp.CollectionListBox.ValueIndex;
            comp.WindTurbine.Airfoil.CnSlope(i) = comp.dc_ndslopeEditField.Value;
            comp.WindTurbine.Airfoil.StallAngle1(i) = comp.StallangleatposEditField.Value;
            comp.WindTurbine.Airfoil.StallAngle2(i) = comp.StallangleatnegEditField.Value;
            comp.WindTurbine.Airfoil.CritCn1(i) = comp.Criticalc_natposEditField.Value;
            comp.WindTurbine.Airfoil.CritCn2(i) = comp.Criticalc_natnegEditField.Value;
            applyPolarTable(comp);
            applyGeometryTable(comp);
        end

        function applyPolarTable(comp)
            i = comp.CollectionListBox.ValueIndex;
            T = comp.PolarTable.Data;
            endTable = length(T(:,1));
            comp.WindTurbine.Airfoil.Alpha{i} = ones(endTable,1);
            comp.WindTurbine.Airfoil.Cl{i} = ones(endTable,1);
            comp.WindTurbine.Airfoil.Cd{i} = ones(endTable,1);
            comp.WindTurbine.Airfoil.Cm{i} = ones(endTable,1);

            comp.WindTurbine.Airfoil.Alpha{i} = cell2mat(T(:,1));
            comp.WindTurbine.Airfoil.Cl{i} = cell2mat(T(:,2));
            comp.WindTurbine.Airfoil.Cd{i} = cell2mat(T(:,3));
            comp.WindTurbine.Airfoil.Cm{i} = cell2mat(T(:,4));
        end

        function applyGeometryTable(comp)
            i = comp.CollectionListBox.ValueIndex;
            T = comp.GeometryTable.Data;
            endTable = length(T(:,1));
            comp.WindTurbine.Airfoil.Geometry{i} = ones(endTable,2);

            comp.WindTurbine.Airfoil.Geometry{i} = [cell2mat(T(:,1))'; cell2mat(T(:,2))'];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.variableNamesPolar = ["alpha [deg]" "c_l [-]" "c_d [-]" "c_m [-]"];
            comp.variableNamesGeometry = ["x [-]" "y [-]"];
        end

        % Value changed function: Criticalc_natnegEditField, 
        % ...and 4 other components
        function TabDataChanged(comp, event)
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end

        % Button pushed function: ExportpolartableButton
        function ExportpolartableButtonPushed(comp, event)
            DataTable = cell2table(comp.PolarTable.Data, VariableNames = comp.variableNamesPolar);
            exportTable(comp.mainApp, DataTable, 'Export aerofoil polar data');
        end

        % Button pushed function: ImportpolartableButton
        function ImportpolartableButtonPushed(comp, event)
            [DataTable] = importTable(comp.mainApp, 'Import aerofoil polar data', 'polartable');
            if isempty(DataTable)
                return;
            end
            
            % Verify that data is within acceptable limits
            subinfo{1} = checkTableImport(comp.mainApp, DataTable, comp.variableNamesPolar, 1, [-180, 180]);
            subinfo{2} = checkTableImport(comp.mainApp, DataTable, comp.variableNamesPolar, [2, 4], [-Inf, Inf]);
            subinfo{3} = checkTableImport(comp.mainApp, DataTable, comp.variableNamesPolar, 3, [0, Inf]);
            info = sprintf('%s', subinfo{:});
            if ~isempty(info)
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            comp.PolarTable.Data = table2cell(DataTable(:, 1:4));
            applyEdits(comp);
            i = comp.CollectionListBox.ValueIndex;
            if min(comp.WindTurbine.Airfoil.Alpha{i}) > -180 || max(comp.WindTurbine.Airfoil.Alpha{i}) < 180
                uialert(comp.mainApp.FASTToolUIFigure, 'Imported aerofoil polars have been extended to +/- 180 deg with default values', 'Info', Icon='info');
            end
            processImport(comp);
        end

        % Button pushed function: ExportgeometrytableButton
        function ExportgeometrytableButtonPushed(comp, event)
            DataTable = cell2table(comp.GeometryTable.Data, VariableNames = comp.variableNamesGeometry);
            exportTable(comp.mainApp, DataTable, 'Export aerofoil geometry data');
        end

        % Button pushed function: ImportgeometrytableButton
        function ImportgeometrytableButtonPushed(comp, event)
            [DataTable] = importTable(comp.mainApp, 'Import aerofoil geometry data', 'geometrytable');
            if isempty(DataTable)
                return;
            end
            
            % Verify that data is within acceptable limits
            subinfo{1} = checkTableImport(comp.mainApp, DataTable, comp.variableNamesGeometry, 1, [0, 1]);
            subinfo{2} = checkTableImport(comp.mainApp, DataTable, comp.variableNamesGeometry, 2, [-Inf, Inf]);
            info = sprintf('%s', subinfo{:});
            if ~isempty(info)
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            comp.GeometryTable.Data = table2cell(DataTable(:, 1:2));
            applyEdits(comp);
            uialert(comp.mainApp.FASTToolUIFigure, 'Imported aerofoil geometry has been resampled to 399 coordinates and smoothened if needed', 'Info', Icon='info');
            processImport(comp);
        end

        % Button pushed function: RestoreallButton
        function RestoreallButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            oldAerofoilsRemain = false;

            % Check whether aerofoils are used by the blade that are not in
            % the restore data and add these to the dataset
            for i = 1:length(comp.WindTurbine.Blade.IFoil)
                if ~ismember(comp.WindTurbine.Airfoil.Name{comp.WindTurbine.Blade.IFoil(i)}, Data.Airfoil.Name)
                    j = length(Data.Airfoil.Name) + 1;
                    k = comp.WindTurbine.Blade.IFoil(i);
                    Data.Airfoil.Name{j} = comp.WindTurbine.Airfoil.Name{k};
                    Data.Airfoil.Geometry{j} = comp.WindTurbine.Airfoil.Geometry{k};
                    Data.Airfoil.Alpha{j} = comp.WindTurbine.Airfoil.Alpha{k};
                    Data.Airfoil.Cl{j} = comp.WindTurbine.Airfoil.Cl{k};
                    Data.Airfoil.Cd{j} = comp.WindTurbine.Airfoil.Cd{k};
                    Data.Airfoil.Cm{j} = comp.WindTurbine.Airfoil.Cm{k};
                    Data.Airfoil.CnSlope(j) = comp.WindTurbine.Airfoil.CnSlope(k);
                    Data.Airfoil.StallAngle1(j) = comp.WindTurbine.Airfoil.StallAngle1(k);
                    Data.Airfoil.StallAngle2(j) = comp.WindTurbine.Airfoil.StallAngle2(k);
                    Data.Airfoil.CritCn1(j) = comp.WindTurbine.Airfoil.CritCn1(k);
                    Data.Airfoil.CritCn2(j) = comp.WindTurbine.Airfoil.CritCn2(k);
                    oldAerofoilsRemain = true;
                end
            end

            % Renumber the aerofoils in the blade, according to their names
            name = comp.WindTurbine.Airfoil.Name(comp.WindTurbine.Blade.IFoil);
            for i = 1:length(name)
                comp.WindTurbine.Blade.IFoil(i) = find(strcmp(Data.Airfoil.Name, name{i}));
            end

            setSpecs(comp, Data);
            updateForChangedAerofoilSelection(comp.mainApp, comp.CollectionListBox.ValueIndex)

            comp.specsChanged = false;
            if oldAerofoilsRemain
                uialert(comp.mainApp.FASTToolUIFigure, 'Aerofoils used for the blades are not deleted', 'Info', Icon='info');
                comp.specsChanged = true;
            end
            updateForChangedAerofoilSet(comp.mainApp); % Let main app update RotorSpecs with changed options for aerofoils
            updateForChangedSpecs(comp.mainApp);
        end

        % Value changed function: CollectionListBox
        function CollectionListBoxValueChanged(comp, event)
            setSpecs(comp, comp.WindTurbine);
            updateForChangedAerofoilSelection(comp.mainApp, comp.CollectionListBox.ValueIndex)
        end

        % Button pushed function: NewButton
        function NewButtonPushed(comp, event)
            name = inputdlg('Enter aerofoil name:');
            figure(comp.mainApp.FASTToolUIFigure); % Needed to get FASTTool back to foreground after the dialogue closes
            
            if ~isempty(name)
                if sum(strcmp(comp.WindTurbine.Airfoil.Name, name))
                    uialert(comp.mainApp.FASTToolUIFigure, 'Name is already used', 'Error');
                else
                    i = length(comp.WindTurbine.Airfoil.Name) + 1;
                    comp.WindTurbine.Airfoil.Name{i} = name{1};
                    setNewAerofoilData(comp.WindTurbine);
                    
                    comp.CollectionListBox.Items = string(comp.WindTurbine.Airfoil.Name);
                    comp.CollectionListBox.ValueIndex = i;
                    setSpecs(comp, comp.WindTurbine);
                    updateForChangedAerofoilSelection(comp.mainApp, comp.CollectionListBox.ValueIndex)
                    comp.specsChanged = true;
                    updateForChangedAerofoilSet(comp.mainApp); % Let main app update RotorSpecs with changed options for aerofoils
                end
            end

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(comp, event)
            % Prevent deletion of base airfoils
            if comp.CollectionListBox.ValueIndex <= 8
                uialert(comp.mainApp.FASTToolUIFigure, 'Cannot delete base aerofoils', 'Error');
            else
                i = comp.CollectionListBox.ValueIndex;
                if ismember(i, comp.WindTurbine.Blade.IFoil)
                    uialert(comp.mainApp.FASTToolUIFigure, 'Cannot delete aerofoil that is used for the blade', 'Error');
                else
                    comp.WindTurbine.Airfoil.Name(i) = [];
                    comp.WindTurbine.Airfoil.Geometry(i) = [];
                    comp.WindTurbine.Airfoil.Alpha(i) = [];
                    comp.WindTurbine.Airfoil.Cl(i) = [];
                    comp.WindTurbine.Airfoil.Cd(i) = [];
                    comp.WindTurbine.Airfoil.Cm(i) = [];
                    comp.WindTurbine.Airfoil.StallAngle1(i) = [];
                    comp.WindTurbine.Airfoil.StallAngle2(i) = [];
                    comp.WindTurbine.Airfoil.CnSlope(i) = [];
                    comp.WindTurbine.Airfoil.CritCn1(i) = [];
                    comp.WindTurbine.Airfoil.CritCn2(i) = [];
                    % Aerofoils of blade stations with a higher index must
                    % be renumbered because of the deletion:
                    for j = i + 1 : length(comp.WindTurbine.Airfoil.Name) + 1
                        comp.WindTurbine.Blade.IFoil(comp.WindTurbine.Blade.IFoil == j) = j - 1;
                    end

                    comp.CollectionListBox.Items = string(comp.WindTurbine.Airfoil.Name);
                    if i > length(comp.WindTurbine.Airfoil.Name)
                        i = i - 1;
                    end
                    comp.CollectionListBox.ValueIndex = i;
                    setSpecs(comp, comp.WindTurbine);
                    updateForChangedAerofoilSelection(comp.mainApp, comp.CollectionListBox.ValueIndex)
                    comp.specsChanged = true;
                    updateForChangedAerofoilSet(comp.mainApp); % Let main app update RotorSpecs with changed options for aerofoils
                end
            end
        end

        % Cell edit callback: GeometryTable
        function GeometryTableCellEdit(comp, event)

            % Verify that data is within acceptable limits
            switch event.Indices(2)
                case 1
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [0, 1]);
                case 2
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [-Inf, Inf]);
            end
            if ~isempty(info)
                comp.GeometryTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end

        % Cell edit callback: PolarTable
        function PolarTableCellEdit(comp, event)

            % Verify that data is within acceptable limits
            switch event.Indices(2)
                case 1
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [-180, 180]);
                case {2, 4}
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [-Inf, Inf]);
                case 3
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [0, Inf]);
            end
            if ~isempty(info)
                comp.PolarTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout1 = uigridlayout(comp.GridLayout);
            comp.AerofoilcollectionPanel = uipanel(comp.GridLayout1);
            comp.AerofoilcollectionPanel.Title = 'Aerofoil collection';
            comp.GridLayout11 = uigridlayout(comp.AerofoilcollectionPanel);
            comp.CollectionListBox = uilistbox(comp.GridLayout11);
            comp.CollectionListBox.Items = {};
            comp.CollectionListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CollectionListBoxValueChanged, true);
            comp.CollectionListBox.Value = {};
            comp.NewButton = uibutton(comp.GridLayout11, 'push');
            comp.NewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @NewButtonPushed, true);
            comp.NewButton.Text = 'New';
            comp.DeleteButton = uibutton(comp.GridLayout11, 'push');
            comp.DeleteButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @DeleteButtonPushed, true);
            comp.DeleteButton.Text = 'Delete';
            comp.DynamicstallPanel = uipanel(comp.GridLayout1);
            comp.DynamicstallPanel.Title = 'Dynamic stall';
            comp.GridLayout12 = uigridlayout(comp.DynamicstallPanel);
            comp.dc_ndslopeEditFieldLabel = uilabel(comp.GridLayout12);
            comp.dc_ndslopeEditFieldLabel.Text = 'dc_n/dα slope';
            comp.dc_ndslopeEditField = uieditfield(comp.GridLayout12, 'numeric');
            comp.dc_ndslopeEditField.Limits = [0 Inf];
            comp.dc_ndslopeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.radsup1supLabel = uilabel(comp.GridLayout12);
            comp.radsup1supLabel.Interpreter = 'html';
            comp.radsup1supLabel.Text = 'rad<sup>-1</sup>';
            comp.StallangleatposEditFieldLabel = uilabel(comp.GridLayout12);
            comp.StallangleatposEditFieldLabel.Text = 'Stall angle at pos. α';
            comp.StallangleatposEditField = uieditfield(comp.GridLayout12, 'numeric');
            comp.StallangleatposEditField.Limits = [0 180];
            comp.StallangleatposEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel = uilabel(comp.GridLayout12);
            comp.degLabel.Text = 'deg';
            comp.StallangleatnegEditFieldLabel = uilabel(comp.GridLayout12);
            comp.StallangleatnegEditFieldLabel.Text = 'Stall angle at neg. α';
            comp.StallangleatnegEditField = uieditfield(comp.GridLayout12, 'numeric');
            comp.StallangleatnegEditField.Limits = [-180 0];
            comp.StallangleatnegEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel_2 = uilabel(comp.GridLayout12);
            comp.degLabel_2.Text = 'deg';
            comp.Criticalc_natposEditFieldLabel = uilabel(comp.GridLayout12);
            comp.Criticalc_natposEditFieldLabel.Text = 'Critical c_n at pos. α';
            comp.Criticalc_natposEditField = uieditfield(comp.GridLayout12, 'numeric');
            comp.Criticalc_natposEditField.Limits = [0 Inf];
            comp.Criticalc_natposEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Criticalc_natnegEditFieldLabel = uilabel(comp.GridLayout12);
            comp.Criticalc_natnegEditFieldLabel.Text = 'Critical c_n at neg. α';
            comp.Criticalc_natnegEditField = uieditfield(comp.GridLayout12, 'numeric');
            comp.Criticalc_natnegEditField.Limits = [-Inf 0];
            comp.Criticalc_natnegEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.AerofoilgeometryPanel = uipanel(comp.GridLayout2);
            comp.AerofoilgeometryPanel.Title = 'Aerofoil geometry';
            comp.GridLayout21 = uigridlayout(comp.AerofoilgeometryPanel);
            comp.GeometryTable = uitable(comp.GridLayout21);
            comp.GeometryTable.ColumnName = {'x/c [-]'; 'y/c [-]'};
            comp.GeometryTable.RowName = {};
            comp.GeometryTable.ColumnEditable = true;
            comp.GeometryTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @GeometryTableCellEdit, true);
            comp.ExportgeometrytableButton = uibutton(comp.GridLayout21, 'push');
            comp.ExportgeometrytableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExportgeometrytableButtonPushed, true);
            comp.ExportgeometrytableButton.Text = 'Export table';
            comp.ImportgeometrytableButton = uibutton(comp.GridLayout21, 'push');
            comp.ImportgeometrytableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImportgeometrytableButtonPushed, true);
            comp.ImportgeometrytableButton.Text = 'Import table';
            comp.StaticaerofoilpolarsPanel = uipanel(comp.GridLayout2);
            comp.StaticaerofoilpolarsPanel.Title = 'Static aerofoil polars';
            comp.GridLayout22 = uigridlayout(comp.StaticaerofoilpolarsPanel);
            comp.PolarTable = uitable(comp.GridLayout22);
            comp.PolarTable.ColumnName = {'α [deg]'; 'c_l [-]'; 'c_d [-]'; 'c_m [-]'};
            comp.PolarTable.RowName = {};
            comp.PolarTable.ColumnEditable = true;
            comp.PolarTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @PolarTableCellEdit, true);
            comp.ExportpolartableButton = uibutton(comp.GridLayout22, 'push');
            comp.ExportpolartableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExportpolartableButtonPushed, true);
            comp.ExportpolartableButton.Text = 'Export table';
            comp.ImportpolartableButton = uibutton(comp.GridLayout22, 'push');
            comp.ImportpolartableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImportpolartableButtonPushed, true);
            comp.ImportpolartableButton.Text = 'Import table';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.RestoreallButton = uibutton(comp.GridLayout3, 'push');
            comp.RestoreallButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreallButtonPushed, true);
            comp.RestoreallButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreallButton.Text = 'Restore (all)';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end