classdef Control1Specs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        RestoreButton                   matlab.ui.control.Button
        ActuatorlimitsPanel             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout22                    matlab.ui.container.GridLayout
        NmsLabel                        matlab.ui.control.Label
        TorqueSlewrateEditField         matlab.ui.control.NumericEditField
        SlewrateEditFieldLabel          matlab.ui.control.Label
        NmLabel_3                       matlab.ui.control.Label
        MaximumTorqueEditField          matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        NmLabel_2                       matlab.ui.control.Label
        MinimumTorqueEditField          matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        TorqueLabel                     matlab.ui.control.Label
        GridLayout21                    matlab.ui.container.GridLayout
        degsLabel_3                     matlab.ui.control.Label
        MaximumrateEditField            matlab.ui.control.NumericEditField
        MaximumrateEditFieldLabel       matlab.ui.control.Label
        degsLabel_2                     matlab.ui.control.Label
        MinimumrateEditField            matlab.ui.control.NumericEditField
        MinimumrateEditFieldLabel       matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        MaximumangleEditField           matlab.ui.control.NumericEditField
        MaximumangleEditField_2Label    matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        MinimumangleEditField           matlab.ui.control.NumericEditField
        MinimumangleEditFieldLabel      matlab.ui.control.Label
        PitchLabel                      matlab.ui.control.Label
        GridLayout1                     matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        PartialloadpitchPanel           matlab.ui.container.Panel
        GridLayout122                   matlab.ui.container.GridLayout
        degLabel_4                      matlab.ui.control.Label
        FinepitchangleEditField         matlab.ui.control.NumericEditField
        FinepitchangleEditFieldLabel    matlab.ui.control.Label
        TorquecontrolPanel              matlab.ui.container.Panel
        GridLayout121                   matlab.ui.container.GridLayout
        radsLabel                       matlab.ui.control.Label
        LowpasscutoffEditField          matlab.ui.control.NumericEditField
        GenspeedlowpasscutoffLabel      matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        DemandedtorqueEditField         matlab.ui.control.NumericEditField
        DemandedtorqueEditFieldLabel    matlab.ui.control.Label
        rpmLabel_5                      matlab.ui.control.Label
        GenspeedDEditField              matlab.ui.control.NumericEditField
        GenspeedDEditFieldLabel         matlab.ui.control.Label
        rpmLabel_4                      matlab.ui.control.Label
        GenspeedCEditField              matlab.ui.control.NumericEditField
        GenspeedCLabel                  matlab.ui.control.Label
        rpmLabel_3                      matlab.ui.control.Label
        GenspeedBEditField              matlab.ui.control.NumericEditField
        GenspeedBLabel                  matlab.ui.control.Label
        rpmLabel_2                      matlab.ui.control.Label
        GenspeedAEditField              matlab.ui.control.NumericEditField
        GenspeedALabel                  matlab.ui.control.Label
        Nmradssup2supLabel              matlab.ui.control.Label
        OptimalmodegainEditField        matlab.ui.control.NumericEditField
        OptimalmodegainEditFieldLabel   matlab.ui.control.Label
        SupervisorycontrolPanel         matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        sLabel_2                        matlab.ui.control.Label
        DelayEditField                  matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        DeploytimeEditField             matlab.ui.control.NumericEditField
        DeploytimeEditFieldLabel        matlab.ui.control.Label
        NmLabel_4                       matlab.ui.control.Label
        BraketorqueEditField            matlab.ui.control.NumericEditField
        BraketorqueEditFieldLabel       matlab.ui.control.Label
        ShutdownLabel                   matlab.ui.control.Label
        rpmLabel                        matlab.ui.control.Label
        StartuprotorspeedEditField      matlab.ui.control.NumericEditField
        StartuprotorspeedEditFieldLabel  matlab.ui.control.Label
        degsLabel                       matlab.ui.control.Label
        StartuppitchrateEditField       matlab.ui.control.NumericEditField
        StartuppitchrateEditFieldLabel  matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        StartuppitchangleEditField      matlab.ui.control.NumericEditField
        StartuppitchangleEditFieldLabel  matlab.ui.control.Label
        StartupLabel                    matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        CutoutEditField                 matlab.ui.control.NumericEditField
        CutoutEditFieldLabel            matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        CutinEditField                  matlab.ui.control.NumericEditField
        CutinEditFieldLabel             matlab.ui.control.Label
        WindspeedsLabel                 matlab.ui.control.Label
    end

    properties (Access = private)
        WindTurbine
        mainApp
        specsChanged
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function setSpecs(comp, Data)
            comp.CutinEditField.Value = Data.Control.WindSpeed.Cutin;
            comp.CutoutEditField.Value = Data.Control.WindSpeed.Cutout;

            comp.StartuppitchangleEditField.Value = Data.Control.Pitch.StartupPitch;
            comp.StartuppitchrateEditField.Value = Data.Control.Pitch.StartupPitchRate;
            comp.StartuprotorspeedEditField.Value = Data.Control.Pitch.StartupSpeed;

            comp.BraketorqueEditField.Value = Data.Control.Brake.Torque;
            comp.DeploytimeEditField.Value = Data.Control.Brake.Deploytime;
            comp.DelayEditField.Value = Data.Control.Brake.Delay;

            comp.MinimumangleEditField.Value = Data.Control.Pitch.Min;
            comp.MaximumangleEditField.Value = Data.Control.Pitch.Max;
            comp.MinimumrateEditField.Value = Data.Control.Pitch.Minrate;
            comp.MaximumrateEditField.Value = Data.Control.Pitch.Maxrate;

            comp.OptimalmodegainEditField.Value = Data.Control.Torque.OptGain;
            comp.GenspeedAEditField.Value = Data.Control.Torque.SpeedA;
            comp.GenspeedBEditField.Value = Data.Control.Torque.SpeedB;
            comp.GenspeedCEditField.Value = Data.Control.Torque.SpeedB2;
            comp.GenspeedDEditField.Value = Data.Control.Torque.SpeedC;
            comp.DemandedtorqueEditField.Value = Data.Control.Torque.Demanded;
            comp.LowpasscutoffEditField.Value = Data.Control.Torque.LowPassCutOffFreq;

            comp.FinepitchangleEditField.Value = Data.Control.Pitch.Fine;

            comp.MinimumTorqueEditField.Value = Data.Control.Torque.Min;
            comp.MaximumTorqueEditField.Value = Data.Control.Torque.Limit;
            comp.TorqueSlewrateEditField.Value = Data.Control.Torque.Slewrate;

            applyEdits(comp);
        end
        
    end
    
    methods (Access = private)
        
        %% Extracting WindTurbine data from tab information

        function applyEdits(comp)
            comp.WindTurbine.Control.WindSpeed.Cutin = comp.CutinEditField.Value;
            comp.WindTurbine.Control.WindSpeed.Cutout = comp.CutoutEditField.Value;

            comp.WindTurbine.Control.Pitch.StartupPitch = comp.StartuppitchangleEditField.Value;
            comp.WindTurbine.Control.Pitch.StartupPitchRate = comp.StartuppitchrateEditField.Value;
            comp.WindTurbine.Control.Pitch.StartupSpeed = comp.StartuprotorspeedEditField.Value;

            comp.WindTurbine.Control.Brake.Torque = comp.BraketorqueEditField.Value;
            comp.WindTurbine.Control.Brake.Deploytime = comp.DeploytimeEditField.Value;
            comp.WindTurbine.Control.Brake.Delay = comp.DelayEditField.Value;

            comp.WindTurbine.Control.Pitch.Min = comp.MinimumangleEditField.Value;
            comp.WindTurbine.Control.Pitch.Max = comp.MaximumangleEditField.Value;
            comp.WindTurbine.Control.Pitch.Minrate = comp.MinimumrateEditField.Value;
            comp.WindTurbine.Control.Pitch.Maxrate = comp.MaximumrateEditField.Value;

            comp.WindTurbine.Control.Torque.OptGain = comp.OptimalmodegainEditField.Value;
            comp.WindTurbine.Control.Torque.SpeedA = comp.GenspeedAEditField.Value;
            comp.WindTurbine.Control.Torque.SpeedB = comp.GenspeedBEditField.Value;
            comp.WindTurbine.Control.Torque.SpeedB2 = comp.GenspeedCEditField.Value;
            comp.WindTurbine.Control.Torque.SpeedC = comp.GenspeedDEditField.Value;
            comp.WindTurbine.Control.Torque.Demanded = comp.DemandedtorqueEditField.Value;
            comp.WindTurbine.Control.Torque.LowPassCutOffFreq = comp.LowpasscutoffEditField.Value;

            comp.WindTurbine.Control.Pitch.Fine = comp.FinepitchangleEditField.Value;

            comp.WindTurbine.Control.Torque.Min = comp.MinimumTorqueEditField.Value;
            comp.WindTurbine.Control.Torque.Limit = comp.MaximumTorqueEditField.Value;
            comp.WindTurbine.Control.Torque.Slewrate = comp.TorqueSlewrateEditField.Value;

            setRatedPower(comp.WindTurbine);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BraketorqueEditField, CutinEditField, 
        % ...and 21 other components
        function TabDataChanged(comp, event)
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSpecs(comp, Data);
            comp.specsChanged = false;
            updateForChangedSpecs(comp.mainApp);            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout1 = uigridlayout(comp.GridLayout);
            comp.SupervisorycontrolPanel = uipanel(comp.GridLayout1);
            comp.SupervisorycontrolPanel.Title = 'Supervisory control';
            comp.GridLayout11 = uigridlayout(comp.SupervisorycontrolPanel);
            comp.WindspeedsLabel = uilabel(comp.GridLayout11);
            comp.WindspeedsLabel.Text = 'Wind speeds';
            comp.CutinEditFieldLabel = uilabel(comp.GridLayout11);
            comp.CutinEditFieldLabel.Text = 'Cut-in';
            comp.CutinEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.CutinEditField.Limits = [0 Inf];
            comp.CutinEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.msLabel = uilabel(comp.GridLayout11);
            comp.msLabel.Text = 'm/s';
            comp.CutoutEditFieldLabel = uilabel(comp.GridLayout11);
            comp.CutoutEditFieldLabel.Text = 'Cut-out';
            comp.CutoutEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.CutoutEditField.Limits = [0 Inf];
            comp.CutoutEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.msLabel_2 = uilabel(comp.GridLayout11);
            comp.msLabel_2.Text = 'm/s';
            comp.StartupLabel = uilabel(comp.GridLayout11);
            comp.StartupLabel.Text = 'Startup';
            comp.StartuppitchangleEditFieldLabel = uilabel(comp.GridLayout11);
            comp.StartuppitchangleEditFieldLabel.Text = 'Startup pitch angle';
            comp.StartuppitchangleEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.StartuppitchangleEditField.Limits = [-180 180];
            comp.StartuppitchangleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel = uilabel(comp.GridLayout11);
            comp.degLabel.Text = 'deg';
            comp.StartuppitchrateEditFieldLabel = uilabel(comp.GridLayout11);
            comp.StartuppitchrateEditFieldLabel.Text = 'Startup pitch rate';
            comp.StartuppitchrateEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.StartuppitchrateEditField.Limits = [0 Inf];
            comp.StartuppitchrateEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degsLabel = uilabel(comp.GridLayout11);
            comp.degsLabel.Text = 'deg/s';
            comp.StartuprotorspeedEditFieldLabel = uilabel(comp.GridLayout11);
            comp.StartuprotorspeedEditFieldLabel.Text = 'Startup rotor speed';
            comp.StartuprotorspeedEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.StartuprotorspeedEditField.Limits = [0 Inf];
            comp.StartuprotorspeedEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.rpmLabel = uilabel(comp.GridLayout11);
            comp.rpmLabel.Text = 'rpm';
            comp.ShutdownLabel = uilabel(comp.GridLayout11);
            comp.ShutdownLabel.Text = 'Shutdown';
            comp.BraketorqueEditFieldLabel = uilabel(comp.GridLayout11);
            comp.BraketorqueEditFieldLabel.Text = 'Brake torque';
            comp.BraketorqueEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.BraketorqueEditField.Limits = [0 Inf];
            comp.BraketorqueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NmLabel_4 = uilabel(comp.GridLayout11);
            comp.NmLabel_4.Text = 'Nm';
            comp.DeploytimeEditFieldLabel = uilabel(comp.GridLayout11);
            comp.DeploytimeEditFieldLabel.Text = 'Deploy time';
            comp.DeploytimeEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.DeploytimeEditField.Limits = [0 Inf];
            comp.DeploytimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.sLabel = uilabel(comp.GridLayout11);
            comp.sLabel.Text = 's';
            comp.DelayEditFieldLabel = uilabel(comp.GridLayout11);
            comp.DelayEditFieldLabel.Text = 'Delay';
            comp.DelayEditField = uieditfield(comp.GridLayout11, 'numeric');
            comp.DelayEditField.Limits = [0 Inf];
            comp.DelayEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.sLabel_2 = uilabel(comp.GridLayout11);
            comp.sLabel_2.Text = 's';
            comp.GridLayout12 = uigridlayout(comp.GridLayout1);
            comp.TorquecontrolPanel = uipanel(comp.GridLayout12);
            comp.TorquecontrolPanel.Title = 'Torque control';
            comp.GridLayout121 = uigridlayout(comp.TorquecontrolPanel);
            comp.OptimalmodegainEditFieldLabel = uilabel(comp.GridLayout121);
            comp.OptimalmodegainEditFieldLabel.Text = 'Optimal mode gain';
            comp.OptimalmodegainEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.OptimalmodegainEditField.Limits = [0 Inf];
            comp.OptimalmodegainEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Nmradssup2supLabel = uilabel(comp.GridLayout121);
            comp.Nmradssup2supLabel.Interpreter = 'html';
            comp.Nmradssup2supLabel.Text = {'(Nm)/'; '(rad/s)<sup>2</sup>'};
            comp.GenspeedALabel = uilabel(comp.GridLayout121);
            comp.GenspeedALabel.Text = 'Gen. speed ω [A]';
            comp.GenspeedAEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.GenspeedAEditField.Limits = [0 Inf];
            comp.GenspeedAEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.rpmLabel_2 = uilabel(comp.GridLayout121);
            comp.rpmLabel_2.Text = 'rpm';
            comp.GenspeedBLabel = uilabel(comp.GridLayout121);
            comp.GenspeedBLabel.Text = 'Gen. speed ω [B]';
            comp.GenspeedBEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.GenspeedBEditField.Limits = [0 Inf];
            comp.GenspeedBEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.rpmLabel_3 = uilabel(comp.GridLayout121);
            comp.rpmLabel_3.Text = 'rpm';
            comp.GenspeedCLabel = uilabel(comp.GridLayout121);
            comp.GenspeedCLabel.Text = 'Gen. speed ω [C]';
            comp.GenspeedCEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.GenspeedCEditField.Limits = [0 Inf];
            comp.GenspeedCEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.rpmLabel_4 = uilabel(comp.GridLayout121);
            comp.rpmLabel_4.Text = 'rpm';
            comp.GenspeedDEditFieldLabel = uilabel(comp.GridLayout121);
            comp.GenspeedDEditFieldLabel.Text = 'Gen. speed ω [D]';
            comp.GenspeedDEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.GenspeedDEditField.Limits = [0 Inf];
            comp.GenspeedDEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.rpmLabel_5 = uilabel(comp.GridLayout121);
            comp.rpmLabel_5.Text = 'rpm';
            comp.DemandedtorqueEditFieldLabel = uilabel(comp.GridLayout121);
            comp.DemandedtorqueEditFieldLabel.Text = 'Demanded torque';
            comp.DemandedtorqueEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.DemandedtorqueEditField.Limits = [0 Inf];
            comp.DemandedtorqueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NmLabel = uilabel(comp.GridLayout121);
            comp.NmLabel.Text = 'Nm';
            comp.GenspeedlowpasscutoffLabel = uilabel(comp.GridLayout121);
            comp.GenspeedlowpasscutoffLabel.Text = 'Low-pass cut-off';
            comp.LowpasscutoffEditField = uieditfield(comp.GridLayout121, 'numeric');
            comp.LowpasscutoffEditField.Limits = [0 Inf];
            comp.LowpasscutoffEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.LowpasscutoffEditField.Tooltip = {'Cut-off frequency of generator-speed measurement low-pass filter'};
            comp.radsLabel = uilabel(comp.GridLayout121);
            comp.radsLabel.Text = 'rad/s';
            comp.PartialloadpitchPanel = uipanel(comp.GridLayout12);
            comp.PartialloadpitchPanel.Title = 'Partial-load pitch';
            comp.GridLayout122 = uigridlayout(comp.PartialloadpitchPanel);
            comp.FinepitchangleEditFieldLabel = uilabel(comp.GridLayout122);
            comp.FinepitchangleEditFieldLabel.Text = 'Fine-pitch angle';
            comp.FinepitchangleEditField = uieditfield(comp.GridLayout122, 'numeric');
            comp.FinepitchangleEditField.Limits = [-180 180];
            comp.FinepitchangleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel_4 = uilabel(comp.GridLayout122);
            comp.degLabel_4.Text = 'deg';
            comp.ActuatorlimitsPanel = uipanel(comp.GridLayout);
            comp.ActuatorlimitsPanel.Title = 'Actuator limits';
            comp.GridLayout2 = uigridlayout(comp.ActuatorlimitsPanel);
            comp.GridLayout21 = uigridlayout(comp.GridLayout2);
            comp.PitchLabel = uilabel(comp.GridLayout21);
            comp.PitchLabel.Text = 'Pitch';
            comp.MinimumangleEditFieldLabel = uilabel(comp.GridLayout21);
            comp.MinimumangleEditFieldLabel.Text = 'Minimum angle';
            comp.MinimumangleEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.MinimumangleEditField.Limits = [-180 180];
            comp.MinimumangleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel_2 = uilabel(comp.GridLayout21);
            comp.degLabel_2.Text = 'deg';
            comp.MaximumangleEditField_2Label = uilabel(comp.GridLayout21);
            comp.MaximumangleEditField_2Label.Text = 'Maximum angle';
            comp.MaximumangleEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.MaximumangleEditField.Limits = [-180 180];
            comp.MaximumangleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel_3 = uilabel(comp.GridLayout21);
            comp.degLabel_3.Text = 'deg';
            comp.MinimumrateEditFieldLabel = uilabel(comp.GridLayout21);
            comp.MinimumrateEditFieldLabel.Text = 'Minimum rate';
            comp.MinimumrateEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.MinimumrateEditField.Limits = [-Inf 0];
            comp.MinimumrateEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degsLabel_2 = uilabel(comp.GridLayout21);
            comp.degsLabel_2.Text = 'deg/s';
            comp.MaximumrateEditFieldLabel = uilabel(comp.GridLayout21);
            comp.MaximumrateEditFieldLabel.Text = 'Maximum rate';
            comp.MaximumrateEditField = uieditfield(comp.GridLayout21, 'numeric');
            comp.MaximumrateEditField.Limits = [0 Inf];
            comp.MaximumrateEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degsLabel_3 = uilabel(comp.GridLayout21);
            comp.degsLabel_3.Text = 'deg/s';
            comp.GridLayout22 = uigridlayout(comp.GridLayout2);
            comp.TorqueLabel = uilabel(comp.GridLayout22);
            comp.TorqueLabel.Text = 'Torque';
            comp.MinimumEditFieldLabel = uilabel(comp.GridLayout22);
            comp.MinimumEditFieldLabel.Text = 'Minimum';
            comp.MinimumTorqueEditField = uieditfield(comp.GridLayout22, 'numeric');
            comp.MinimumTorqueEditField.Limits = [0 Inf];
            comp.MinimumTorqueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NmLabel_2 = uilabel(comp.GridLayout22);
            comp.NmLabel_2.Text = 'Nm';
            comp.MaximumEditFieldLabel = uilabel(comp.GridLayout22);
            comp.MaximumEditFieldLabel.Text = 'Maximum';
            comp.MaximumTorqueEditField = uieditfield(comp.GridLayout22, 'numeric');
            comp.MaximumTorqueEditField.Limits = [0 Inf];
            comp.MaximumTorqueEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NmLabel_3 = uilabel(comp.GridLayout22);
            comp.NmLabel_3.Text = 'Nm';
            comp.SlewrateEditFieldLabel = uilabel(comp.GridLayout22);
            comp.SlewrateEditFieldLabel.Text = 'Slew rate';
            comp.TorqueSlewrateEditField = uieditfield(comp.GridLayout22, 'numeric');
            comp.TorqueSlewrateEditField.Limits = [0 Inf];
            comp.TorqueSlewrateEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NmsLabel = uilabel(comp.GridLayout22);
            comp.NmsLabel.Text = 'Nm/s';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.RestoreButton = uibutton(comp.GridLayout3, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreButton.Text = 'Restore';
        end
    end
end