classdef Control2Specs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        RestoreButton           matlab.ui.control.Button
        PIcontrollergainsandfiltersPanel  matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        ImporttableButton       matlab.ui.control.Button
        ExporttableButton       matlab.ui.control.Button
        GainscheduleTable       matlab.ui.control.Table
        Notch2wnEditField       matlab.ui.control.NumericEditField
        Notch2B2EditField       matlab.ui.control.NumericEditField
        Notch2B1EditField       matlab.ui.control.NumericEditField
        Label_2                 matlab.ui.control.Label
        Notch1wnEditField       matlab.ui.control.NumericEditField
        Notch1B2EditField       matlab.ui.control.NumericEditField
        Notch1B1EditField       matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        ConstantCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        ConstantKiEditField     matlab.ui.control.NumericEditField
        KiEditFieldLabel        matlab.ui.control.Label
        ConstantKpEditField     matlab.ui.control.NumericEditField
        KpEditFieldLabel        matlab.ui.control.Label
        nradsLabel              matlab.ui.control.Label
        radsLabel_2             matlab.ui.control.Label
        radsLabel               matlab.ui.control.Label
        cradsLabel              matlab.ui.control.Label
        KLabel                  matlab.ui.control.Label
        NotchfiltersLabel       matlab.ui.control.Label
        LowpassfilterLabel      matlab.ui.control.Label
        PIcontrollergainsLabel  matlab.ui.control.Label
        GridLayout1             matlab.ui.container.GridLayout
        ForeaftdampingforallwindspeedsPanel  matlab.ui.container.Panel
        GridLayout13            matlab.ui.container.GridLayout
        degLabel                matlab.ui.control.Label
        DamperMaximumAmplitudeEditField  matlab.ui.control.NumericEditField
        MaxpitchamplitudeLabel  matlab.ui.control.Label
        DamperGainEditField     matlab.ui.control.NumericEditField
        GainEditFieldLabel      matlab.ui.control.Label
        LowpassfiltertypenotscheduledPanel  matlab.ui.container.Panel
        GridLayout12            matlab.ui.container.GridLayout
        FilterOrderButtonGroup  matlab.ui.container.ButtonGroup
        SecondorderButton       matlab.ui.control.RadioButton
        FirstorderButton        matlab.ui.control.RadioButton
        ControllergainsPanel    matlab.ui.container.Panel
        GridLayout11            matlab.ui.container.GridLayout
        ScheduleButtonGroup     matlab.ui.container.ButtonGroup
        ScheduledgainButton     matlab.ui.control.RadioButton
        ConstantgainButton      matlab.ui.control.RadioButton
    end

    properties (Access = private)
        WindTurbine
        mainApp
        specsChanged
        variableNames
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function setSpecs(comp, Data)
            comp.DamperGainEditField.Value = Data.Control.ForeAft.Gain;
            comp.DamperMaximumAmplitudeEditField.Value = Data.Control.ForeAft.MaxPitchAmplitude;

            if Data.Control.Pitch.LowPassOrder == 1
                comp.FilterOrderButtonGroup.SelectedObject = comp.FirstorderButton;
            else
                comp.FilterOrderButtonGroup.SelectedObject = comp.SecondorderButton;
            end

            comp.ConstantCutoffFrequencyEditField.Value = Data.Control.Pitch.LowPassCutOffFreq;
            comp.ConstantKpEditField.Value = Data.Control.Pitch.Kp;
            comp.ConstantKiEditField.Value = Data.Control.Pitch.Ki;

            if Data.Control.Pitch.Scheduled
                comp.ScheduleButtonGroup.SelectedObject = comp.ScheduledgainButton;
            else
                comp.ScheduleButtonGroup.SelectedObject = comp.ConstantgainButton;
            end
            comp.Notch1B1EditField.Value = Data.Control.Pitch.Notch_beta1;
            comp.Notch1B2EditField.Value = Data.Control.Pitch.Notch_beta2;
            comp.Notch1wnEditField.Value = Data.Control.Pitch.Notch_wn;
            comp.Notch2B1EditField.Value = Data.Control.Pitch.Notch2_beta1;
            comp.Notch2B2EditField.Value = Data.Control.Pitch.Notch2_beta2;
            comp.Notch2wnEditField.Value = Data.Control.Pitch.Notch2_wn;
            setSchedulingTable(comp, Data);

            adjustTab(comp);
            applyEdits(comp);
        end

        % FASTTool.updateGainScheduleTable requests an update of the 
        % gain-scheduling table. This is done when a request is made on the
        % Control analysis tab to reschedule the controller based on the
        % pitch angles of the linear model. The inter- and extrapolation of
        % the schedule to the new pitch angles has been done in
        % WindTurbineClass, following a request from the Control analysis
        % tab.
        function setGainScheduleTable(comp, Data)
            setSchedulingTable(comp, Data);
            comp.specsChanged = true;
            updateForChangedControl(comp.mainApp);
        end
    end
    
    methods (Access = private)
        
        %% Managing the display of data on the tab

        function setSchedulingTable(comp, Data)
            comp.GainscheduleTable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
            comp.GainscheduleTable.Data = [...
                num2cell(Data.Control.Pitch.ScheduledPitchAngles(:) * 180 / pi), ...
                num2cell(Data.Control.Pitch.KpGS(:)), ...
                num2cell(Data.Control.Pitch.KiGS(:)), ...
                num2cell(Data.Control.Pitch.LowPassCutOffFreqGS(:)), ...
                num2cell(Data.Control.Pitch.Notch_beta1GS(:)), ...
                num2cell(Data.Control.Pitch.Notch_beta2GS(:)), ...
                num2cell(Data.Control.Pitch.Notch_wnGS(:)), ...
                num2cell(Data.Control.Pitch.Notch2_beta1GS(:)), ...
                num2cell(Data.Control.Pitch.Notch2_beta2GS(:)), ...
                num2cell(Data.Control.Pitch.Notch2_wnGS(:))];
        end

        %% Managing consistency of tab content and switches

        function adjustTab(comp)
            if comp.ScheduleButtonGroup.SelectedObject == comp.ScheduledgainButton
                comp.PIcontrollergainsLabel.Enable = "off";
                comp.LowpassfilterLabel.Enable = "off";
                comp.NotchfiltersLabel.Enable = "off";
                comp.KpEditFieldLabel.Enable = "off";
                comp.KiEditFieldLabel.Enable = "off";
                comp.Label.Enable = "off";
                comp.Label_2.Enable = "off";
                comp.KLabel.Enable = "off";
                comp.cradsLabel.Enable = "off";
                comp.radsLabel.Enable = "off";
                comp.radsLabel_2.Enable = "off";
                comp.nradsLabel.Enable = "off";
                comp.ConstantCutoffFrequencyEditField.Enable = "off";
                comp.ConstantKpEditField.Enable = "off";
                comp.ConstantKiEditField.Enable = "off";
                comp.Notch1B1EditField.Enable = "off";
                comp.Notch1B2EditField.Enable = "off";
                comp.Notch1wnEditField.Enable = "off";
                comp.Notch2B1EditField.Enable = "off";
                comp.Notch2B2EditField.Enable = "off";
                comp.Notch2wnEditField.Enable = "off";

                comp.GainscheduleTable.Enable = "on";
                comp.ImporttableButton.Enable = "on";
                comp.ExporttableButton.Enable = "on";
            else
                comp.PIcontrollergainsLabel.Enable = "on";
                comp.LowpassfilterLabel.Enable = "on";
                comp.NotchfiltersLabel.Enable = "on";
                comp.KpEditFieldLabel.Enable = "on";
                comp.KiEditFieldLabel.Enable = "on";
                comp.Label.Enable = "on";
                comp.Label_2.Enable = "on";
                comp.KLabel.Enable = "on";
                comp.cradsLabel.Enable = "on";
                comp.radsLabel.Enable = "on";
                comp.radsLabel_2.Enable = "on";
                comp.nradsLabel.Enable = "on";
                comp.ConstantCutoffFrequencyEditField.Enable = "on";
                comp.ConstantKpEditField.Enable = "on";
                comp.ConstantKiEditField.Enable = "on";
                comp.Notch1B1EditField.Enable = "on";
                comp.Notch1B2EditField.Enable = "on";
                comp.Notch1wnEditField.Enable = "on";
                comp.Notch2B1EditField.Enable = "on";
                comp.Notch2B2EditField.Enable = "on";
                comp.Notch2wnEditField.Enable = "on";

                comp.GainscheduleTable.Enable = "off";
                comp.ImporttableButton.Enable = "off";
                comp.ExporttableButton.Enable = "off";
            end
        end

        %% Extracting WindTurbine data from tab information

        function applyEdits(comp)
            comp.WindTurbine.Control.ForeAft.Gain = comp.DamperGainEditField.Value;
            comp.WindTurbine.Control.ForeAft.MaxPitchAmplitude = comp.DamperMaximumAmplitudeEditField.Value;

            if comp.FilterOrderButtonGroup.SelectedObject == comp.FirstorderButton
                comp.WindTurbine.Control.Pitch.LowPassOrder = 1;
            else
                comp.WindTurbine.Control.Pitch.LowPassOrder = 2;
            end

            comp.WindTurbine.Control.Pitch.LowPassCutOffFreq = comp.ConstantCutoffFrequencyEditField.Value;
            comp.WindTurbine.Control.Pitch.Kp = comp.ConstantKpEditField.Value;
            comp.WindTurbine.Control.Pitch.Ki = comp.ConstantKiEditField.Value;

            if comp.ScheduleButtonGroup.SelectedObject == comp.ScheduledgainButton
                comp.WindTurbine.Control.Pitch.Scheduled = 1;
            else
                comp.WindTurbine.Control.Pitch.Scheduled = 0;
            end
            comp.WindTurbine.Control.Pitch.Notch_beta1 = comp.Notch1B1EditField.Value;
            comp.WindTurbine.Control.Pitch.Notch_beta2 = comp.Notch1B2EditField.Value;
            comp.WindTurbine.Control.Pitch.Notch_wn = comp.Notch1wnEditField.Value;
            comp.WindTurbine.Control.Pitch.Notch2_beta1 = comp.Notch2B1EditField.Value;
            comp.WindTurbine.Control.Pitch.Notch2_beta2 = comp.Notch2B2EditField.Value;
            comp.WindTurbine.Control.Pitch.Notch2_wn = comp.Notch2wnEditField.Value;
            applySchedulingTable(comp);
        end

        function applySchedulingTable(comp)
            T = comp.GainscheduleTable.Data;
                endTable = length(T(:,1));
                comp.WindTurbine.Control.Pitch.ScheduledPitchAngles = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.KpGS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.KiGS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.LowPassCutOffFreqGS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.Notch_beta1GS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.Notch_beta2GS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.Notch_wnGS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.Notch2_beta1GS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.Notch2_beta2GS = ones(endTable,1);
                comp.WindTurbine.Control.Pitch.Notch2_wnGS = ones(endTable,1);

                comp.WindTurbine.Control.Pitch.ScheduledPitchAngles(:) = cell2mat(T(:,1)) * pi / 180;
                comp.WindTurbine.Control.Pitch.KpGS(:) = cell2mat(T(:,2));
                comp.WindTurbine.Control.Pitch.KiGS(:) = cell2mat(T(:,3));
                comp.WindTurbine.Control.Pitch.LowPassCutOffFreqGS(:) = cell2mat(T(:,4));
                comp.WindTurbine.Control.Pitch.Notch_beta1GS(:) = cell2mat(T(:,5));
                comp.WindTurbine.Control.Pitch.Notch_beta2GS(:) = cell2mat(T(:,6));
                comp.WindTurbine.Control.Pitch.Notch_wnGS(:) = cell2mat(T(:,7));
                comp.WindTurbine.Control.Pitch.Notch2_beta1GS(:) = cell2mat(T(:,8));
                comp.WindTurbine.Control.Pitch.Notch2_beta2GS(:) = cell2mat(T(:,9));
                comp.WindTurbine.Control.Pitch.Notch2_wnGS(:) = cell2mat(T(:,10));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.variableNames = ["Pitch [deg]" "Kp [-]" "Ki [-]" "ωc (LPF) [rad/s]" "β1 [rad/s]" "β2 [rad/s]" "ωn [rad/s]" "β1_2 [rad/s]" "β2_2 [rad/s]" "ωn_2 [rad/s]"];
        end

        % Callback function: ConstantCutoffFrequencyEditField, 
        % ...and 12 other components
        function TabDataChanged(comp, event)
            adjustTab(comp);
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedControl(comp.mainApp);
        end

        % Button pushed function: ExporttableButton
        function ExporttableButtonPushed(comp, event)
            DataTable = cell2table(comp.GainscheduleTable.Data, VariableNames = comp.variableNames);
            exportTable(comp.mainApp, DataTable, 'Export control scheduling data');
        end

        % Button pushed function: ImporttableButton
        function ImporttableButtonPushed(comp, event)
            [DataTable] = importTable(comp.mainApp, 'Import control scheduling data', 'controltable');
            if isempty(DataTable)
                return;
            end

            % Verify that data is within acceptable limits
            subinfo{1} = checkTableImport(comp.mainApp, DataTable, comp.variableNames, 1, [-180, 180]);
            subinfo{2} = checkTableImport(comp.mainApp, DataTable, comp.variableNames, [2, 3], [-Inf, 0]);
            subinfo{3} = checkTableImport(comp.mainApp, DataTable, comp.variableNames, [4, 5, 6, 7, 8, 9, 10], [0, Inf]);
            info = sprintf('%s', subinfo{:});
            if ~isempty(info)
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end
            
            comp.GainscheduleTable.Data = table2cell(DataTable(:, 1:10));
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedControl(comp.mainApp);            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSpecs(comp, Data);
            comp.specsChanged = false;
            updateForChangedControl(comp.mainApp);            
        end

        % Cell edit callback: GainscheduleTable
        function GainscheduleTableCellEdit(comp, event)

            % Verify that data is within acceptable limits
            switch event.Indices(2)
                case 1
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [-180, 180]);
                case {2, 3}
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [-Inf, 0]);
                case {4, 5, 6, 7, 8, 9, 10}
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [0, Inf]);
            end
            if ~isempty(info)
                comp.GainscheduleTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedControl(comp.mainApp);            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout1 = uigridlayout(comp.GridLayout);
            comp.ControllergainsPanel = uipanel(comp.GridLayout1);
            comp.ControllergainsPanel.Title = 'Controller gains';
            comp.GridLayout11 = uigridlayout(comp.ControllergainsPanel);
            comp.ScheduleButtonGroup = uibuttongroup(comp.GridLayout11);
            comp.ScheduleButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.ConstantgainButton = uiradiobutton(comp.ScheduleButtonGroup);
            comp.ConstantgainButton.Text = 'Constant gain';
            comp.ConstantgainButton.Value = true;
            comp.ScheduledgainButton = uiradiobutton(comp.ScheduleButtonGroup);
            comp.ScheduledgainButton.Text = 'Scheduled gain';
            comp.LowpassfiltertypenotscheduledPanel = uipanel(comp.GridLayout1);
            comp.LowpassfiltertypenotscheduledPanel.Title = 'Low-pass filter type (not scheduled)';
            comp.GridLayout12 = uigridlayout(comp.LowpassfiltertypenotscheduledPanel);
            comp.FilterOrderButtonGroup = uibuttongroup(comp.GridLayout12);
            comp.FilterOrderButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.FirstorderButton = uiradiobutton(comp.FilterOrderButtonGroup);
            comp.FirstorderButton.Text = '1st order';
            comp.FirstorderButton.Value = true;
            comp.SecondorderButton = uiradiobutton(comp.FilterOrderButtonGroup);
            comp.SecondorderButton.Text = '2nd order';
            comp.ForeaftdampingforallwindspeedsPanel = uipanel(comp.GridLayout1);
            comp.ForeaftdampingforallwindspeedsPanel.Title = 'Fore-aft damping (for all wind speeds)';
            comp.GridLayout13 = uigridlayout(comp.ForeaftdampingforallwindspeedsPanel);
            comp.GainEditFieldLabel = uilabel(comp.GridLayout13);
            comp.GainEditFieldLabel.Text = 'Gain';
            comp.DamperGainEditField = uieditfield(comp.GridLayout13, 'numeric');
            comp.DamperGainEditField.Limits = [0 Inf];
            comp.DamperGainEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.MaxpitchamplitudeLabel = uilabel(comp.GridLayout13);
            comp.MaxpitchamplitudeLabel.Text = 'Max. pitch amplitude';
            comp.DamperMaximumAmplitudeEditField = uieditfield(comp.GridLayout13, 'numeric');
            comp.DamperMaximumAmplitudeEditField.Limits = [0 Inf];
            comp.DamperMaximumAmplitudeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel = uilabel(comp.GridLayout13);
            comp.degLabel.Text = 'deg';
            comp.PIcontrollergainsandfiltersPanel = uipanel(comp.GridLayout);
            comp.PIcontrollergainsandfiltersPanel.Title = 'PI controller gains and filters';
            comp.GridLayout2 = uigridlayout(comp.PIcontrollergainsandfiltersPanel);
            comp.PIcontrollergainsLabel = uilabel(comp.GridLayout2);
            comp.PIcontrollergainsLabel.Text = 'PI-controller gains';
            comp.LowpassfilterLabel = uilabel(comp.GridLayout2);
            comp.LowpassfilterLabel.Text = 'Low-pass filter';
            comp.NotchfiltersLabel = uilabel(comp.GridLayout2);
            comp.NotchfiltersLabel.Text = 'Notch filters';
            comp.KLabel = uilabel(comp.GridLayout2);
            comp.KLabel.Text = 'K [-]';
            comp.cradsLabel = uilabel(comp.GridLayout2);
            comp.cradsLabel.Text = 'ωc [rad/s]';
            comp.radsLabel = uilabel(comp.GridLayout2);
            comp.radsLabel.Text = 'β1 [rad/s]';
            comp.radsLabel_2 = uilabel(comp.GridLayout2);
            comp.radsLabel_2.Text = 'β2 [rad/s]';
            comp.nradsLabel = uilabel(comp.GridLayout2);
            comp.nradsLabel.Text = 'ωn [rad/s]';
            comp.KpEditFieldLabel = uilabel(comp.GridLayout2);
            comp.KpEditFieldLabel.Text = 'Kp';
            comp.ConstantKpEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ConstantKpEditField.Limits = [-Inf 0];
            comp.ConstantKpEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.KiEditFieldLabel = uilabel(comp.GridLayout2);
            comp.KiEditFieldLabel.Text = 'Ki';
            comp.ConstantKiEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ConstantKiEditField.Limits = [-Inf 0];
            comp.ConstantKiEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.ConstantCutoffFrequencyEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ConstantCutoffFrequencyEditField.Limits = [0 Inf];
            comp.ConstantCutoffFrequencyEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Label = uilabel(comp.GridLayout2);
            comp.Label.Text = '1:';
            comp.Notch1B1EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.Notch1B1EditField.Limits = [0 Inf];
            comp.Notch1B1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Notch1B2EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.Notch1B2EditField.Limits = [0 Inf];
            comp.Notch1B2EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Notch1wnEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.Notch1wnEditField.Limits = [0 Inf];
            comp.Notch1wnEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Label_2 = uilabel(comp.GridLayout2);
            comp.Label_2.Text = '2:';
            comp.Notch2B1EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.Notch2B1EditField.Limits = [0 Inf];
            comp.Notch2B1EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Notch2B2EditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.Notch2B2EditField.Limits = [0 Inf];
            comp.Notch2B2EditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Notch2wnEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.Notch2wnEditField.Limits = [0 Inf];
            comp.Notch2wnEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.GainscheduleTable = uitable(comp.GridLayout2);
            comp.GainscheduleTable.ColumnName = {'Pitch [deg]'; 'Kp [-]'; 'Ki [-]'; 'ωc (LPF) [rad/s]'; 'β1 [rad/s]'; 'β2 [rad/s]'; 'ωn [rad/s]'; 'β1 [rad/s'; 'β2 [rad/s]'; 'ωn [rad/s]'};
            comp.GainscheduleTable.RowName = {};
            comp.GainscheduleTable.ColumnEditable = true;
            comp.GainscheduleTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @GainscheduleTableCellEdit, true);
            comp.ExporttableButton = uibutton(comp.GridLayout2, 'push');
            comp.ExporttableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExporttableButtonPushed, true);
            comp.ExporttableButton.Text = 'Export table';
            comp.ImporttableButton = uibutton(comp.GridLayout2, 'push');
            comp.ImporttableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImporttableButtonPushed, true);
            comp.ImporttableButton.Text = 'Import table';
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.RestoreButton = uibutton(comp.GridLayout3, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreButton.Text = 'Restore';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end