classdef ControlAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                  matlab.ui.container.GridLayout
        SetandrunPanel              matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        LinearModelFile             matlab.ui.control.Label
        LinearmodelLabel_2          matlab.ui.control.Label
        ControllerFile              matlab.ui.control.Label
        ResultsFile                 matlab.ui.control.Label
        ControllerLabel             matlab.ui.control.Label
        ResultsfileLabel            matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        SamplingtimeEditFieldLabel  matlab.ui.control.Label
        StepsizeEditFieldLabel      matlab.ui.control.Label
        OptionsTree                 matlab.ui.container.CheckBoxTree
        ResponseNode                matlab.ui.container.TreeNode
        NominalplantNode            matlab.ui.container.TreeNode
        LoopgainNode                matlab.ui.container.TreeNode
        MarginsNode                 matlab.ui.container.TreeNode
        ControlelementsNode         matlab.ui.container.TreeNode
        PIcontrollerNode            matlab.ui.container.TreeNode
        LowpassfilterNode           matlab.ui.container.TreeNode
        NotchfilterNode             matlab.ui.container.TreeNode
        PlotoptionsLabel            matlab.ui.control.Label
        RescheduleButton            matlab.ui.control.Button
        OperatingPointsTree         matlab.ui.container.CheckBoxTree
        OperatingpointsdegmsLabel   matlab.ui.control.Label
        sLabel                      matlab.ui.control.Label
        SamplingtimeEditField       matlab.ui.control.NumericEditField
        msLabel                     matlab.ui.control.Label
        StepsizeEditField           matlab.ui.control.NumericEditField
        LineariseButton             matlab.ui.control.Button
        CreatenewLabel              matlab.ui.control.Label
        OpenButton                  matlab.ui.control.Button
        UseexistingLabel            matlab.ui.control.Label
        LinearmodelLabel            matlab.ui.control.Label
        PlotPanel                   matlab.ui.container.Panel
        GridLayout1                 matlab.ui.container.GridLayout
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        WindTurbine
        mainApp
        mode
        pointsTreeText % Text used for nodes to identify operating points in tree
    end
    
    properties (Access = public)
        existLinearModel logical
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing the display of data on the tab

        function resetUI(comp)
            comp.existLinearModel = false;
            comp.StepsizeEditField.Value = 1;
            delete(comp.OperatingPointsTree.Children);
            comp.OptionsTree.CheckedNodes = [];
            comp.OptionsTree.SelectedNodes = [];
            scroll(comp.OptionsTree, 'top');
            cla(comp.UIAxes);
            cla(comp.UIAxes2);
            legend(comp.UIAxes, "off");
            comp.UIAxes.Title.String = "";
            comp.SaveButton.Enable = "off";
            comp.RescheduleButton.Enable = "off";
            comp.mode = "project";

            comp.RescheduleButton.Visible = "on";
            comp.sLabel.Visible = "on";
            comp.SamplingtimeEditField.Visible = "on";
            comp.SamplingtimeEditFieldLabel.Visible = "on";
            comp.msLabel.Visible = "on";
            comp.StepsizeEditField.Visible = "on";
            comp.StepsizeEditFieldLabel.Visible = "on";
            comp.LineariseButton.Visible = "on";
            comp.CreatenewLabel.Visible = "on";
            comp.OpenButton.Visible = "on";
            comp.UseexistingLabel.Visible = "on";
            comp.LinearmodelLabel.Visible = "on";

            comp.SaveButton.Text = "Save";
            comp.ResultsfileLabel.Visible = "off";
            comp.LinearmodelLabel_2.Visible = "off";
            comp.ControllerLabel.Visible = "off";
            comp.ResultsFile.Visible = "off";
            comp.LinearModelFile.Visible = "off";
            comp.ControllerFile.Visible = "off";
            comp.ResultsFile.Text = '';
            comp.LinearModelFile.Text = '';
            comp.ControllerFile.Text = '';
            comp.ResultsFile.Tooltip = '';
            comp.LinearModelFile.Tooltip = '';
            comp.ControllerFile.Tooltip = '';
        end

        function setResultsMode(comp)
            comp.mode = "results";
            comp.RescheduleButton.Visible = "off";
            comp.sLabel.Visible = "off";
            comp.SamplingtimeEditField.Visible = "off";
            comp.SamplingtimeEditFieldLabel.Visible = "off";
            comp.msLabel.Visible = "off";
            comp.StepsizeEditField.Visible = "off";
            comp.StepsizeEditFieldLabel.Visible = "off";
            comp.LineariseButton.Visible = "off";
            comp.CreatenewLabel.Visible = "off";
            comp.OpenButton.Visible = "off";
            comp.UseexistingLabel.Visible = "off";
            comp.LinearmodelLabel.Visible = "off";

            comp.SaveButton.Text = "Load";
            comp.SaveButton.Enable = "on";
            comp.ResultsfileLabel.Visible = "on";
            comp.LinearmodelLabel_2.Visible = "on";
            comp.ControllerLabel.Visible = "on";
            comp.ResultsFile.Visible = "on";
            comp.LinearModelFile.Visible = "on";
            comp.ControllerFile.Visible = "on";
        end
        
        function setSpecs(comp, Data)
            comp.SamplingtimeEditField.Value = Data.CertificationSettings.Run.DT;
        end

        function loadingReady(comp)
            switch comp.mode
                case "project"
                    delete(comp.OperatingPointsTree.Children);
                    ControlResults = comp.WindTurbine.ControlResults;
                    comp.existLinearModel = true;

                    comp.UIAxes.Title.String = comp.WindTurbine.LinearModel.fileName;
                    if ~isempty(ControlResults.fullLoadIndex)
                        all = uitreenode(comp.OperatingPointsTree, "Text", "All");
                        comp.pointsTreeText = {};
                        for i = 1:length(ControlResults.fullLoadIndex)
                            text = sprintf(['%.1f' ' [' '%.1f' ']'] , comp.WindTurbine.LinearModel.Lin.Pitch(ControlResults.fullLoadIndex(i))*180/pi, comp.WindTurbine.LinearModel.Lin.V(ControlResults.fullLoadIndex(i)));
                            uitreenode(all, "Text", text);
                            comp.pointsTreeText{i} = text;
                        end
                        expand(comp.OperatingPointsTree);
                    end
                    plotControlAnalysis(comp);
                    comp.SaveButton.Enable = "on";
                    comp.RescheduleButton.Enable = "on";
                
                case "results"
                    comp.ResultsFile.Text = comp.WindTurbine.ControlResults.fileName;
                    comp.LinearModelFile.Text = comp.WindTurbine.ControlResults.Info.linearModelFile;
                    comp.ControllerFile.Text = comp.WindTurbine.ControlResults.Info.projectFile;
                    comp.ResultsFile.Tooltip = comp.WindTurbine.ControlResults.fileName;
                    comp.LinearModelFile.Tooltip = comp.WindTurbine.ControlResults.Info.linearModelFile;
                    comp.ControllerFile.Tooltip = comp.WindTurbine.ControlResults.Info.projectFile;
                    delete(comp.OperatingPointsTree.Children);
                    all = uitreenode(comp.OperatingPointsTree, "Text", "All");
                    comp.pointsTreeText = {};
                    for i = 1:length(comp.WindTurbine.ControlResults.pitch)
                        text = sprintf(['%.1f' ' [' '%.1f' ']'] , comp.WindTurbine.ControlResults.pitch(i)*180/pi, comp.WindTurbine.ControlResults.windSpeed(i));
                        uitreenode(all, "Text", text);
                        comp.pointsTreeText{i} = text;
                    end
                    expand(comp.OperatingPointsTree);
                    plotControlAnalysis(comp);
            end
        end

        function plotControlAnalysis(comp)

            if ~comp.existLinearModel && ~strcmp(comp.mode, "results")
                return;
            end

            ControlResults = comp.WindTurbine.ControlResults;

            cla(comp.UIAxes);
            cla(comp.UIAxes2);
            legend(comp.UIAxes, "off");

            selectedPointIndex = [];
            for i = 1 : length(comp.OperatingPointsTree.CheckedNodes)
                if comp.OperatingPointsTree.CheckedNodes(i).Text ~= "All"
                    selectedPointIndex = [selectedPointIndex (find(contains(comp.pointsTreeText, ...
                        comp.OperatingPointsTree.CheckedNodes(i).Text)))];
                end
            end

            isNominalPlant = false;
            isLoopGain = false;
            isMargins = false;
            noControllerOptions = true;
            controllerOptions = contains(ControlResults.responseType, "");
            for i = 1 : length(comp.OptionsTree.CheckedNodes)
                if comp.OptionsTree.CheckedNodes(i).Text == "Nominal plant"
                    isNominalPlant = true;
                elseif comp.OptionsTree.CheckedNodes(i).Text == "Loop gain"
                    isLoopGain = true;
                elseif comp.OptionsTree.CheckedNodes(i).Text == "Margins"
                    isMargins = true;
                elseif comp.OptionsTree.CheckedNodes(i).Text == "PI controller"
                    noControllerOptions = false;
                    controllerOptions = controllerOptions & contains(ControlResults.responseType, "PI");
                elseif comp.OptionsTree.CheckedNodes(i).Text == "Low-pass filter"
                    noControllerOptions = false;
                    controllerOptions = controllerOptions & contains(ControlResults.responseType, "LPF");
                elseif comp.OptionsTree.CheckedNodes(i).Text == "Notch filter"
                    noControllerOptions = false;
                    controllerOptions = controllerOptions & contains(ControlResults.responseType, "Notch");
                end
            end
            if not(noControllerOptions)
                controllerIndex = find(controllerOptions, 1 );
            end

            % Set index for controller according to selected elements

            % Create vector of plot colors
            plotColour = linspace(0.8, 0, length(selectedPointIndex));
            plotLineStyle = {'-', '--', '-'};
            plotLineWidth = [1, 1, 2];

            if isMargins
                legendMargins = cell(length(selectedPointIndex), 12);
                legendMargins(:,1) = {' GM:'};
                legendMargins(:,3) = {' [dB] '};
                legendMargins(:,4) = {' \omega_{pc}:'};
                legendMargins(:,6) = {' [rad/s] '};
                legendMargins(:,7) = {' PM:'};
                legendMargins(:,9) = {' [deg] '};
                legendMargins(:,10) = {' \omega_{gc}:'};
                legendMargins(:,12) = {' [rad/s] '};

                for i = 1:length(selectedPointIndex)
                    legendMargins{i,2} = num2str(ControlResults.GM(selectedPointIndex(i)),'%.2f');
                    legendMargins{i,5} = num2str(ControlResults.GMFreq(selectedPointIndex(i)),'%.3f');
                    legendMargins{i,8} = num2str(ControlResults.PM(selectedPointIndex(i)), '%.2f');
                    legendMargins{i,11} = num2str(ControlResults.PMFreq(selectedPointIndex(i)),'%.3f');

                    if isnan(ControlResults.GM(selectedPointIndex(i))) || isinf(ControlResults.GM(selectedPointIndex(i)))
                        legendMargins{i,2} = ['\color{red}' legendMargins{i,2} '\color{black}'];
                    end
                    if isnan(ControlResults.GMFreq(selectedPointIndex(i))) || isinf(ControlResults.GMFreq(selectedPointIndex(i)))
                        legendMargins{i,5} = ['\color{red}' legendMargins{i,5} '\color{black}'];
                    end
                    if isnan(ControlResults.PM(selectedPointIndex(i))) || isinf(ControlResults.PM(selectedPointIndex(i)))
                        legendMargins{i,8} = ['\color{red}' legendMargins{i,8} '\color{black}'];
                    end
                    if isnan(ControlResults.PMFreq(selectedPointIndex(i))) || isinf(ControlResults.PMFreq(selectedPointIndex(i)))
                        legendMargins{i,11} = ['\color{red}' legendMargins{i,11} '\color{black}'];
                    end
                    if ~ControlResults.IsGMFreqWithinLimit(selectedPointIndex(i))
                        legendMargins{i,5} = ['\color{orange}' legendMargins{i,5} '\color{black}'];
                    end
                    if ~ControlResults.IsPMFreqWithinLimit(selectedPointIndex(i))
                        legendMargins{i,11} = ['\color{orange}' legendMargins{i,11} '\color{black}'];
                    end
                    
                end
                legendMargins = strcat(legendMargins(:,1), legendMargins(:,2), ...
                    legendMargins(:,3), legendMargins(:,4), legendMargins(:,5), ...
                    legendMargins(:,6), legendMargins(:,7), legendMargins(:,8), ...
                    legendMargins(:,9), legendMargins(:,10), legendMargins(:,11), ...
                    legendMargins(:,12));
            end

            for i = 1:length(selectedPointIndex)
                if isNominalPlant
                    h(i) = semilogx(comp.UIAxes, ControlResults.omega, ControlResults.Magnitude{8}(:,selectedPointIndex(i)), 'Color', ones(1,3)*plotColour(i), 'LineStyle', plotLineStyle{1}, 'LineWidth', plotLineWidth(1));
                    hold(comp.UIAxes,'on');
                end
                if not(noControllerOptions)
                    h(i) = semilogx(comp.UIAxes, ControlResults.omega, ControlResults.Magnitude{controllerIndex}(:,selectedPointIndex(i)), 'Color', ones(1,3)*plotColour(i), 'LineStyle', plotLineStyle{2}, 'LineWidth', plotLineWidth(2));
                    hold(comp.UIAxes,'on');
                end
                if isLoopGain
                    h(i) = semilogx(comp.UIAxes, ControlResults.omega, ControlResults.Magnitude{9}(:,selectedPointIndex(i)), 'Color', ones(1,3)*plotColour(i), 'LineStyle', plotLineStyle{3}, 'LineWidth', plotLineWidth(3));
                    hold(comp.UIAxes,'on');
                    if isMargins && ControlResults.IsGMFreqWithinLimit(selectedPointIndex(i))
                        semilogx(comp.UIAxes, [ControlResults.GMFreq(selectedPointIndex(i)) ControlResults.GMFreq(selectedPointIndex(i))], [0 -ControlResults.GM(selectedPointIndex(i))], 'Color', [0 0.8 0], 'LineStyle', plotLineStyle{2}, 'LineWidth', plotLineWidth(1));
                        hold(comp.UIAxes,'on');
                        semilogx(comp.UIAxes, ControlResults.GMFreq(selectedPointIndex(i)), -ControlResults.GM(selectedPointIndex(i)), 'o', 'Color', [0 0.8 0], 'LineStyle', plotLineStyle{1}, 'LineWidth', plotLineWidth(1));
                    end
                end
            end
            semilogx(comp.UIAxes, ControlResults.omega, zeros(1,length(ControlResults.omega)), 'LineStyle', '--', 'Color', 'k', 'LineWidth', 0.5);

            if ~isempty(selectedPointIndex)
                legendEntry = cell(length(selectedPointIndex), 1);
                j = 0;
                if comp.OperatingPointsTree.CheckedNodes(1).Text == "All"
                    j = 1;
                end
                for i = 1 : length(selectedPointIndex)
                    legendEntry{i} = comp.OperatingPointsTree.CheckedNodes(i + j).Text;
                end
                if isMargins && exist('legendMargins','var')
                    legendEntry = strcat(legendEntry,legendMargins);
                end
            end
            
            if exist('h', 'var')
                legend(comp.UIAxes, h, legendEntry, 'Location', 'southwest');
            end

            for i = 1:length(selectedPointIndex)
                if isNominalPlant
                    semilogx(comp.UIAxes2, ControlResults.omega, ControlResults.Phase{8}(:,selectedPointIndex(i)), 'Color', ones(1,3)*plotColour(i), 'LineStyle', plotLineStyle{1}, 'LineWidth', plotLineWidth(1));
                    hold(comp.UIAxes2,'on');
                end
                if not(noControllerOptions)
                    semilogx(comp.UIAxes2, ControlResults.omega, ControlResults.Phase{controllerIndex}(:,selectedPointIndex(i)), 'Color', ones(1,3)*plotColour(i), 'LineStyle', plotLineStyle{2}, 'LineWidth', plotLineWidth(2));
                    hold(comp.UIAxes2,'on');
                end
                if isLoopGain
                    semilogx(comp.UIAxes2, ControlResults.omega, ControlResults.Phase{9}(:,selectedPointIndex(i)), 'Color', ones(1,3)*plotColour(i), 'LineStyle', plotLineStyle{3}, 'LineWidth', plotLineWidth(3));
                    hold(comp.UIAxes2,'on');
                    if isMargins && ControlResults.IsPMFreqWithinLimit(selectedPointIndex(i))
                        if ControlResults.PM(selectedPointIndex(i)) >= 0
                            dotPM = -180 + ControlResults.PM(selectedPointIndex(i));
                        else
                            dotPM = 180 + ControlResults.PM(selectedPointIndex(i));
                        end
                        linePM = [-180 dotPM];
                        semilogx(comp.UIAxes2, [ControlResults.PMFreq(selectedPointIndex(i)) ControlResults.PMFreq(selectedPointIndex(i))], linePM, 'Color', [0 0.8 0], 'LineStyle', plotLineStyle{2}, 'LineWidth', plotLineWidth(1));
                        semilogx(comp.UIAxes2, ControlResults.PMFreq(selectedPointIndex(i)), dotPM, 'o', 'Color', [0 0.8 0], 'LineStyle', plotLineStyle{1}, 'LineWidth', plotLineWidth(1));
                    end
                end
            end
            semilogx(comp.UIAxes2, ControlResults.omega, 180*ones(1,length(ControlResults.omega)), ControlResults.omega, -180*ones(1,length(ControlResults.omega)), 'LineStyle', '--', 'Color', 'k', 'LineWidth', 0.5);
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            expand(comp.OperatingPointsTree);
            expand(comp.OptionsTree);
        end

        % Button pushed function: LineariseButton
        function LineariseButtonPushed(comp, event)
            if ~prepareFAST(comp.mainApp)
                return;
            end

            comp.WindTurbine.LinearModel.stepSize = comp.StepsizeEditField.Value;

            info = ['Linearisation may take up to around 1 hour for a full range with stepsize 1 m/s.\n\n', ...
                'Tip: Reduce the range between cut-in and cut-out wind speeds in tab ''Control 1\'' to test the linearisation for a smaller range.\n\n', ...
                'Continue with linearisation?'];
            selection = uiconfirm(comp.mainApp.FASTToolUIFigure, sprintf(info), "Starting linearisation");
            if ~strcmp(selection, 'OK')
                return;
            end

            if ~setLinearisationfile(comp.mainApp)
                return;
            end

            try
                showProgress(comp.mainApp, 'Linearising', 'off');
                [success, exception] = linearise(comp.WindTurbine);
            catch exception2
                endProgress(comp.mainApp);
                if contains(exception2.message,'Simulink')
                    info = ['Linearisation resulted in an error. See Matlab Command Window for details.\n\n', ...
                        'Note that Simulink needs to be installed for this action.'];
                else
                    info = 'Linearisation resulted in an error. See Matlab Command Window for details.';
                end
                uialert(comp.mainApp.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                rethrow(exception2); % All necessary steps are completed before this call because 'rethrow' will terminate the function
            end
            
            switch success
                case -2
                    endProgress(comp.mainApp);
                    if contains(exception.message,'Simulink')
                        info = ['Linearisation resulted in an error. See Matlab Command Window for details.\n\n', ...
                            'Note that Simulink needs to be installed for this action.'];
                    elseif contains(exception.message,'FAST_SFunc')
                        info = ['Linearisation resulted in an error. See Matlab Command Window for details.\n\n', ...
                            'Try linearising with a smaller ''Sampling time'' (particularly relevant for smaller turbines).\n\n', ...
                            'If the problem persists, turbine behaviour may be extreme or instable. Some potential causes are:\n', ...
                            '- rotor stiffness (too low)\n', ...
                            '- tower stiffness (too low)\n', ...
                            '- generator inertia (too low)\n'];
                    else
                        info = 'Linearisation resulted in an error. See Matlab Command Window for details.';
                    end
                    uialert(comp.mainApp.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                    rethrow(exception); % All necessary steps are completed before this call because 'rethrow' will terminate the function
                case -1
                    saveMatfile(comp.mainApp, 'void', 'linearmodel');
                    reportProgress(comp.mainApp, 'Performing linear control analysis');
                    analyseControl(comp.WindTurbine);
                    endProgress(comp.mainApp);
                    loadingReady(comp);
                    if contains(exception.message,'FAST_SFunc')
                        info = ['Linearisation stopped before cut-out wind speed was reached. See Matlab Command Window for details.\n\n', ...
                            'Results up to this point have been saved. Evaluate whether this suffices for the gain-schedule design.\n\n', ...
                            'Otherwise, try linearising with a smaller ''Sampling time'' (particularly relevant for smaller turbines).\n\n', ...
                            'If the problem persists, turbine behaviour may be extreme or instable. Some potential causes are:\n', ...
                            '- rotor stiffness (too low)\n', ...
                            '- tower stiffness (too low)\n', ...
                            '- generator inertia (too low)\n'];
                    else
                        info = ['Linearisation stopped before cut-out wind speed was reached. See Matlab Command Window for details.\n\n', ...
                            'Results up to this point have been saved.'];
                    end
                    uialert(comp.mainApp.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                    rethrow(exception); % All necessary steps are completed before this call because 'rethrow' will terminate the function
                case 0
                    endProgress(comp.mainApp);
                    uialert(comp.mainApp.FASTToolUIFigure, 'No wind speeds found in full-load range', 'Info', Icon='info');
                case 1
                    saveMatfile(comp.mainApp, 'void', 'linearmodel');
                    reportProgress(comp.mainApp, 'Performing linear control analysis');
                    analyseControl(comp.WindTurbine);
                    endProgress(comp.mainApp);
                    loadingReady(comp);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(comp, event)
            if loadMatfile(comp.mainApp, 'Open linear model', 'linearmodel')
                showProgress(comp.mainApp, 'Performing linear control analysis', 'on');
                analyseControl(comp.WindTurbine);
                endProgress(comp.mainApp);

                loadingReady(comp);
            end
        end

        % Callback function: OperatingPointsTree
        function OperatingPointsTreeCheckedNodesChanged(comp, event)
            plotControlAnalysis(comp);
        end

        % Callback function: OptionsTree
        function OptionsTreeCheckedNodesChanged(comp, event)
            if ~isempty(comp.pointsTreeText)
                plotControlAnalysis(comp);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(comp, event)
            if strcmp(comp.mode, "project")
                saveMatfile(comp.mainApp, 'Save linear-control-analysis results', 'controlanalysis');
            else
                if loadMatfile(comp.mainApp, 'Load linear-control-analysis results', 'controlanalysis')
                    loadingReady(comp);
                end
            end
        end

        % Button pushed function: RescheduleButton
        function RescheduleButtonPushed(comp, event)
            RescheduleControl(comp.WindTurbine);
            updateGainScheduleTable(comp.mainApp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.GridLayout1 = uigridlayout(comp.PlotPanel);
            comp.UIAxes = uiaxes(comp.GridLayout1);
            ylabel(comp.UIAxes, 'Magnitude [dB]')
            comp.UIAxes.XScale = 'log';
            comp.UIAxes2 = uiaxes(comp.GridLayout1);
            xlabel(comp.UIAxes2, 'Frequency [rad/s]')
            ylabel(comp.UIAxes2, 'Phase [deg]')
            comp.UIAxes2.XScale = 'log';
            comp.SetandrunPanel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.SetandrunPanel);
            comp.LinearmodelLabel = uilabel(comp.GridLayout2);
            comp.LinearmodelLabel.Text = 'Linear model';
            comp.UseexistingLabel = uilabel(comp.GridLayout2);
            comp.UseexistingLabel.Text = 'Use existing';
            comp.OpenButton = uibutton(comp.GridLayout2, 'push');
            comp.OpenButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @OpenButtonPushed, true);
            comp.OpenButton.Text = 'Open';
            comp.CreatenewLabel = uilabel(comp.GridLayout2);
            comp.CreatenewLabel.Text = 'Create new';
            comp.LineariseButton = uibutton(comp.GridLayout2, 'push');
            comp.LineariseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LineariseButtonPushed, true);
            comp.LineariseButton.Text = 'Linearise';
            comp.StepsizeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.StepsizeEditField.Limits = [0 Inf];
            comp.StepsizeEditField.Value = 1;
            comp.msLabel = uilabel(comp.GridLayout2);
            comp.msLabel.Text = 'm/s';
            comp.SamplingtimeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.SamplingtimeEditField.Limits = [0 360];
            comp.SamplingtimeEditField.Enable = 'off';
            comp.SamplingtimeEditField.Tooltip = {'Sampling time is set in tab ''Simulation'''};
            comp.SamplingtimeEditField.Value = 1;
            comp.sLabel = uilabel(comp.GridLayout2);
            comp.sLabel.Enable = 'off';
            comp.sLabel.Text = 's';
            comp.OperatingpointsdegmsLabel = uilabel(comp.GridLayout2);
            comp.OperatingpointsdegmsLabel.Text = {'Operating points'; '(deg) [(m/s)]'};
            comp.OperatingPointsTree = uitree(comp.GridLayout2, 'checkbox');
            % Assign Checked Nodes
            comp.OperatingPointsTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @OperatingPointsTreeCheckedNodesChanged, true);
            comp.RescheduleButton = uibutton(comp.GridLayout2, 'push');
            comp.RescheduleButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RescheduleButtonPushed, true);
            comp.RescheduleButton.Tooltip = {'Set these angles in Control 2 (existing schedule is interpolated)'};
            comp.RescheduleButton.Text = '< Reschedule';
            comp.PlotoptionsLabel = uilabel(comp.GridLayout2);
            comp.PlotoptionsLabel.Text = 'Plot options';
            comp.OptionsTree = uitree(comp.GridLayout2, 'checkbox');
            comp.ResponseNode = uitreenode(comp.OptionsTree);
            comp.ResponseNode.Text = 'Response';
            comp.NominalplantNode = uitreenode(comp.ResponseNode);
            comp.NominalplantNode.Text = 'Nominal plant';
            comp.LoopgainNode = uitreenode(comp.ResponseNode);
            comp.LoopgainNode.Text = 'Loop gain';
            comp.MarginsNode = uitreenode(comp.ResponseNode);
            comp.MarginsNode.Text = 'Margins';
            comp.ControlelementsNode = uitreenode(comp.OptionsTree);
            comp.ControlelementsNode.Text = 'Control elements';
            comp.PIcontrollerNode = uitreenode(comp.ControlelementsNode);
            comp.PIcontrollerNode.Text = 'PI controller';
            comp.LowpassfilterNode = uitreenode(comp.ControlelementsNode);
            comp.LowpassfilterNode.Text = 'Low-pass filter';
            comp.NotchfilterNode = uitreenode(comp.ControlelementsNode);
            comp.NotchfilterNode.Text = 'Notch filter';
            % Assign Checked Nodes
            comp.OptionsTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @OptionsTreeCheckedNodesChanged, true);
            comp.StepsizeEditFieldLabel = uilabel(comp.GridLayout2);
            comp.StepsizeEditFieldLabel.Text = 'Stepsize';
            comp.SamplingtimeEditFieldLabel = uilabel(comp.GridLayout2);
            comp.SamplingtimeEditFieldLabel.Enable = 'off';
            comp.SamplingtimeEditFieldLabel.Text = 'Sampling time';
            comp.SaveButton = uibutton(comp.GridLayout2, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButtonPushed, true);
            comp.SaveButton.Text = 'Save';
            comp.ResultsfileLabel = uilabel(comp.GridLayout2);
            comp.ResultsfileLabel.Text = 'Results file';
            comp.ControllerLabel = uilabel(comp.GridLayout2);
            comp.ControllerLabel.Text = 'Controller';
            comp.ResultsFile = uilabel(comp.GridLayout2);
            comp.ResultsFile.Text = '';
            comp.ControllerFile = uilabel(comp.GridLayout2);
            comp.ControllerFile.Text = '';
            comp.LinearmodelLabel_2 = uilabel(comp.GridLayout2);
            comp.LinearmodelLabel_2.Text = 'Linear model';
            comp.LinearModelFile = uilabel(comp.GridLayout2);
            comp.LinearModelFile.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end