classdef DrivetrainAndNacelleSpecs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        RestoreButton           matlab.ui.control.Button
        DrivetrainPanel         matlab.ui.container.Panel
        kgmsup2supLabel         matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        GeneratorHSSInertia     matlab.ui.control.NumericEditField
        MomentofinertiaLabel    matlab.ui.control.Label
        TransmissionratioLabel  matlab.ui.control.Label
        GeneratorEfficiency     matlab.ui.control.NumericEditField
        EfficiencyLabel_2       matlab.ui.control.Label
        GearboxRatio            matlab.ui.control.NumericEditField
        GearboxEfficiency       matlab.ui.control.NumericEditField
        EfficiencyLabel         matlab.ui.control.Label
        GeneratorLabel          matlab.ui.control.Label
        GearboxLabel            matlab.ui.control.Label
        GearboxCheckBox         matlab.ui.control.CheckBox
        ImageGeared             matlab.ui.control.Image
        ImageDirectDrive        matlab.ui.control.Image
        NacellePanel            matlab.ui.container.Panel
        kgLabel_2               matlab.ui.control.Label
        HousingMass             matlab.ui.control.NumericEditField
        DropDownHousingType     matlab.ui.control.DropDown
        NacelleLabel            matlab.ui.control.Label
        kgLabel                 matlab.ui.control.Label
        HubMass                 matlab.ui.control.NumericEditField
        DropDownHubType         matlab.ui.control.DropDown
        HubLabel                matlab.ui.control.Label
        mLabel_4                matlab.ui.control.Label
        HubOverhang             matlab.ui.control.NumericEditField
        mLabel_3                matlab.ui.control.Label
        HousingDiameter         matlab.ui.control.NumericEditField
        degLabel                matlab.ui.control.Label
        HubShaftTilt            matlab.ui.control.NumericEditField
        mLabel_2                matlab.ui.control.Label
        HousingLength           matlab.ui.control.NumericEditField
        mLabel                  matlab.ui.control.Label
        HubLength               matlab.ui.control.NumericEditField
        Image                   matlab.ui.control.Image
    end

    properties (Access = private)
        WindTurbine
        mainApp
        specsChanged
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function setSpecs(comp, Data)
            comp.DropDownHousingType.ValueIndex = Data.Nacelle.Housing.Type;
            comp.HousingDiameter.Value = Data.Nacelle.Housing.Diameter;
            comp.HousingLength.Value = Data.Nacelle.Housing.Length;
            comp.HousingMass.Value = Data.Nacelle.Housing.Mass;

            comp.DropDownHubType.ValueIndex = Data.Nacelle.Hub.Type;
            comp.HubLength.Value = Data.Nacelle.Hub.Length;
            comp.HubMass.Value = Data.Nacelle.Hub.Mass;
            comp.HubOverhang.Value = Data.Nacelle.Hub.Overhang;
            comp.HubShaftTilt.Value = Data.Nacelle.Hub.ShaftTilt;

            if Data.Drivetrain.Gearbox.Ratio == 1
                comp.GearboxCheckBox.Value = 0;
                Data.Drivetrain.Gearbox.Efficiency = 1;
            else
                comp.GearboxCheckBox.Value = 1;
            end
                
            comp.GearboxEfficiency.Value = Data.Drivetrain.Gearbox.Efficiency;
            comp.GearboxRatio.Value = Data.Drivetrain.Gearbox.Ratio;

            comp.GeneratorEfficiency.Value = Data.Drivetrain.Generator.Efficiency;
            comp.GeneratorHSSInertia.Value = Data.Drivetrain.Generator.HSSInertia;

            adjustTab(comp);
            applyEdits(comp);
        end
        
    end
    
    methods (Access = private)

        %% Managing consistency of tab content and switches

        function adjustTab(comp)
            if comp.GearboxCheckBox.Value == 1
                comp.EfficiencyLabel.Enable = "on";
                comp.GearboxEfficiency.Enable = "on";
                comp.TransmissionratioLabel.Enable = "on";
                comp.GearboxRatio.Enable = "on";
                comp.ImageGeared.Visible = "on";
                comp.ImageDirectDrive.Visible = "off";
            else
                comp.GearboxEfficiency.Value = 1;
                comp.GearboxRatio.Value = 1;
                comp.EfficiencyLabel.Enable = "off";
                comp.GearboxEfficiency.Enable = "off";
                comp.TransmissionratioLabel.Enable = "off";
                comp.GearboxRatio.Enable = "off";
                comp.ImageGeared.Visible = "off";
                comp.ImageDirectDrive.Visible = "on";
            end
        end

        %% Extracting WindTurbine data from tab information

        function applyEdits(comp)
            comp.WindTurbine.Nacelle.Housing.Type = comp.DropDownHousingType.ValueIndex;
            comp.WindTurbine.Nacelle.Housing.Diameter = comp.HousingDiameter.Value;
            comp.WindTurbine.Nacelle.Housing.Length = comp.HousingLength.Value;
            comp.WindTurbine.Nacelle.Housing.Mass = comp.HousingMass.Value;

            comp.WindTurbine.Nacelle.Hub.Type = comp.DropDownHubType.ValueIndex;
            comp.WindTurbine.Nacelle.Hub.Length = comp.HubLength.Value;
            comp.WindTurbine.Nacelle.Hub.Mass = comp.HubMass.Value;
            comp.WindTurbine.Nacelle.Hub.Overhang = comp.HubOverhang.Value;
            comp.WindTurbine.Nacelle.Hub.ShaftTilt = comp.HubShaftTilt.Value;

            comp.WindTurbine.Drivetrain.Gearbox.Efficiency = comp.GearboxEfficiency.Value;
            comp.WindTurbine.Drivetrain.Gearbox.Ratio = comp.GearboxRatio.Value;

            comp.WindTurbine.Drivetrain.Generator.Efficiency = comp.GeneratorEfficiency.Value;
            comp.WindTurbine.Drivetrain.Generator.HSSInertia = comp.GeneratorHSSInertia.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDownHousingType, DropDownHubType, 
        % ...and 12 other components
        function TabDataChanged(comp, event)
            adjustTab(comp);
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSpecs(comp, Data);
            comp.specsChanged = false;
            updateForChangedSpecs(comp.mainApp);            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.NacellePanel = uipanel(comp.GridLayout);
            comp.NacellePanel.Title = 'Nacelle';
            comp.Image = uiimage(comp.NacellePanel);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'graphics', 'nacelle.png');
            comp.HubLength = uieditfield(comp.NacellePanel, 'numeric');
            comp.HubLength.Limits = [0 Inf];
            comp.HubLength.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.mLabel = uilabel(comp.NacellePanel);
            comp.mLabel.Text = 'm';
            comp.HousingLength = uieditfield(comp.NacellePanel, 'numeric');
            comp.HousingLength.Limits = [0 Inf];
            comp.HousingLength.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.mLabel_2 = uilabel(comp.NacellePanel);
            comp.mLabel_2.Text = 'm';
            comp.HubShaftTilt = uieditfield(comp.NacellePanel, 'numeric');
            comp.HubShaftTilt.Limits = [0 90];
            comp.HubShaftTilt.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel = uilabel(comp.NacellePanel);
            comp.degLabel.Text = 'deg';
            comp.HousingDiameter = uieditfield(comp.NacellePanel, 'numeric');
            comp.HousingDiameter.Limits = [0 Inf];
            comp.HousingDiameter.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.mLabel_3 = uilabel(comp.NacellePanel);
            comp.mLabel_3.Text = 'm';
            comp.HubOverhang = uieditfield(comp.NacellePanel, 'numeric');
            comp.HubOverhang.Limits = [0 Inf];
            comp.HubOverhang.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.mLabel_4 = uilabel(comp.NacellePanel);
            comp.mLabel_4.Text = 'm';
            comp.HubLabel = uilabel(comp.NacellePanel);
            comp.HubLabel.Text = 'Hub';
            comp.DropDownHubType = uidropdown(comp.NacellePanel);
            comp.DropDownHubType.Items = {'Ellipsoidal', 'Blunt', 'Conical', 'Ogive'};
            comp.DropDownHubType.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.DropDownHubType.Value = 'Ellipsoidal';
            comp.HubMass = uieditfield(comp.NacellePanel, 'numeric');
            comp.HubMass.Limits = [0 Inf];
            comp.HubMass.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.kgLabel = uilabel(comp.NacellePanel);
            comp.kgLabel.Text = 'kg';
            comp.NacelleLabel = uilabel(comp.NacellePanel);
            comp.NacelleLabel.Text = 'Nacelle';
            comp.DropDownHousingType = uidropdown(comp.NacellePanel);
            comp.DropDownHousingType.Items = {'Box', 'Ellipsoidal', 'Cylindrical', 'Kingpin', 'Winged', 'Drop pad', 'Helipad'};
            comp.DropDownHousingType.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.DropDownHousingType.Value = 'Box';
            comp.HousingMass = uieditfield(comp.NacellePanel, 'numeric');
            comp.HousingMass.Limits = [0 Inf];
            comp.HousingMass.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.kgLabel_2 = uilabel(comp.NacellePanel);
            comp.kgLabel_2.Text = 'kg';
            comp.DrivetrainPanel = uipanel(comp.GridLayout);
            comp.DrivetrainPanel.Title = 'Drive train';
            comp.ImageDirectDrive = uiimage(comp.DrivetrainPanel);
            comp.ImageDirectDrive.ImageSource = fullfile(pathToMLAPP, 'graphics', 'drivetrain_directdrive.png');
            comp.ImageGeared = uiimage(comp.DrivetrainPanel);
            comp.ImageGeared.ImageSource = fullfile(pathToMLAPP, 'graphics', 'drivetrain_geared.png');
            comp.GearboxCheckBox = uicheckbox(comp.DrivetrainPanel);
            comp.GearboxCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.GearboxCheckBox.Text = '';
            comp.GearboxLabel = uilabel(comp.DrivetrainPanel);
            comp.GearboxLabel.Text = 'Gearbox';
            comp.GeneratorLabel = uilabel(comp.DrivetrainPanel);
            comp.GeneratorLabel.Text = 'Generator';
            comp.EfficiencyLabel = uilabel(comp.DrivetrainPanel);
            comp.EfficiencyLabel.Text = 'Efficiency';
            comp.GearboxEfficiency = uieditfield(comp.DrivetrainPanel, 'numeric');
            comp.GearboxEfficiency.Limits = [0 100];
            comp.GearboxEfficiency.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.GearboxRatio = uieditfield(comp.DrivetrainPanel, 'numeric');
            comp.GearboxRatio.Limits = [0 Inf];
            comp.GearboxRatio.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.EfficiencyLabel_2 = uilabel(comp.DrivetrainPanel);
            comp.EfficiencyLabel_2.Text = 'Efficiency';
            comp.GeneratorEfficiency = uieditfield(comp.DrivetrainPanel, 'numeric');
            comp.GeneratorEfficiency.Limits = [0 100];
            comp.GeneratorEfficiency.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.TransmissionratioLabel = uilabel(comp.DrivetrainPanel);
            comp.TransmissionratioLabel.Text = 'Transmission ratio';
            comp.MomentofinertiaLabel = uilabel(comp.DrivetrainPanel);
            comp.MomentofinertiaLabel.Text = 'Moment of inertia';
            comp.GeneratorHSSInertia = uieditfield(comp.DrivetrainPanel, 'numeric');
            comp.GeneratorHSSInertia.Limits = [0 Inf];
            comp.GeneratorHSSInertia.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.Label = uilabel(comp.DrivetrainPanel);
            comp.Label.Text = '%';
            comp.Label_2 = uilabel(comp.DrivetrainPanel);
            comp.Label_2.Text = '%';
            comp.kgmsup2supLabel = uilabel(comp.DrivetrainPanel);
            comp.kgmsup2supLabel.Interpreter = 'html';
            comp.kgmsup2supLabel.Text = 'kg m<sup>2</sup>';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.RestoreButton = uibutton(comp.GridLayout2, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreButton.Text = 'Restore';
        end
    end
end