classdef FASTTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FASTToolUIFigure           matlab.ui.Figure
        ProjectMenu                matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        SaveasMenu                 matlab.ui.container.Menu
        CompareMenu                matlab.ui.container.Menu
        ReviewresultsMenu          matlab.ui.container.Menu
        CloseMenu                  matlab.ui.container.Menu
        SimulinkMenu               matlab.ui.container.Menu
        FullmodelMenu              matlab.ui.container.Menu
        TorquecontrollerMenu       matlab.ui.container.Menu
        PitchcontrollerMenu        matlab.ui.container.Menu
        ForeaftdamperMenu          matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        AboutMenu                  matlab.ui.container.Menu
        Image                      matlab.ui.control.Image
        LeftPanel                  matlab.ui.container.Panel
        SpecsTabGroup              matlab.ui.container.TabGroup
        RotorTab                   matlab.ui.container.Tab
        RotorSpecs                 RotorSpecs
        AerofoilsTab               matlab.ui.container.Tab
        AerofoilSpecs              AerofoilSpecs
        TowerTab                   matlab.ui.container.Tab
        TowerSpecs                 TowerSpecs
        DrivetrainnacelleTab       matlab.ui.container.Tab
        DrivetrainAndNacelleSpecs  DrivetrainAndNacelleSpecs
        Control1Tab                matlab.ui.container.Tab
        Control1Specs              Control1Specs
        Control2Tab                matlab.ui.container.Tab
        Control2Specs              Control2Specs
        RightPanel                 matlab.ui.container.Panel
        AnalysisTabGroup           matlab.ui.container.TabGroup
        ThreeDTab                  matlab.ui.container.Tab
        VisualAnalysis3D           VisualAnalysis3D
        VisualTab                  matlab.ui.container.Tab
        VisualAnalysis             VisualAnalysis
        RotorAnalysisTab           matlab.ui.container.Tab
        RotorAnalysis              RotorAnalysis
        SteadyOpTab                matlab.ui.container.Tab
        SteadyOpAnalysis           SteadyOpAnalysis
        ModalTab                   matlab.ui.container.Tab
        ModalAnalysis              ModalAnalysis
        ControllinearTab           matlab.ui.container.Tab
        ControlAnalysis            ControlAnalysis
        SimulationTab              matlab.ui.container.Tab
        Simulation                 Simulation
        QuickscanTab               matlab.ui.container.Tab
        ResultsAnalysis            ResultsAnalysis
    end

    
    properties (Access = private)
        WindTurbine % Handle to object of WindTurbineClass
        FileIO % Handle to object of FileIOClass
        ProgressDialog % Handle to the progress dialogue box, used to provide updates
        MergeDialogueApp % App component that opens in a separate modal window to compare and merge project files
        projectName % Name of the open project, copied from its filename and shown in the title bar
        mode % Flag to indicate whether the FASTTool window is used to work with a project file or to view results
        linearisationFileName % Variable to temporarily store the filename for a linear model until linearisation has been successfully completed
        linearisationPathName % Variable to temporarily store the pathname for a linear model until linearisation has been successfully completed
    end

    properties (Access = public)
        version = '2.1'; % Version number of FASTTool (Saved to 'Info' in all mat-files and shown in 'About')
        versionDate = 'August 2026'; % Release data of this version (shown in 'About')
    end

    methods (Access = public)

        %% Managing the progress window

        % The progress dialogue window, used to inform the user of the
        % status of a process, is managed by the FASTTool main app. This is
        % done to enable the use of a modal dialogue window, meaning the
        % user interaction in the main window is disabled while the
        % progress dialogue window is shown.
        %
        % The next three functions can be called to create a progress
        % window, to update it and to close it. The window must be closed
        % to return control to the main window.
        %
        % Usage:
        %   Progress dialogue windows are used for processes that take some
        %   time, making it desirable to inform the user of the status of
        %   the activities. The triggering function of the process will
        %   create the progress window (showProgress()), then run the
        %   process and finally destroy the progress window
        %   (endProgress()). These functions are typically the
        %   'ButtonPushed' callbacks of the action buttons on the analysis
        %   tabs. Besides this, a progress window is created and destroyed
        %   by FASTTool.updateForChangedControl, because a linear control
        %   analysis is triggered by editing the controller variables.
        %   During the execution of the process, updates are optionally
        %   send to the progress window at significant steps in the
        %   process. These updates are typically done in functions in
        %   WindTurbineClass, because the methods of that class manage
        %   these execution of the analysis processes.

        function showProgress(app, title, indeterminateState)
            app.ProgressDialog = uiprogressdlg(app.FASTToolUIFigure, Title = title, Indeterminate = indeterminateState);
        end

        % The next function can be called to update the text message in a
        % progress window and/or it's progress indication.
        %
        % Any character string input will be interpreted as the intended
        % new progress message, while any numerical input will be
        % interpreted as the progress indication. Either or both can be
        % supplied (with one, respectively two inputs). The order of the
        % inputs doesn't matter.
        %
        % The function does not check whether the progress window
        % exists and whether the numerical indicator of progress is a value
        % between 0 and 1.
        function reportProgress(app, varargin)
            for i = 2:nargin
                if isnumeric(varargin{nargin - 1})
                    app.ProgressDialog.Value = varargin{1};
                elseif ischar(varargin{nargin - 1})
                    app.ProgressDialog.Message = varargin{nargin - 1};
                end
            end
        end

        function endProgress(app)
            close(app.ProgressDialog);
        end

        %% Reading files and importing data

        % This function is called by RotorAnalysis, SteadyOpAnalysis,
        % ModalAnalysis, ControlAnalysis and ResultsAnalysis following a
        % request to load saved analysis results, and by ControlAnalysis
        % following a request to open a linear model from file. When the
        % file specified by the user is a correct file for 'type', its
        % content will be loaded and stored into the appropriate parameter
        % in WindTurbine.
        function success = loadMatfile(app, title, type)
            success = false;
            [fileName, pathName] = selectReadFile(app, 'mat', title);
            if fileName == 0
                return;
            end

            if ~loadMatFile(app.FileIO, fileName, pathName, type)
                info = ['<' fileName '> is not a valid file'];
                uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
            else
                success = true;
            end
        end

        % This function is called by RotorSpecs, AerofoilSpecs, TowerSpecs
        % and Control2Specs following a request to import tabular data from
        % an Excel file. The Excel file will normally have been generated
        % with FASTTool.exportTable and (potentially) edited by the user.
        % When the file specified by the user is a correct file for 'type',
        % its content will be loaded into the parameter DataTable, to be
        % displayed on the tab that made the call.
        function [DataTable] = importTable(app, title, type)
            [fileName, pathName] = selectReadFile(app, 'xlsx', title);
            if fileName == 0
                DataTable = [];
                return;
            end

            [DataTable, message] = readImportTable(app.FileIO, pathName, fileName, type);
            if isempty(DataTable)
                uialert(app.FASTToolUIFigure, message, 'Info', Icon='info');
                return;
            end
        end

        % This function is called by RotorSpecs, AerofoilSpecs,
        % TowerSpecs, DrivetrainAndNacelleSpecsSpecs, Control1Specs,
        % Control2Specs and Simulation following a request to restore
        % project data from file. The content of the open project file will
        % be loaded into the parameter Data, which will be past on to the
        % tab that made the call. The tab will then display the data from
        % 'Data' that is relevant to that tab. Only the data that is
        % displayed on the tab is subsequently updated in WindTurbine.
        % Thus, the tab acts as a filter, and only restores the part of
        % 'Data' that is edited on that tab.
        function [Data] = getRestoredata(app)
            [Data] = getRestoreData(app.FileIO);
        end

        %% Writing files and exporting data

        % This function is called by Simulation, before making the call to
        % WindTurbine to perform a simulation. This allows the user to
        % choose a filename before a (potentially long) run or batch run
        % starts. It avoids that prompting the user after the simulation
        % fails, which might lead to loss of the results. For a batch run,
        % the filename will be extended by identifiers for the individual
        % runs, without the need to prompt the user again.
        function success = setSimulationfile(app)
            [fileName, pathName] = selectWriteFile(app, 'mat', 'Save simulation results');
            if fileName == 0
                success = false;
                return;
            else
                success = true;
            end

            setSimulationFile(app.FileIO, fileName, pathName);
        end

        % This function is called by ControlAnalysis, before making the
        % call to WindTurbine to perform a linearisation. This allows the
        % user to choose a filename before a (potentially long)
        % linearisation starts. It avoids that prompting the user after the
        % linearisation fails, which might lead to loss of the results.
        function success = setLinearisationfile(app)
            [fileName, pathName] = selectWriteFile(app, 'mat', 'Save linear model');
            if fileName == 0
                success = false;
                return;
            else
                success = true;
                app.linearisationFileName = fileName;
                app.linearisationPathName = pathName;
            end
        end

        % This function is called by RotorAnalysis, SteadyOpAnalysis,
        % ModalAnalysis and ControlAnalysis following a request to save
        % analysis results, and by ControlAnalysis following a request to
        % save a linear model from file. Simulation makes no call to this
        % function, since simulation results are saved in
        % FileIO.saveSimulationResults in response to a call from
        % WindTurbine.Simulate. This is done to save individual runs in a
        % batch of simulations. The parameter to be saved are extracted
        % from the appropriate substructure from WindTurbine, using the
        % lists of parameter names specified in the FileIOClass properties.
        function saveMatfile(app, title, type)
            if strcmp(type, 'linearmodel') % User has already been asked for filename for saving linear model before linearisation is started
                fileName = app.linearisationFileName;
                pathName = app.linearisationPathName;
            else
                [fileName, pathName] = selectWriteFile(app, 'mat', title);
                if fileName == 0
                    return;
                end
            end

            saveMatFile(app.FileIO, fileName, pathName, type);
        end

        % This function is called by RotorSpecs, AerofoilSpecs, TowerSpecs
        % and Control2Specs following a request to export tabular data from
        % 'DataTable' to an Excel file. The Excel file can be edited by the
        % user and imported with FASTTool.importTable.
        function exportTable(app, DataTable, title)
            [fileName, pathName] = selectWriteFile(app, 'xlsx', title);
            if fileName == 0
                return;
            end

            writeExportTable(app.FileIO, pathName, fileName, DataTable);
        end

        %% Checking table inputs (from file or user edits)

        % This function is called by RotorSpecs, AerofoilSpecs, TowerSpecs
        % and Control2Specs following the import of tabular data from
        % an Excel file. It checks whether the data in the specified
        % 'columns' is numeric and falls within the range specified by
        % 'limits'. The function returns a message that states which
        % columns violate these conditions, using the parameter name
        % stripped off of its unit. The function is called multiple times,
        % each time with a group of columns for which the same limits
        % apply. The calling function concatenates the messages, to send an
        % error message to the user (if there are violations).
        function infoText = checkTableImport(app, DataTable, variableNames, columns, limits)
            checkTable = DataTable(:, columns);
            checkColNames = extractBefore(variableNames(columns), " [");
            [~, col1] = find(isnan(table2array(checkTable)));
            [~, col2] = find(table2array(checkTable) < limits(1));
            [~, col3] = find(table2array(checkTable) > limits(2));
            invalidCol = unique([col1' col2' col3']);
            if ~isempty(invalidCol)
                subinfo = sprintf(', %s', checkColNames(invalidCol));
                if all(limits == [-Inf, 0])
                    infoText = sprintf('- %s must only contain negative or zero numeric values\n\n', subinfo(3:end));
                elseif all(limits == [0, Inf])
                    infoText = sprintf('- %s must only contain positive or zero numeric values\n\n', subinfo(3:end));
                elseif all(limits == [-Inf, Inf])
                    infoText = sprintf('- %s must only contain numeric values\n\n', subinfo(3:end));
                else
                    infoText = sprintf('- %s must only contain numeric values between %g and %g\n\n', subinfo(3:end), limits);
                end
            else
                infoText = '';
            end
        end

        % This function is called by RotorSpecs, AerofoilSpecs, TowerSpecs
        % and Control2Specs following a change by the user of a cell in a
        % table. It checks whether the data is numeric and falls within the
        % range specified by 'limits'. Unlike editable fields, no type or
        % limits can be specified for these cells in the (Table-) component
        % properties. The function returns a message that states a
        % violation. The calling function sends this as an error message to
        % the user (if there is a violation).
        function infoText = checkTableCellEdit(app, newData, limits)
            if newData < limits(1) || newData > limits(2) || isnan(newData)
                if all(limits == [-Inf, 0])
                    infoText = 'Input must be a negative or zero numeric value';
                elseif all(limits == [0, Inf])
                    infoText = 'Input must be a positive or zero numeric value';
                elseif all(limits == [-Inf, Inf])
                    infoText = 'Input must be a numeric value';
                else
                    infoText = sprintf('Input must be a numeric value between %g and %g', limits);
                end
            else
                infoText = '';
            end
        end

        %% Managing responses to data edits (including table imports and data restores)

        % This group of functions manages how the main figure and some
        % individual tabs respond to edits and actions on other tabs.
        %
        % For edits of data on a tab, the main figure responds by updating
        % the asterisks (*) in the tab titles for tabs with changed data
        % since opening or last saving. Each tab administers its state of
        % changes and FASTTool polls this state to update the titles and to
        % determine whether the user needs to be prompted for saving when
        % closing a project. The need for polling all tab components causes
        % some extra overhead, but simplifies responsibilities of the
        % individual tabs and the main figure.
        %
        % For actions on tabs that change information used in a plot on
        % VisualAnalysis3D or VisualAnalysis, an update of the
        % visualisation is triggered.
        %
        % Specific additional or alternative responses are explained above
        % the respective functions below.
        function updateForChangedSpecs(app)
            showChangeStates(app);
            updateVisualisation(app);
        end

        % Import of a blade table in RotorSpecs can result in a previously
        % unused aerofoil name in the table. In such cases, a direct call
        % to WindTurbine is made to generate a (dummy) dataset for this new
        % aerofoil. Via the functions call here this new aerofoil is also
        % added list on the AerofoilSpecs tab.
        function updateForChangedAerofoilsUsed(app)
            setAerofoilList(app.AerofoilSpecs);
            updateVisualisation(app);
        end

        % When the selected aerofoil in the list on the AerofoilSpecs tab
        % changes, this function ensures that the plot on the
        % VisualAnalysis tab is updated, when the user chooses to get the
        % best match.
        function updateForChangedAerofoilSelection(app, selectedAerofoilIndex)
            setMatch(app.VisualAnalysis, selectedAerofoilIndex);
            updateVisualisation(app);
        end

        % When the set of aerofoils is changed on the AerofoilSpecs tab,
        % this function ensures that the names of the available aerofoils
        % appear in the dropdown list for aerofoil selection in the blade
        % data-table.
        function updateForChangedAerofoilSet(app)
            formatBladeTable(app.RotorSpecs);
            showChangeStates(app);
            updateVisualisation(app);
        end

        % When the settings of the pitch controller on the Control2Specs
        % tab change, this function ensures that a new linear analysis is
        % performed, needed to update the Bode plots and margins.
        function updateForChangedControl(app)
            showChangeStates(app);
            if app.ControlAnalysis.existLinearModel
                showProgress(app, 'Performing linear control analysis', 'on');
                analyseControl(app.WindTurbine);
                endProgress(app);
            end
            updateVisualisation(app);
        end

        % This function updates the gain-schedule table on the
        % Control2Spects tab when requested on the ControlAnalysis tab.
        % This indirect route with the '<Reschedule' button on the
        % ControlAnalysis tab is chosen because that better contextualises
        % its purpose and its status (enabled/disabled) with the opened
        % linear model.
        function updateGainScheduleTable(app)
            setGainScheduleTable(app.Control2Specs, app.WindTurbine);
        end

        %% Preparing and finishing modal analysis, linearisation and simulation

        % Running the modal analysis, linearisation and simulation requires
        % that the app is deployed on Windows and that a temporary
        % directory is created to write input and output files. This
        % temporary directory may not have too many characters in its
        % pathname, because BModes has only 100 characters available to
        % specify its output file (including the path). This function calls
        % FileIO.createTempDirectory to check whether a temporary directory
        % exists that fulfils the conditions or to create it otherwise. If
        % this is not succesfull, the user is informed. This preparation is
        % done when the user tries to perform a modal analysis, a
        % linearisation or a simulation, and not at startup of FASTTool, to
        % avoid that warning messages are given when the user doesn't want
        % to perform these actions during this session. The temporary
        % directory is removed when FASTTool closes.

        function success = prepareFAST(app)
            success = false;

            % Bmodes and FAST are compiled for Windows, and cannot run on
            % other systems
            computerType = computer;
            if contains(computerType, 'MAC')
                info = ['This action cannot be performed on MacOS.\n\n', ...
                    'Please use Matlab on a Windows platform to use this function.'];
                uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                return;
            end
            if contains(computerType, 'LNX')
                info = ['This action cannot be performed on Linux.\n', ...
                    '(Matlab Online runs on Linux)\n\n',...
                    'Please use Matlab on a Windows platform to use this function.'];
                uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                return;
            end

            [status, overflowLength, tempDirName] = prepareTempDirectory(app.FileIO);
            if status == 0
                info = ['Matlab does not have the right to write in the current working directory.\n\n', ...
                    'Please change to a different working directory and try again.\n\n'];
                uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
            elseif status == 1
                success = true;
            elseif status == 2
                if overflowLength == 1
                    text = sprintf('%i character', overflowLength);
                else
                    text = sprintf('%i characters', overflowLength);
                end
                info = ['Due to the modal analysis there is a limit on the number of characters of the path of the current working directory.\n\n', ...
                    'The current working directory exceeds this limit by ', ...
                    text, '.\n\n', ...
                    'Please change to a working directory with a shorter path and try again.'];
                uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
            elseif status == 3
                info = sprintf('Temporary folder\n\n%s\n\ncould not be fully accessed and may contain a file that is open in another program.\n\n', tempDirName);
                info = append(info, sprintf('Please remove this temporary folder and try again. This might require closing FASTTool and Matlab, or another program that is blocking access to a file in the folder.'));
                uialert(app.FASTToolUIFigure, info, 'Info', Icon='info');
            end
        end
    end
    
    methods (Access = private)

        %% Managing filename dialogue windows

        % These functions request the user to choose a file for reading or
        % writing. They are used for project (mat) files, (mat) files with
        % results from analyses and linearisation, and Excel files with
        % (editable/edited) tabular data. They check that the extension of
        % the chosen file matches the specification. No other checks are
        % performed on the selected file.

        function [fileName, pathName] = selectReadFile(app, extension, title)
            [fileName, pathName] = uigetfile(['*.' extension], title);
            figure(app.FASTToolUIFigure); % Needed to get FASTTool back to foreground after the dialogue closes
            if ~(fileName == 0)
                [~,~,ext] = fileparts(fileName);
                if ~strcmp(ext, ['.' extension])
                    uialert(app.FASTToolUIFigure, ['<' fileName '> is not a valid file'], 'Info', Icon='info');
                    fileName = 0;
                    pathName = 0;
                    return;
                end
            end
        end

        function [fileName, pathName] = selectWriteFile(app, extension, title)
            [fileName, pathName] = uiputfile(['*.' extension], title);
            figure(app.FASTToolUIFigure); % Needed to get FASTTool back to foreground after the dialogue closes
            if ~(fileName == 0)
                [~,~,ext] = fileparts(fileName);
                if ~strcmp(ext, ['.' extension])
                    uialert(app.FASTToolUIFigure, ['<' fileName '> is not a valid file'], 'Info', Icon='info');
                    fileName = 0;
                    pathName = 0;
                    return;
                end
            end
        end

        %% Managing figure updates after edits (including table imports)

        function updateVisualisation(app)
            switch app.AnalysisTabGroup.SelectedTab.Title
                case "Visual"
                    plotSpecs(app.VisualAnalysis);
                case "3D"
                    plotSpecs(app.VisualAnalysis3D);
                case "3D*"
                    plotSpecs(app.VisualAnalysis3D);
                case "Control (linear)"
                    plotControlAnalysis(app.ControlAnalysis);
            end
        end

        %% Managing states and notifications (*) of changed data

        % When a project is opened or saved, the tabs are informed
        % that their content matches the latest saved project file. Also at
        % startup and when closing a project, this information is reset.
        function resetChangeStates(app)
            resetChangeState(app.RotorSpecs);
            resetChangeState(app.AerofoilSpecs);
            resetChangeState(app.TowerSpecs);
            resetChangeState(app.DrivetrainAndNacelleSpecs);
            resetChangeState(app.Control1Specs);
            resetChangeState(app.Control2Specs);
            resetChangeState(app.VisualAnalysis3D);
            resetChangeState(app.Simulation);
            app.RotorTab.Title = "Rotor";
            app.AerofoilsTab.Title = "Aerofoils";
            app.TowerTab.Title = "Tower";
            app.DrivetrainnacelleTab.Title = "Drivetrain & nacelle";
            app.Control1Tab.Title = "Control 1";
            app.Control2Tab.Title = "Control 2";
            app.ThreeDTab.Title = "3D";
            app.SimulationTab.Title = "Simulation";
        end

        % This function is called when the user makes a request to close a
        % project, to prompt them for saving in case changes have been
        % made.
        function changed = projectChanged(app)
            if getChangeState(app.RotorSpecs) || ...
            getChangeState(app.AerofoilSpecs) || ...
            getChangeState(app.TowerSpecs) || ...
            getChangeState(app.DrivetrainAndNacelleSpecs) || ...
            getChangeState(app.Control1Specs) || ...
            getChangeState(app.Control2Specs) || ...
            getChangeState(app.VisualAnalysis3D) || ...
            getChangeState(app.Simulation)
                changed = true;
            else
                changed = false;
            end
        end

        % This function is called to follow up on calls to FASTTool that
        % inform it of changes in data on the tabs.
        function showChangeStates(app)
            if getChangeState(app.RotorSpecs)
                app.RotorTab.Title = "Rotor*";
            else
                app.RotorTab.Title = "Rotor";
            end
            if getChangeState(app.AerofoilSpecs)
                app.AerofoilsTab.Title = "Aerofoils*";
            else
                app.AerofoilsTab.Title = "Aerofoils";
            end
            if getChangeState(app.TowerSpecs)
                app.TowerTab.Title = "Tower*";
            else
                app.TowerTab.Title = "Tower";
            end
            if getChangeState(app.DrivetrainAndNacelleSpecs)
                app.DrivetrainnacelleTab.Title = "Drivetrain & nacelle*";
            else
                app.DrivetrainnacelleTab.Title = "Drivetrain & nacelle";
            end
            if getChangeState(app.Control1Specs)
                app.Control1Tab.Title = "Control 1*";
            else
                app.Control1Tab.Title = "Control 1";
            end
            if getChangeState(app.Control2Specs)
                app.Control2Tab.Title = "Control 2*";
            else
                app.Control2Tab.Title = "Control 2";
            end
            if getChangeState(app.VisualAnalysis3D)
                app.ThreeDTab.Title = "3D*";
            else
                app.ThreeDTab.Title = "3D";
            end
            if getChangeState(app.Simulation)
                app.SimulationTab.Title = "Simulation*";
            else
                app.SimulationTab.Title = "Simulation";
            end
        end

        %% Managing request for closing a project

        % This function is called to follow up on direct or indirect
        % request to close a project, to check whether the user needs to be
        % prompted to save any changes.
        % A direct request comes from the menu item 'Close'. Indirect
        % requests follow up on the request via the menu to 'Open' a new
        % project, the request to change to the 'Review results' mode and
        % the request to close FASTTool.
        function sure = sureToClose(app)
            sure = true;
            if projectChanged(app)
                selection = uiconfirm(app.FASTToolUIFigure, "Save changes?", "Closing project", Options = ["Yes","No", "Save as ...", "Cancel"]);
                switch selection
                    case "Yes"
                        saveProject(app.FileIO);
                    case "No"
                    case "Save as ..."
                        [fileName, pathName] = selectWriteFile(app, 'mat', 'Save project');
                        if fileName == 0
                            sure = false;
                            return;
                        end

                        saveProjectAs(app.FileIO, fileName, pathName);
                        app.projectName = fileName;
                        app.FASTToolUIFigure.Name = ['FASTTool - ' app.projectName];
                        resetChangeStates(app);
                    case "Cancel"
                        sure = false;
                end
            end
        end

        %% Resetting all components to initial conditions

        % This function performs a full reset of the main figure and its
        % tabs. It is called at the start of FASTTool, when a project is
        % closed, when the 'Review results' mode is started and in most
        % cases when a project is opened. If a project is opened when there
        % is already an open project, this function is not called, but a
        % limit subset of resets is performed. See FASTTool.OpenProject for
        % the reason for this.
        function resetAllUI(app)
            app.FASTToolUIFigure.Name = 'FASTTool';
            app.mode = "void";
            app.Image.Visible = "on";
            app.RightPanel.Enable = "off";
            app.LeftPanel.Enable = "off";
            app.LeftPanel.Visible = "on";
            app.VisualAnalysis3D.Visible = "on";
            app.VisualAnalysis.Visible = "on";
            app.Simulation.Visible = "on";
            app.SaveMenu.Enable = "off";
            app.SaveasMenu.Enable = "off";
            app.CloseMenu.Enable = "off";
            app.ReviewresultsMenu.Enable = "on";

            app.SpecsTabGroup.SelectedTab = app.RotorTab;
            app.AnalysisTabGroup.SelectedTab = app.ThreeDTab;
            updateFocusStatus(app.VisualAnalysis3D, app.AnalysisTabGroup.SelectedTab.Title);

            resetChangeStates(app);
            resetUI(app.AerofoilSpecs);
            resetUI(app.VisualAnalysis3D);
            resetUI(app.VisualAnalysis);
            resetUI(app.RotorAnalysis);
            resetUI(app.SteadyOpAnalysis);
            resetUI(app.ModalAnalysis);
            resetUI(app.ControlAnalysis);
            resetUI(app.ResultsAnalysis);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            movegui(app.FASTToolUIFigure); % Position of the figure is set left and above the (main) screen, so this moves it onto the screen from the top-left
            app.WindTurbine = WindTurbineClass;
            app.FileIO = FileIOClass;

            setHandles(app.WindTurbine, app.FileIO, app);
            setHandles(app.FileIO, app.WindTurbine, app);
            setHandles(app.RotorSpecs, app.WindTurbine, app);
            setHandles(app.AerofoilSpecs, app.WindTurbine, app);
            setHandles(app.TowerSpecs, app.WindTurbine, app);
            setHandles(app.DrivetrainAndNacelleSpecs, app.WindTurbine, app);
            setHandles(app.Control1Specs, app.WindTurbine, app);
            setHandles(app.Control2Specs, app.WindTurbine, app);
            setHandles(app.VisualAnalysis3D, app.WindTurbine, app);
            setHandles(app.VisualAnalysis, app.WindTurbine);
            setHandles(app.RotorAnalysis, app.WindTurbine, app);
            setHandles(app.SteadyOpAnalysis, app.WindTurbine, app);
            setHandles(app.ModalAnalysis, app.WindTurbine, app);
            setHandles(app.ControlAnalysis, app.WindTurbine, app);
            setHandles(app.Simulation, app.WindTurbine, app);
            setHandles(app.ResultsAnalysis, app.WindTurbine, app);
            resetAllUI(app);
        end

        % Menu selected function: OpenMenu
        function OpenProject(app, event)
            if ~sureToClose(app)
                return;
            end

            [fileName, pathName] = selectReadFile(app, 'mat', 'Open project');
            if fileName == 0
                return;
            end

            [Data] = openProject(app.FileIO, fileName, pathName, 'project');
            if isempty(Data)
                info = ['<' fileName '> is not a valid project file'];
                uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                return;
            end

            resetChangeStates(app);
            % When opening a new project, a full reset of the main figure
            % and tabs is done, unless it follows up on a previous open
            % project. In such cases, the tabs that are selected and
            % selections made on AerofoilSpecs, VisualAnalysis3D and
            % VisualAnalysis are preserved. By maintaining these
            % selections, the user sees the data and visualisation of the
            % new project replacing those of the previous project, which
            % facilitates comparison of two project files. This enables a
            % comparison of details that are not presented in the compare
            % and merge dialogue.
            if strcmp(app.mode, "project")
                resetUI(app.RotorAnalysis);
                resetUI(app.SteadyOpAnalysis);
                resetUI(app.ModalAnalysis);
                resetUI(app.ControlAnalysis);
                resetUI(app.ResultsAnalysis);
            else
                resetAllUI(app);
            end
            app.mode = "project";

            app.projectName = fileName;
            app.FASTToolUIFigure.Name = ['FASTTool - ' app.projectName];

            % Data is loaded from the project file to the WindTurbine
            % object by feeding it into the user-interface elements and
            % then reading these elements. This creates a filter of the
            % data, which ensures that only the relevant parameters are
            % loaded, that all relevant parameters are loaded and that
            % these parameters have the correct format. This also enables
            % restoring only part of the data in one of the specification
            % tabs. In these restore functions all data is loaded from the
            % open project file, but the assignment to WindTurbine is
            % filtered through the elements of that tab.
            % The data on file for WindTurbine.Airfoil is too extensive for
            % the AerofoilSpecs tab, since it contains data for multiple
            % aerofoils. All aerofoil data is therefore first assigned
            % directly to WindTurbine.Airfoil. The AerofoilSpecs tab only
            % filters data and assigns it to part of WindTurbine.Airfoil
            % for the visible/selected aerofoil on the tab. The restore
            % function on this tab restores the data for all aerofoils
            % (visible on the tab and not visible) and keeps aerofoil data
            % that is not on file but that has been added and used in the
            % current blade specs.
            app.WindTurbine.Airfoil = Data.Airfoil;
            setSpecs(app.RotorSpecs, Data);
            selectedAerofoilIndex = setSpecs(app.AerofoilSpecs, Data);
            setMatch(app.VisualAnalysis, selectedAerofoilIndex);
            setSpecs(app.TowerSpecs, Data);
            setSpecs(app.DrivetrainAndNacelleSpecs, Data);
            setSpecs(app.Control1Specs, Data);
            setSpecs(app.Control2Specs, Data);
            setSpecs(app.VisualAnalysis3D, Data);
            setSpecs(app.SteadyOpAnalysis, Data);
            setSpecs(app.ControlAnalysis, Data);

            % Set the match of the aerofoil selection after all specs are
            % set because it updates the visualisation. This would throw
            % an error if the selected visualitation would need data that
            % hadn't been set.
            updateVisualisation(app);
            setSettings(app.Simulation, Data);
            app.RightPanel.Enable = "on";
            app.LeftPanel.Enable = "on";
            app.SaveMenu.Enable = "on";
            app.SaveasMenu.Enable = "on";
            app.CloseMenu.Enable = "on";
            app.Image.Visible = "off";
        end

        % Menu selected function: SaveMenu
        function SaveProject(app, event)
            saveProject(app.FileIO);
            resetChangeStates(app);
        end

        % Menu selected function: SaveasMenu
        function SaveProjectAs(app, event)
            [fileName, pathName] = selectWriteFile(app, 'mat', 'Save project');
            if fileName == 0
                return;
            end

            saveProjectAs(app.FileIO, fileName, pathName);
            app.projectName = fileName;
            app.FASTToolUIFigure.Name = ['FASTTool - ' app.projectName];
            resetChangeStates(app);
        end

        % Menu selected function: CloseMenu
        function CloseProject(app, event)
            if ~sureToClose(app)
                return;
            end
            resetAllUI(app);
        end

        % Menu selected function: CompareMenu
        function CompareProjectFiles(app, event)
            app.MergeDialogueApp = MergeDialogue(app, app.FileIO, app.FASTToolUIFigure.Position);            
        end

        % Menu selected function: ReviewresultsMenu
        function ReviewresultsMenuSelected(app, event)
            if ~sureToClose(app)
                return;
            end
            resetAllUI(app);
            app.mode = "results";
            app.ReviewresultsMenu.Enable = "off";

            app.Image.Visible = "off";
            app.LeftPanel.Visible = "off";
            app.RightPanel.Enable = "on";
            app.CloseMenu.Enable = "on";
            app.AnalysisTabGroup.SelectedTab = app.RotorAnalysisTab;
            app.VisualAnalysis3D.Visible = "off";
            app.VisualAnalysis.Visible = "off";
            app.Simulation.Visible = "off";

            setResultsMode(app.RotorAnalysis);
            setResultsMode(app.SteadyOpAnalysis);
            setResultsMode(app.ModalAnalysis);
            setResultsMode(app.ControlAnalysis);
        end

        % Menu selected function: FullmodelMenu
        function ViewFullModel(app, event)
            app.ProgressDialog = uiprogressdlg(app.FASTToolUIFigure,'Title','Opening Simulink. This may take some time.', Indeterminate = 'on');
            viewModel(app.WindTurbine, 'full');
            close(app.ProgressDialog);
        end

        % Menu selected function: TorquecontrollerMenu
        function ViewTorqueController(app, event)
            app.ProgressDialog = uiprogressdlg(app.FASTToolUIFigure,'Title','Opening Simulink. This may take some time.', Indeterminate = 'on');
            viewModel(app.WindTurbine, 'torque');
            close(app.ProgressDialog);
        end

        % Menu selected function: PitchcontrollerMenu
        function ViewPitchController(app, event)
            app.ProgressDialog = uiprogressdlg(app.FASTToolUIFigure,'Title','Opening Simulink. This may take some time.', Indeterminate = 'on');
            viewModel(app.WindTurbine, 'pitch');
            close(app.ProgressDialog);
        end

        % Menu selected function: ForeaftdamperMenu
        function ViewForeAftDamper(app, event)
            app.ProgressDialog = uiprogressdlg(app.FASTToolUIFigure,'Title','Opening Simulink. This may take some time.', Indeterminate = 'on');
            viewModel(app.WindTurbine, 'damper');
            close(app.ProgressDialog);
        end

        % Menu selected function: AboutMenu
        function ShowAboutInfo(app, event)
            info = [...
                'FASTTool v' app.version ' (' app.versionDate ')\n', ...
                '\n', ...
                'Developed for the course AE4W09: Wind turbine design\n', ...
                '\n', ...
                'Works on Windows with Matlab R2024b and above\n', ...
                '\n', ...
                'Uses FAST v8.16\n', ...
                '\n', ...
                'GUI and steady-state analyses: Michiel Zaaijer\n', ...
                'Images and 3D plots: Rene Bos\n', ...
                'Linear control analysis: Atindriyo Pamososuryo\n',...
                'Simulink integration: Sebastiaan Mulders, Sachin Navalkar and Jan-Willem van Wingerden.\n', ...
                'Icon: https://www.flaticon.com/free-icons/wind-turbine'];
            uialert(app.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
        end

        % Selection change function: SpecsTabGroup
        function SpecsTabChanged(app, event)
            setMatch(app.VisualAnalysis, app.SpecsTabGroup.SelectedTab.Title);
            updateVisualisation(app);
        end

        % Selection change function: AnalysisTabGroup
        function AnalysisTabChanged(app, event)
            % When the selection of tab in the analysis panel changes, the
            % VisualAnalysis3D tab is triggered to set whether it isconfirm
            % selected or not. If it is not selected (and thus not visible)
            % updating of an animated visualisation is bypassed because it
            % is time consuming. This might otherwise compete with
            % activities chosen to run on other analysis tabs.
            updateFocusStatus(app.VisualAnalysis3D, app.AnalysisTabGroup.SelectedTab.Title);
            if strcmp(app.mode, "project")
                updateVisualisation(app);
            end
        end

        % Close request function: FASTToolUIFigure
        function FASTToolCloseRequest(app, event)
            if ~sureToClose(app)
                return;
            end
            app.VisualAnalysis3D.stopAnimation;
            close_system('FAST/Scope', 0);
            [success, tempDirName] = removeTempDirectory(app.FileIO);
            if ~success
                info = sprintf('Temporary folder\n\n%s\n\ncreated to run a modal analysis, linearisation or simulation, could not be removed.\n\n', tempDirName);
                info = append(info, sprintf('Please try to remove this manually. This might require closing Matlab.'));
                % uiconfirm with the unused output 'selection' is used
                % instead of uialert, to pause closing FASTTool until the
                % user has responded to the messages.
                selection = uiconfirm(app.FASTToolUIFigure, info, "Closing FASTTool", Options = "OK", Icon = "info");
            end
            delete(app.FileIO);
            delete(app.WindTurbine);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.FASTToolUIFigure = uifigure('Visible', 'off');
            app.FASTToolUIFigure.Name = 'FASTTool';
            app.FASTToolUIFigure.Icon = fullfile(pathToMLAPP, 'graphics', 'icon.png');
            app.FASTToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @FASTToolCloseRequest, true);
            app.ProjectMenu = uimenu(app.FASTToolUIFigure);
            app.ProjectMenu.Text = 'Project';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenProject, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProject, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.ProjectMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveProjectAs, true);
            app.SaveasMenu.Text = 'Save as ...';
            app.CompareMenu = uimenu(app.ProjectMenu);
            app.CompareMenu.MenuSelectedFcn = createCallbackFcn(app, @CompareProjectFiles, true);
            app.CompareMenu.Separator = 'on';
            app.CompareMenu.Text = 'Compare';
            app.ReviewresultsMenu = uimenu(app.ProjectMenu);
            app.ReviewresultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ReviewresultsMenuSelected, true);
            app.ReviewresultsMenu.Separator = 'on';
            app.ReviewresultsMenu.Text = 'Review results';
            app.CloseMenu = uimenu(app.ProjectMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseProject, true);
            app.CloseMenu.Separator = 'on';
            app.CloseMenu.Text = 'Close';
            app.SimulinkMenu = uimenu(app.FASTToolUIFigure);
            app.SimulinkMenu.Text = 'Simulink';
            app.FullmodelMenu = uimenu(app.SimulinkMenu);
            app.FullmodelMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewFullModel, true);
            app.FullmodelMenu.Text = 'Full model';
            app.TorquecontrollerMenu = uimenu(app.SimulinkMenu);
            app.TorquecontrollerMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewTorqueController, true);
            app.TorquecontrollerMenu.Text = 'Torque controller';
            app.PitchcontrollerMenu = uimenu(app.SimulinkMenu);
            app.PitchcontrollerMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewPitchController, true);
            app.PitchcontrollerMenu.Text = 'Pitch controller';
            app.ForeaftdamperMenu = uimenu(app.SimulinkMenu);
            app.ForeaftdamperMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewForeAftDamper, true);
            app.ForeaftdamperMenu.Text = 'Fore-aft damper';
            app.HelpMenu = uimenu(app.FASTToolUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowAboutInfo, true);
            app.AboutMenu.Text = 'About';
            app.RightPanel = uipanel(app.FASTToolUIFigure);
            app.RightPanel.Title = 'Analyses';
            app.AnalysisTabGroup = uitabgroup(app.RightPanel);
            app.AnalysisTabGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTabChanged, true);
            app.ThreeDTab = uitab(app.AnalysisTabGroup);
            app.ThreeDTab.Title = '3D';
            app.VisualAnalysis3D = VisualAnalysis3D(app.ThreeDTab);
            app.VisualTab = uitab(app.AnalysisTabGroup);
            app.VisualTab.Title = 'Visual';
            app.VisualAnalysis = VisualAnalysis(app.VisualTab);
            app.RotorAnalysisTab = uitab(app.AnalysisTabGroup);
            app.RotorAnalysisTab.Title = 'Rotor';
            app.RotorAnalysis = RotorAnalysis(app.RotorAnalysisTab);
            app.SteadyOpTab = uitab(app.AnalysisTabGroup);
            app.SteadyOpTab.Title = 'Steady Op';
            app.SteadyOpAnalysis = SteadyOpAnalysis(app.SteadyOpTab);
            app.ModalTab = uitab(app.AnalysisTabGroup);
            app.ModalTab.Title = 'Modal';
            app.ModalAnalysis = ModalAnalysis(app.ModalTab);
            app.ControllinearTab = uitab(app.AnalysisTabGroup);
            app.ControllinearTab.Title = 'Control (linear)';
            app.ControlAnalysis = ControlAnalysis(app.ControllinearTab);
            app.SimulationTab = uitab(app.AnalysisTabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Simulation = Simulation(app.SimulationTab);
            app.QuickscanTab = uitab(app.AnalysisTabGroup);
            app.QuickscanTab.Title = 'Quick scan';
            app.ResultsAnalysis = ResultsAnalysis(app.QuickscanTab);
            app.LeftPanel = uipanel(app.FASTToolUIFigure);
            app.LeftPanel.Title = 'Turbine specifications';
            app.SpecsTabGroup = uitabgroup(app.LeftPanel);
            app.SpecsTabGroup.SelectionChangedFcn = createCallbackFcn(app, @SpecsTabChanged, true);
            app.RotorTab = uitab(app.SpecsTabGroup);
            app.RotorTab.Title = 'Rotor';
            app.RotorSpecs = RotorSpecs(app.RotorTab);
            app.AerofoilsTab = uitab(app.SpecsTabGroup);
            app.AerofoilsTab.Title = 'Aerofoils';
            app.AerofoilSpecs = AerofoilSpecs(app.AerofoilsTab);
            app.TowerTab = uitab(app.SpecsTabGroup);
            app.TowerTab.Title = 'Tower';
            app.TowerSpecs = TowerSpecs(app.TowerTab);
            app.DrivetrainnacelleTab = uitab(app.SpecsTabGroup);
            app.DrivetrainnacelleTab.Title = 'Drivetrain & nacelle';
            app.DrivetrainAndNacelleSpecs = DrivetrainAndNacelleSpecs(app.DrivetrainnacelleTab);
            app.Control1Tab = uitab(app.SpecsTabGroup);
            app.Control1Tab.Title = 'Control 1';
            app.Control1Specs = Control1Specs(app.Control1Tab);
            app.Control2Tab = uitab(app.SpecsTabGroup);
            app.Control2Tab.Title = 'Control 2';
            app.Control2Specs = Control2Specs(app.Control2Tab);
            app.Image = uiimage(app.FASTToolUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'graphics', 'background.png');
            % Show the figure after all components are created
            app.FASTToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FASTTool

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FASTToolUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FASTToolUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FASTToolUIFigure)
        end
    end
end