classdef MergeDialogue < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout11               matlab.ui.container.GridLayout
        IdentifyLabel              matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout10               matlab.ui.container.GridLayout
        ScalarDataPanel            matlab.ui.container.Panel
        GridLayout9                matlab.ui.container.GridLayout
        EditField2_10              matlab.ui.control.NumericEditField
        EditField2_9               matlab.ui.control.NumericEditField
        EditField2_8               matlab.ui.control.NumericEditField
        EditField2_7               matlab.ui.control.NumericEditField
        EditField2_6               matlab.ui.control.NumericEditField
        EditField2_5               matlab.ui.control.NumericEditField
        EditField2_4               matlab.ui.control.NumericEditField
        EditField2_3               matlab.ui.control.NumericEditField
        EditField2_2               matlab.ui.control.NumericEditField
        EditField2_1               matlab.ui.control.NumericEditField
        EditField_10               matlab.ui.control.NumericEditField
        Label_10                   matlab.ui.control.Label
        EditField_9                matlab.ui.control.NumericEditField
        Label_9                    matlab.ui.control.Label
        EditField_8                matlab.ui.control.NumericEditField
        Label_8                    matlab.ui.control.Label
        EditField_7                matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        EditField_6                matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        EditField_5                matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        Label_3                    matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        EditField_1                matlab.ui.control.NumericEditField
        Label_1                    matlab.ui.control.Label
        TableDataPanel             matlab.ui.container.Panel
        GridLayout9_2              matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        UITable2                   matlab.ui.control.Table
        GridLayout8                matlab.ui.container.GridLayout
        FilenameProject2           matlab.ui.control.Label
        Project2Label              matlab.ui.control.Label
        FilenameProject1           matlab.ui.control.Label
        Project1Label              matlab.ui.control.Label
        BrowseButton_2             matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        GridLayout7                matlab.ui.container.GridLayout
        SimulationButtonGroup      matlab.ui.container.ButtonGroup
        SimulationButton2          matlab.ui.control.RadioButton
        SimulationButton1          matlab.ui.control.RadioButton
        AirDensityButtonGroup      matlab.ui.container.ButtonGroup
        AirDensityButton2          matlab.ui.control.RadioButton
        AirDensityButton1          matlab.ui.control.RadioButton
        SitingButtonGroup          matlab.ui.container.ButtonGroup
        SitingButton2              matlab.ui.control.RadioButton
        SitingButton1              matlab.ui.control.RadioButton
        PitchScheduledButtonGroup  matlab.ui.container.ButtonGroup
        PitchScheduledButton2      matlab.ui.control.RadioButton
        PitchScheduledButton1      matlab.ui.control.RadioButton
        PitchConstantButtonGroup   matlab.ui.container.ButtonGroup
        PitchConstantButton2       matlab.ui.control.RadioButton
        PitchConstantButton1       matlab.ui.control.RadioButton
        DampingButtonGroup         matlab.ui.container.ButtonGroup
        DampingButton2             matlab.ui.control.RadioButton
        DampingButton1             matlab.ui.control.RadioButton
        FilterTypeButtonGroup      matlab.ui.container.ButtonGroup
        FilterTypeButton2          matlab.ui.control.RadioButton
        FilterTypeButton1          matlab.ui.control.RadioButton
        PitchTypeButtonGroup       matlab.ui.container.ButtonGroup
        PitchTypeButton2           matlab.ui.control.RadioButton
        PitchTypeButton1           matlab.ui.control.RadioButton
        LimitsButtonGroup          matlab.ui.container.ButtonGroup
        LimitsButton2              matlab.ui.control.RadioButton
        LimitsButton1              matlab.ui.control.RadioButton
        FinepitchButtonGroup       matlab.ui.container.ButtonGroup
        FinepitchButton2           matlab.ui.control.RadioButton
        FinepitchButton1           matlab.ui.control.RadioButton
        TorqueButtonGroup          matlab.ui.container.ButtonGroup
        TorqueButton2              matlab.ui.control.RadioButton
        TorqueButton1              matlab.ui.control.RadioButton
        SupervisoryButtonGroup     matlab.ui.container.ButtonGroup
        SupervisoryButton2         matlab.ui.control.RadioButton
        SupervisoryButton1         matlab.ui.control.RadioButton
        DrivetrainButtonGroup      matlab.ui.container.ButtonGroup
        DrivetrainButton2          matlab.ui.control.RadioButton
        DrivetrainButton1          matlab.ui.control.RadioButton
        NacelleButtonGroup         matlab.ui.container.ButtonGroup
        NacelleButton2             matlab.ui.control.RadioButton
        NacelleButton1             matlab.ui.control.RadioButton
        Tower2ButtonGroup          matlab.ui.container.ButtonGroup
        Tower2Button2              matlab.ui.control.RadioButton
        Tower2Button1              matlab.ui.control.RadioButton
        TowerButtonGroup           matlab.ui.container.ButtonGroup
        TowerButton2               matlab.ui.control.RadioButton
        TowerButton1               matlab.ui.control.RadioButton
        AerofoilButtonGroup        matlab.ui.container.ButtonGroup
        AerofoilButton2            matlab.ui.control.RadioButton
        AerofoilButton1            matlab.ui.control.RadioButton
        RotorButtonGroup           matlab.ui.container.ButtonGroup
        RotorButton2               matlab.ui.control.RadioButton
        RotorButton1               matlab.ui.control.RadioButton
        BladeButtonGroup           matlab.ui.container.ButtonGroup
        BladeButton2               matlab.ui.control.RadioButton
        BladeButton1               matlab.ui.control.RadioButton
        GridLayout6                matlab.ui.container.GridLayout
        LabelSelect2               matlab.ui.control.Label
        LabelSelect1               matlab.ui.control.Label
        SelectionLabel             matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        GridLayout4                matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        SaveasButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        FASTToolApp
        FileIO
        DataProject1 = [];
        fileName1
        pathName1
        DataProject2 = [];
        fileName2
        pathName2
        MergedData
        identical
        aerofoilSelectionFollowsBlade
    end
    
    methods (Access = private)

%% Managing which projects are to be compared and/or merged

        function updateData(app, projectID)
            [fileName, pathName] = uigetfile('*.mat', 'Project 1 to compare');
            figure(app.UIFigure); % Needed to get FASTTool back to foreground after the dialogue closes
            if fileName == 0
                return;
            end
            [~,~,ext] = fileparts(fileName);
            if ~strcmp(ext,'.mat')
                info = ['<' fileName '> is not a valid project file'];
                uialert(app.UIFigure, sprintf(info), 'Info', Icon='info');
                return;
            end

            [Data] = openProject(app.FileIO, fileName, pathName, 'compare');
            if isempty(Data)
                info = ['<' fileName '> is not a valid project file'];
                uialert(app.UIFigure, sprintf(info), 'Info', Icon='info');
                return;
            end
            if projectID == 1
                app.DataProject1 = Data;
                app.FilenameProject1.Text = fileName;
                app.FilenameProject1.Tooltip = fileName;
                app.fileName1 = fileName;
                app.pathName1 = pathName;
            else
                app.DataProject2 = Data;
                app.FilenameProject2.Text = fileName;
                app.FilenameProject2.Tooltip = fileName;
                app.fileName2 = fileName;
                app.pathName2 = pathName;
            end
            
            if ~isempty(app.DataProject1) && ~isempty(app.DataProject2)
                resetButtonGroups(app);
                compareAndMark(app);
                showData(app);
            end
        end

%% Managing the display and selection of equal and differing data in groups

        function compareAndMark(app)
            app.identical = true(1, 19);
            
            % Blade geometry and properties
            if ~(isequal(app.DataProject1.Blade.Radius, app.DataProject2.Blade.Radius) && ...
                    isequal(app.DataProject1.Blade.Chord, app.DataProject2.Blade.Chord) && ...
                    isequal(app.DataProject1.Blade.Twist, app.DataProject2.Blade.Twist) && ...
                    isequal(app.DataProject1.Blade.NFoil, app.DataProject2.Blade.NFoil) && ...
                    isequal(app.DataProject1.Blade.Mass, app.DataProject2.Blade.Mass) && ...
                    isequal(app.DataProject1.Blade.EIflap, app.DataProject2.Blade.EIflap) && ...
                    isequal(app.DataProject1.Blade.EIedge, app.DataProject2.Blade.EIedge) && ...
                    isequal(app.DataProject1.Airfoil.Name(app.DataProject1.Blade.IFoil), app.DataProject2.Airfoil.Name(app.DataProject2.Blade.IFoil)))
                app.identical(1) = false;
                app.BladeButton2.Visible = "on";
                app.BladeButton1.Position(1) = 6;
                app.ListBox.Items{1}(1) = '*';
            else
                app.ListBox.Items{1}(1) = ' ';
            end
                
            % Rotor geometry
            if ~(app.DataProject1.Blade.Number == app.DataProject2.Blade.Number && ...
                    app.DataProject1.Blade.Cone == app.DataProject2.Blade.Cone)
                app.identical(2) = false;
                app.RotorButton2.Visible = "on";
                app.RotorButton1.Position(1) = 6;
                app.ListBox.Items{2}(1) = '*';
            else
                app.ListBox.Items{2}(1) = ' ';
            end

            % Aerofoils
            if ~isequal(app.DataProject1.Airfoil, app.DataProject2.Airfoil)
                app.identical(3) = false;
                app.AerofoilButton2.Visible = "on";
                app.AerofoilButton1.Position(1) = 6;
                app.ListBox.Items{3}(1) = '*';
            else
                app.ListBox.Items{3}(1) = ' ';
            end

            % Tower geometry
            if ~(isequal(app.DataProject1.Tower.Height, app.DataProject2.Tower.Height) && ...
                    isequal(app.DataProject1.Tower.Diameter, app.DataProject2.Tower.Diameter) && ...
                    isequal(app.DataProject1.Tower.WallThickness, app.DataProject2.Tower.WallThickness))
                app.identical(4) = false;
                app.TowerButton2.Visible = "on";
                app.TowerButton1.Position(1) = 6;
                app.ListBox.Items{4}(1) = '*';
            else
                app.ListBox.Items{4}(1) = ' ';
            end

            % Hub height and tower material
            if ~(app.DataProject1.Tower.HubHeight == app.DataProject2.Tower.HubHeight && ...
                    app.DataProject1.Tower.EffectiveDensity == app.DataProject2.Tower.EffectiveDensity)
                app.identical(5) = false;
                app.Tower2Button2.Visible = "on";
                app.Tower2Button1.Position(1) = 6;
                app.ListBox.Items{5}(1) = '*';
            else
                app.ListBox.Items{5}(1) = ' ';
            end

            % Nacelle

            % Remove obsolete field from Nacelle
            if isfield(app.DataProject1.Nacelle.Hub, 'Diameter')
                app.DataProject1.Nacelle.Hub = rmfield(app.DataProject1.Nacelle.Hub, 'Diameter');
            end
            if isfield(app.DataProject2.Nacelle.Hub, 'Diameter')
                app.DataProject2.Nacelle.Hub = rmfield(app.DataProject2.Nacelle.Hub, 'Diameter');
            end

            if ~isequal(app.DataProject1.Nacelle, app.DataProject2.Nacelle)
                app.identical(6) = false;
                app.NacelleButton2.Visible = "on";
                app.NacelleButton1.Position(1) = 6;
                app.ListBox.Items{6}(1) = '*';
            else
                app.ListBox.Items{6}(1) = ' ';
            end

            % Drivetrain
            if ~isequal(app.DataProject1.Drivetrain, app.DataProject2.Drivetrain)
                app.identical(7) = false;
                app.DrivetrainButton2.Visible = "on";
                app.DrivetrainButton1.Position(1) = 6;
                app.ListBox.Items{7}(1) = '*';
            else
                app.ListBox.Items{7}(1) = ' ';
            end

            % Supervisory control
            if ~(isequal(app.DataProject1.Control.WindSpeed, app.DataProject2.Control.WindSpeed) && ...
                    app.DataProject1.Control.Pitch.StartupPitch == app.DataProject2.Control.Pitch.StartupPitch && ...
                    app.DataProject1.Control.Pitch.StartupPitchRate == app.DataProject2.Control.Pitch.StartupPitchRate && ...
                    app.DataProject1.Control.Pitch.StartupSpeed == app.DataProject2.Control.Pitch.StartupSpeed && ...
                    isequal(app.DataProject1.Control.Brake, app.DataProject2.Control.Brake))
                app.identical(8) = false;
                app.SupervisoryButton2.Visible = "on";
                app.SupervisoryButton1.Position(1) = 6;
                app.ListBox.Items{8}(1) = '*';
            else
                app.ListBox.Items{8}(1) = ' ';
            end

            % Torque control
            if ~(app.DataProject1.Control.Torque.OptGain == app.DataProject2.Control.Torque.OptGain && ...
                    app.DataProject1.Control.Torque.SpeedA == app.DataProject2.Control.Torque.SpeedA && ...
                    app.DataProject1.Control.Torque.SpeedB == app.DataProject2.Control.Torque.SpeedB && ...
                    app.DataProject1.Control.Torque.SpeedB2 == app.DataProject2.Control.Torque.SpeedB2 && ...
                    app.DataProject1.Control.Torque.SpeedC == app.DataProject2.Control.Torque.SpeedC && ...
                    app.DataProject1.Control.Torque.Demanded == app.DataProject2.Control.Torque.Demanded && ...
                    app.DataProject1.Control.Torque.LowPassCutOffFreq == app.DataProject2.Control.Torque.LowPassCutOffFreq)
                app.identical(9) = false;
                app.TorqueButton2.Visible = "on";
                app.TorqueButton1.Position(1) = 6;
                app.ListBox.Items{9}(1) = '*';
            else
                app.ListBox.Items{9}(1) = ' ';
            end

            % Partial-load pitch
            if ~(app.DataProject1.Control.Pitch.Fine == app.DataProject2.Control.Pitch.Fine)
                app.identical(10) = false;
                app.FinepitchButton2.Visible = "on";
                app.FinepitchButton1.Position(1) = 6;
                app.ListBox.Items{10}(1) = '*';
            else
                app.ListBox.Items{10}(1) = ' ';
            end

            % Actuator limits
            if ~(app.DataProject1.Control.Pitch.Min == app.DataProject2.Control.Pitch.Min && ...
                    app.DataProject1.Control.Pitch.Max == app.DataProject2.Control.Pitch.Max && ...
                    app.DataProject1.Control.Pitch.Minrate == app.DataProject2.Control.Pitch.Minrate && ...
                    app.DataProject1.Control.Pitch.Maxrate == app.DataProject2.Control.Pitch.Maxrate && ...
                    app.DataProject1.Control.Torque.Min == app.DataProject2.Control.Torque.Min && ...
                    app.DataProject1.Control.Torque.Limit == app.DataProject2.Control.Torque.Limit && ...
                    app.DataProject1.Control.Torque.Slewrate == app.DataProject2.Control.Torque.Slewrate)
                app.identical(11) = false;
                app.LimitsButton2.Visible = "on";
                app.LimitsButton1.Position(1) = 6;
                app.ListBox.Items{11}(1) = '*';
            else
                app.ListBox.Items{11}(1) = ' ';
            end

            % Pitch-control type (constant/scheduled)
            if~(app.DataProject1.Control.Pitch.Scheduled == app.DataProject2.Control.Pitch.Scheduled)
                app.identical(12) = false;
                app.PitchTypeButton2.Visible = "on";
                app.PitchTypeButton1.Position(1) = 6;
                app.ListBox.Items{12}(1) = '*';
            else
                app.ListBox.Items{12}(1) = ' ';
            end

            % Low-pass filter type
            if~(app.DataProject1.Control.Pitch.LowPassOrder == app.DataProject2.Control.Pitch.LowPassOrder)
                app.identical(13) = false;
                app.FilterTypeButton2.Visible = "on";
                app.FilterTypeButton1.Position(1) = 6;
                app.ListBox.Items{13}(1) = '*';
            else
                app.ListBox.Items{13}(1) = ' ';
            end

            % Fore-aft damping
            if ~isequal(app.DataProject1.Control.ForeAft, app.DataProject2.Control.ForeAft)
                app.identical(14) = false;
                app.DampingButton2.Visible = "on";
                app.DampingButton1.Position(1) = 6;
                app.ListBox.Items{14}(1) = '*';
            else
                app.ListBox.Items{14}(1) = ' ';
            end

            % Pitch controller (constant)
            if~(app.DataProject1.Control.Pitch.Kp == app.DataProject2.Control.Pitch.Kp && ...
                    app.DataProject1.Control.Pitch.Ki == app.DataProject2.Control.Pitch.Ki && ...
                    app.DataProject1.Control.Pitch.LowPassCutOffFreq == app.DataProject2.Control.Pitch.LowPassCutOffFreq && ...
                    app.DataProject1.Control.Pitch.Notch_beta1 == app.DataProject2.Control.Pitch.Notch_beta1 && ...
                    app.DataProject1.Control.Pitch.Notch_beta2 == app.DataProject2.Control.Pitch.Notch_beta2 && ...
                    app.DataProject1.Control.Pitch.Notch_wn == app.DataProject2.Control.Pitch.Notch_wn && ...
                    app.DataProject1.Control.Pitch.Notch2_beta1 == app.DataProject2.Control.Pitch.Notch2_beta1 && ...
                    app.DataProject1.Control.Pitch.Notch2_beta2 == app.DataProject2.Control.Pitch.Notch2_beta2 && ...
                    app.DataProject1.Control.Pitch.Notch2_wn == app.DataProject2.Control.Pitch.Notch2_wn)
                app.identical(15) = false;
                app.PitchConstantButton2.Visible = "on";
                app.PitchConstantButton1.Position(1) = 6;
                app.ListBox.Items{15}(1) = '*';
            else
                app.ListBox.Items{15}(1) = ' ';
            end

            % Pitch controller (scheduled)
            if~(isequal(app.DataProject1.Control.Pitch.ScheduledPitchAngles, app.DataProject2.Control.Pitch.ScheduledPitchAngles) && ...
                    isequal(app.DataProject1.Control.Pitch.KpGS, app.DataProject2.Control.Pitch.KpGS) && ...
                    isequal(app.DataProject1.Control.Pitch.KiGS, app.DataProject2.Control.Pitch.KiGS) && ...
                    isequal(app.DataProject1.Control.Pitch.LowPassCutOffFreqGS, app.DataProject2.Control.Pitch.LowPassCutOffFreqGS) && ...
                    isequal(app.DataProject1.Control.Pitch.Notch_beta1GS, app.DataProject2.Control.Pitch.Notch_beta1GS) && ...
                    isequal(app.DataProject1.Control.Pitch.Notch_beta2GS, app.DataProject2.Control.Pitch.Notch_beta2GS) && ...
                    isequal(app.DataProject1.Control.Pitch.Notch_wnGS, app.DataProject2.Control.Pitch.Notch_wnGS) && ...
                    isequal(app.DataProject1.Control.Pitch.Notch2_beta1GS, app.DataProject2.Control.Pitch.Notch2_beta1GS) && ...
                    isequal(app.DataProject1.Control.Pitch.Notch2_beta2GS, app.DataProject2.Control.Pitch.Notch2_beta2GS) && ...
                    isequal(app.DataProject1.Control.Pitch.Notch2_wnGS, app.DataProject2.Control.Pitch.Notch2_wnGS))
                app.identical(16) = false;
                app.PitchScheduledButton2.Visible = "on";
                app.PitchScheduledButton1.Position(1) = 6;
                app.ListBox.Items{16}(1) = '*';
            else
                app.ListBox.Items{16}(1) = ' ';
            end

            % Siting
            if ~isequal(app.DataProject1.Appearance, app.DataProject2.Appearance)
                app.identical(17) = false;
                app.SitingButton2.Visible = "on";
                app.SitingButton1.Position(1) = 6;
                app.ListBox.Items{17}(1) = '*';
            else
                app.ListBox.Items{17}(1) = ' ';
            end

            % Air density
            if ~(app.DataProject1.CertificationSettings.Wind.AirDensity == app.DataProject2.CertificationSettings.Wind.AirDensity)
                app.identical(18) = false;
                app.AirDensityButton2.Visible = "on";
                app.AirDensityButton1.Position(1) = 6;
                app.ListBox.Items{18}(1) = '*';
            else
                app.ListBox.Items{18}(1) = ' ';
            end

            % Simulation settings
            if ~(app.DataProject1.CertificationSettings.Wind.Type == app.DataProject2.CertificationSettings.Wind.Type && ...
                    app.DataProject1.CertificationSettings.Wind.EventTime == app.DataProject2.CertificationSettings.Wind.EventTime && ...
                    isequal(app.DataProject1.CertificationSettings.Wind.Class, app.DataProject2.CertificationSettings.Wind.Class) && ...
                    isequal(app.DataProject1.CertificationSettings.Wind.Speed, app.DataProject2.CertificationSettings.Wind.Speed) && ...
                    app.DataProject1.CertificationSettings.Mode.Type == app.DataProject2.CertificationSettings.Mode.Type && ...
                    app.DataProject1.CertificationSettings.Mode.EventTime == app.DataProject2.CertificationSettings.Mode.EventTime && ...
                    app.DataProject1.CertificationSettings.Run.StartupTime == app.DataProject2.CertificationSettings.Run.StartupTime && ...
                    app.DataProject1.CertificationSettings.Run.Time == app.DataProject2.CertificationSettings.Run.Time && ...
                    app.DataProject1.CertificationSettings.Run.DT == app.DataProject2.CertificationSettings.Run.DT && ...
                    app.DataProject1.CertificationSettings.Run.Seeds == app.DataProject2.CertificationSettings.Run.Seeds && ...
                    app.DataProject1.CertificationSettings.Run.SeedNumber == app.DataProject2.CertificationSettings.Run.SeedNumber && ...
                    app.DataProject1.CertificationSettings.Run.RandomSeed == app.DataProject2.CertificationSettings.Run.RandomSeed)
                app.identical(19) = false;
                app.SimulationButton2.Visible = "on";
                app.SimulationButton1.Position(1) = 6;
                app.ListBox.Items{19}(1) = '*';
            else
                app.ListBox.Items{19}(1) = ' ';
            end

            if all(app.identical)
                uialert(app.UIFigure, 'The two project files are identical', 'Info', Icon='info');
                app.CancelButton.Text = "OK";
                app.SaveasButton.Visible = "off";
            elseif sum(~app.identical) == 1
                uialert(app.UIFigure, 'The two projects differ only in one aspect. No merge required.', 'Info', Icon='info');
                resetButtonGroups(app);
                app.CancelButton.Text = "OK";
                app.SaveasButton.Visible = "off";
            else
                app.CancelButton.Text = "Cancel";
                app.SaveasButton.Visible = "on";
            end

            if ~app.identical(1) && ~app.identical(3) % Both blade and aerofoil data differ
                app.aerofoilSelectionFollowsBlade = true;
                app.AerofoilButtonGroup.Enable = "off";
            end

        end

        function showData(app)
            app.ScalarDataPanel.Visible = "off";
            app.TableDataPanel.Visible = "off";
            app.IdentifyLabel.Text = "Data are identical";

            app.Label_1.Text = '';
            app.EditField_1.Visible = "off";
            app.EditField2_1.Visible = "off";
            app.Label_2.Text = '';
            app.EditField_2.Visible = "off";
            app.EditField2_2.Visible = "off";
            app.Label_3.Text = '';
            app.EditField_3.Visible = "off";
            app.EditField2_3.Visible = "off";
            app.Label_4.Text = '';
            app.EditField_4.Visible = "off";
            app.EditField2_4.Visible = "off";
            app.Label_5.Text = '';
            app.EditField_5.Visible = "off";
            app.EditField2_5.Visible = "off";
            app.Label_6.Text = '';
            app.EditField_6.Visible = "off";
            app.EditField2_6.Visible = "off";
            app.Label_7.Text = '';
            app.EditField_7.Visible = "off";
            app.EditField2_7.Visible = "off";
            app.Label_8.Text = '';
            app.EditField_8.Visible = "off";
            app.EditField2_8.Visible = "off";
            app.Label_9.Text = '';
            app.EditField_9.Visible = "off";
            app.EditField2_9.Visible = "off";
            app.Label_10.Text = '';
            app.EditField_10.Visible = "off";
            app.EditField2_10.Visible = "off";

            switch app.ListBox.Value
                case 'Blade geometry and properties'
                    app.TableDataPanel.Visible = "on";
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', app.DataProject1.Airfoil.Name', 'numeric', 'numeric', 'numeric'};
                    app.UITable.ColumnName = ["Radius [m]" "Chord [m]" "Twist [deg]" "Aerofoil" "Mass density [kg/m]" "Flap. stiff. [N m^2]" "Edge. stiff. [N m^2]"];
                    app.UITable.Data = [...
                        num2cell(app.DataProject1.Blade.Radius(:)), ...
                        num2cell(app.DataProject1.Blade.Chord(:)), ...
                        num2cell(app.DataProject1.Blade.Twist(:)), ...
                        app.DataProject1.Airfoil.Name(app.DataProject1.Blade.IFoil(app.DataProject1.Blade.NFoil)), ...
                        num2cell(app.DataProject1.Blade.Mass(:)), ...
                        num2cell(app.DataProject1.Blade.EIflap(:)), ...
                        num2cell(app.DataProject1.Blade.EIedge(:))];
                    app.UITable2.ColumnFormat = {'numeric', 'numeric', 'numeric', app.DataProject2.Airfoil.Name', 'numeric', 'numeric', 'numeric'};
                    app.UITable2.ColumnName = ["Radius [m]" "Chord [m]" "Twist [deg]" "Aerofoil" "Mass density [kg/m]" "Flap. stiff. [N m^2]" "Edge. stiff. [N m^2]"];
                    app.UITable2.Data = [...
                        num2cell(app.DataProject2.Blade.Radius(:)), ...
                        num2cell(app.DataProject2.Blade.Chord(:)), ...
                        num2cell(app.DataProject2.Blade.Twist(:)), ...
                        app.DataProject2.Airfoil.Name(app.DataProject2.Blade.IFoil(app.DataProject2.Blade.NFoil)), ...
                        num2cell(app.DataProject2.Blade.Mass(:)), ...
                        num2cell(app.DataProject2.Blade.EIflap(:)), ...
                        num2cell(app.DataProject2.Blade.EIedge(:))];
                    if ~app.identical(1)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Rotor geometry'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Number of blades';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Blade.Number;
                    app.EditField2_1.Value = app.DataProject2.Blade.Number;
                    app.Label_2.Text = 'Cone angle';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Blade.Cone;
                    app.EditField2_2.Value = app.DataProject2.Blade.Cone;
                    if ~app.identical(2)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Aerofoils'
                    if ~app.identical(3) && app.aerofoilSelectionFollowsBlade
                        app.IdentifyLabel.Text = "Data differ (not shown). Selection follows selection for blade.";
                    elseif ~app.identical(3)
                        app.IdentifyLabel.Text = "Data differ (not shown)";
                    else
                        app.IdentifyLabel.Text = "Data are identical (not shown)";
                    end

                case 'Tower geometry'
                    app.TableDataPanel.Visible = "on";
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric'};
                    app.UITable.ColumnName = ["Height [m]" "Diameter [m]" "Wall thickness [m]"];
                    app.UITable.Data = [...
                        num2cell(app.DataProject1.Tower.Height(:)), ...
                        num2cell(app.DataProject1.Tower.Diameter(:)), ...
                        num2cell(app.DataProject1.Tower.WallThickness(:))];
                    app.UITable2.ColumnFormat = {'numeric', 'numeric', 'numeric'};
                    app.UITable2.ColumnName = ["Height [m]" "Diameter [m]" "Wall thickness [m]"];
                    app.UITable2.Data = [...
                        num2cell(app.DataProject2.Tower.Height(:)), ...
                        num2cell(app.DataProject2.Tower.Diameter(:)), ...
                        num2cell(app.DataProject2.Tower.WallThickness(:))];
                    if ~app.identical(4)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Hub height and tower material'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Hub Height';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Tower.HubHeight;
                    app.EditField2_1.Value = app.DataProject2.Tower.HubHeight;
                    app.Label_2.Text = 'Tower effective density';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Tower.EffectiveDensity;
                    app.EditField2_2.Value = app.DataProject2.Tower.EffectiveDensity;
                    if ~app.identical(5)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Nacelle'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Hub type (coded)';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Nacelle.Hub.Type;
                    app.EditField2_1.Value = app.DataProject2.Nacelle.Hub.Type;
                    app.Label_2.Text = 'Hub length';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Nacelle.Hub.Length;
                    app.EditField2_2.Value = app.DataProject2.Nacelle.Hub.Length;
                    app.Label_3.Text = 'Hub mass';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Nacelle.Hub.Mass;
                    app.EditField2_3.Value = app.DataProject2.Nacelle.Hub.Mass;
                    app.Label_4.Text = 'Rotor overhang';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Nacelle.Hub.Overhang;
                    app.EditField2_4.Value = app.DataProject2.Nacelle.Hub.Overhang;
                    app.Label_5.Text = 'Shaft tilt';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.Nacelle.Hub.ShaftTilt;
                    app.EditField2_5.Value = app.DataProject2.Nacelle.Hub.ShaftTilt;
                    app.Label_6.Text = 'Nacelle type (coded)';
                    app.EditField_6.Visible = "on";
                    app.EditField2_6.Visible = "on";
                    app.EditField_6.Value = app.DataProject1.Nacelle.Housing.Type;
                    app.EditField2_6.Value = app.DataProject2.Nacelle.Housing.Type;
                    app.Label_7.Text = 'Nacelle length';
                    app.EditField_7.Visible = "on";
                    app.EditField2_7.Visible = "on";
                    app.EditField_7.Value = app.DataProject1.Nacelle.Housing.Length;
                    app.EditField2_7.Value = app.DataProject2.Nacelle.Housing.Length;
                    app.Label_8.Text = 'Nacelle height';
                    app.EditField_8.Visible = "on";
                    app.EditField2_8.Visible = "on";
                    app.EditField_8.Value = app.DataProject1.Nacelle.Housing.Diameter;
                    app.EditField2_8.Value = app.DataProject2.Nacelle.Housing.Diameter;
                    app.Label_9.Text = 'Nacelle mass';
                    app.EditField_9.Visible = "on";
                    app.EditField2_9.Visible = "on";
                    app.EditField_9.Value = app.DataProject1.Nacelle.Housing.Mass;
                    app.EditField2_9.Value = app.DataProject2.Nacelle.Housing.Mass;
                    if ~app.identical(6)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Drivetrain'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Gearbox efficiency';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Drivetrain.Gearbox.Efficiency;
                    app.EditField2_1.Value = app.DataProject2.Drivetrain.Gearbox.Efficiency;
                    app.Label_2.Text = 'Transmission ratio (1 = DD)';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Drivetrain.Gearbox.Ratio;
                    app.EditField2_2.Value = app.DataProject2.Drivetrain.Gearbox.Ratio;
                    app.Label_3.Text = 'Generator efficiency';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Drivetrain.Generator.Efficiency;
                    app.EditField2_3.Value = app.DataProject2.Drivetrain.Generator.Efficiency;
                    app.Label_4.Text = 'Generator moment of inertia';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Drivetrain.Generator.HSSInertia;
                    app.EditField2_4.Value = app.DataProject2.Drivetrain.Generator.HSSInertia;
                    if ~app.identical(7)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Supervisory control'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Cut-in wind speed';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.WindSpeed.Cutin;
                    app.EditField2_1.Value = app.DataProject2.Control.WindSpeed.Cutin;
                    app.Label_2.Text = 'Cut-out wind speed';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Control.WindSpeed.Cutout;
                    app.EditField2_2.Value = app.DataProject2.Control.WindSpeed.Cutout;
                    app.Label_3.Text = 'Startup pitch angle';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Control.Pitch.StartupPitch;
                    app.EditField2_3.Value = app.DataProject2.Control.Pitch.StartupPitch;
                    app.Label_4.Text = 'Startup pitch rate';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Control.Pitch.StartupPitchRate;
                    app.EditField2_4.Value = app.DataProject2.Control.Pitch.StartupPitchRate;
                    app.Label_5.Text = 'Startup rotor speed';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.Control.Pitch.StartupSpeed;
                    app.EditField2_5.Value = app.DataProject2.Control.Pitch.StartupSpeed;
                    app.Label_6.Text = 'Shutdown brake torque';
                    app.EditField_6.Visible = "on";
                    app.EditField2_6.Visible = "on";
                    app.EditField_6.Value = app.DataProject1.Control.Brake.Torque;
                    app.EditField2_6.Value = app.DataProject2.Control.Brake.Torque;
                    app.Label_7.Text = 'Shutdown deploy time';
                    app.EditField_7.Visible = "on";
                    app.EditField2_7.Visible = "on";
                    app.EditField_7.Value = app.DataProject1.Control.Brake.Deploytime;
                    app.EditField2_7.Value = app.DataProject2.Control.Brake.Deploytime;
                    app.Label_8.Text = 'Shutdown delay';
                    app.EditField_8.Visible = "on";
                    app.EditField2_8.Visible = "on";
                    app.EditField_8.Value = app.DataProject1.Control.Brake.Delay;
                    app.EditField2_8.Value = app.DataProject2.Control.Brake.Delay;
                    if ~app.identical(8)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Torque control'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Optimal mode gain';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.Torque.OptGain;
                    app.EditField2_1.Value = app.DataProject2.Control.Torque.OptGain;
                    app.Label_2.Text = 'Generator speed A';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Control.Torque.SpeedA;
                    app.EditField2_2.Value = app.DataProject2.Control.Torque.SpeedA;
                    app.Label_3.Text = 'Generator speed B';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Control.Torque.SpeedB;
                    app.EditField2_3.Value = app.DataProject2.Control.Torque.SpeedB;
                    app.Label_4.Text = 'Generator speed B2';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Control.Torque.SpeedB2;
                    app.EditField2_4.Value = app.DataProject2.Control.Torque.SpeedB2;
                    app.Label_5.Text = 'Generator speed C';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.Control.Torque.SpeedC;
                    app.EditField2_5.Value = app.DataProject2.Control.Torque.SpeedC;
                    app.Label_6.Text = 'Demanded torque';
                    app.EditField_6.Visible = "on";
                    app.EditField2_6.Visible = "on";
                    app.EditField_6.Value = app.DataProject1.Control.Torque.Demanded;
                    app.EditField2_6.Value = app.DataProject2.Control.Torque.Demanded;
                    app.Label_7.Text = 'Low-pass filter cut-off frequency';
                    app.EditField_7.Visible = "on";
                    app.EditField2_7.Visible = "on";
                    app.EditField_7.Value = app.DataProject1.Control.Torque.LowPassCutOffFreq;
                    app.EditField2_7.Value = app.DataProject2.Control.Torque.LowPassCutOffFreq;
                    if ~app.identical(9)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Partial-load pitch'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Fine-pitch angle';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.Pitch.Fine;
                    app.EditField2_1.Value = app.DataProject2.Control.Pitch.Fine;
                     if ~app.identical(10)
                        app.IdentifyLabel.Text = "Data differ";
                    end

               case 'Actuator limits'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Minimum pitch angle';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.Pitch.Min;
                    app.EditField2_1.Value = app.DataProject2.Control.Pitch.Min;
                    app.Label_2.Text = 'Maximum pitch angle';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Control.Pitch.Max;
                    app.EditField2_2.Value = app.DataProject2.Control.Pitch.Max;
                    app.Label_3.Text = 'Minimum pitch rate';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Control.Pitch.Minrate;
                    app.EditField2_3.Value = app.DataProject2.Control.Pitch.Minrate;
                    app.Label_4.Text = 'Maximum pitch rate';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Control.Pitch.Maxrate;
                    app.EditField2_4.Value = app.DataProject2.Control.Pitch.Maxrate;
                    app.Label_5.Text = 'Minimum torque';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.Control.Torque.Min;
                    app.EditField2_5.Value = app.DataProject2.Control.Torque.Min;
                    app.Label_6.Text = 'Maximum torque';
                    app.EditField_6.Visible = "on";
                    app.EditField2_6.Visible = "on";
                    app.EditField_6.Value = app.DataProject1.Control.Torque.Limit;
                    app.EditField2_6.Value = app.DataProject2.Control.Torque.Limit;
                    app.Label_7.Text = 'Slew rate';
                    app.EditField_7.Visible = "on";
                    app.EditField2_7.Visible = "on";
                    app.EditField_7.Value = app.DataProject1.Control.Torque.Slewrate;
                    app.EditField2_7.Value = app.DataProject2.Control.Torque.Slewrate;
                    if ~app.identical(11)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Pitch-control type (constant/scheduled)'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Constant (0) or scheduled (1)';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.Pitch.Scheduled;
                    app.EditField2_1.Value = app.DataProject2.Control.Pitch.Scheduled;
                    if ~app.identical(12)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Low-pass filter type'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Order';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.Pitch.LowPassOrder;
                    app.EditField2_1.Value = app.DataProject2.Control.Pitch.LowPassOrder;
                    if ~app.identical(13)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Fore-aft damping'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Gain';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.ForeAft.Gain;
                    app.EditField2_1.Value = app.DataProject2.Control.ForeAft.Gain;
                    app.Label_2.Text = 'Maximum amplitude';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Control.ForeAft.MaxPitchAmplitude;
                    app.EditField2_2.Value = app.DataProject2.Control.ForeAft.MaxPitchAmplitude;
                    if ~app.identical(14)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Pitch controller (constant)'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Kp';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Control.Pitch.Kp;
                    app.EditField2_1.Value = app.DataProject2.Control.Pitch.Kp;
                    app.Label_2.Text = 'Ki';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Control.Pitch.Ki;
                    app.EditField2_2.Value = app.DataProject2.Control.Pitch.Ki;
                    app.Label_3.Text = 'Low-pass filter cut-off frequency';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Control.Pitch.LowPassCutOffFreq;
                    app.EditField2_3.Value = app.DataProject2.Control.Pitch.LowPassCutOffFreq;
                    app.Label_4.Text = 'Notch 1 - Beta 1';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Control.Pitch.Notch_beta1;
                    app.EditField2_4.Value = app.DataProject2.Control.Pitch.Notch_beta1;
                    app.Label_5.Text = 'Notch 1 - Beta 2';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.Control.Pitch.Notch_beta2;
                    app.EditField2_5.Value = app.DataProject2.Control.Pitch.Notch_beta2;
                    app.Label_6.Text = 'Notch 1 - Omega n';
                    app.EditField_6.Visible = "on";
                    app.EditField2_6.Visible = "on";
                    app.EditField_6.Value = app.DataProject1.Control.Pitch.Notch_wn;
                    app.EditField2_6.Value = app.DataProject2.Control.Pitch.Notch_wn;
                    app.Label_7.Text = 'Notch 2 - Beta 1';
                    app.EditField_7.Visible = "on";
                    app.EditField2_7.Visible = "on";
                    app.EditField_7.Value = app.DataProject1.Control.Pitch.Notch2_beta1;
                    app.EditField2_7.Value = app.DataProject2.Control.Pitch.Notch2_beta1;
                    app.Label_8.Text = 'Notch 2 - Beta 2';
                    app.EditField_8.Visible = "on";
                    app.EditField2_8.Visible = "on";
                    app.EditField_8.Value = app.DataProject1.Control.Pitch.Notch2_beta2;
                    app.EditField2_8.Value = app.DataProject2.Control.Pitch.Notch2_beta2;
                    app.Label_9.Text = 'Notch 2 - Omega n';
                    app.EditField_9.Visible = "on";
                    app.EditField2_9.Visible = "on";
                    app.EditField_9.Value = app.DataProject1.Control.Pitch.Notch2_wn;
                    app.EditField2_9.Value = app.DataProject2.Control.Pitch.Notch2_wn;
                    if ~app.identical(15)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Pitch controller (scheduled)'
                    app.TableDataPanel.Visible = "on";
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
                    app.UITable.ColumnName = ["Pitch [deg]" "Kp [-]" "Ki [-]" "ωc (LPF) [rad/s]" "β1 [rad/s]" "β2 [rad/s]" "ωn [rad/s]" "β1_2 [rad/s]" "β2_2 [rad/s]" "ωn_2 [rad/s]"];
                    app.UITable.Data = [...
                        num2cell(app.DataProject1.Control.Pitch.ScheduledPitchAngles(:) * 180 / pi), ...
                        num2cell(app.DataProject1.Control.Pitch.KpGS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.KiGS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.LowPassCutOffFreqGS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.Notch_beta1GS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.Notch_beta2GS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.Notch_wnGS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.Notch_beta1GS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.Notch_beta2GS(:)), ...
                        num2cell(app.DataProject1.Control.Pitch.Notch_wnGS(:))];
                    app.UITable2.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric'};
                    app.UITable2.ColumnName = ["Pitch [deg]" "Kp [-]" "Ki [-]" "ωc (LPF) [rad/s]" "β1 [rad/s]" "β2 [rad/s]" "ωn [rad/s]" "β1_2 [rad/s]" "β2_2 [rad/s]" "ωn_2 [rad/s]"];
                    app.UITable2.Data = [...
                        num2cell(app.DataProject2.Control.Pitch.ScheduledPitchAngles(:) * 180 / pi), ...
                        num2cell(app.DataProject2.Control.Pitch.KpGS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.KiGS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.LowPassCutOffFreqGS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.Notch_beta1GS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.Notch_beta2GS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.Notch_wnGS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.Notch_beta1GS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.Notch_beta2GS(:)), ...
                        num2cell(app.DataProject2.Control.Pitch.Notch_wnGS(:))];
                    if ~app.identical(16)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Siting'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Blade style (coded)';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.Appearance(1);
                    app.EditField2_1.Value = app.DataProject2.Appearance(1);
                    app.Label_2.Text = 'Blade colour (coded)';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.Appearance(2);
                    app.EditField2_2.Value = app.DataProject2.Appearance(2);
                    app.Label_3.Text = 'Tower style (coded)';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.Appearance(3);
                    app.EditField2_3.Value = app.DataProject2.Appearance(3);
                    app.Label_4.Text = 'Tower colour (coded)';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.Appearance(4);
                    app.EditField2_4.Value = app.DataProject2.Appearance(4);
                    app.Label_5.Text = 'Site type (coded)';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.Appearance(5);
                    app.EditField2_5.Value = app.DataProject2.Appearance(5);
                    if ~app.identical(17)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Air density'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Air density';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.CertificationSettings.Wind.AirDensity;
                    app.EditField2_1.Value = app.DataProject2.CertificationSettings.Wind.AirDensity;
                    if ~app.identical(18)
                        app.IdentifyLabel.Text = "Data differ";
                    end

                case 'Simulation settings'
                    app.ScalarDataPanel.Visible = "on";
                    app.Label_1.Text = 'Wind class (coded)';
                    app.EditField_1.Visible = "on";
                    app.EditField2_1.Visible = "on";
                    app.EditField_1.Value = app.DataProject1.CertificationSettings.Wind.Class(1);
                    app.EditField2_1.Value = app.DataProject2.CertificationSettings.Wind.Class(1);
                    app.Label_2.Text = 'Turbulence class (coded)';
                    app.EditField_2.Visible = "on";
                    app.EditField2_2.Visible = "on";
                    app.EditField_2.Value = app.DataProject1.CertificationSettings.Wind.Class(2);
                    app.EditField2_2.Value = app.DataProject2.CertificationSettings.Wind.Class(2);
                    app.Label_3.Text = 'Wind condition (coded)';
                    app.EditField_3.Visible = "on";
                    app.EditField2_3.Visible = "on";
                    app.EditField_3.Value = app.DataProject1.CertificationSettings.Wind.Type;
                    app.EditField2_3.Value = app.DataProject2.CertificationSettings.Wind.Type;
                    app.Label_4.Text = 'Wind speed (start)';
                    app.EditField_4.Visible = "on";
                    app.EditField2_4.Visible = "on";
                    app.EditField_4.Value = app.DataProject1.CertificationSettings.Wind.Speed(1);
                    app.EditField2_4.Value = app.DataProject2.CertificationSettings.Wind.Speed(1);
                    app.Label_5.Text = 'Turbine operation (coded)';
                    app.EditField_5.Visible = "on";
                    app.EditField2_5.Visible = "on";
                    app.EditField_5.Value = app.DataProject1.CertificationSettings.Mode.Type;
                    app.EditField2_5.Value = app.DataProject2.CertificationSettings.Mode.Type;
                    app.Label_6.Text = 'Startup time to discard';
                    app.EditField_6.Visible = "on";
                    app.EditField2_6.Visible = "on";
                    app.EditField_6.Value = app.DataProject1.CertificationSettings.Run.StartupTime;
                    app.EditField2_6.Value = app.DataProject2.CertificationSettings.Run.StartupTime;
                    app.Label_7.Text = 'Runtime';
                    app.EditField_7.Visible = "on";
                    app.EditField2_7.Visible = "on";
                    app.EditField_7.Value = app.DataProject1.CertificationSettings.Run.Time;
                    app.EditField2_7.Value = app.DataProject2.CertificationSettings.Run.Time;
                    app.Label_8.Text = 'Sampling time';
                    app.EditField_8.Visible = "on";
                    app.EditField2_8.Visible = "on";
                    app.EditField_8.Value = app.DataProject1.CertificationSettings.Run.DT;
                    app.EditField2_8.Value = app.DataProject2.CertificationSettings.Run.DT;
                    app.Label_9.Text = 'Number of seeds';
                    app.EditField_9.Visible = "on";
                    app.EditField2_9.Visible = "on";
                    app.EditField_9.Value = app.DataProject1.CertificationSettings.Run.Seeds;
                    app.EditField2_9.Value = app.DataProject2.CertificationSettings.Run.Seeds;
                    app.Label_10.Text = '... (other parameters not shown)';
                    if ~app.identical(19)
                        app.IdentifyLabel.Text = "Data differ";
                    end
            end
        end

        function resetButtonGroups(app)

            app.aerofoilSelectionFollowsBlade = false;
            app.AerofoilButtonGroup.Enable = "on";

            app.BladeButtonGroup.SelectedObject = app.BladeButton1;
            app.RotorButtonGroup.SelectedObject = app.RotorButton1;
            app.AerofoilButtonGroup.SelectedObject = app.AerofoilButton1;
            app.TowerButtonGroup.SelectedObject = app.TowerButton1;
            app.Tower2ButtonGroup.SelectedObject = app.Tower2Button1;
            app.NacelleButtonGroup.SelectedObject = app.NacelleButton1;
            app.DrivetrainButtonGroup.SelectedObject = app.DrivetrainButton1;
            app.SupervisoryButtonGroup.SelectedObject = app.SupervisoryButton1;
            app.TorqueButtonGroup.SelectedObject = app.TorqueButton1;
            app.FinepitchButtonGroup.SelectedObject = app.FinepitchButton1;
            app.LimitsButtonGroup.SelectedObject = app.LimitsButton1;
            app.PitchTypeButtonGroup.SelectedObject = app.PitchTypeButton1;
            app.FilterTypeButtonGroup.SelectedObject = app.FilterTypeButton1;
            app.DampingButtonGroup.SelectedObject = app.DampingButton1;
            app.PitchConstantButtonGroup.SelectedObject = app.PitchConstantButton1;
            app.PitchScheduledButtonGroup.SelectedObject = app.PitchScheduledButton1;
            app.SitingButtonGroup.SelectedObject = app.SitingButton1;
            app.AirDensityButtonGroup.SelectedObject = app.AirDensityButton1;
            app.SimulationButtonGroup.SelectedObject = app.SimulationButton1;

            app.BladeButton1.Position(1)= 24;
            app.RotorButton1.Position(1)= 24;
            app.AerofoilButton1.Position(1)= 24;
            app.TowerButton1.Position(1)= 24;
            app.Tower2Button1.Position(1)= 24;
            app.NacelleButton1.Position(1)= 24;
            app.DrivetrainButton1.Position(1)= 24;
            app.SupervisoryButton1.Position(1)= 24;
            app.TorqueButton1.Position(1)= 24;
            app.FinepitchButton1.Position(1)= 24;
            app.LimitsButton1.Position(1)= 24;
            app.PitchTypeButton1.Position(1)= 24;
            app.FilterTypeButton1.Position(1)= 24;
            app.DampingButton1.Position(1)= 24;
            app.PitchConstantButton1.Position(1)= 24;
            app.PitchScheduledButton1.Position(1)= 24;
            app.SitingButton1.Position(1)= 24;
            app.AirDensityButton1.Position(1)= 24;
            app.SimulationButton1.Position(1)= 24;

            app.BladeButton2.Visible = "off";
            app.RotorButton2.Visible = "off";
            app.AerofoilButton2.Visible = "off";
            app.TowerButton2.Visible = "off";
            app.Tower2Button2.Visible = "off";
            app.NacelleButton2.Visible = "off";
            app.DrivetrainButton2.Visible = "off";
            app.SupervisoryButton2.Visible = "off";
            app.TorqueButton2.Visible = "off";
            app.FinepitchButton2.Visible = "off";
            app.LimitsButton2.Visible = "off";
            app.PitchTypeButton2.Visible = "off";
            app.FilterTypeButton2.Visible = "off";
            app.DampingButton2.Visible = "off";
            app.PitchConstantButton2.Visible = "off";
            app.PitchScheduledButton2.Visible = "off";
            app.SitingButton2.Visible = "off";
            app.AirDensityButton2.Visible = "off";
            app.SimulationButton2.Visible = "off";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, FASTToolApp, FileIO, position)
            app.FASTToolApp = FASTToolApp;
            app.FileIO = FileIO;

            app.UIFigure.Position(1) = max(position(1) + floor((position(3)-app.UIFigure.Position(3))/2), position(1) + 10);
            app.UIFigure.Position(2) = min(position(2) + ceil((position(4)-app.UIFigure.Position(4))/2), position(2) + position(4) - app.UIFigure.Position(4) - 10);

            app.ListBox.ItemsData = {'Blade geometry and properties', ...
                'Rotor geometry', ...
                'Aerofoils', ...
                'Tower geometry', ...
                'Hub height and tower material', ...
                'Nacelle', ...
                'Drivetrain', ...
                'Supervisory control', ...
                'Torque control', ...
                'Partial-load pitch', ...
                'Actuator limits', ...
                'Pitch-control type (constant/scheduled)', ...
                'Low-pass filter type', ...
                'Fore-aft damping', ...
                'Pitch controller (constant)', ...
                'Pitch controller (scheduled)', ...
                'Siting', ...
                'Air density', ...
                'Simulation settings'};

            resetButtonGroups(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            updateData(app, 1);
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            updateData(app, 2);
        end

        % Button pushed function: SaveasButton
        function SaveasButtonPushed(app, event)
            [fileName, pathName] = uiputfile('*.mat', 'Save project');
            figure(app.UIFigure); % Needed to get FASTTool back to foreground after the dialogue closes
            if fileName == 0
                return;
            end
            [~,~,ext] = fileparts(fileName);
            if ~strcmp(ext,'.mat')
                info = ['<' fileName '> is not a valid filename'];
                uialert(app.UIFigure, sprintf(info), 'Info', Icon='info');
                return;
            end
            if (strcmp(pathName, app.pathName1) && strcmp(fileName, app.fileName1)) || ...
                    (strcmp(pathName, app.pathName2) && strcmp(fileName, app.fileName2)) || ...
                    (strcmp(pathName, app.FileIO.projectPathName) && strcmp(fileName, app.FileIO.projectFileName))
                uialert(app.UIFigure, 'Please choose a name of a file that is not open', 'Info', Icon='info');
                return;
            end

            app.MergedData = app.DataProject1;

            if app.BladeButtonGroup.SelectedObject == app.BladeButton2
                app.MergedData.Blade.Radius = app.DataProject2.Blade.Radius;
                app.MergedData.Blade.Chord = app.DataProject2.Blade.Chord;
                app.MergedData.Blade.Twist = app.DataProject2.Blade.Twist;
                app.MergedData.Blade.NFoil = app.DataProject2.Blade.NFoil;
                app.MergedData.Blade.Mass = app.DataProject2.Blade.Mass;
                app.MergedData.Blade.EIflap = app.DataProject2.Blade.EIflap;
                app.MergedData.Blade.EIedge = app.DataProject2.Blade.EIedge;
                app.MergedData.Blade.NFoil = app.DataProject2.Blade.NFoil;
                app.MergedData.Blade.IFoil = app.DataProject2.Blade.IFoil;
            end

            if app.AerofoilButtonGroup.SelectedObject == app.AerofoilButton2
                app.MergedData.Airfoil = app.DataProject2.Airfoil; 
            end

            if app.RotorButtonGroup.SelectedObject == app.RotorButton2
                app.MergedData.Blade.Number = app.DataProject2.Blade.Number;
                app.MergedData.Blade.Cone = app.DataProject2.Blade.Cone;
            end

            if app.TowerButtonGroup.SelectedObject == app.TowerButton2
                app.MergedData.Tower.Height = app.DataProject2.Tower.Height;
                app.MergedData.Tower.Diameter = app.DataProject2.Tower.Diameter;
                app.MergedData.Tower.WallThickness = app.DataProject2.Tower.WallThickness;
            end
            
            if app.Tower2ButtonGroup.SelectedObject == app.Tower2Button2
                app.MergedData.Tower.HubHeight = app.DataProject2.Tower.HubHeight;
                app.MergedData.Tower.EffectiveDensity = app.DataProject2.Tower.EffectiveDensity;
            end
            
            if app.NacelleButtonGroup.SelectedObject == app.NacelleButton2
                app.MergedData.Nacelle = app.DataProject2.Nacelle;
            end
            
            if app.DrivetrainButtonGroup.SelectedObject == app.DrivetrainButton2
                app.MergedData.Drivetrain = app.DataProject2.Drivetrain;
            end
            
            if app.SupervisoryButtonGroup.SelectedObject == app.SupervisoryButton2
                app.MergedData.Control.WindSpeed = app.DataProject2.Control.WindSpeed;
                app.MergedData.Control.Pitch.StartupPitch = app.DataProject2.Control.Pitch.StartupPitch;
                app.MergedData.Control.Pitch.StartupPitchRate = app.DataProject2.Control.Pitch.StartupPitchRate;
                app.MergedData.Control.Pitch.StartupSpeed = app.DataProject2.Control.Pitch.StartupSpeed;
                app.MergedData.Control.Brake = app.DataProject2.Control.Brake;
            end
            
            if app.TorqueButtonGroup.SelectedObject == app.TorqueButton2
            app.MergedData.Control.Torque.OptGain = app.DataProject2.Control.Torque.OptGain;
                    app.MergedData.Control.Torque.SpeedA = app.DataProject2.Control.Torque.SpeedA;
                    app.MergedData.Control.Torque.SpeedB = app.DataProject2.Control.Torque.SpeedB;
                    app.MergedData.Control.Torque.SpeedB2 = app.DataProject2.Control.Torque.SpeedB2;
                    app.MergedData.Control.Torque.SpeedC = app.DataProject2.Control.Torque.SpeedC;
                    app.MergedData.Control.Torque.Demanded = app.DataProject2.Control.Torque.Demanded;
                    app.MergedData.Control.Torque.LowPassCutOffFreq = app.DataProject2.Control.Torque.LowPassCutOffFreq;
            end
            
            if app.FinepitchButtonGroup.SelectedObject == app.FinepitchButton2
                app.MergedData.Control.Pitch.Fine = app.DataProject2.Control.Pitch.Fine;
            end
            
            if app.LimitsButtonGroup.SelectedObject == app.LimitsButton2
                app.MergedData.Control.Pitch.Min = app.DataProject2.Control.Pitch.Min;
                app.MergedData.Control.Pitch.Max = app.DataProject2.Control.Pitch.Max;
                app.MergedData.Control.Pitch.Minrate = app.DataProject2.Control.Pitch.Minrate;
                app.MergedData.Control.Pitch.Maxrate = app.DataProject2.Control.Pitch.Maxrate;
                app.MergedData.Control.Torque.Min = app.DataProject2.Control.Torque.Min;
                app.MergedData.Control.Torque.Limit = app.DataProject2.Control.Torque.Limit;
                app.MergedData.Control.Torque.Slewrate = app.DataProject2.Control.Torque.Slewrate;
            end
            
            if app.PitchTypeButtonGroup.SelectedObject == app.PitchTypeButton2
                app.MergedData.Control.Pitch.Scheduled = app.DataProject2.Control.Pitch.Scheduled;
            end
            
            if app.FilterTypeButtonGroup.SelectedObject == app.FilterTypeButton2
                app.MergedData.Control.Pitch.LowPassOrder = app.DataProject2.Control.Pitch.LowPassOrder;
            end
            
            if app.DampingButtonGroup.SelectedObject == app.DampingButton2
                app.MergedData.Control.ForeAft = app.DataProject2.Control.ForeAft;
            end
            
            if app.PitchConstantButtonGroup.SelectedObject == app.PitchConstantButton2
                app.MergedData.Control.Pitch.Kp = app.DataProject2.Control.Pitch.Kp;
                app.MergedData.Control.Pitch.Ki = app.DataProject2.Control.Pitch.Ki;
                app.MergedData.Control.Pitch.LowPassCutOffFreq = app.DataProject2.Control.Pitch.LowPassCutOffFreq;
                app.MergedData.Control.Pitch.Notch_beta1 = app.DataProject2.Control.Pitch.Notch_beta1;
                app.MergedData.Control.Pitch.Notch_beta2 = app.DataProject2.Control.Pitch.Notch_beta2;
                app.MergedData.Control.Pitch.Notch_wn = app.DataProject2.Control.Pitch.Notch_wn;
                app.MergedData.Control.Pitch.Notch2_beta1 = app.DataProject2.Control.Pitch.Notch2_beta1;
                app.MergedData.Control.Pitch.Notch2_beta2 = app.DataProject2.Control.Pitch.Notch2_beta2;
                app.MergedData.Control.Pitch.Notch2_wn = app.DataProject2.Control.Pitch.Notch2_wn;
            end
            
            if app.PitchScheduledButtonGroup.SelectedObject == app.PitchScheduledButton2
                app.MergedData.Control.Pitch.ScheduledPitchAngles = app.DataProject2.Control.Pitch.ScheduledPitchAngles;
                app.MergedData.Control.Pitch.KpGS = app.DataProject2.Control.Pitch.KpGS;
                app.MergedData.Control.Pitch.KiGS = app.DataProject2.Control.Pitch.KiGS;
                app.MergedData.Control.Pitch.LowPassCutOffFreqGS = app.DataProject2.Control.Pitch.LowPassCutOffFreqGS;
                app.MergedData.Control.Pitch.Notch_beta1GS = app.DataProject2.Control.Pitch.Notch_beta1GS;
                app.MergedData.Control.Pitch.Notch_beta2GS = app.DataProject2.Control.Pitch.Notch_beta2GS;
                app.MergedData.Control.Pitch.Notch_wnGS = app.DataProject2.Control.Pitch.Notch_wnGS;
                app.MergedData.Control.Pitch.Notch2_beta1GS = app.DataProject2.Control.Pitch.Notch2_beta1GS;
                app.MergedData.Control.Pitch.Notch2_beta2GS = app.DataProject2.Control.Pitch.Notch2_beta2GS;
                app.MergedData.Control.Pitch.Notch2_wnGS = app.DataProject2.Control.Pitch.Notch2_wnGS;
            end
            
            if app.SitingButtonGroup.SelectedObject == app.SitingButton2
                app.MergedData.Appearance = app.DataProject2.Appearance;
            end
            
            if app.AirDensityButtonGroup.SelectedObject == app.AirDensityButton2
                app.MergedData.CertificationSettings.Wind.AirDensity = app.DataProject2.CertificationSettings.Wind.AirDensity;
            end
            
            if app.SimulationButtonGroup.SelectedObject == app.SimulationButton2
                app.MergedData.CertificationSettings.Wind.Type = app.DataProject2.CertificationSettings.Wind.Type;
                app.MergedData.CertificationSettings.Wind.EventTime = app.DataProject2.CertificationSettings.Wind.EventTime;
                app.MergedData.CertificationSettings.Wind.Class = app.DataProject2.CertificationSettings.Wind.Class;
                app.MergedData.CertificationSettings.Wind.Speed = app.DataProject2.CertificationSettings.Wind.Speed;
                app.MergedData.CertificationSettings.Mode.Type = app.DataProject2.CertificationSettings.Mode.Type;
                app.MergedData.CertificationSettings.Mode.EventTime = app.DataProject2.CertificationSettings.Mode.EventTime;
                app.MergedData.CertificationSettings.Run.StartupTime = app.DataProject2.CertificationSettings.Run.StartupTime;
                app.MergedData.CertificationSettings.Run.Time = app.DataProject2.CertificationSettings.Run.Time;
                app.MergedData.CertificationSettings.Run.DT = app.DataProject2.CertificationSettings.Run.DT;
                app.MergedData.CertificationSettings.Run.Seeds = app.DataProject2.CertificationSettings.Run.Seeds;
                app.MergedData.CertificationSettings.Run.SeedNumber = app.DataProject2.CertificationSettings.Run.SeedNumber;
                app.MergedData.CertificationSettings.Run.RandomSeed = app.DataProject2.CertificationSettings.Run.RandomSeed;
            end
            
            saveMergedProject(app.FileIO, fileName, pathName, app.MergedData);
            app.CancelButton.Text = "OK";
            app.SaveasButton.Visible = "off";
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if ~(isempty(app.DataProject1) || isempty(app.DataProject2))
                showData(app);
            end
        end

        % Selection changed function: BladeButtonGroup
        function BladeButtonGroupSelectionChanged(app, event)
            if app.aerofoilSelectionFollowsBlade
                if app.BladeButtonGroup.SelectedObject == app.BladeButton1
                    app.AerofoilButtonGroup.SelectedObject = app.AerofoilButton1;
                else
                    app.AerofoilButtonGroup.SelectedObject = app.AerofoilButton2;
                end
            end

            if strcmp(app.CancelButton.Text, "OK")
                app.CancelButton.Text = "Cancel";
                app.SaveasButton.Visible = "on";
            end
        end

        % Selection changed function: AerofoilButtonGroup, 
        % ...and 17 other components
        function ButtonGroupSelectionChanged(app, event)
            if strcmp(app.CancelButton.Text, "OK")
                app.CancelButton.Text = "Cancel";
                app.SaveasButton.Visible = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SaveasButton = uibutton(app.GridLayout4, 'push');
            app.SaveasButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasButtonPushed, true);
            app.SaveasButton.Visible = 'off';
            app.SaveasButton.Text = 'Save as ...';
            app.CancelButton = uibutton(app.GridLayout4, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.ListBox = uilistbox(app.GridLayout5);
            app.ListBox.Items = {' Blade geometry and properties', ' Rotor geometry', ' Aerofoils', ' Tower geometry', ' Hub height and tower material', ' Nacelle', ' Drivetrain', ' Supervisory control', ' Torque control', ' Partial-load pitch', ' Actuator limits', ' Pitch-control type (constant/scheduled)', ' Low-pass filter type', ' Fore-aft damping', ' Pitch controller (constant)', ' Pitch controller (scheduled)', ' Siting', ' Air density', ' Simulation settings'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = ' Blade geometry and properties';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.SelectionLabel = uilabel(app.GridLayout6);
            app.SelectionLabel.Text = 'Selection';
            app.LabelSelect1 = uilabel(app.GridLayout6);
            app.LabelSelect1.Text = '1';
            app.LabelSelect2 = uilabel(app.GridLayout6);
            app.LabelSelect2.Text = '2';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.BladeButtonGroup = uibuttongroup(app.GridLayout7);
            app.BladeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BladeButtonGroupSelectionChanged, true);
            app.BladeButton1 = uiradiobutton(app.BladeButtonGroup);
            app.BladeButton1.Text = '';
            app.BladeButton1.Value = true;
            app.BladeButton2 = uiradiobutton(app.BladeButtonGroup);
            app.BladeButton2.Text = '';
            app.RotorButtonGroup = uibuttongroup(app.GridLayout7);
            app.RotorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.RotorButton1 = uiradiobutton(app.RotorButtonGroup);
            app.RotorButton1.Text = '';
            app.RotorButton1.Value = true;
            app.RotorButton2 = uiradiobutton(app.RotorButtonGroup);
            app.RotorButton2.Text = '';
            app.AerofoilButtonGroup = uibuttongroup(app.GridLayout7);
            app.AerofoilButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AerofoilButton1 = uiradiobutton(app.AerofoilButtonGroup);
            app.AerofoilButton1.Text = '';
            app.AerofoilButton1.Value = true;
            app.AerofoilButton2 = uiradiobutton(app.AerofoilButtonGroup);
            app.AerofoilButton2.Text = '';
            app.TowerButtonGroup = uibuttongroup(app.GridLayout7);
            app.TowerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TowerButton1 = uiradiobutton(app.TowerButtonGroup);
            app.TowerButton1.Text = '';
            app.TowerButton1.Value = true;
            app.TowerButton2 = uiradiobutton(app.TowerButtonGroup);
            app.TowerButton2.Text = '';
            app.Tower2ButtonGroup = uibuttongroup(app.GridLayout7);
            app.Tower2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Tower2Button1 = uiradiobutton(app.Tower2ButtonGroup);
            app.Tower2Button1.Text = '';
            app.Tower2Button1.Value = true;
            app.Tower2Button2 = uiradiobutton(app.Tower2ButtonGroup);
            app.Tower2Button2.Text = '';
            app.NacelleButtonGroup = uibuttongroup(app.GridLayout7);
            app.NacelleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NacelleButton1 = uiradiobutton(app.NacelleButtonGroup);
            app.NacelleButton1.Text = '';
            app.NacelleButton1.Value = true;
            app.NacelleButton2 = uiradiobutton(app.NacelleButtonGroup);
            app.NacelleButton2.Text = '';
            app.DrivetrainButtonGroup = uibuttongroup(app.GridLayout7);
            app.DrivetrainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DrivetrainButton1 = uiradiobutton(app.DrivetrainButtonGroup);
            app.DrivetrainButton1.Text = '';
            app.DrivetrainButton1.Value = true;
            app.DrivetrainButton2 = uiradiobutton(app.DrivetrainButtonGroup);
            app.DrivetrainButton2.Text = '';
            app.SupervisoryButtonGroup = uibuttongroup(app.GridLayout7);
            app.SupervisoryButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SupervisoryButton1 = uiradiobutton(app.SupervisoryButtonGroup);
            app.SupervisoryButton1.Text = '';
            app.SupervisoryButton1.Value = true;
            app.SupervisoryButton2 = uiradiobutton(app.SupervisoryButtonGroup);
            app.SupervisoryButton2.Text = '';
            app.TorqueButtonGroup = uibuttongroup(app.GridLayout7);
            app.TorqueButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TorqueButton1 = uiradiobutton(app.TorqueButtonGroup);
            app.TorqueButton1.Text = '';
            app.TorqueButton1.Value = true;
            app.TorqueButton2 = uiradiobutton(app.TorqueButtonGroup);
            app.TorqueButton2.Text = '';
            app.FinepitchButtonGroup = uibuttongroup(app.GridLayout7);
            app.FinepitchButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FinepitchButton1 = uiradiobutton(app.FinepitchButtonGroup);
            app.FinepitchButton1.Text = '';
            app.FinepitchButton1.Value = true;
            app.FinepitchButton2 = uiradiobutton(app.FinepitchButtonGroup);
            app.FinepitchButton2.Text = '';
            app.LimitsButtonGroup = uibuttongroup(app.GridLayout7);
            app.LimitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.LimitsButton1 = uiradiobutton(app.LimitsButtonGroup);
            app.LimitsButton1.Text = '';
            app.LimitsButton1.Value = true;
            app.LimitsButton2 = uiradiobutton(app.LimitsButtonGroup);
            app.LimitsButton2.Text = '';
            app.PitchTypeButtonGroup = uibuttongroup(app.GridLayout7);
            app.PitchTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PitchTypeButton1 = uiradiobutton(app.PitchTypeButtonGroup);
            app.PitchTypeButton1.Text = '';
            app.PitchTypeButton1.Value = true;
            app.PitchTypeButton2 = uiradiobutton(app.PitchTypeButtonGroup);
            app.PitchTypeButton2.Text = '';
            app.FilterTypeButtonGroup = uibuttongroup(app.GridLayout7);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.FilterTypeButton1 = uiradiobutton(app.FilterTypeButtonGroup);
            app.FilterTypeButton1.Text = '';
            app.FilterTypeButton1.Value = true;
            app.FilterTypeButton2 = uiradiobutton(app.FilterTypeButtonGroup);
            app.FilterTypeButton2.Text = '';
            app.DampingButtonGroup = uibuttongroup(app.GridLayout7);
            app.DampingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DampingButton1 = uiradiobutton(app.DampingButtonGroup);
            app.DampingButton1.Text = '';
            app.DampingButton1.Value = true;
            app.DampingButton2 = uiradiobutton(app.DampingButtonGroup);
            app.DampingButton2.Text = '';
            app.PitchConstantButtonGroup = uibuttongroup(app.GridLayout7);
            app.PitchConstantButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PitchConstantButton1 = uiradiobutton(app.PitchConstantButtonGroup);
            app.PitchConstantButton1.Text = '';
            app.PitchConstantButton1.Value = true;
            app.PitchConstantButton2 = uiradiobutton(app.PitchConstantButtonGroup);
            app.PitchConstantButton2.Text = '';
            app.PitchScheduledButtonGroup = uibuttongroup(app.GridLayout7);
            app.PitchScheduledButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PitchScheduledButton1 = uiradiobutton(app.PitchScheduledButtonGroup);
            app.PitchScheduledButton1.Text = '';
            app.PitchScheduledButton1.Value = true;
            app.PitchScheduledButton2 = uiradiobutton(app.PitchScheduledButtonGroup);
            app.PitchScheduledButton2.Text = '';
            app.SitingButtonGroup = uibuttongroup(app.GridLayout7);
            app.SitingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SitingButton1 = uiradiobutton(app.SitingButtonGroup);
            app.SitingButton1.Text = '';
            app.SitingButton1.Value = true;
            app.SitingButton2 = uiradiobutton(app.SitingButtonGroup);
            app.SitingButton2.Text = '';
            app.AirDensityButtonGroup = uibuttongroup(app.GridLayout7);
            app.AirDensityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AirDensityButton1 = uiradiobutton(app.AirDensityButtonGroup);
            app.AirDensityButton1.Text = '';
            app.AirDensityButton1.Value = true;
            app.AirDensityButton2 = uiradiobutton(app.AirDensityButtonGroup);
            app.AirDensityButton2.Text = '';
            app.SimulationButtonGroup = uibuttongroup(app.GridLayout7);
            app.SimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SimulationButton1 = uiradiobutton(app.SimulationButtonGroup);
            app.SimulationButton1.Text = '';
            app.SimulationButton1.Value = true;
            app.SimulationButton2 = uiradiobutton(app.SimulationButtonGroup);
            app.SimulationButton2.Text = '';
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.BrowseButton = uibutton(app.GridLayout8, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BrowseButton_2 = uibutton(app.GridLayout8, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.Project1Label = uilabel(app.GridLayout8);
            app.Project1Label.Text = 'Project 1:';
            app.FilenameProject1 = uilabel(app.GridLayout8);
            app.FilenameProject1.Text = '';
            app.Project2Label = uilabel(app.GridLayout8);
            app.Project2Label.Text = 'Project 2:';
            app.FilenameProject2 = uilabel(app.GridLayout8);
            app.FilenameProject2.Text = '';
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout10 = uigridlayout(app.Panel);
            app.TableDataPanel = uipanel(app.GridLayout10);
            app.TableDataPanel.Visible = 'off';
            app.GridLayout9_2 = uigridlayout(app.TableDataPanel);
            app.UITable2 = uitable(app.GridLayout9_2);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable = uitable(app.GridLayout9_2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ScalarDataPanel = uipanel(app.GridLayout10);
            app.ScalarDataPanel.Visible = 'off';
            app.GridLayout9 = uigridlayout(app.ScalarDataPanel);
            app.Label_1 = uilabel(app.GridLayout9);
            app.Label_1.Text = '';
            app.EditField_1 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_1.Editable = 'off';
            app.EditField_1.Visible = 'off';
            app.Label_2 = uilabel(app.GridLayout9);
            app.Label_2.Text = '';
            app.EditField_2 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Visible = 'off';
            app.Label_3 = uilabel(app.GridLayout9);
            app.Label_3.Text = '';
            app.EditField_3 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Visible = 'off';
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = '';
            app.EditField_4 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Visible = 'off';
            app.Label_5 = uilabel(app.GridLayout9);
            app.Label_5.Text = '';
            app.EditField_5 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Visible = 'off';
            app.Label_6 = uilabel(app.GridLayout9);
            app.Label_6.Text = '';
            app.EditField_6 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Visible = 'off';
            app.Label_7 = uilabel(app.GridLayout9);
            app.Label_7.Text = '';
            app.EditField_7 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Visible = 'off';
            app.Label_8 = uilabel(app.GridLayout9);
            app.Label_8.Text = '';
            app.EditField_8 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Visible = 'off';
            app.Label_9 = uilabel(app.GridLayout9);
            app.Label_9.Text = '';
            app.EditField_9 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Visible = 'off';
            app.Label_10 = uilabel(app.GridLayout9);
            app.Label_10.Text = '';
            app.EditField_10 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Visible = 'off';
            app.EditField2_1 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_1.Editable = 'off';
            app.EditField2_1.Visible = 'off';
            app.EditField2_2 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_2.Editable = 'off';
            app.EditField2_2.Visible = 'off';
            app.EditField2_3 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_3.Editable = 'off';
            app.EditField2_3.Visible = 'off';
            app.EditField2_4 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_4.Editable = 'off';
            app.EditField2_4.Visible = 'off';
            app.EditField2_5 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_5.Editable = 'off';
            app.EditField2_5.Visible = 'off';
            app.EditField2_6 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_6.Editable = 'off';
            app.EditField2_6.Visible = 'off';
            app.EditField2_7 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_7.Editable = 'off';
            app.EditField2_7.Visible = 'off';
            app.EditField2_8 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_8.Editable = 'off';
            app.EditField2_8.Visible = 'off';
            app.EditField2_9 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_9.Editable = 'off';
            app.EditField2_9.Visible = 'off';
            app.EditField2_10 = uieditfield(app.GridLayout9, 'numeric');
            app.EditField2_10.Editable = 'off';
            app.EditField2_10.Visible = 'off';
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.IdentifyLabel = uilabel(app.GridLayout11);
            app.IdentifyLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MergeDialogue(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end