classdef ModalAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        SetandrunPanel           matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        ResultsFile              matlab.ui.control.Label
        ResultsfileLabel         matlab.ui.control.Label
        Project                  matlab.ui.control.Label
        ProjectLabel             matlab.ui.control.Label
        DisplayLabel             matlab.ui.control.Label
        DisplayDropDown          matlab.ui.control.DropDown
        SaveButton               matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        NaturalFrequenciesTree   matlab.ui.container.CheckBoxTree
        TowerNodes               matlab.ui.container.TreeNode
        FirstForeAftNode         matlab.ui.container.TreeNode
        SecondForeAftNode        matlab.ui.container.TreeNode
        FirstSideSideNode        matlab.ui.container.TreeNode
        SecondSideSideNode       matlab.ui.container.TreeNode
        BladeNodes               matlab.ui.container.TreeNode
        FirstFlapwiseNode        matlab.ui.container.TreeNode
        SecondFlapwiseNode       matlab.ui.container.TreeNode
        FirstEdgewiseNode        matlab.ui.container.TreeNode
        SecondEdgewiseNode       matlab.ui.container.TreeNode
        ExcitationsTree          matlab.ui.container.CheckBoxTree
        AllNode                  matlab.ui.container.TreeNode
        PNode_1                  matlab.ui.container.TreeNode
        PNode_2                  matlab.ui.container.TreeNode
        PNode_3                  matlab.ui.container.TreeNode
        PNode_4                  matlab.ui.container.TreeNode
        PNode_5                  matlab.ui.container.TreeNode
        PNode_6                  matlab.ui.container.TreeNode
        PNode_7                  matlab.ui.container.TreeNode
        PNode_8                  matlab.ui.container.TreeNode
        PNode_9                  matlab.ui.container.TreeNode
        PNode_10                 matlab.ui.container.TreeNode
        PNode_11                 matlab.ui.container.TreeNode
        PNode_12                 matlab.ui.container.TreeNode
        NaturalfrequenciesLabel  matlab.ui.control.Label
        ExcitationsLabel         matlab.ui.control.Label
        PlotPanel                matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes1                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        WindTurbine
        mainApp
        mode
        plotArea (4,4) double % Area available for plots: [x y width height]
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing the display of data on the tab

        function resetUI(comp)
            comp.SaveButton.Enable = "off";
            comp.DisplayLabel.Enable = "off";
            comp.DisplayDropDown.Enable = "off";
            comp.DisplayDropDown.ValueIndex = 1;
            comp.DisplayDropDown.Tooltip = "";
            comp.NaturalFrequenciesTree.CheckedNodes = [];
            comp.NaturalFrequenciesTree.SelectedNodes = [];
            scroll(comp.NaturalFrequenciesTree, 'top');
            comp.ExcitationsTree.CheckedNodes = [];
            comp.ExcitationsTree.SelectedNodes = [];
            scroll(comp.ExcitationsTree, 'top');
            
            cla(comp.UIAxes);
            cla(comp.UIAxes1);
            cla(comp.UIAxes2);
            legend(comp.UIAxes, "off");
            comp.UIAxes.Toolbar.Visible = "off";
            comp.UIAxes1.Title.Visible = "off";
            comp.UIAxes2.Title.Visible = "off";
            xlim(comp.UIAxes, "auto");
            ylim(comp.UIAxes, "auto");
            comp.UIAxes.Visible = "on";
            comp.mode = "project";

            comp.RunButton.Visible = "on";

            comp.SaveButton.Text = "Save";
            comp.ResultsfileLabel.Visible = "off";
            comp.ProjectLabel.Visible = "off";
            comp.ResultsFile.Visible = "off";
            comp.Project.Visible = "off";
            comp.ResultsFile.Text = '';
            comp.Project.Text = '';
            comp.ResultsFile.Tooltip = '';
            comp.Project.Tooltip = '';
        end

        function setResultsMode(comp)
            comp.mode = "results";
            comp.RunButton.Visible = "off";

            comp.SaveButton.Text = "Load";
            comp.SaveButton.Enable = "on";
            comp.ResultsfileLabel.Visible = "on";
            comp.ProjectLabel.Visible = "on";
            comp.ResultsFile.Visible = "on";
            comp.Project.Visible = "on";
        end
    end
    
    methods (Access = private)
        
        function plotModalAnalysis(comp)

            Data = comp.WindTurbine.ModalResults;

            cla(comp.UIAxes);
            cla(comp.UIAxes1);
            cla(comp.UIAxes2);
            legend(comp.UIAxes, "off");
            comp.UIAxes.Toolbar.Visible = "off";
            comp.UIAxes1.Title.Visible = "off";
            comp.UIAxes2.Title.Visible = "off";

            switch comp.DisplayDropDown.Value
                case 'Campbell diagram'
                    comp.UIAxes.Visible = "on";
                    comp.UIAxes.Toolbar.Visible = "on";
                    % Axis limits
                    RPM1 = Data.cutInRotorSpeed;
                    RPM2 = Data.ratedRotorSpeed;
                    RPMlim = Data.rotorSpeed(end);
                    Flim = [];
                    for i = 1 : length(comp.NaturalFrequenciesTree.CheckedNodes)
                        switch comp.NaturalFrequenciesTree.CheckedNodes(i).Text
                            case "1st fore-aft"
                                Flim = [Flim; max(Data.Tower_ForeAft1_freq)];
                            case "2nd fore-aft"
                                Flim = [Flim; max(Data.Tower_ForeAft2_freq)];
                            case "1st side-side"
                                Flim = [Flim; max(Data.Tower_SideSide1_freq)];
                            case "2nd side-side"
                                Flim = [Flim; max(Data.Tower_SideSide2_freq)];
                            case "1st flapwise"
                                Flim = [Flim; max(Data.Blade_Flap1_freq)];
                            case "2nd flapwise"
                                Flim = [Flim; max(Data.Blade_Flap2_freq)];
                            case "1st edgewise"
                                Flim = [Flim; max(Data.Blade_Edge1_freq)];
                            case "2nd edgewise"
                                Flim = [Flim; max(Data.Blade_Edge2_freq)];
                        end
                    end

                    if isempty(Flim) || sum(isnan(Flim)) > 0
                        Flim = 1;
                    end

                    Flim = ceil(max(Flim));
                    xlim(comp.UIAxes, [0 RPMlim]);
                    ylim(comp.UIAxes, [0 Flim(1)]);

                    % Plot 1-12P lines
                    hold(comp.UIAxes,'on');
                    patch(comp.UIAxes, [RPM1 RPM2 RPM2 RPM1], [0 0 1e3 1e3], [0.95 0.95 0.95], 'EdgeColor', 'none');
                    plot(comp.UIAxes, [1 1]*RPM1, [0 1e3], '--', 'Color', [0.5 0.5 0.5]);
                    plot(comp.UIAxes, [1 1]*RPM2, [0 1e3], '--', 'Color', [0.5 0.5 0.5]);

                    for i = 1 : length(comp.ExcitationsTree.CheckedNodes)
                        P = 0;
                        switch comp.ExcitationsTree.CheckedNodes(i).Text
                            case "1P"
                                P = 1;
                            case "2P"
                                P = 2;
                            case "3P"
                                P = 3;
                            case "4P"
                                P = 4;
                            case "5P"
                                P = 5;
                            case "6P"
                                P = 6;
                            case "7P"
                                P = 7;
                            case "8P"
                                P = 8;
                            case "9P"
                                P = 9;
                            case "10P"
                                P = 10;
                            case "11P"
                                P = 11;
                            case "12P"
                                P = 12;
                        end
                        if P ~= 0
                            plot(comp.UIAxes, [0 RPMlim], P*[0 RPMlim]/60, 'Color', [0.25 0.25 0.25]);
                            if P*0.95*RPMlim/60 > 0.95*Flim
                                x = 0.95*Flim*60/P;
                                y = 0.95*Flim;
                                if x > RPM1 && x < RPM2
                                    bg = [0.95 0.95 0.95];
                                else
                                    bg = 'w';
                                end
                                text(comp.UIAxes, x, y, [int2str(P), 'P'], 'Fontsize', 8, 'Color', [0.25 0.25 0.25], 'BackgroundColor', bg, 'HorizontalAlignment', 'center');
                            else
                                x = 0.95*RPMlim;
                                y = P*0.95*RPMlim/60;
                                if x > RPM1 && x < RPM2
                                    bg = [0.95 0.95 0.95];
                                else
                                    bg = 'w';
                                end
                                text(comp.UIAxes, x, y, [int2str(P), 'P'], 'Fontsize', 8, 'Color', [0.25 0.25 0.25], 'BackgroundColor', bg, 'HorizontalAlignment', 'center');
                            end
                        end
                    end

                    % Plot natural frequencies
                    LegendEntries = {};
                    Lines = [];
                    n = 1;

                    for i = 1 : length(comp.NaturalFrequenciesTree.CheckedNodes)
                        switch comp.NaturalFrequenciesTree.CheckedNodes(i).Text
                            case "1st fore-aft"
                                l1 = plot(comp.UIAxes, Data.rotorSpeed, Data.Tower_ForeAft1_freq, '-o', 'Color', [127.5 0 0]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '1st tower fore-aft';
                                Lines(n) = l1;
                                n = n + 1;
                            case "2nd fore-aft"
                                l2 = plot(comp.UIAxes, Data.rotorSpeed, Data.Tower_ForeAft2_freq, '-o', 'Color', [237 28 36]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '2nd tower fore-aft';
                                Lines(n) = l2;
                                n = n + 1;
                            case "1st side-side"
                                l3 = plot(comp.UIAxes, Data.rotorSpeed, Data.Tower_SideSide1_freq, '-o', 'Color',  [241 140 34]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '1st tower side-side';
                                Lines(n) = l3;
                                n = n + 1;
                            case "2nd side-side"
                                l4 = plot(comp.UIAxes, Data.rotorSpeed, Data.Tower_SideSide2_freq, '-o', 'Color', [255 222 23]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '2nd tower side-side';
                                Lines(n) = l4;
                                n = n + 1;
                            case "1st flapwise"
                                l5 = plot(comp.UIAxes, Data.rotorSpeed, Data.Blade_Flap1_freq, '-o', 'Color', [173 209 54]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '1st blade flapwise';
                                Lines(n) = l5;
                                n = n + 1;
                            case "2nd flapwise"
                                l6 = plot(comp.UIAxes, Data.rotorSpeed, Data.Blade_Flap2_freq, '-o', 'Color', [8 135 67]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '2nd blade flapwise';
                                Lines(n) = l6;
                                n = n + 1;
                            case "1st edgewise"
                                l7 = plot(comp.UIAxes, Data.rotorSpeed, Data.Blade_Edge1_freq, '-o', 'Color', [71 195 211]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '1st blade edgewise';
                                Lines(n) = l7;
                                n = n + 1;
                            case "2nd edgewise"
                                l8 = plot(comp.UIAxes, Data.rotorSpeed, Data.Blade_Edge2_freq, '-o', 'Color',  [33 64 154]/255, 'MarkerSize', 2);
                                LegendEntries{n} = '2nd blade edgewise';
                                Lines(n) = l8;
                        end
                    end

                    hold(comp.UIAxes,'off');

                    if ~isempty(Lines)
                        legend(comp.UIAxes, Lines, LegendEntries, 'Location', 'NorthWest', 'Color', 'none', 'Box', 'off');
                    end
                case 'Tower fore-aft modes'
                    comp.UIAxes.Visible = "off";
                    comp.UIAxes1.Position = comp.plotArea(1,:);
                    comp.UIAxes2.Position = comp.plotArea(2,:);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Tower_ForeAft1_freq(1));
                    plotTower(comp, comp.UIAxes1, comp.WindTurbine.ModalResults.Tower_ForeAft1_shape, "First tower fore-aft mode", textString);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Tower_ForeAft2_freq(1));
                    plotTower(comp, comp.UIAxes2, comp.WindTurbine.ModalResults.Tower_ForeAft2_shape, "Second tower fore-aft mode", textString);

                case 'Tower side-side modes'
                    comp.UIAxes.Visible = "off";
                    comp.UIAxes1.Position = comp.plotArea(1,:);
                    comp.UIAxes2.Position = comp.plotArea(2,:);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Tower_SideSide1_freq(1));
                    plotTower(comp, comp.UIAxes1, comp.WindTurbine.ModalResults.Tower_SideSide1_shape, "First tower side-side mode", textString);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Tower_SideSide2_freq(1));
                    plotTower(comp, comp.UIAxes2, comp.WindTurbine.ModalResults.Tower_SideSide2_shape, "Second tower side-side mode", textString);

                case 'Blade flapwise modes'
                    comp.UIAxes.Visible = "off";
                    comp.UIAxes1.Position = comp.plotArea(3,:);
                    comp.UIAxes2.Position = comp.plotArea(4,:);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Blade_Flap1_freq(1));
                    plotBlade(comp, comp.UIAxes1, comp.WindTurbine.ModalResults.Blade_Flap1_shape, "First blade flapwise mode", textString);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Blade_Flap2_freq(1));
                    plotBlade(comp, comp.UIAxes2, comp.WindTurbine.ModalResults.Blade_Flap2_shape, "Second blade flapwise mode", textString);

                case 'Blade edgewise modes'
                    comp.UIAxes.Visible = "off";
                    comp.UIAxes1.Position = comp.plotArea(3,:);
                    comp.UIAxes2.Position = comp.plotArea(4,:);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Blade_Edge1_freq(1));
                    plotBlade(comp, comp.UIAxes1, comp.WindTurbine.ModalResults.Blade_Edge1_shape, "First blade edgewise mode", textString);
                    textString = sprintf('Freq. = %4.3f Hz', comp.WindTurbine.ModalResults.Blade_Edge2_freq(1));
                    plotBlade(comp, comp.UIAxes2, comp.WindTurbine.ModalResults.Blade_Edge2_shape, "Second blade edgewise mode", textString);
            end
        end

        function plotTower(comp, plotAxes, shape, title, textString)
            % Get geometry from handles
            Tower = comp.WindTurbine.Tower;

            % Create fixed number of tower elements (N = 21)
            N = 21;
            x = linspace(Tower.Height(1), Tower.Height(end), N);
            Tower.Diameter = interp1(Tower.Height, Tower.Diameter, x);
            Tower.Height = x;

            % Plot settings
            plotAxes.Title.String = title;
            plotAxes.Title.Visible = "on";
            view(plotAxes, 135,20);
            light(plotAxes);
            lightangle(plotAxes, 0, 45);
            hold(plotAxes, "on");
            axis(plotAxes, "equal");

            % Undeformed tower geometry
            r = repmat(Tower.Diameter(:)/2,[1,N]);
            azi = repmat(linspace(0,2*pi,N),[length(Tower.Diameter),1]);
            x = r.*cos(azi);
            y = r.*sin(azi);
            z = repmat(Tower.Height(:),[1,N]);
            surf(plotAxes, x,y,z, ...
                FaceColor = [1, 1, 1], ...
                EdgeColor = [0.75, 0.75, 0.75], ...
                AmbientStrength = 0.8, ...
                DiffuseStrength = 0.5, ...
                SpecularStrength = 0.9, ...
                BackFaceLighting = 'reverselit', ...
                FaceAlpha = 0.25);

            % Wall
            vert = [[-1 1 1 -1 -1 1 1 -1] * Tower.Diameter(1); ...
                [-1 -1 1 1 -1 -1 1 1] * Tower.Diameter(1); ...
                [-1 -1 -1 -1 0 0 0 0]/10];
            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
            patch(plotAxes, ...
                Vertices = vert', ...
                Faces = faces, ...
                FaceColor = [0.75, 0.75, 0.75], ...
                EdgeColor = [0.75, 0.75, 0.75], ...
                AmbientStrength = 0.8, ...
                DiffuseStrength = 0.5, ...
                SpecularStrength = 0.9, ...
                BackFaceLighting = 'reverselit');

            % Deformed tower geometry
            N = 20;
            r = repmat(Tower.Diameter(:)/2,[1,N]);
            azi = repmat(linspace(0,2*pi,N),[length(Tower.Diameter),1]);
            x = r.*cos(azi) + repmat(Tower.Height(end)*shape(1,:)',[1,N]);
            y = r.*sin(azi) + repmat(Tower.Height(end)*shape(2,:)',[1,N]);
            z = repmat(Tower.Height(:),[1,N]);
            surf(plotAxes, x,y,z, ...
                FaceColor = [1, 1, 1], ...
                EdgeColor = [0 165, 213]/255, ...
                AmbientStrength = 0.8, ...
                DiffuseStrength = 0.5, ...
                SpecularStrength = 0.9, ...
                BackFaceLighting = 'reverselit');
            text(plotAxes, 1, 0.1, textString, Units="normalized", VerticalAlignment="bottom", BackgroundColor="w", EdgeColor="k", LineWidth=1);
        end

        function plotBlade(comp, plotAxes, shape, title, textString)
            % Get geometry from handles
            Blade = comp.WindTurbine.Blade;
            Airfoil = comp.WindTurbine.Airfoil;

            % Create fixed number of blade elements (N = 21)
            N = 21;
            x = linspace(Blade.Radius(1), Blade.Radius(end), N);
            Blade.Twist = interp1(Blade.Radius, Blade.Twist, x);
            Blade.Chord = interp1(Blade.Radius, Blade.Chord, x);
            Blade.PitchAxis = interp1(Blade.Radius, Blade.PitchAxis, x);
            Blade.Thickness = interp1(Blade.Radius, Blade.Thickness, x);
            Blade.NFoil = interp1(Blade.Radius, Blade.NFoil, x, 'nearest');
            Blade.Radius = x;

            % Plot settings
            plotAxes.Title.String = title;
            plotAxes.Title.Visible = "on";
            view(plotAxes, 45,20);
            light(plotAxes);
            lightangle(plotAxes, 0, 45);
            hold(plotAxes, "on");
            axis(plotAxes, "equal");

            % Undeformed blade geometry
            N = 40;
            x = [];
            y = [];
            for i = 1:length(Blade.NFoil)
                x = [x; ([Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1:N:end), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1)] - Blade.PitchAxis(i)) * Blade.Chord(i)];
            end
            for i = 1:length(Blade.NFoil)
                t_u = max(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:200));
                t_l = min(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,200:end));
                t(i) = t_u - t_l;
            end
            for i = 1:length(Blade.NFoil)
                y = [y; -1*[Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:N:end), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1)] * Blade.Thickness(i)/t(i)];
            end
            z = repmat(Blade.Radius(:),[1,size(x,2)]);
            for i = 1:length(Blade.NFoil)
                t = pi/2 - Blade.Twist(i) * pi/180;
                Rz = [cos(t),-sin(t), 0; ...
                    sin(t), cos(t), 0; ...
                    0,      0,      1];
                A = Rz * [x(i,:); y(i,:); z(i,:)];
                x(i,:) = A(1,:);
                y(i,:) = A(2,:);
                z(i,:) = A(3,:);
            end
            surf(plotAxes, z,y,x, ...
                FaceColor = [1, 1, 1], ...
                EdgeColor = [0.75, 0.75, 0.75], ...
                AmbientStrength = 0.8, ...
                DiffuseStrength = 0.5, ...
                SpecularStrength = 0.9, ...
                BackFaceLighting = 'reverselit', ...
                FaceAlpha = 0.25);

            % Wall
            vert = [[-1 -1 -1 -1 0 0 0 0]/10 + Blade.Radius(1); ...
                [-1 -1 1 1 -1 -1 1 1] * Blade.Chord(1); ...
                [-1 1 1 -1 -1 1 1 -1] * Blade.Chord(1)];
            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
            patch(plotAxes, ...
                Vertices = vert', ...
                Faces = faces, ...
                FaceColor = [0.75, 0.75, 0.75], ...
                EdgeColor = [0.75, 0.75, 0.75], ...
                AmbientStrength = 0.8, ...
                DiffuseStrength = 0.5, ...
                SpecularStrength = 0.9, ...
                BackFaceLighting = 'reverselit');

            % Deformed blade geometry
            N = 40;
            x = [];
            y = [];
            for i = 1:length(Blade.NFoil)
                x = [x; ([Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1:N:end), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1)] - Blade.PitchAxis(i)) * Blade.Chord(i)];
            end
            for i = 1:length(Blade.NFoil)
                t_u = max(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:200));
                t_l = min(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,200:end));
                t(i) = t_u - t_l;
            end
            for i = 1:length(Blade.NFoil)
                y = [y; -1*[Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:N:end), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1)] * Blade.Thickness(i)/t(i)];
            end
            z = repmat(Blade.Radius(:),[1,size(x,2)]);
            for i = 1:length(Blade.NFoil)
                t = pi/2 - Blade.Twist(i) * 2*pi/180 - pi/180*shape(3,i);
                Rz = [cos(t),-sin(t), 0; ...
                    sin(t), cos(t), 0; ...
                    0,      0,      1];
                A = Rz * [x(i,:); y(i,:); z(i,:)];
                x(i,:) = A(1,:) + Blade.Radius(end)*shape(1,i);
                y(i,:) = A(2,:) + Blade.Radius(end)*shape(2,i);
                z(i,:) = A(3,:);
            end
            surf(plotAxes, z,y,x, ...
                FaceColor = [1, 1, 1], ...
                EdgeColor = [0 165, 213]/255, ...
                AmbientStrength = 0.8, ...
                DiffuseStrength = 0.5, ...
                SpecularStrength = 0.9, ...
                BackFaceLighting = 'reverselit');
            text(plotAxes, 0.5, 0.9, textString, Units="normalized", HorizontalAlignment="center", VerticalAlignment="top", BackgroundColor="w", EdgeColor="k", LineWidth=1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            expand(comp.NaturalFrequenciesTree);
            expand(comp.ExcitationsTree);

            % Set UIAxes positions, indexing then according to:
            % 1 = Left half
            % 2 = Right half
            % 3 = Top half
            % 4 = Bottom half
            plotCorner = comp.UIAxes.Position(1:2);
            plotWidth = comp.UIAxes.Position(3);
            plotHeight = comp.UIAxes.Position(4);

            comp.plotArea(1,:) = [plotCorner plotWidth/2 plotHeight];
            comp.plotArea(2,:) = [plotCorner plotWidth/2 plotHeight] + [plotWidth/2 0 0 0];
            comp.plotArea(3,:) = [plotCorner plotWidth plotHeight/2] + [0 plotHeight/2 0 0];
            comp.plotArea(4,:) = [plotCorner plotWidth plotHeight/2];
            disableDefaultInteractivity(comp.UIAxes1);
            disableDefaultInteractivity(comp.UIAxes2);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            if ~prepareFAST(comp.mainApp)
                return;
            end

            try
                showProgress(comp.mainApp, 'Performing modal analysis', 'on');
                performModalAnalysis(comp.WindTurbine);
                endProgress(comp.mainApp);

                comp.SaveButton.Enable = "on";
                comp.DisplayLabel.Enable = "on";
                comp.DisplayDropDown.Enable = "on";
                plotModalAnalysis(comp);
            catch exception
                endProgress(comp.mainApp);
                uialert(comp.mainApp.FASTToolUIFigure, 'Modal analysis resulted in an error - See Matlab Command Window for details', 'Info', Icon='info');
                rethrow(exception); % All necessary steps are completed before this call because 'rethrow' will terminate the function
            end
        end

        % Callback function: NaturalFrequenciesTree
        function NaturalFrequenciesTreeCheckedNodesChanged(comp, event)
            if comp.DisplayLabel.Enable == "on"
                plotModalAnalysis(comp);
            end
        end

        % Callback function: ExcitationsTree
        function ExcitationsTreeCheckedNodesChanged(comp, event)
            if comp.DisplayLabel.Enable == "on"
                plotModalAnalysis(comp);
            end
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(comp, event)
            plotModalAnalysis(comp);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(comp, event)
            if strcmp(comp.mode, "project")
                saveMatfile(comp.mainApp, 'Save modal-analysis results', 'modalanalysis');
            else
                if loadMatfile(comp.mainApp, 'Load modal-analysis results', 'modalanalysis')
                    comp.ResultsFile.Text = comp.WindTurbine.ModalResults.fileName;
                    comp.Project.Text = comp.WindTurbine.ModalResults.Info.projectFile;
                    comp.ResultsFile.Tooltip = comp.WindTurbine.ModalResults.fileName;
                    comp.Project.Tooltip = comp.WindTurbine.ModalResults.Info.projectFile;
                    comp.DisplayLabel.Enable = "on";
                    comp.DisplayDropDown.Tooltip = "Other options (mode shapes) not available in results view";
                    plotModalAnalysis(comp);
                end
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.UIAxes2 = uiaxes(comp.PlotPanel);
            comp.UIAxes2.Visible = 'off';
            comp.UIAxes1 = uiaxes(comp.PlotPanel);
            comp.UIAxes1.Visible = 'off';
            comp.UIAxes = uiaxes(comp.PlotPanel);
            title(comp.UIAxes, 'Campbell diagram')
            xlabel(comp.UIAxes, 'Operating range [rpm]')
            ylabel(comp.UIAxes, 'Natural or excitation frequency [Hz]')
            comp.SetandrunPanel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.SetandrunPanel);
            comp.ExcitationsLabel = uilabel(comp.GridLayout2);
            comp.ExcitationsLabel.Text = 'Excitations';
            comp.NaturalfrequenciesLabel = uilabel(comp.GridLayout2);
            comp.NaturalfrequenciesLabel.Text = 'Natural frequencies';
            comp.ExcitationsTree = uitree(comp.GridLayout2, 'checkbox');
            comp.AllNode = uitreenode(comp.ExcitationsTree);
            comp.AllNode.Text = 'All';
            comp.PNode_1 = uitreenode(comp.AllNode);
            comp.PNode_1.Text = '1P';
            comp.PNode_2 = uitreenode(comp.AllNode);
            comp.PNode_2.Text = '2P';
            comp.PNode_3 = uitreenode(comp.AllNode);
            comp.PNode_3.Text = '3P';
            comp.PNode_4 = uitreenode(comp.AllNode);
            comp.PNode_4.Text = '4P';
            comp.PNode_5 = uitreenode(comp.AllNode);
            comp.PNode_5.Text = '5P';
            comp.PNode_6 = uitreenode(comp.AllNode);
            comp.PNode_6.Text = '6P';
            comp.PNode_7 = uitreenode(comp.AllNode);
            comp.PNode_7.Text = '7P';
            comp.PNode_8 = uitreenode(comp.AllNode);
            comp.PNode_8.Text = '8P';
            comp.PNode_9 = uitreenode(comp.AllNode);
            comp.PNode_9.Text = '9P';
            comp.PNode_10 = uitreenode(comp.AllNode);
            comp.PNode_10.Text = '10P';
            comp.PNode_11 = uitreenode(comp.AllNode);
            comp.PNode_11.Text = '11P';
            comp.PNode_12 = uitreenode(comp.AllNode);
            comp.PNode_12.Text = '12P';
            % Assign Checked Nodes
            comp.ExcitationsTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @ExcitationsTreeCheckedNodesChanged, true);
            comp.NaturalFrequenciesTree = uitree(comp.GridLayout2, 'checkbox');
            comp.TowerNodes = uitreenode(comp.NaturalFrequenciesTree);
            comp.TowerNodes.Text = 'Tower';
            comp.FirstForeAftNode = uitreenode(comp.TowerNodes);
            comp.FirstForeAftNode.Text = '1st fore-aft';
            comp.SecondForeAftNode = uitreenode(comp.TowerNodes);
            comp.SecondForeAftNode.Text = '2nd fore-aft';
            comp.FirstSideSideNode = uitreenode(comp.TowerNodes);
            comp.FirstSideSideNode.Text = '1st side-side';
            comp.SecondSideSideNode = uitreenode(comp.TowerNodes);
            comp.SecondSideSideNode.Text = '2nd side-side';
            comp.BladeNodes = uitreenode(comp.NaturalFrequenciesTree);
            comp.BladeNodes.Text = 'Blade';
            comp.FirstFlapwiseNode = uitreenode(comp.BladeNodes);
            comp.FirstFlapwiseNode.Text = '1st flapwise';
            comp.SecondFlapwiseNode = uitreenode(comp.BladeNodes);
            comp.SecondFlapwiseNode.Text = '2nd flapwise';
            comp.FirstEdgewiseNode = uitreenode(comp.BladeNodes);
            comp.FirstEdgewiseNode.Text = '1st edgewise';
            comp.SecondEdgewiseNode = uitreenode(comp.BladeNodes);
            comp.SecondEdgewiseNode.Text = '2nd edgewise';
            % Assign Checked Nodes
            comp.NaturalFrequenciesTree.CheckedNodesChangedFcn = matlab.apps.createCallbackFcn(comp, @NaturalFrequenciesTreeCheckedNodesChanged, true);
            comp.RunButton = uibutton(comp.GridLayout2, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.SaveButton = uibutton(comp.GridLayout2, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButtonPushed, true);
            comp.SaveButton.Text = 'Save';
            comp.DisplayDropDown = uidropdown(comp.GridLayout2);
            comp.DisplayDropDown.Items = {'Campbell diagram', 'Tower fore-aft modes', 'Tower side-side modes', 'Blade flapwise modes', 'Blade edgewise modes'};
            comp.DisplayDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DisplayDropDownValueChanged, true);
            comp.DisplayDropDown.Value = 'Campbell diagram';
            comp.DisplayLabel = uilabel(comp.GridLayout2);
            comp.DisplayLabel.Text = 'Display';
            comp.ProjectLabel = uilabel(comp.GridLayout2);
            comp.ProjectLabel.Text = 'Project';
            comp.Project = uilabel(comp.GridLayout2);
            comp.Project.Text = '';
            comp.ResultsfileLabel = uilabel(comp.GridLayout2);
            comp.ResultsfileLabel.Text = 'Results file';
            comp.ResultsFile = uilabel(comp.GridLayout2);
            comp.ResultsFile.Text = '';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end