classdef ResultsAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout              matlab.ui.container.GridLayout
        SetandrunPanel          matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        Project                 matlab.ui.control.Label
        ProjectLabel            matlab.ui.control.Label
        Filename                matlab.ui.control.Label
        SimulationresultsLabel  matlab.ui.control.Label
        ListBox                 matlab.ui.control.ListBox
        LoadButton              matlab.ui.control.Button
        PlotPanel               matlab.ui.container.Panel
        GridLayout1             matlab.ui.container.GridLayout
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        WindTurbine
        frequency
        mainApp
        powerSpectrum
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing the display of data on the tab

        function resetUI(comp)
            comp.ListBox.Items = {};
            comp.Filename.Text = '';
            comp.Filename.Tooltip = '';
            comp.Project.Text = '';
            comp.Project.Tooltip = '';
            cla(comp.UIAxes);
            cla(comp.UIAxes2);
        end

        function plotResults(comp)
            plot(comp.UIAxes, comp.WindTurbine.SimulationResults.Time, comp.WindTurbine.SimulationResults.(comp.ListBox.Value));
            loglog(comp.UIAxes2, comp.frequency, comp.powerSpectrum(comp.ListBox.ValueIndex,:));
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(comp, event)
            if loadMatfile(comp.mainApp, 'Load simulation results', 'simulationresults')
                comp.Filename.Text = comp.WindTurbine.SimulationResults.fileName;
                comp.Filename.Tooltip = comp.WindTurbine.SimulationResults.fileName;
                comp.Project.Text = comp.WindTurbine.SimulationResults.Info.projectFile;
                comp.Project.Tooltip = comp.WindTurbine.SimulationResults.Info.projectFile;
                comp.ListBox.ItemsData = comp.WindTurbine.SimulationResults.Info.dataDescription(2:end,1);
                comp.ListBox.Items = strcat(comp.WindTurbine.SimulationResults.Info.dataDescription(2:end,1), {' - '}, comp.WindTurbine.SimulationResults.Info.dataDescription(2:end,2));
                comp.ListBox.ValueIndex = 1;

                nrSamples = length(comp.WindTurbine.SimulationResults.Time);
                sampleFrequency = 1/(comp.WindTurbine.SimulationResults.Time(2) - comp.WindTurbine.SimulationResults.Time(1));
                comp.frequency = (0 : floor(nrSamples/2) - 1)*(sampleFrequency / nrSamples)*(2*pi);

                nrSignals = length(comp.ListBox.ItemsData);
                comp.powerSpectrum = ones(nrSignals, floor(nrSamples/2));
                for i = 1 : nrSignals
                    FourierTransform = fft(comp.WindTurbine.SimulationResults.(comp.ListBox.ItemsData{i}));
                    comp.powerSpectrum(i,1:floor(nrSamples/2)) = abs(FourierTransform(1:floor(nrSamples/2))).^2/nrSamples;
                end

                plotResults(comp);
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(comp, event)
            plotResults(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.GridLayout1 = uigridlayout(comp.PlotPanel);
            comp.UIAxes2 = uiaxes(comp.GridLayout1);
            title(comp.UIAxes2, 'Power of the discrete fourier transform')
            xlabel(comp.UIAxes2, 'Frequency [rad/s]')
            comp.UIAxes2.XScale = 'log';
            comp.UIAxes2.YScale = 'log';
            comp.UIAxes = uiaxes(comp.GridLayout1);
            title(comp.UIAxes, 'Time series')
            xlabel(comp.UIAxes, 'Time [s]')
            comp.SetandrunPanel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.SetandrunPanel);
            comp.LoadButton = uibutton(comp.GridLayout2, 'push');
            comp.LoadButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LoadButtonPushed, true);
            comp.LoadButton.Text = 'Load';
            comp.ListBox = uilistbox(comp.GridLayout2);
            comp.ListBox.Items = {};
            comp.ListBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ListBoxValueChanged, true);
            comp.ListBox.Value = {};
            comp.SimulationresultsLabel = uilabel(comp.GridLayout2);
            comp.SimulationresultsLabel.Text = 'Simulation results:';
            comp.Filename = uilabel(comp.GridLayout2);
            comp.Filename.Text = '';
            comp.ProjectLabel = uilabel(comp.GridLayout2);
            comp.ProjectLabel.Text = 'Project:';
            comp.Project = uilabel(comp.GridLayout2);
            comp.Project.Text = '';
        end
    end
end