classdef RotorAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout               matlab.ui.container.GridLayout
        SetandrunPanel           matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        ResultsFile              matlab.ui.control.Label
        ResultsfileLabel         matlab.ui.control.Label
        Project                  matlab.ui.control.Label
        ProjectLabel             matlab.ui.control.Label
        DisplayLabel             matlab.ui.control.Label
        DisplayDropDown          matlab.ui.control.DropDown
        instepsofEditFieldLabel  matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        toEditFieldLabel         matlab.ui.control.Label
        RunButton                matlab.ui.control.Button
        degLabel_3               matlab.ui.control.Label
        instepsofEditField       matlab.ui.control.NumericEditField
        degLabel_2               matlab.ui.control.Label
        toEditField              matlab.ui.control.NumericEditField
        degLabel                 matlab.ui.control.Label
        FromEditField            matlab.ui.control.NumericEditField
        FromEditFieldLabel       matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        CustomrangeButton        matlab.ui.control.RadioButton
        FinepitchangleButton     matlab.ui.control.RadioButton
        PlotPanel                matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        WindTurbine
        mainApp
        mode
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing the display of data on the tab

        function resetUI(comp)
            comp.FromEditField.Value = 0.0;
            comp.toEditField.Value = 25.0;
            comp.instepsofEditField.Value = 5.0;
            comp.SaveButton.Enable = "off";
            comp.DisplayLabel.Enable = "off";
            comp.DisplayDropDown.Enable = "off";
            comp.DisplayDropDown.ValueIndex = 1;
            comp.ButtonGroup.SelectedObject = comp.FinepitchangleButton;
            comp.FromEditFieldLabel.Enable = "off";
            comp.FromEditField.Enable = "off";
            comp.degLabel.Enable = "off";
            comp.toEditFieldLabel.Enable = "off";
            comp.toEditField.Enable = "off";
            comp.degLabel_2.Enable = "off";
            comp.instepsofEditFieldLabel.Enable = "off";
            comp.instepsofEditField.Enable = "off";
            comp.degLabel_3.Enable = "off";
            cla(comp.UIAxes);
            legend(comp.UIAxes, "off");
            xlim(comp.UIAxes, "auto");
            comp.mode = "project";

            comp.ButtonGroup.Visible = "on";
            comp.FromEditFieldLabel.Visible = "on";
            comp.FromEditField.Visible = "on";
            comp.degLabel.Visible = "on";
            comp.toEditFieldLabel.Visible = "on";
            comp.toEditField.Visible = "on";
            comp.degLabel_3.Visible = "on";
            comp.instepsofEditFieldLabel.Visible = "on";
            comp.instepsofEditField.Visible = "on";
            comp.degLabel_2.Visible = "on";
            comp.RunButton.Visible = "on";

            comp.SaveButton.Text = "Save";
            comp.ResultsfileLabel.Visible = "off";
            comp.ProjectLabel.Visible = "off";
            comp.ResultsFile.Visible = "off";
            comp.Project.Visible = "off";
            comp.ResultsFile.Text = '';
            comp.Project.Text = '';
            comp.ResultsFile.Tooltip = '';
            comp.Project.Tooltip = '';
        end

        function setResultsMode(comp)
            comp.mode = "results";
            comp.ButtonGroup.Visible = "off";
            comp.FromEditFieldLabel.Visible = "off";
            comp.FromEditField.Visible = "off";
            comp.degLabel.Visible = "off";
            comp.toEditFieldLabel.Visible = "off";
            comp.toEditField.Visible = "off";
            comp.degLabel_3.Visible = "off";
            comp.instepsofEditFieldLabel.Visible = "off";
            comp.instepsofEditField.Visible = "off";
            comp.degLabel_2.Visible = "off";
            comp.RunButton.Visible = "off";

            comp.SaveButton.Text = "Load";
            comp.SaveButton.Enable = "on";
            comp.ResultsfileLabel.Visible = "on";
            comp.ProjectLabel.Visible = "on";
            comp.ResultsFile.Visible = "on";
            comp.Project.Visible = "on";
        end
    end
    
    methods (Access = private)
        
        function adjustTab(comp, source)
            switch source
                case comp.ButtonGroup
                    selectedButton = comp.ButtonGroup.SelectedObject;
                    switch selectedButton
                        case comp.FinepitchangleButton
                            comp.FromEditFieldLabel.Enable = "off";
                            comp.FromEditField.Enable = "off";
                            comp.degLabel.Enable = "off";
                            comp.toEditFieldLabel.Enable = "off";
                            comp.toEditField.Enable = "off";
                            comp.degLabel_2.Enable = "off";
                            comp.instepsofEditFieldLabel.Enable = "off";
                            comp.instepsofEditField.Enable = "off";
                            comp.degLabel_3.Enable = "off";
                        case comp.CustomrangeButton
                            comp.FromEditFieldLabel.Enable = "on";
                            comp.FromEditField.Enable = "on";
                            comp.degLabel.Enable = "on";
                            comp.toEditFieldLabel.Enable = "on";
                            comp.toEditField.Enable = "on";
                            comp.degLabel_2.Enable = "on";
                            comp.instepsofEditFieldLabel.Enable = "on";
                            comp.instepsofEditField.Enable = "on";
                            comp.degLabel_3.Enable = "on";
                    end
                case comp.DisplayDropDown
                    plotRotorAnalysis(comp);
            end
        end

        function plotRotorAnalysis(comp)

            Data = comp.WindTurbine.RotorResults;

            % Prepare legend and x-axis (same for all curves)
            legendLabel = cell(1, length(Data.pitch));
            legendLabel{1} = [num2str(Data.pitch(1), '%.1f'), ' deg'];
            for i = 2:length(Data.pitch)
                legendLabel{i} = [num2str(Data.pitch(i), '%.1f'), ' deg'];
            end
            xlim(comp.UIAxes, [0 max(Data.tsr)]);


            switch comp.DisplayDropDown.Value
                case 'Power coefficient'
                    ylabel(comp.UIAxes, 'Power coefficient [-]');
                    plot(comp.UIAxes, Data.tsr,Data.cP(1,:));
                    hold(comp.UIAxes,'on');
                    for i = 2:length(Data.pitch)
                        plot(comp.UIAxes, Data.tsr,Data.cP(i,:));
                    end
                    hold(comp.UIAxes,'off');

                    [row, col] = find(Data.cP == max(Data.cP,[],"all"));
                    maxc_P = Data.cP(row(1), col(1));
                    lamdaOpt = Data.tsr(col(1));
                    pitchOpt = Data.pitch(row(1));
                    if isscalar(Data.pitch)
                        optimalPoint = sprintf('max c_P = %4.3f\n@lamda = %3.1f', maxc_P, lamdaOpt);
                    else
                        optimalPoint = sprintf('max c_P = %4.3f\n@lamda = %3.1f\n@pitch = %2.1f deg', maxc_P, lamdaOpt, pitchOpt);
                    end
                    text(comp.UIAxes, 0.03, 0.95, optimalPoint, Units="normalized", VerticalAlignment="top", BackgroundColor="w", EdgeColor="k", LineWidth=1);
                case 'Thrust coefficient'
                    ylabel(comp.UIAxes, 'Thrust coefficient [-]');
                    plot(comp.UIAxes, Data.tsr,Data.cT(1,:));
                    hold(comp.UIAxes,'on');
                    for i = 2:length(Data.pitch)
                        plot(comp.UIAxes, Data.tsr,Data.cT(i,:));
                    end
                    hold(comp.UIAxes,'off');
                case 'Torque coefficient'
                    ylabel(comp.UIAxes, 'Torque coefficient [-]');
                    plot(comp.UIAxes, Data.tsr,Data.cQ(1,:));
                    hold(comp.UIAxes,'on');
                    for i = 2:length(Data.pitch)
                        plot(comp.UIAxes, Data.tsr,Data.cQ(i,:));
                    end
                    hold(comp.UIAxes,'off');
            end

            legend(comp.UIAxes, legendLabel);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)

            % Get pitch angle range
            switch comp.ButtonGroup.SelectedObject
                case comp.FinepitchangleButton
                    pitch = comp.WindTurbine.Control.Pitch.Fine;
                case comp.CustomrangeButton
                    pitch = comp.FromEditField.Value : comp.instepsofEditField.Value : comp.toEditField.Value;
                    if isempty(pitch)
                        pitch = comp.FromEditField.Value;
                    end
            end
            comp.WindTurbine.RotorResults.pitch = pitch;

            try
                showProgress(comp.mainApp, 'Computing rotor performance', 'off');
                performRotorAnalysis(comp.WindTurbine);
                endProgress(comp.mainApp);

                comp.SaveButton.Enable = "on";
                comp.DisplayLabel.Enable = "on";
                comp.DisplayDropDown.Enable = "on";
                plotRotorAnalysis(comp);
            catch exception
                endProgress(comp.mainApp);
                uialert(comp.mainApp.FASTToolUIFigure, 'Rotor analysis resulted in an error - See Matlab Command Window for details', 'Info', Icon='info');
                rethrow(exception); % All necessary steps are completed before this call because 'rethrow' will terminate the function
            end
        end

        % Callback function: ButtonGroup, DisplayDropDown, FromEditField, 
        % ...and 1 other component
        function TabDataChanged(comp, event)
            adjustTab(comp, event.Source);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(comp, event)
            if strcmp(comp.mode, "project")
                saveMatfile(comp.mainApp, 'Save rotor-analysis results', 'rotoranalysis');
            else
                if loadMatfile(comp.mainApp, 'Load rotor-analysis results', 'rotoranalysis')
                    comp.ResultsFile.Text = comp.WindTurbine.RotorResults.fileName;
                    comp.Project.Text = comp.WindTurbine.RotorResults.Info.projectFile;
                    comp.ResultsFile.Tooltip = comp.WindTurbine.RotorResults.fileName;
                    comp.Project.Tooltip = comp.WindTurbine.RotorResults.Info.projectFile;
                    comp.DisplayLabel.Enable = "on";
                    comp.DisplayDropDown.Enable = "on";
                    plotRotorAnalysis(comp);
                end
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.PlotPanel);
            title(comp.UIAxes, 'Rotor performance')
            xlabel(comp.UIAxes, 'Tip speed ratio [-]')
            comp.SetandrunPanel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.SetandrunPanel);
            comp.ButtonGroup = uibuttongroup(comp.GridLayout2);
            comp.ButtonGroup.SelectionChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.FinepitchangleButton = uiradiobutton(comp.ButtonGroup);
            comp.FinepitchangleButton.Text = 'Fine pitch angle';
            comp.FinepitchangleButton.Value = true;
            comp.CustomrangeButton = uiradiobutton(comp.ButtonGroup);
            comp.CustomrangeButton.Text = 'Custom range:';
            comp.FromEditFieldLabel = uilabel(comp.GridLayout2);
            comp.FromEditFieldLabel.Text = 'From';
            comp.FromEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.FromEditField.Limits = [-10 Inf];
            comp.FromEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel = uilabel(comp.GridLayout2);
            comp.degLabel.Text = 'deg';
            comp.toEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.toEditField.LowerLimitInclusive = 'off';
            comp.toEditField.Limits = [-10 Inf];
            comp.toEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.toEditField.Value = 25;
            comp.degLabel_2 = uilabel(comp.GridLayout2);
            comp.degLabel_2.Text = 'deg';
            comp.instepsofEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.instepsofEditField.Limits = [0 360];
            comp.instepsofEditField.Value = 5;
            comp.degLabel_3 = uilabel(comp.GridLayout2);
            comp.degLabel_3.Text = 'deg';
            comp.RunButton = uibutton(comp.GridLayout2, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.toEditFieldLabel = uilabel(comp.GridLayout2);
            comp.toEditFieldLabel.Text = 'to';
            comp.SaveButton = uibutton(comp.GridLayout2, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButtonPushed, true);
            comp.SaveButton.Text = 'Save';
            comp.instepsofEditFieldLabel = uilabel(comp.GridLayout2);
            comp.instepsofEditFieldLabel.Text = 'in steps of';
            comp.DisplayDropDown = uidropdown(comp.GridLayout2);
            comp.DisplayDropDown.Items = {'Power coefficient', 'Thrust coefficient', 'Torque coefficient'};
            comp.DisplayDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.DisplayDropDown.Value = 'Power coefficient';
            comp.DisplayLabel = uilabel(comp.GridLayout2);
            comp.DisplayLabel.Text = 'Display';
            comp.ProjectLabel = uilabel(comp.GridLayout2);
            comp.ProjectLabel.Text = 'Project';
            comp.Project = uilabel(comp.GridLayout2);
            comp.Project.Text = '';
            comp.ResultsfileLabel = uilabel(comp.GridLayout2);
            comp.ResultsfileLabel.Text = 'Results file';
            comp.ResultsFile = uilabel(comp.GridLayout2);
            comp.ResultsFile.Text = '';
        end
    end
end