classdef RotorSpecs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        RestoreButton          matlab.ui.control.Button
        GridLayout1            matlab.ui.container.GridLayout
        RotorgeometryPanel     matlab.ui.container.Panel
        NumberofbladesSpinner  matlab.ui.control.Spinner
        degLabel               matlab.ui.control.Label
        ConeangleEditField     matlab.ui.control.NumericEditField
        Image                  matlab.ui.control.Image
        BladegeometryandpropertiesPanel  matlab.ui.container.Panel
        GridLayout11           matlab.ui.container.GridLayout
        ImporttableButton      matlab.ui.control.Button
        ExporttableButton      matlab.ui.control.Button
        BladeTable             matlab.ui.control.Table
    end

    properties (Access = private)
        WindTurbine
        mainApp
        specsChanged
        variableNames
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function setSpecs(comp, Data)
            comp.NumberofbladesSpinner.Value = Data.Blade.Number;
            comp.ConeangleEditField.Value = Data.Blade.Cone;
            setBladeTable(comp, Data);

            applyEdits(comp);
        end
        
        % The function formatBladeTable is called from within RotorSpecs
        % and from FASTTool to initialise or update the column with
        % aerofoil names because the list of names in
        % comp.WindTurbine.Airfoil.Name is used to limit the selection of
        % aerofoils in this column to the aerofoils in the database.
        % The external call from FASTTool is needed to update this list
        % when an aerofoil has been added to or removed from the database
        % on the Aerofoils tab (AerofoilSpecs).
        function formatBladeTable(comp)
            comp.BladeTable.ColumnFormat = {'numeric', 'numeric', 'numeric', comp.WindTurbine.Airfoil.Name', 'numeric', 'numeric', 'numeric'};
        end
        
    end
    
    methods (Access = private)
        
        %% Managing the display of data on the tab

        function setBladeTable(comp, Data)
            formatBladeTable(comp);
            % Aerofoil names are taken from the loaded data, for
            % consistency between Name and IFoil in the blade and aerofoil
            % data. In 'applyBladeTable', Blade.IFoil might be renumbered,
            % for consistency with the aerofoil dataset in
            % comp.WindTurbine.Airfoil (= dataset open in the UI).
            comp.BladeTable.Data = [...
                num2cell(Data.Blade.Radius(:)), ...
                num2cell(Data.Blade.Chord(:)), ...
                num2cell(Data.Blade.Twist(:)), ...
                Data.Airfoil.Name(Data.Blade.IFoil(Data.Blade.NFoil)), ...
                num2cell(Data.Blade.Mass(:)), ...
                num2cell(Data.Blade.EIflap(:)), ...
                num2cell(Data.Blade.EIedge(:))];
        end
        
        %% Extracting WindTurbine data from tab information

        function applyEdits(comp)
            comp.WindTurbine.Blade.Number = comp.NumberofbladesSpinner.Value;
            comp.WindTurbine.Blade.Cone = comp.ConeangleEditField.Value;
            applyBladeTable(comp);
        end

        function applyBladeTable(comp)
            newAerofoils = [];

            T = comp.BladeTable.Data;
            endTable = length(T(:,1));
            comp.WindTurbine.Blade.Radius = ones(endTable,1);
            comp.WindTurbine.Blade.Chord = ones(endTable,1);
            comp.WindTurbine.Blade.Twist = ones(endTable,1);
            comp.WindTurbine.Blade.NFoil = ones(endTable,1);
            comp.WindTurbine.Blade.Mass = ones(endTable,1);
            comp.WindTurbine.Blade.EIflap = ones(endTable,1);
            comp.WindTurbine.Blade.EIedge = ones(endTable,1);

            comp.WindTurbine.Blade.Radius = cell2mat(T(:,1));
            comp.WindTurbine.Blade.Chord = cell2mat(T(:,2));
            comp.WindTurbine.Blade.Twist = cell2mat(T(:,3));
            N = 1;
            comp.WindTurbine.Blade.NFoil(1) = 1;

            if ~ismember(char(T(1,4)), comp.WindTurbine.Airfoil.Name)
                    j = length(comp.WindTurbine.Airfoil.Name) + 1;
                    comp.WindTurbine.Airfoil.Name{j} = char(T(1,4));
                    setNewAerofoilData(comp.WindTurbine);
                    newAerofoils = [newAerofoils; string(comp.WindTurbine.Airfoil.Name{j})];
            end
            comp.WindTurbine.Blade.IFoil = find(strcmp(comp.WindTurbine.Airfoil.Name, char(T(1,4))));
            for i = 2 : endTable
                if ~strcmp(char(T(i,4)), char(T(i-1,4)))
                    N = N + 1;
                    if ~ismember(char(T(i,4)), comp.WindTurbine.Airfoil.Name)
                        j = length(comp.WindTurbine.Airfoil.Name) + 1;
                        comp.WindTurbine.Airfoil.Name{j} = char(T(i,4));
                        setNewAerofoilData(comp.WindTurbine);
                        newAerofoils = [newAerofoils; string(comp.WindTurbine.Airfoil.Name{j})];
                    end
                    comp.WindTurbine.Blade.IFoil = ...
                        [comp.WindTurbine.Blade.IFoil; ...
                        find(strcmp(comp.WindTurbine.Airfoil.Name, char(T(i,4))))];
                end
                comp.WindTurbine.Blade.NFoil(i) = N;
            end
            comp.WindTurbine.Blade.Mass = cell2mat(T(:,5));
            comp.WindTurbine.Blade.EIflap = cell2mat(T(:,6));
            comp.WindTurbine.Blade.EIedge = cell2mat(T(:,7));
            setRemainingBladeProperties(comp.WindTurbine);

            if ~isempty(newAerofoils)
                if isscalar(newAerofoils)
                    text = ["New aerofoil has been added to dataset"; "(with default values):"; ""];
                else
                    text = ["New aerofoils have been added to dataset";  "(with default values):"; ""];
                end
                message = [text;newAerofoils];
                uialert(comp.mainApp.FASTToolUIFigure, message, 'Info', Icon='info');
                formatBladeTable(comp);
                updateForChangedAerofoilsUsed(comp.mainApp); % Let main app update AerofoilSpecs with changes in used aerofoils
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.variableNames = ["Radius [m]" "Chord [m]" "Twist [deg]" "Aerofoil" "Mass density [kg/m]" "Flap. stiff. [N m^2]" "Edge. stiff. [N m^2]"];
        end

        % Value changed function: ConeangleEditField, NumberofbladesSpinner
        function TabDataChanged(comp, event)
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end

        % Button pushed function: ExporttableButton
        function ExporttableButtonPushed(comp, event)
            DataTable = cell2table(comp.BladeTable.Data, VariableNames = comp.variableNames);
            exportTable(comp.mainApp, DataTable, 'Export blade data');
        end

        % Button pushed function: ImporttableButton
        function ImporttableButtonPushed(comp, event)
            [DataTable] = importTable(comp.mainApp, 'Import blade data', 'bladetable');
            if isempty(DataTable)
                return;
            end

            % Verify that data is within acceptable limits
            subinfo{1} = checkTableImport(comp.mainApp, DataTable, comp.variableNames, [1, 2, 5, 6, 7], [0, Inf]);
            subinfo{2} = checkTableImport(comp.mainApp, DataTable, comp.variableNames, 3, [-90, 90]);
            info = sprintf('%s', subinfo{:});
            if ~isempty(info)
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end
            
            comp.BladeTable.Data = table2cell(DataTable(:, 1:7));
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSpecs(comp, Data);
            comp.specsChanged = false;
            updateForChangedSpecs(comp.mainApp);
        end

        % Cell edit callback: BladeTable
        function BladeTableCellEdit(comp, event)

            % Verify that data is within acceptable limits
            info = '';
            switch event.Indices(2)
                case {1, 2, 5, 6, 7}
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [0, Inf]);
                case 3
                    info = checkTableCellEdit(comp.mainApp, event.NewData, [-90, 90]);
            end
            if ~isempty(info)
                comp.BladeTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout1 = uigridlayout(comp.GridLayout);
            comp.BladegeometryandpropertiesPanel = uipanel(comp.GridLayout1);
            comp.BladegeometryandpropertiesPanel.Title = 'Blade geometry and properties';
            comp.GridLayout11 = uigridlayout(comp.BladegeometryandpropertiesPanel);
            comp.BladeTable = uitable(comp.GridLayout11);
            comp.BladeTable.ColumnName = {'Radius [m]'; 'Chord [m]'; 'Twist [deg]'; 'Aerofoil'; 'Mass density [kg/m]'; 'Flap. stiff. [N m^2]'; 'Edge. stiff. [N m^2]'};
            comp.BladeTable.RowName = {};
            comp.BladeTable.ColumnEditable = true;
            comp.BladeTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @BladeTableCellEdit, true);
            comp.ExporttableButton = uibutton(comp.GridLayout11, 'push');
            comp.ExporttableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExporttableButtonPushed, true);
            comp.ExporttableButton.Text = 'Export table';
            comp.ImporttableButton = uibutton(comp.GridLayout11, 'push');
            comp.ImporttableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImporttableButtonPushed, true);
            comp.ImporttableButton.Text = 'Import table';
            comp.RotorgeometryPanel = uipanel(comp.GridLayout1);
            comp.RotorgeometryPanel.Title = 'Rotor geometry';
            comp.Image = uiimage(comp.RotorgeometryPanel);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'graphics', 'blades.png');
            comp.ConeangleEditField = uieditfield(comp.RotorgeometryPanel, 'numeric');
            comp.ConeangleEditField.Limits = [-90 90];
            comp.ConeangleEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.degLabel = uilabel(comp.RotorgeometryPanel);
            comp.degLabel.Text = 'deg';
            comp.NumberofbladesSpinner = uispinner(comp.RotorgeometryPanel);
            comp.NumberofbladesSpinner.Limits = [1 Inf];
            comp.NumberofbladesSpinner.ValueDisplayFormat = '%.0d';
            comp.NumberofbladesSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NumberofbladesSpinner.Value = 1;
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.RestoreButton = uibutton(comp.GridLayout2, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreButton.Text = 'Restore';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end