classdef Simulation < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        SetandrunPanel                matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        RestoreButton                 matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        AirdensityEditFieldLabel      matlab.ui.control.Label
        StepdurationEditFieldLabel    matlab.ui.control.Label
        SpecifiedSeedEditField        matlab.ui.control.NumericEditField
        FirstseednrLabel              matlab.ui.control.Label
        SpecifiedseednumbersCheckBox  matlab.ui.control.CheckBox
        NumberofseedsEditField        matlab.ui.control.NumericEditField
        NumberofseedsEditFieldLabel   matlab.ui.control.Label
        sLabel_3                      matlab.ui.control.Label
        SamplingtimeEditField         matlab.ui.control.NumericEditField
        SampletimeLabel               matlab.ui.control.Label
        sLabel_2                      matlab.ui.control.Label
        RuntimeEditField              matlab.ui.control.NumericEditField
        RuntimekeepsLabel             matlab.ui.control.Label
        sLabel                        matlab.ui.control.Label
        StartupTimeEditField          matlab.ui.control.NumericEditField
        StartuptimediscardsLabel      matlab.ui.control.Label
        SimulationsettingsLabel       matlab.ui.control.Label
        kgm3Label                     matlab.ui.control.Label
        AirdensityEditField           matlab.ui.control.NumericEditField
        OperationAtsLabel             matlab.ui.control.Label
        OperationEventAtEditField     matlab.ui.control.NumericEditField
        OperationAtLabel              matlab.ui.control.Label
        OperatingModeDropDown         matlab.ui.control.DropDown
        OperationLabel                matlab.ui.control.Label
        StepdurationsLabel            matlab.ui.control.Label
        StepdurationEditField         matlab.ui.control.NumericEditField
        instepsofmsLabel              matlab.ui.control.Label
        instepsofEditField            matlab.ui.control.NumericEditField
        instepsofEditFieldLabel       matlab.ui.control.Label
        tomsLabel                     matlab.ui.control.Label
        toEditField                   matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        MultiplerunsCheckBox          matlab.ui.control.CheckBox
        msLabel                       matlab.ui.control.Label
        WindEditField                 matlab.ui.control.NumericEditField
        WindEditFieldLabel            matlab.ui.control.Label
        WindAtsLabel                  matlab.ui.control.Label
        WindEventAtEditField          matlab.ui.control.NumericEditField
        WindAtLabel                   matlab.ui.control.Label
        WindTypeDropDown              matlab.ui.control.DropDown
        IECTurbulenceClassDropDown    matlab.ui.control.DropDown
        IECWindClassDropDown          matlab.ui.control.DropDown
        ClassLabel                    matlab.ui.control.Label
        WindLabel                     matlab.ui.control.Label
        PlotPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        WindTurbine
        mainApp
        settingsChanged
        windStepDuration
        windStepSize
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.settingsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.settingsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function setSettings(comp, Data)
            comp.AirdensityEditField.Value = Data.CertificationSettings.Wind.AirDensity;

            % Windtypes are reordered in UI, to be more logically organised:
            % Group 1: Stepped wind - doesn't allow multiple runs and uses the wind-speed array for the settings of one run.
            % Group 2: Steady wind, NWP - Constant wind speed
            % Group 3: EWS, EDC, EOG, ECG - Deterministic wind, with transient
            % Group 4: NTM, ETM, EWM1, EWM50 - Stochastich wind (turbulent)
            % The wind types used in the code are set in ItemsData
            comp.WindTypeDropDown.ValueIndex = find(Data.CertificationSettings.Wind.Type == comp.WindTypeDropDown.ItemsData);

            % The setting of StepdurationEditField.Enable is tested in
            % adjustTab to determine whether the WindTypeDropDown selection
            % changed to 'Stepped wind'. If StepdurationEditField.Enable =
            % "off" and WindTypeDropDown.Value == 2 (Stepped wind), then
            % the wind type has changed to Stepped wind, and the step
            % duration will be adjusted to keep the runtime constant.
            comp.StepdurationEditField.Enable = "off";
            comp.StepdurationEditField.Value = Data.CertificationSettings.Run.Time;

            comp.WindEventAtEditField.Value = Data.CertificationSettings.Wind.EventTime;
            comp.IECWindClassDropDown.ValueIndex = Data.CertificationSettings.Wind.Class(1);
            comp.IECTurbulenceClassDropDown.ValueIndex = Data.CertificationSettings.Wind.Class(2);

            if isscalar(Data.CertificationSettings.Wind.Speed)
                comp.MultiplerunsCheckBox.Value = false;
                comp.WindEditField.Value = Data.CertificationSettings.Wind.Speed;
                comp.instepsofEditField.Value = 1;
                comp.toEditField.Value = Data.CertificationSettings.Wind.Speed;
            else
                comp.WindEditField.Value = Data.CertificationSettings.Wind.Speed(1);
                comp.instepsofEditField.Value = Data.CertificationSettings.Wind.Speed(2) - Data.CertificationSettings.Wind.Speed(1);
                comp.toEditField.Value = Data.CertificationSettings.Wind.Speed(end);
                if Data.CertificationSettings.Wind.Type == 2 % Stepped wind
                    comp.MultiplerunsCheckBox.Value = false;
                else
                    comp.MultiplerunsCheckBox.Value = true;
                end
            end

            comp.OperatingModeDropDown.ValueIndex = Data.CertificationSettings.Mode.Type;
            comp.OperationEventAtEditField.Value = Data.CertificationSettings.Mode.EventTime;

            comp.StartupTimeEditField.Value = Data.CertificationSettings.Run.StartupTime;
            comp.RuntimeEditField.Value = Data.CertificationSettings.Run.Time;
            comp.SamplingtimeEditField.Value = Data.CertificationSettings.Run.DT;
            comp.NumberofseedsEditField.Value = Data.CertificationSettings.Run.Seeds;
            comp.SpecifiedSeedEditField.Value = Data.CertificationSettings.Run.SeedNumber;

            if Data.CertificationSettings.Run.RandomSeed
                comp.SpecifiedseednumbersCheckBox.Value = false;
            else
                comp.SpecifiedseednumbersCheckBox.Value = true;
            end

            adjustTab(comp);
            applyEdits(comp);
        end
    end
    
    methods (Access = private)
        
        function applyEdits(comp)
            comp.WindTurbine.CertificationSettings.Wind.AirDensity = comp.AirdensityEditField.Value;
            comp.WindTurbine.CertificationSettings.Wind.Type = comp.WindTypeDropDown.ItemsData(comp.WindTypeDropDown.ValueIndex);
            comp.WindTurbine.CertificationSettings.Wind.EventTime = comp.WindEventAtEditField.Value;
            comp.WindTurbine.CertificationSettings.Wind.Class = [...
                comp.IECWindClassDropDown.ValueIndex; ...
                comp.IECTurbulenceClassDropDown.ValueIndex];

            if (comp.MultiplerunsCheckBox.Value && comp.MultiplerunsCheckBox.Enable) || comp.WindTypeDropDown.ValueIndex == 1 % Batch run is possible and selected, or Stepped wind is selected, which uses multiple wind speeds for one run
                comp.WindTurbine.CertificationSettings.Wind.Speed = comp.WindEditField.Value : comp.instepsofEditField.Value : comp.toEditField.Value;
                if isempty(comp.WindTurbine.CertificationSettings.Wind.Speed)
                    comp.WindTurbine.CertificationSettings.Wind.Speed = comp.WindEditField.Value;
                end
            else
                comp.WindTurbine.CertificationSettings.Wind.Speed = comp.WindEditField.Value;
            end
            comp.windStepDuration = comp.StepdurationEditField.Value;
            comp.windStepSize = comp.instepsofEditField.Value;

            comp.WindTurbine.CertificationSettings.Mode.Type = comp.OperatingModeDropDown.ValueIndex;
            comp.WindTurbine.CertificationSettings.Mode.EventTime = comp.OperationEventAtEditField.Value;
                                                                                      
            comp.WindTurbine.CertificationSettings.Run.StartupTime = comp.StartupTimeEditField.Value;
            comp.WindTurbine.CertificationSettings.Run.Time = comp.RuntimeEditField.Value;
            comp.WindTurbine.CertificationSettings.Run.DT = comp.SamplingtimeEditField.Value;
            comp.WindTurbine.Control.DT = comp.SamplingtimeEditField.Value;
            comp.WindTurbine.CertificationSettings.Run.Seeds = comp.NumberofseedsEditField.Value;
            comp.WindTurbine.CertificationSettings.Run.SeedNumber = comp.SpecifiedSeedEditField.Value;

            if comp.SpecifiedseednumbersCheckBox.Value
                comp.WindTurbine.CertificationSettings.Run.RandomSeed = false;
            else
                comp.WindTurbine.CertificationSettings.Run.RandomSeed = true;
            end

            comp.WindTurbine.CertificationSettings.Wind.T = comp.WindTurbine.CertificationSettings.Run.StartupTime + comp.WindTurbine.CertificationSettings.Run.Time;

            plotWindSpeedCharacterisation(comp);
        end

        function adjustTab(comp)
            % First a test is performed to assess whether the
            % WindTypeDropDown changed from anything else to Stepped wind.
            % This is the case when Stepped wind is selected, but
            % StepdurationEditField is not enabled (since it is only
            % enabled when Stepped wind was already selected). In that
            % case, the step duration is set according to the number of
            % wind speeds in the range to keep the runtime constant. While
            % the selected wind type is Stepped wind, the step duration and
            % the wind speed range can be edited, which will change the
            % runtime. For all other wind types the step duration cannot be
            % edited and will be set equal to the runtime (for visual
            % consistency on the tab only). By doing this, the runtime
            % remains unchanged when switching between wind types and can
            % only be edited by changing step duration or wind speed range
            % (when Stepped wind is selected) or directly (otherwise).
            if ~comp.StepdurationEditField.Enable && comp.WindTypeDropDown.ValueIndex == 1 % Stepduration was not enabled and Stepped wind is selected, so this was a change in selection to Stepped wind
                rangeTest = comp.WindEditField.Value : comp.instepsofEditField.Value : comp.toEditField.Value;
                if isempty(rangeTest)
                    rangeTest = comp.WindEditField.Value;
                end                    
                comp.StepdurationEditField.Value = comp.RuntimeEditField.Value / length(rangeTest);
            end

            comp.WindAtLabel.Enable = "off";
            comp.WindEventAtEditField.Enable = "off";
            comp.WindAtsLabel.Enable = "off";
            comp.OperationAtLabel.Enable = "off";
            comp.OperationEventAtEditField.Enable = "off";
            comp.OperationAtsLabel.Enable = "off";
            comp.WindEditFieldLabel.Enable = "off";
            comp.WindEditField.Enable = "off";
            comp.msLabel.Enable = "off";
            comp.MultiplerunsCheckBox.Enable = "off";
            comp.toEditFieldLabel.Enable = "off";
            comp.toEditField.Enable = "off";
            comp.tomsLabel.Enable = "off";
            comp.instepsofEditFieldLabel.Enable = "off";
            comp.instepsofEditField.Enable = "off";
            comp.instepsofmsLabel.Enable = "off";
            comp.StepdurationEditFieldLabel.Enable = "off";
            comp.StepdurationEditField.Enable = "off";
            comp.StepdurationsLabel.Enable = "off";
            comp.NumberofseedsEditFieldLabel.Enable = "off";
            comp.NumberofseedsEditField.Enable = "off";
            comp.SpecifiedseednumbersCheckBox.Enable = "off";
            comp.FirstseednrLabel.Enable = "off";
            comp.SpecifiedSeedEditField.Enable = "off";
            comp.RuntimeEditField.Enable = "off";
            if comp.WindTypeDropDown.ValueIndex ~= 10 && comp.WindTypeDropDown.ValueIndex ~= 11 % Not the annual or 50-year extreme, for which the wind speed is fixed
                comp.WindEditFieldLabel.Enable = "on";
                comp.WindEditField.Enable = "on";
                comp.msLabel.Enable = "on";
            end
            if comp.WindTypeDropDown.ValueIndex ~= 1 && comp.WindTypeDropDown.ValueIndex ~= 10 && comp.WindTypeDropDown.ValueIndex ~= 11 % Not stepped wind, which uses the wind speed range for one run, or the annual or 50-year extreme, for which the wind speed is fixed
                comp.MultiplerunsCheckBox.Enable = "on";
            end
            if comp.WindTypeDropDown.ValueIndex == 4 ||comp.WindTypeDropDown.ValueIndex == 5 ||comp.WindTypeDropDown.ValueIndex == 6 ||comp.WindTypeDropDown.ValueIndex == 7
                comp.WindAtLabel.Enable = "on";
                comp.WindEventAtEditField.Enable = "on";
                comp.WindAtsLabel.Enable = "on";
            end
            if comp.OperatingModeDropDown.ValueIndex == 2 || comp.OperatingModeDropDown.ValueIndex == 3 || comp.OperatingModeDropDown.ValueIndex == 4 || comp.OperatingModeDropDown.ValueIndex == 5
                comp.OperationAtLabel.Enable = "on";
                comp.OperationEventAtEditField.Enable = "on";
                comp.OperationAtsLabel.Enable = "on";
            end
            if comp.WindTypeDropDown.ValueIndex == 1 || (comp.MultiplerunsCheckBox.Value && comp.MultiplerunsCheckBox.Enable) % Stepped wind or multiple wind speeds
                comp.toEditFieldLabel.Enable = "on";
                comp.toEditField.Enable = "on";
                comp.tomsLabel.Enable = "on";
                comp.instepsofEditFieldLabel.Enable = "on";
                comp.instepsofEditField.Enable = "on";
                comp.instepsofmsLabel.Enable = "on";
                comp.WindEditFieldLabel.Text = "From";
            else
                comp.WindEditFieldLabel.Text = "Wind speed";
            end
            rangeTest = comp.WindEditField.Value : comp.instepsofEditField.Value : comp.toEditField.Value;
            if isempty(rangeTest)
                rangeTest = comp.WindEditField.Value;
            end
            if comp.WindTypeDropDown.ValueIndex == 1 % Stepped wind
                comp.StepdurationEditFieldLabel.Enable = "on";
                comp.StepdurationEditField.Enable = "on";
                comp.StepdurationsLabel.Enable = "on";
                comp.RuntimeEditField.Value = comp.StepdurationEditField.Value * length(rangeTest);
            else
                comp.RuntimeEditField.Enable = "on";
                comp.StepdurationEditField.Value = comp.RuntimeEditField.Value;
            end
            if comp.WindTypeDropDown.ValueIndex == 8 || comp.WindTypeDropDown.ValueIndex == 9 || comp.WindTypeDropDown.ValueIndex == 10 || comp.WindTypeDropDown.ValueIndex == 11
                comp.NumberofseedsEditFieldLabel.Enable = "on";
                comp.NumberofseedsEditField.Enable = "on";
                comp.SpecifiedseednumbersCheckBox.Enable = "on";
                if comp.SpecifiedseednumbersCheckBox.Value
                    comp.FirstseednrLabel.Enable = "on";
                    comp.SpecifiedSeedEditField.Enable = "on";
                end
            end
        end

        function plotWindSpeedCharacterisation(comp)
            cla(comp.UIAxes);

            discardTime = comp.WindTurbine.CertificationSettings.Run.StartupTime;
            % Grey box for period that will be discarded
            hold(comp.UIAxes,'on');
            patch(comp.UIAxes, [0 discardTime discardTime 0], [-1e3 -1e3 1e3 1e3], [0.95 0.95 0.95], 'EdgeColor', 'none');
            plot(comp.UIAxes, [0 0], [-1e3 1e3], '--', 'Color', [0.5 0.5 0.5]);
            plot(comp.UIAxes, [discardTime discardTime], [-1e3 1e3], '--', 'Color', [0.5 0.5 0.5]);

            % Time series
            timestep = 0.1;
            U = comp.WindTurbine.CertificationSettings.Wind.Speed(1);
            t = 0:timestep:comp.WindTurbine.CertificationSettings.Wind.T;

            % IEC class
            if comp.WindTurbine.CertificationSettings.Wind.Class(2) == 1
                Iref = 0.16;
            elseif comp.WindTurbine.CertificationSettings.Wind.Class(2) == 2
                Iref = 0.14;
            elseif comp.WindTurbine.CertificationSettings.Wind.Class(2) == 3
                Iref = 0.12;
            end
            if comp.WindTurbine.CertificationSettings.Wind.Class(1) == 1
                Vref = 50;
            elseif comp.WindTurbine.CertificationSettings.Wind.Class(1) == 2
                Vref = 42.5;
            elseif comp.WindTurbine.CertificationSettings.Wind.Class(1) == 3
                Vref = 37.5;
            end
            if comp.WindTurbine.Tower.HubHeight >= 60
                Lambda1 = 0.7*comp.WindTurbine.Tower.HubHeight;
            else
                Lambda1 = 42;
            end

            % Plot wind
            switch comp.WindTurbine.CertificationSettings.Wind.Type
                case 1 % Steady wind

                    % Constant wind
                    u = 0*t + U;
                    comp.UIAxes.YLim = [0 1.2*U];
                    plot(comp.UIAxes, t,u);
                    legend(comp.UIAxes, 'off');

                case 2 % Stepped wind

                    % Stepped profile
                    u = comp.WindTurbine.CertificationSettings.Wind.Speed(1) + ...
                        comp.windStepSize * max(0,floor((t-discardTime)/comp.windStepDuration));
                    u(end) = comp.WindTurbine.CertificationSettings.Wind.Speed(end);
                    comp.UIAxes.YLim = [0 1.2*max(u)];
                    plot(comp.UIAxes, t,u);
                    legend(comp.UIAxes, 'off');

                case 3 % Normal wind profile (NWP)

                    % Constant wind
                    u1 = 0*t + U*((comp.WindTurbine.Tower.HubHeight+comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2;
                    u2 = 0*t + U;
                    u3 = 0*t + U*((comp.WindTurbine.Tower.HubHeight-comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2;

                    % Plot
                    comp.UIAxes.YLim = [0 1.2*u1(1)];
                    lines = plot(comp.UIAxes, t,u1,t,u2,t,u3);
                    legend(comp.UIAxes, lines, {'u(z_{hub} + R)','u(z_{hub})','u(z_{hub} - R)'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 4 % Normal turbulence model (NTM)

                    % NTM values
                    sigma1 = Iref*(0.75*U+5.6);
                    sigma2 = 0.8*sigma1;
                    sigma3 = 0.5*sigma1;
                    Lu = 8.1*Lambda1;
                    Lv = 2.7*Lambda1;
                    Lw = 0.66*Lambda1;

                    % Wave number vector
                    L = U * comp.WindTurbine.CertificationSettings.Wind.T;
                    N = length(t);
                    m = ifftshift(-N/2:N/2-1);
                    k = 2*pi*m/L;

                    % Spectrum
                    Fu = sigma1^2 * 4*Lu/U./(1+6/(2*pi)*abs(k)*Lu).^(5/3);
                    Fv = sigma2^2 * 4*Lv/U./(1+6/(2*pi)*abs(k)*Lv).^(5/3);
                    Fw = sigma3^2 * 4*Lw/U./(1+6/(2*pi)*abs(k)*Lw).^(5/3);
                    n = randn([3,length(k)]) + sqrt(-1)*randn([3,length(k)]);
                    dZ = sqrt(2*pi*[Fu;Fv;Fw]/L) .* n;

                    % IFFT
                    u = N*real(ifft(dZ(1,:)));
                    v = N*real(ifft(dZ(2,:)));
                    w = N*real(ifft(dZ(3,:)));
                    u = (u -mean(u)) * sigma1/std(u) + U;
                    v = (v -mean(v)) * sigma2/std(v);
                    w = (w -mean(w)) * sigma3/std(w);

                    % Plot
                    comp.UIAxes.YLim = [min([u v w])-0.2*max([u v w]) 1.2*max([u v w])];
                    lines = plot(comp.UIAxes, t,u,t,v,t,w);
                    legend(comp.UIAxes, lines, {'u','v','w'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 5 % Annual extreme wind speed (EWM)

                    % EWM values
                    V50 = Vref;
                    V1 = 0.8*V50;
                    sigma1 = 0.11*V1;
                    sigma2 = 0.8*sigma1;
                    sigma3 = 0.5*sigma1;
                    Lu = 8.1*Lambda1;
                    Lv = 2.7*Lambda1;
                    Lw = 0.66*Lambda1;

                    % Wave number vector
                    L = U * comp.WindTurbine.CertificationSettings.Wind.T;
                    N = length(t);
                    m = ifftshift(-N/2:N/2-1);
                    k = 2*pi*m/L;

                    % Spectrum
                    Fu = sigma1^2 * 4*Lu/U./(1+6/(2*pi)*abs(k)*Lu).^(5/3);
                    Fv = sigma2^2 * 4*Lv/U./(1+6/(2*pi)*abs(k)*Lv).^(5/3);
                    Fw = sigma3^2 * 4*Lw/U./(1+6/(2*pi)*abs(k)*Lw).^(5/3);
                    n = randn([3,length(k)]) + sqrt(-1)*randn([3,length(k)]);
                    dZ = sqrt(2*pi*[Fu;Fv;Fw]/L) .* n;

                    % IFFT
                    u = N*real(ifft(dZ(1,:)));
                    v = N*real(ifft(dZ(2,:)));
                    w = N*real(ifft(dZ(3,:)));
                    u = (u -mean(u)) * sigma1/std(u) + V1;
                    v = (v -mean(v)) * sigma2/std(v);
                    w = (w -mean(w)) * sigma3/std(w);

                    % Plot
                    comp.UIAxes.YLim = [min([u v w])-0.2*max([u v w]) 1.2*max([u v w])];
                    lines = plot(comp.UIAxes, t,u,t,v,t,w);
                    legend(comp.UIAxes, lines, {'u','v','w'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 6 % 50-year extreme wind speed (EWM)

                    % EWM values
                    V50 = Vref;
                    sigma1 = 0.11*V50;
                    sigma2 = 0.8*sigma1;
                    sigma3 = 0.5*sigma1;
                    Lu = 8.1*Lambda1;
                    Lv = 2.7*Lambda1;
                    Lw = 0.66*Lambda1;

                    % Wave number vector
                    L = U * comp.WindTurbine.CertificationSettings.Wind.T;
                    N = length(t);
                    m = ifftshift(-N/2:N/2-1);
                    k = 2*pi*m/L;

                    % Spectrum
                    Fu = sigma1^2 * 4*Lu/U./(1+6/(2*pi)*abs(k)*Lu).^(5/3);
                    Fv = sigma2^2 * 4*Lv/U./(1+6/(2*pi)*abs(k)*Lv).^(5/3);
                    Fw = sigma3^2 * 4*Lw/U./(1+6/(2*pi)*abs(k)*Lw).^(5/3);
                    n = randn([3,length(k)]) + sqrt(-1)*randn([3,length(k)]);
                    dZ = sqrt(2*pi*[Fu;Fv;Fw]/L) .* n;

                    % IFFT
                    u = N*real(ifft(dZ(1,:)));
                    v = N*real(ifft(dZ(2,:)));
                    w = N*real(ifft(dZ(3,:)));
                    u = (u -mean(u)) * sigma1/std(u) + V50;
                    v = (v -mean(v)) * sigma2/std(v);
                    w = (w -mean(w)) * sigma3/std(w);

                    % Plot
                    comp.UIAxes.YLim = [min([u v w])-0.2*max([u v w]) 1.2*max([u v w])];
                    lines = plot(comp.UIAxes, t,u,t,v,t,w);
                    legend(comp.UIAxes, lines, {'u','v','w'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 7 % Extreme wind shear (EWS)

                    % NTM values
                    sigma1 = Iref*(0.75*U+5.6);

                    % Wind speeds
                    beta = 6.4;
                    Tg = 12;
                    t0 = discardTime + comp.WindTurbine.CertificationSettings.Wind.EventTime;
                    u1 = U * ((comp.WindTurbine.Tower.HubHeight+comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2 + 0.5*(2.5+0.2*beta*sigma1*(2*comp.WindTurbine.Blade.Radius(end)/Lambda1)^0.25) * (1-cos(2*pi*(t-t0)/Tg));
                    u2 = 0*t + U;
                    u3 = U * ((comp.WindTurbine.Tower.HubHeight-comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2 - 0.5*(2.5+0.2*beta*sigma1*(2*comp.WindTurbine.Blade.Radius(end)/Lambda1)^0.25) * (1-cos(2*pi*(t-t0)/Tg));
                    u1(t < t0) = U * ((comp.WindTurbine.Tower.HubHeight+comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2;
                    u1(t > t0+Tg) = U * ((comp.WindTurbine.Tower.HubHeight+comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2;
                    u3(t < t0) = U * ((comp.WindTurbine.Tower.HubHeight-comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2;
                    u3(t > t0+Tg) = U * ((comp.WindTurbine.Tower.HubHeight-comp.WindTurbine.Blade.Radius(end))/comp.WindTurbine.Tower.HubHeight).^0.2;

                    % Plot
                    comp.UIAxes.YLim = [0 1.2*max([u1 u2 u3])];
                    lines = plot(comp.UIAxes, t,u1,t,u2,t,u3);
                    legend(comp.UIAxes, lines, {'u(z_{hub} + R)','u(z_{hub})','u(z_{hub} - R)'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 8 % Extreme turbulence model (ETM)

                    % ETM values
                    Vave = 0.2*Vref;
                    c = 2;
                    sigma1 = c * Iref*(0.072*(Vave/c+3)*(U/c-4) + 10);
                    sigma2 = 0.8*sigma1;
                    sigma3 = 0.5*sigma1;
                    Lu = 8.1*Lambda1;
                    Lv = 2.7*Lambda1;
                    Lw = 0.66*Lambda1;

                    % Wave number vector
                    L = U * comp.WindTurbine.CertificationSettings.Wind.T;
                    N = length(t);
                    m = ifftshift(-N/2:N/2-1);
                    k = 2*pi*m/L;

                    % Spectrum
                    Fu = sigma1^2 * 4*Lu/U./(1+6/(2*pi)*abs(k)*Lu).^(5/3);
                    Fv = sigma2^2 * 4*Lv/U./(1+6/(2*pi)*abs(k)*Lv).^(5/3);
                    Fw = sigma3^2 * 4*Lw/U./(1+6/(2*pi)*abs(k)*Lw).^(5/3);
                    n = randn([3,length(k)]) + sqrt(-1)*randn([3,length(k)]);
                    dZ = sqrt(2*pi*[Fu;Fv;Fw]/L) .* n;

                    % IFFT
                    u = N*real(ifft(dZ(1,:)));
                    v = N*real(ifft(dZ(2,:)));
                    w = N*real(ifft(dZ(3,:)));
                    u = (u -mean(u)) * sigma1/std(u) + U;
                    v = (v -mean(v)) * sigma2/std(v);
                    w = (w -mean(w)) * sigma3/std(w);

                    % Plot
                    comp.UIAxes.YLim = [min([u v w])-0.2*max([u v w]) 1.2*max([u v w])];
                    lines = plot(comp.UIAxes, t,u,t,v,t,w);
                    legend(comp.UIAxes, lines, {'u','v','w'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 9 % Extreme operating gust (EOG)

                    % EWM values
                    Ve50 = 1.4*Vref;
                    Ve1 = 0.8*Ve50;

                    % NTM values
                    sigma1 = Iref*(0.75*U+5.6);

                    % Gust at t0
                    Vgust = min([1.35*(Ve1-U), 3.3*sigma1/(1+0.1*2*comp.WindTurbine.Blade.Radius(end)/Lambda1)]);
                    t0 = discardTime + comp.WindTurbine.CertificationSettings.Wind.EventTime;
                    Tg = 10.5;
                    u = 0*t + U - 0.37*Vgust*sin(3*pi*(t-t0)/Tg) .* (1-cos(2*pi*(t-t0)/Tg));
                    u(t < t0) = U;
                    u(t > t0+Tg) = U;

                    % Plot
                    comp.UIAxes.YLim = [0 1.2*max(u)];
                    plot(comp.UIAxes, t,u);
                    legend(comp.UIAxes, 'off');

                case 10 % Extreme direction change (EDC)

                    % NTM values
                    sigma1 = Iref*(0.75*U+5.6);

                    % EDC values
                    t0 = discardTime + comp.WindTurbine.CertificationSettings.Wind.EventTime;
                    Tg = 6;
                    thetae = 4*atan(sigma1/(U*(1+0.1*2*comp.WindTurbine.Blade.Radius(end)/Lambda1)));
                    theta = 0.5*thetae * (1-cos(pi*(t-t0)/Tg));
                    theta(t < t0) = 0;
                    theta(t > t0+Tg) = thetae;

                    % Wind speeds
                    u = U*cos(theta);
                    v = U*sin(theta);
                    w = 0*t;

                    % Plot
                    comp.UIAxes.YLim = [0 1.2*U];
                    lines = plot(comp.UIAxes, t,u,t,v,t,w);
                    legend(comp.UIAxes, lines, {'u','v','w'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');

                case 11 % Extreme coherent gust (ECG)

                    % ECG values
                    t0 = discardTime + comp.WindTurbine.CertificationSettings.Wind.EventTime;
                    Tg = 10;
                    Vcg = 15;
                    V = U + 0.5*Vcg*(1-cos(pi*(t-t0)/Tg));
                    V(t < t0) = U;
                    V(t > t0+Tg) = U + Vcg;
                    if U < 4
                        thetacg = pi;
                    else
                        thetacg = 4*pi/U;
                    end
                    theta = 0.5*thetacg*(1-cos(pi*(t-t0)/Tg));
                    theta(t < t0) = 0;
                    theta(t > t0+Tg) = thetacg;

                    % Wind speeds
                    u = V.*cos(theta);
                    v = V.*sin(theta);
                    w = 0*t;

                    % Plot
                    comp.UIAxes.YLim = [0 1.2*max(V)];
                    lines = plot(comp.UIAxes, t,u,t,v,t,w);
                    legend(comp.UIAxes, lines, {'u','v','w'}, 'Location', 'NorthEast', 'Color', 'none', 'Box', 'off');
            end
            text(comp.UIAxes, discardTime, comp.UIAxes.YLim(1), '\leftarrow discarded', VerticalAlignment="bottom");
            noOfWindSpeeds = length(comp.WindTurbine.CertificationSettings.Wind.Speed);
            if noOfWindSpeeds > 1 && ...
                    comp.WindTurbine.CertificationSettings.Wind.Type ~= 2 && ... % Not stepped wind
                    comp.WindTurbine.CertificationSettings.Wind.Type ~= 5 && ... % Not extreme wind
                    comp.WindTurbine.CertificationSettings.Wind.Type ~= 6 % Not extreme wind
                textWindSpeeds = sprintf('Repeated for wind speeds: %3.1f', comp.WindTurbine.CertificationSettings.Wind.Speed(1));
                if noOfWindSpeeds < 5
                    textWindSpeeds = append(textWindSpeeds, sprintf(', %3.1f', comp.WindTurbine.CertificationSettings.Wind.Speed(2:noOfWindSpeeds)));
                    textWindSpeeds = [textWindSpeeds, ' m/s'];
                else
                    textWindSpeeds = append(textWindSpeeds, sprintf(', %3.1f, ...', comp.WindTurbine.CertificationSettings.Wind.Speed(2)));
                    textWindSpeeds = append(textWindSpeeds, sprintf(', %3.1f', comp.WindTurbine.CertificationSettings.Wind.Speed(end)));
                    textWindSpeeds = [textWindSpeeds, ' m/s'];
                end
                text(comp.UIAxes, 0.03, 0.95, textWindSpeeds, Units="normalized", VerticalAlignment="top", BackgroundColor="w", EdgeColor="k", LineWidth=1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            disableDefaultInteractivity(comp.UIAxes);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)
            if ~prepareFAST(comp.mainApp)
                return;
            end

            % Cases with wind speeds higher than cut-out wind speed with
            % power-production on can lead to errors. The supervisory
            % control in Simulink does not check for this inconsistency.
            if (comp.WindTypeDropDown.ValueIndex == 10 || ...
                    comp.WindTypeDropDown.ValueIndex == 11 || ...
                    comp.WindEditField.Value > comp.WindTurbine.Control.WindSpeed.Cutout || ...
                    (comp.toEditField.Enable && ...
                    comp.toEditField.Value > comp.WindTurbine.Control.WindSpeed.Cutout)) && ...
                    (comp.OperatingModeDropDown.ValueIndex ~= 6 && ...
                    comp.OperatingModeDropDown.ValueIndex ~= 7)
                info = ['Wind speeds higher than cut-out wind speed in combination with power production can lead to errors during simulation.\n\n', ...
                    'Continue with these settings?'];
                selection = uiconfirm(comp.mainApp.FASTToolUIFigure, sprintf(info), "Starting simulation");
                if ~strcmp(selection, 'OK')
                    return;
                end
            end

            if ~(setSimulationfile(comp.mainApp))
                return;
            end

            try
                showProgress(comp.mainApp, 'Simulating', 'off');
                simulate(comp.WindTurbine);
                endProgress(comp.mainApp);
            catch exception
                endProgress(comp.mainApp);
                if contains(exception.message,'Simulink')
                    info = ['Simulation resulted in an error. See Matlab Command Window for details.\n\n', ...
                        'Note that Simulink needs to be installed for this action.'];
                elseif contains(exception.message,'FAST_SFunc')
                    info = ['Simulation resulted in an error. See Matlab Command Window for details.\n\n', ...
                        'Try simulating with a smaller ''Sampling time'' (particularly relevant for smaller turbines).\n\n', ...
                        'If the problem persists, turbine response may be extreme or instable. Some potential causes are:\n', ...
                        '- simulation of power production with extreme wind\n', ...
                        '  (e.g. ''EWM1'' with ''Normal shutdown'')\n', ...
                        '- controller settings\n', ...
                        '- rotor stiffness (too low)\n', ...
                        '- tower stiffness (too low)\n', ...
                        '- generator inertia (too low)\n', ...
                        '- fore-aft damping ''Gain'' (too high)\n\n', ...
                        'Results for correctly completed simulations in a batch have been saved.'];
                else
                    info = ['Simulation resulted in an error. See Matlab Command Window for details.\n\n', ...
                        'Results for correctly completed simulations in a batch have been saved.'];
                end
                uialert(comp.mainApp.FASTToolUIFigure, sprintf(info), 'Info', Icon='info');
                evalin("base", "clear Drivetrain Control FAST_InputFileName TMax RPM_Init T_GenSpeedInit P_InitAngle OutList Legend CertificationSettings DT");
                rethrow(exception); % All necessary steps are completed before this call because 'rethrow' will terminate the function
            end
        end

        % Value changed function: AirdensityEditField, 
        % ...and 17 other components
        function TabDataChanged(comp, event)
            adjustTab(comp);
            applyEdits(comp);
            comp.settingsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSettings(comp, Data);
            comp.settingsChanged = false;
            updateForChangedSpecs(comp.mainApp);            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.PlotPanel);
            title(comp.UIAxes, 'Wind conditions characterisation')
            xlabel(comp.UIAxes, 'Time [s]')
            ylabel(comp.UIAxes, 'Wind speed [m/s]')
            comp.SetandrunPanel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.SetandrunPanel);
            comp.WindLabel = uilabel(comp.GridLayout2);
            comp.WindLabel.Text = 'Wind';
            comp.ClassLabel = uilabel(comp.GridLayout2);
            comp.ClassLabel.Tooltip = {''};
            comp.ClassLabel.Text = 'Class';
            comp.IECWindClassDropDown = uidropdown(comp.GridLayout2);
            comp.IECWindClassDropDown.Items = {'I', 'II', 'III'};
            comp.IECWindClassDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.IECWindClassDropDown.Value = 'I';
            comp.IECTurbulenceClassDropDown = uidropdown(comp.GridLayout2);
            comp.IECTurbulenceClassDropDown.Items = {'A', 'B', 'C'};
            comp.IECTurbulenceClassDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.IECTurbulenceClassDropDown.Value = 'A';
            comp.WindTypeDropDown = uidropdown(comp.GridLayout2);
            comp.WindTypeDropDown.Items = {'Stepped wind', 'Steady wind', 'Normal wind profile (NWP)', 'Extreme wind shear (EWS)', 'Extreme direction change (EDC)', 'Extreme operating gust (EOG)', 'Extreme coherent gust (ECG)', 'Normal turbulence model (NTM)', 'Extreme turbulence model (ETM)', 'Annual extreme wind speed (EWM1)', '50-year extreme wind speed (EWM50)'};
            comp.WindTypeDropDown.ItemsData = [2 1 3 7 10 9 11 4 8 5 6];
            comp.WindTypeDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.WindTypeDropDown.Value = 2;
            comp.WindAtLabel = uilabel(comp.GridLayout2);
            comp.WindAtLabel.Text = 'At';
            comp.WindEventAtEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.WindEventAtEditField.Limits = [0 Inf];
            comp.WindEventAtEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.WindAtsLabel = uilabel(comp.GridLayout2);
            comp.WindAtsLabel.Text = 's';
            comp.WindEditFieldLabel = uilabel(comp.GridLayout2);
            comp.WindEditFieldLabel.Text = 'Wind speed';
            comp.WindEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.WindEditField.Limits = [0 Inf];
            comp.WindEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.msLabel = uilabel(comp.GridLayout2);
            comp.msLabel.Text = 'm/s';
            comp.MultiplerunsCheckBox = uicheckbox(comp.GridLayout2);
            comp.MultiplerunsCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.MultiplerunsCheckBox.Text = 'Multiple runs:';
            comp.toEditFieldLabel = uilabel(comp.GridLayout2);
            comp.toEditFieldLabel.Text = 'to';
            comp.toEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.toEditField.Limits = [0 Inf];
            comp.toEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.tomsLabel = uilabel(comp.GridLayout2);
            comp.tomsLabel.Text = 'm/s';
            comp.instepsofEditFieldLabel = uilabel(comp.GridLayout2);
            comp.instepsofEditFieldLabel.Text = 'in steps of';
            comp.instepsofEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.instepsofEditField.Limits = [0 Inf];
            comp.instepsofEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.instepsofmsLabel = uilabel(comp.GridLayout2);
            comp.instepsofmsLabel.Text = 'm/s';
            comp.StepdurationEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.StepdurationEditField.Limits = [0 Inf];
            comp.StepdurationEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.StepdurationEditField.Tooltip = {'Computed from runtime and number of steps'};
            comp.StepdurationsLabel = uilabel(comp.GridLayout2);
            comp.StepdurationsLabel.Text = 's';
            comp.OperationLabel = uilabel(comp.GridLayout2);
            comp.OperationLabel.Text = 'Operation';
            comp.OperatingModeDropDown = uidropdown(comp.GridLayout2);
            comp.OperatingModeDropDown.Items = {'Power production', 'Power production with grid loss', 'Startup', 'Normal shutdown', 'Emergency shutdown', 'Idling', 'Parked'};
            comp.OperatingModeDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.OperatingModeDropDown.Value = 'Power production';
            comp.OperationAtLabel = uilabel(comp.GridLayout2);
            comp.OperationAtLabel.Text = 'At';
            comp.OperationEventAtEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.OperationEventAtEditField.Limits = [0 Inf];
            comp.OperationEventAtEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.OperationAtsLabel = uilabel(comp.GridLayout2);
            comp.OperationAtsLabel.Text = 's';
            comp.AirdensityEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.AirdensityEditField.Limits = [0 Inf];
            comp.AirdensityEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.kgm3Label = uilabel(comp.GridLayout2);
            comp.kgm3Label.Text = 'kg/m^3';
            comp.SimulationsettingsLabel = uilabel(comp.GridLayout2);
            comp.SimulationsettingsLabel.Text = 'Simulation settings';
            comp.StartuptimediscardsLabel = uilabel(comp.GridLayout2);
            comp.StartuptimediscardsLabel.Text = 'Startup [discard]';
            comp.StartupTimeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.StartupTimeEditField.Limits = [0 Inf];
            comp.StartupTimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.sLabel = uilabel(comp.GridLayout2);
            comp.sLabel.Text = 's';
            comp.RuntimekeepsLabel = uilabel(comp.GridLayout2);
            comp.RuntimekeepsLabel.Text = 'Runtime [keep]';
            comp.RuntimeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.RuntimeEditField.Limits = [0 Inf];
            comp.RuntimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.sLabel_2 = uilabel(comp.GridLayout2);
            comp.sLabel_2.Text = 's';
            comp.SampletimeLabel = uilabel(comp.GridLayout2);
            comp.SampletimeLabel.Text = 'Sampling time';
            comp.SamplingtimeEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.SamplingtimeEditField.Limits = [0 Inf];
            comp.SamplingtimeEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.sLabel_3 = uilabel(comp.GridLayout2);
            comp.sLabel_3.Text = 's';
            comp.NumberofseedsEditFieldLabel = uilabel(comp.GridLayout2);
            comp.NumberofseedsEditFieldLabel.Text = 'Number of seeds';
            comp.NumberofseedsEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.NumberofseedsEditField.Limits = [1 Inf];
            comp.NumberofseedsEditField.RoundFractionalValues = 'on';
            comp.NumberofseedsEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.NumberofseedsEditField.Value = 1;
            comp.SpecifiedseednumbersCheckBox = uicheckbox(comp.GridLayout2);
            comp.SpecifiedseednumbersCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.SpecifiedseednumbersCheckBox.Text = 'Specified seed numbers:';
            comp.FirstseednrLabel = uilabel(comp.GridLayout2);
            comp.FirstseednrLabel.Text = 'First seed nr.';
            comp.SpecifiedSeedEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.SpecifiedSeedEditField.Limits = [-2100000000 2100000000];
            comp.SpecifiedSeedEditField.RoundFractionalValues = 'on';
            comp.SpecifiedSeedEditField.ValueDisplayFormat = '%.0f';
            comp.SpecifiedSeedEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.SpecifiedSeedEditField.Value = 1;
            comp.StepdurationEditFieldLabel = uilabel(comp.GridLayout2);
            comp.StepdurationEditFieldLabel.Text = 'Step duration';
            comp.AirdensityEditFieldLabel = uilabel(comp.GridLayout2);
            comp.AirdensityEditFieldLabel.Text = 'Air density';
            comp.RunButton = uibutton(comp.GridLayout2, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.RestoreButton = uibutton(comp.GridLayout2, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Text = 'Restore';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end