classdef SteadyOpAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                matlab.ui.container.GridLayout
        PlotPanel                 matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        Panel                     matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ResultsFile               matlab.ui.control.Label
        ResultsfileLabel          matlab.ui.control.Label
        Project                   matlab.ui.control.Label
        ProjectLabel              matlab.ui.control.Label
        DisplayDropDown           matlab.ui.control.DropDown
        SaveButton                matlab.ui.control.Button
        RunButton                 matlab.ui.control.Button
        instepsofEditFieldLabel   matlab.ui.control.Label
        msLabel_3                 matlab.ui.control.Label
        instepsofEditField        matlab.ui.control.NumericEditField
        msLabel_2                 matlab.ui.control.Label
        toEditField               matlab.ui.control.NumericEditField
        toEditFieldLabel          matlab.ui.control.Label
        msLabel                   matlab.ui.control.Label
        FromEditField             matlab.ui.control.NumericEditField
        kgm3Label                 matlab.ui.control.Label
        AirdensityEditField       matlab.ui.control.NumericEditField
        FromEditFieldLabel        matlab.ui.control.Label
        AirdensityEditFieldLabel  matlab.ui.control.Label
        DisplayLabel              matlab.ui.control.Label
    end

    properties (Access = private)
        WindTurbine
        mainApp
        mode
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing the display of data on the tab

        function resetUI(comp)
            comp.FromEditField.Value = 0.0;
            comp.toEditField.Value = 30.0;
            comp.instepsofEditField.Value = 0.1;
            comp.SaveButton.Enable = "off";
            comp.DisplayLabel.Enable = "off";
            comp.DisplayDropDown.Enable = "off";
            comp.DisplayDropDown.ValueIndex = 1;
            cla(comp.UIAxes);
            xlim(comp.UIAxes, "auto");
            comp.mode = "project";

            comp.AirdensityEditFieldLabel.Visible = "on";
            comp.AirdensityEditField.Visible = "on";
            comp.kgm3Label.Visible = "on";
            comp.FromEditFieldLabel.Visible = "on";
            comp.FromEditField.Visible = "on";
            comp.msLabel.Visible = "on";
            comp.toEditFieldLabel.Visible = "on";
            comp.toEditField.Visible = "on";
            comp.msLabel_2.Visible = "on";
            comp.instepsofEditFieldLabel.Visible = "on";
            comp.instepsofEditField.Visible = "on";
            comp.msLabel_3.Visible = "on";
            comp.RunButton.Visible = "on";

            comp.SaveButton.Text = "Save";
            comp.ResultsfileLabel.Visible = "off";
            comp.ProjectLabel.Visible = "off";
            comp.ResultsFile.Visible = "off";
            comp.Project.Visible = "off";
            comp.ResultsFile.Text = '';
            comp.Project.Text = '';
            comp.ResultsFile.Tooltip = '';
            comp.Project.Tooltip = '';
        end

        function setResultsMode(comp)
            comp.mode = "results";
            comp.AirdensityEditFieldLabel.Visible = "off";
            comp.AirdensityEditField.Visible = "off";
            comp.kgm3Label.Visible = "off";
            comp.FromEditFieldLabel.Visible = "off";
            comp.FromEditField.Visible = "off";
            comp.msLabel.Visible = "off";
            comp.toEditFieldLabel.Visible = "off";
            comp.toEditField.Visible = "off";
            comp.msLabel_2.Visible = "off";
            comp.instepsofEditFieldLabel.Visible = "off";
            comp.instepsofEditField.Visible = "off";
            comp.msLabel_3.Visible = "off";
            comp.RunButton.Visible = "off";

            comp.SaveButton.Text = "Load";
            comp.SaveButton.Enable = "on";
            comp.ResultsfileLabel.Visible = "on";
            comp.ProjectLabel.Visible = "on";
            comp.ResultsFile.Visible = "on";
            comp.Project.Visible = "on";
        end
        
        function setSpecs(comp, Data)
            comp.AirdensityEditField.Value = Data.CertificationSettings.Wind.AirDensity;
        end
    end
    
    methods (Access = private)
        
        function plotSteadyOpAnalysis(comp)

            Data = comp.WindTurbine.SteadyOpResults;

            % Prepare x-axis (same for all curves)
            xlim(comp.UIAxes, [0 max(Data.windSpeed)]);

            switch comp.DisplayDropDown.Value
                case 'Power'
                    ylabel(comp.UIAxes, 'Power [W]');
                    plot(comp.UIAxes, Data.windSpeed,Data.electricalPower);
                    
                    % Find when power comes to within 0.2% of the rated
                    % power, to determine rated wind speed. Finding the
                    % first point where rated power is achieved or exceeded
                    % gets disturbed by small variations in power in full load
                    j = find(ceil(500*Data.electricalPower/Data.ratedPower)/500 >= 1);
                    if isempty(j)
                        rated = sprintf('Rated power = %3.2f MW (from control settings)\nRated power not reached in operational or analysed range', Data.ratedPower/1000000);
                    elseif j(1) == 1
                        ratedWindSpeed = Data.windSpeed(j(1));
                        rated = sprintf('Rated power = %3.2f MW\nRated wind speed = %3.1f m/s or below the analysed range', Data.ratedPower/1000000, ratedWindSpeed);
                    else
                        ratedWindSpeed = Data.windSpeed(j(1));
                        rated = sprintf('Rated power = %3.2f MW\nRated wind speed = %3.1f m/s', Data.ratedPower/1000000, ratedWindSpeed);
                    end
                    text(comp.UIAxes, 0.03, 0.95, rated, Units="normalized", VerticalAlignment="top", BackgroundColor="w", EdgeColor="k", LineWidth=1);
                case 'Thrust'
                    ylabel(comp.UIAxes, 'Thrust [N]');
                    plot(comp.UIAxes, Data.windSpeed,Data.thrust);
                case 'Torque'
                    ylabel(comp.UIAxes, 'Torque [Nm]');
                    plot(comp.UIAxes, Data.windSpeed,Data.torque);
                case 'Rotational speed'
                    ylabel(comp.UIAxes, 'Rotational speed [rpm]');
                    plot(comp.UIAxes, Data.windSpeed,Data.rotorSpeed);
                case 'Pitch angle'
                    ylabel(comp.UIAxes, 'Pitch angle [deg]');
                    plot(comp.UIAxes, Data.windSpeed,Data.pitch);
                case 'Tip speed ratio'
                    ylabel(comp.UIAxes, 'Tip speed ratio [-]');
                    plot(comp.UIAxes, Data.windSpeed,Data.tsr);
                case 'Power coefficient'
                    ylabel(comp.UIAxes, 'Power coefficient [-]');
                    plot(comp.UIAxes, Data.windSpeed,Data.cP);
                case 'Thrust coefficient'
                    ylabel(comp.UIAxes, 'Thrust coefficient [-]');
                    plot(comp.UIAxes, Data.windSpeed,Data.cT);
                case 'Torque coefficient'
                    ylabel(comp.UIAxes, 'Torque coefficient [-]');
                    plot(comp.UIAxes, Data.windSpeed,Data.cQ);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(comp, event)

            % Get wind speed range
            comp.WindTurbine.SteadyOpResults.windSpeed = comp.FromEditField.Value : comp.instepsofEditField.Value : comp.toEditField.Value;
            if isempty(comp.WindTurbine.SteadyOpResults.windSpeed)
                comp.WindTurbine.SteadyOpResults.windSpeed = comp.FromEditField.Value;
            end

            try
                showProgress(comp.mainApp, 'Computing steady operation performance', 'on');
                performSteadyOpAnalysis(comp.WindTurbine);
                endProgress(comp.mainApp);

                comp.SaveButton.Enable = "on";
                comp.DisplayLabel.Enable = "on";
                comp.DisplayDropDown.Enable = "on";
                plotSteadyOpAnalysis(comp);
            catch exception
                endProgress(comp.mainApp);
                uialert(comp.mainApp.FASTToolUIFigure, 'Steady-operation analysis resulted in an error - See Matlab Command Window for details', 'Info', Icon='info');
                rethrow(exception); % All necessary steps are completed before this call because 'rethrow' will terminate the function
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(comp, event)
            if strcmp(comp.mode, "project")
                saveMatfile(comp.mainApp, 'Save steady-operation results', 'steadyopanalysis');
            else
                if loadMatfile(comp.mainApp, 'Load steady-operation results', 'steadyopanalysis')
                    comp.ResultsFile.Text = comp.WindTurbine.SteadyOpResults.fileName;
                    comp.Project.Text = comp.WindTurbine.SteadyOpResults.Info.projectFile;
                    comp.ResultsFile.Tooltip = comp.WindTurbine.SteadyOpResults.fileName;
                    comp.Project.Tooltip = comp.WindTurbine.SteadyOpResults.Info.projectFile;
                    comp.DisplayLabel.Enable = "on";
                    comp.DisplayDropDown.Enable = "on";
                    plotSteadyOpAnalysis(comp);
                end
            end
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(comp, event)
            plotSteadyOpAnalysis(comp);            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.Panel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.Panel);
            comp.DisplayLabel = uilabel(comp.GridLayout2);
            comp.DisplayLabel.Text = 'Display';
            comp.AirdensityEditFieldLabel = uilabel(comp.GridLayout2);
            comp.AirdensityEditFieldLabel.Enable = 'off';
            comp.AirdensityEditFieldLabel.Text = 'Air density';
            comp.FromEditFieldLabel = uilabel(comp.GridLayout2);
            comp.FromEditFieldLabel.Text = 'Wind speed from';
            comp.AirdensityEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.AirdensityEditField.Limits = [0 Inf];
            comp.AirdensityEditField.Enable = 'off';
            comp.AirdensityEditField.Tooltip = {'Air density is set in tab ''Simulation'''};
            comp.kgm3Label = uilabel(comp.GridLayout2);
            comp.kgm3Label.Enable = 'off';
            comp.kgm3Label.Text = 'kg/m^3';
            comp.FromEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.FromEditField.Limits = [0 Inf];
            comp.msLabel = uilabel(comp.GridLayout2);
            comp.msLabel.Text = 'm/s';
            comp.toEditFieldLabel = uilabel(comp.GridLayout2);
            comp.toEditFieldLabel.Text = 'to';
            comp.toEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.toEditField.LowerLimitInclusive = 'off';
            comp.toEditField.Limits = [0 Inf];
            comp.toEditField.Value = 30;
            comp.msLabel_2 = uilabel(comp.GridLayout2);
            comp.msLabel_2.Text = 'm/s';
            comp.instepsofEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.instepsofEditField.Limits = [0 Inf];
            comp.instepsofEditField.Value = 0.1;
            comp.msLabel_3 = uilabel(comp.GridLayout2);
            comp.msLabel_3.Text = 'm/s';
            comp.instepsofEditFieldLabel = uilabel(comp.GridLayout2);
            comp.instepsofEditFieldLabel.Text = 'in steps of';
            comp.RunButton = uibutton(comp.GridLayout2, 'push');
            comp.RunButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunButtonPushed, true);
            comp.RunButton.Text = 'Run';
            comp.SaveButton = uibutton(comp.GridLayout2, 'push');
            comp.SaveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveButtonPushed, true);
            comp.SaveButton.Text = 'Save';
            comp.DisplayDropDown = uidropdown(comp.GridLayout2);
            comp.DisplayDropDown.Items = {'Power', 'Thrust', 'Torque', 'Rotational speed', 'Pitch angle', 'Tip speed ratio', 'Power coefficient', 'Thrust coefficient', 'Torque coefficient'};
            comp.DisplayDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DisplayDropDownValueChanged, true);
            comp.DisplayDropDown.Value = 'Power';
            comp.ProjectLabel = uilabel(comp.GridLayout2);
            comp.ProjectLabel.Text = 'Project';
            comp.Project = uilabel(comp.GridLayout2);
            comp.Project.Text = '';
            comp.ResultsfileLabel = uilabel(comp.GridLayout2);
            comp.ResultsfileLabel.Text = 'Results file';
            comp.ResultsFile = uilabel(comp.GridLayout2);
            comp.ResultsFile.Text = '';
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.PlotPanel);
            title(comp.UIAxes, 'Steady operation')
            xlabel(comp.UIAxes, 'Wind speed [m/s]')
        end
    end
end