classdef TowerSpecs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        RestoreButton                   matlab.ui.control.Button
        GridLayout1                     matlab.ui.container.GridLayout
        HubheightandmaterialPanel       matlab.ui.container.Panel
        mLabel                          matlab.ui.control.Label
        HubheightEditField              matlab.ui.control.NumericEditField
        kgmsup3supLabel                 matlab.ui.control.Label
        TowereffectivedensityEditField  matlab.ui.control.NumericEditField
        TowereffectivedensityLabel      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DimensionsPanel                 matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ImporttableButton               matlab.ui.control.Button
        ExporttableButton               matlab.ui.control.Button
        TowerTable                      matlab.ui.control.Table
    end

    properties (Access = private)
        WindTurbine
        mainApp
        specsChanged
        variableNames
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function setSpecs(comp, Data)
            comp.HubheightEditField.Value = Data.Tower.HubHeight;
            comp.TowereffectivedensityEditField.Value = Data.Tower.EffectiveDensity;
            setTowerTable(comp, Data);

            applyEdits(comp);
        end
        
    end
    
    methods (Access = private)
        
        %% Managing the display of data on the tab

        function setTowerTable(comp, Data)
            comp.TowerTable.ColumnFormat = {'numeric', 'numeric', 'numeric'};
            comp.TowerTable.Data = [...
                num2cell(Data.Tower.Height(:)), ...
                num2cell(Data.Tower.Diameter(:)), ...
                num2cell(Data.Tower.WallThickness(:))];
        end
        
        %% Extracting WindTurbine data from tab information

        function applyEdits(comp)
            comp.WindTurbine.Tower.HubHeight = comp.HubheightEditField.Value;
            comp.WindTurbine.Tower.EffectiveDensity = comp.TowereffectivedensityEditField.Value;
            applyTowerTable(comp);
            
            setRemainingTowerProperties(comp.WindTurbine);
        end

        function applyTowerTable(comp)
            T = comp.TowerTable.Data;
            endTable = length(T(:,1));
            comp.WindTurbine.Tower.Height = ones(endTable,1);
            comp.WindTurbine.Tower.Diameter = ones(endTable,1);
            comp.WindTurbine.Tower.WallThickness = ones(endTable,1);

            comp.WindTurbine.Tower.Height = cell2mat(T(:,1));
            comp.WindTurbine.Tower.Diameter = cell2mat(T(:,2));
            comp.WindTurbine.Tower.WallThickness = cell2mat(T(:,3));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.variableNames = ["Height [m]" "Diameter [m]" "Wall thickness [m]"];
        end

        % Value changed function: HubheightEditField, 
        % ...and 1 other component
        function TabDataChanged(comp, event)
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: ExporttableButton
        function ExporttableButtonPushed(comp, event)
            DataTable = cell2table(comp.TowerTable.Data, VariableNames = comp.variableNames);
            exportTable(comp.mainApp, DataTable, 'Export tower data');
        end

        % Button pushed function: ImporttableButton
        function ImporttableButtonPushed(comp, event)
            [DataTable] = importTable(comp.mainApp, 'Import tower data', 'towertable');
            if isempty(DataTable)
                return;
            end
            
            % Verify that data is within acceptable limits
            subinfo{1} = checkTableImport(comp.mainApp, DataTable, comp.variableNames, [1, 2, 3], [0, Inf]);
            info = sprintf('%s', subinfo{:});
            if ~isempty(info)
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end
            
            comp.TowerTable.Data = table2cell(DataTable(:, 1:3));
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSpecs(comp, Data);
            comp.specsChanged = false;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Cell edit callback: TowerTable
        function TowerTableCellEdit(comp, event)

            % Verify that data is within acceptable limits
            info = checkTableCellEdit(comp.mainApp, event.NewData, [0, Inf]);
            if ~isempty(info)
                comp.TowerTable.Data{event.Indices(1), event.Indices(2)} = event.PreviousData;
                uialert(comp.mainApp.FASTToolUIFigure, info, 'Info', Icon='info');
                return;
            end

            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout1 = uigridlayout(comp.GridLayout);
            comp.DimensionsPanel = uipanel(comp.GridLayout1);
            comp.DimensionsPanel.Title = 'Dimensions';
            comp.GridLayout11 = uigridlayout(comp.DimensionsPanel);
            comp.TowerTable = uitable(comp.GridLayout11);
            comp.TowerTable.ColumnName = {'Height [m]'; 'Diameter [m]'; 'Wall thickness [m]'};
            comp.TowerTable.RowName = {};
            comp.TowerTable.ColumnEditable = true;
            comp.TowerTable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @TowerTableCellEdit, true);
            comp.ExporttableButton = uibutton(comp.GridLayout11, 'push');
            comp.ExporttableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ExporttableButtonPushed, true);
            comp.ExporttableButton.Text = 'Export table';
            comp.ImporttableButton = uibutton(comp.GridLayout11, 'push');
            comp.ImporttableButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ImporttableButtonPushed, true);
            comp.ImporttableButton.Text = 'Import table';
            comp.HubheightandmaterialPanel = uipanel(comp.GridLayout1);
            comp.HubheightandmaterialPanel.Title = 'Hub height and material';
            comp.Image = uiimage(comp.HubheightandmaterialPanel);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'graphics', 'tower.png');
            comp.TowereffectivedensityLabel = uilabel(comp.HubheightandmaterialPanel);
            comp.TowereffectivedensityLabel.Text = 'Tower effective density';
            comp.TowereffectivedensityEditField = uieditfield(comp.HubheightandmaterialPanel, 'numeric');
            comp.TowereffectivedensityEditField.Limits = [0 Inf];
            comp.TowereffectivedensityEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.TowereffectivedensityEditField.Value = 8500;
            comp.kgmsup3supLabel = uilabel(comp.HubheightandmaterialPanel);
            comp.kgmsup3supLabel.Interpreter = 'html';
            comp.kgmsup3supLabel.Text = 'kg/m<sup>3</sup>';
            comp.HubheightEditField = uieditfield(comp.HubheightandmaterialPanel, 'numeric');
            comp.HubheightEditField.Limits = [0 Inf];
            comp.HubheightEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.mLabel = uilabel(comp.HubheightandmaterialPanel);
            comp.mLabel.Text = 'm';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.RestoreButton = uibutton(comp.GridLayout2, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreButton.Text = 'Restore';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end