classdef VisualAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                    matlab.ui.container.GridLayout
        SelectionPanel                matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        DisplayDropDownLabel          matlab.ui.control.Label
        AerofoilDropDownLabel         matlab.ui.control.Label
        DisplayDropDown               matlab.ui.control.DropDown
        AerofoilDropDown              matlab.ui.control.DropDown
        BestmatchwithlefttabCheckBox  matlab.ui.control.CheckBox
        PlotPanel                     matlab.ui.container.Panel
        NogainschedulingappliedLabel  matlab.ui.control.Label
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        WindTurbine
        itemsAerofoil
        itemsAll
        itemsControl1
        itemsControl2
        itemsRotor
        itemsTower
        plotArea (6,4) double % Area available for plots: [x y width height]
        selectedAerofoilPlot
        selectedAerofoilSpecs
        selectedControl2Plot
        selectedRotorPlot
        specsTab
    end
    
    
    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object
        
        function setHandles(comp, WindTurbine)
            comp.WindTurbine = WindTurbine;
        end

        %% Managing the display of data on the tab

        function resetUI(comp)
            comp.UIAxes1.Position = comp.plotArea(2,:);
            comp.UIAxes2.Position = comp.plotArea(3,:);
            comp.itemsRotor = {'Blade geometry', 'Blade mass', 'Blade stiffness'};
            comp.itemsAerofoil = {'Aerofoil geometry', 'Aerofoil polars'};
            comp.itemsTower = {'Tower'};
            comp.itemsControl1 = {'Torque control curve'};
            comp.itemsControl2 = {'Pitch: Kp, Ki and low-pass', 'Pitch: Notch filter 1', 'Pitch: Notch filter 2'};
            comp.itemsAll = [comp.itemsRotor, comp.itemsAerofoil, comp.itemsTower, comp.itemsControl1, comp.itemsControl2];
            comp.DisplayDropDown.Items = comp.itemsAll;
            comp.DisplayDropDown.ValueIndex = 1;
            comp.AerofoilDropDown.Items = {};
            comp.AerofoilDropDown.ValueIndex = [];
            comp.specsTab = "Rotor";
            comp.selectedRotorPlot = 'Blade geometry';
            comp.selectedAerofoilPlot = 'Aerofoil polars';
            comp.selectedControl2Plot = 'Pitch: Kp, Ki and low-pass';
            comp.selectedAerofoilSpecs = 1;
            comp.BestmatchwithlefttabCheckBox.Value = false;
            resetAxes(comp);
        end

        % FASTTool.setMatchAerofoilSelection and FASTTool.SpecsTabChanged
        % request to set the matching graph to be displayed on this tab in
        % case the user has chosen the option to show the best match. The
        % request from FASTTool.setMatchAerofoilSelection is done when the
        % aerofoil selection is changed on the tab Aerofoils and changes
        % the index of the aerofoil for which to show the matching plot.
        % The request from FASTTool.SpecsTabChanged is done when the
        % selection of the tab in the left-hand side panel changes and this
        % changes the property that indicates the tab for which to show the
        % matching plot.
        function setMatch(comp, match)
            if isnumeric(match)
                comp.selectedAerofoilSpecs = match;
            elseif ischar(match)
                comp.specsTab = match;
            end
        end

        function plotSpecs(comp)
            resetAxes(comp);

            comp.NogainschedulingappliedLabel.Visible = "off";
            ScheduledPitchAngles = comp.WindTurbine.Control.Pitch.ScheduledPitchAngles * 180 / pi;
            comp.AerofoilDropDown.Visible = "off";
            comp.AerofoilDropDownLabel.Visible = "off";

            if comp.BestmatchwithlefttabCheckBox.Value
                switch comp.specsTab
                    case "Rotor"
                        comp.DisplayDropDown.Items = comp.itemsRotor;
                        comp.DisplayDropDown.Value = comp.selectedRotorPlot;
                    case "Rotor*"
                        comp.DisplayDropDown.Items = comp.itemsRotor;
                        comp.DisplayDropDown.Value = comp.selectedRotorPlot;
                    case "Aerofoils"
                        comp.DisplayDropDown.Items = comp.itemsAerofoil;
                        comp.DisplayDropDown.Value = comp.selectedAerofoilPlot;
                        comp.AerofoilDropDown.Items = string(comp.WindTurbine.Airfoil.Name(comp.selectedAerofoilSpecs));
                        comp.AerofoilDropDown.ValueIndex = 1;
                    case "Aerofoils*"
                        comp.DisplayDropDown.Items = comp.itemsAerofoil;
                        comp.DisplayDropDown.Value = comp.selectedAerofoilPlot;
                        comp.AerofoilDropDown.Items = string(comp.WindTurbine.Airfoil.Name(comp.selectedAerofoilSpecs));
                        comp.AerofoilDropDown.ValueIndex = 1;
                    case "Tower"
                        comp.DisplayDropDown.Items = comp.itemsTower;
                        comp.DisplayDropDown.ValueIndex = 1;
                    case "Tower*"
                        comp.DisplayDropDown.Items = comp.itemsTower;
                        comp.DisplayDropDown.ValueIndex = 1;
                    case "Control 1"
                        comp.DisplayDropDown.Items = comp.itemsControl1;
                        comp.DisplayDropDown.ValueIndex = 1;
                    case "Control 1*"
                        comp.DisplayDropDown.Items = comp.itemsControl1;
                        comp.DisplayDropDown.ValueIndex = 1;
                    case "Control 2"
                        comp.DisplayDropDown.Items = comp.itemsControl2;
                        comp.DisplayDropDown.Value = comp.selectedControl2Plot;
                    case "Control 2*"
                        comp.DisplayDropDown.Items = comp.itemsControl2;
                        comp.DisplayDropDown.Value = comp.selectedControl2Plot;
                end
            else
                updateAerofoilDropDown(comp);
            end

            switch comp.DisplayDropDown.Value
                case 'Aerofoil geometry'
                    comp.AerofoilDropDown.Visible = "on";
                    comp.AerofoilDropDownLabel.Visible = "on";
                    if comp.BestmatchwithlefttabCheckBox.Value
                        i = comp.selectedAerofoilSpecs;
                    else
                        i = comp.AerofoilDropDown.ValueIndex;
                    end
                    comp.UIAxes1.Position = comp.plotArea(1,:);
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes1.Toolbar.Visible = "on";
                    comp.UIAxes1.XLim = [-0.1 1.1];
                    comp.UIAxes1.YLim = [-0.62 0.62];
                    hold(comp.UIAxes1, "on");
                    plot(comp.UIAxes1, [-0.1 1.1], [0 0], '--', Color = [0.5 0.5 0.5], LineWidth = 0.25);
                    patch(comp.UIAxes1, comp.WindTurbine.Airfoil.Geometry{i}(1,:), comp.WindTurbine.Airfoil.Geometry{i}(2,:), [0 165, 213]/255, EdgeColor = 0.5*[0 165, 213]/255, LineWidth = 1);
                    hold(comp.UIAxes1, "off");
                    comp.UIAxes1.Title.String = "Aerofoil shape";
                case 'Aerofoil polars'
                    comp.AerofoilDropDown.Visible = "on";
                    comp.AerofoilDropDownLabel.Visible = "on";
                    if comp.BestmatchwithlefttabCheckBox.Value
                        i = comp.selectedAerofoilSpecs;
                    else
                        i = comp.AerofoilDropDown.ValueIndex;
                    end
                    comp.UIAxes1.Position = comp.plotArea(2,:);
                    comp.UIAxes2.Position = comp.plotArea(3,:);
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes2.Visible = "on";
                    comp.UIAxes1.Toolbar.Visible = "on";
                    comp.UIAxes2.Toolbar.Visible = "on";
                    hold(comp.UIAxes1, "on");
                    comp.UIAxes1.XLim = [-180 180];
                    plot(comp.UIAxes1, [-180 180], [0 0], '--', Color = [0.5 0.5 0.5], LineWidth = 0.25);
                    Cl_plot = plot(comp.UIAxes1, comp.WindTurbine.Airfoil.Alpha{i}, comp.WindTurbine.Airfoil.Cl{i});
                    Cd_plot = plot(comp.UIAxes1, comp.WindTurbine.Airfoil.Alpha{i}, comp.WindTurbine.Airfoil.Cd{i});
                    Cm_plot = plot(comp.UIAxes1, comp.WindTurbine.Airfoil.Alpha{i}, comp.WindTurbine.Airfoil.Cm{i});
                    hold(comp.UIAxes1, "off");
                    hold(comp.UIAxes2, "on");
                    Cl = comp.WindTurbine.Airfoil.Cl{i}(comp.WindTurbine.Airfoil.Alpha{i} > -30);
                    Cd = comp.WindTurbine.Airfoil.Cd{i}(comp.WindTurbine.Airfoil.Alpha{i} > -30);
                    alpha = comp.WindTurbine.Airfoil.Alpha{i}(comp.WindTurbine.Airfoil.Alpha{i} > -30);
                    Cl = Cl(alpha < 30);
                    Cd = Cd(alpha < 30);
                    comp.UIAxes2.XLim = [0 0.025];
                    plot(comp.UIAxes2, [0 0.025], [0 0], '--', Color = [0.5 0.5 0.5], LineWidth = 0.25);
                    plot(comp.UIAxes2, Cd, Cl);
                    hold(comp.UIAxes2, "off");
                    comp.UIAxes1.Title.String = "Aerofoil polars";
                    ylabel(comp.UIAxes1, "[-]");
                    xlabel(comp.UIAxes1, "Angle of attack [deg]");
                    legend(comp.UIAxes1, [Cl_plot, Cd_plot, Cm_plot], {'Cl'; 'Cd'; 'Cm'}, Location = 'Best', Color = 'none', Box = 'off');
                    ylabel(comp.UIAxes2, "c_l [-]");
                    xlabel(comp.UIAxes2, "c_d [-]");
                case 'Blade geometry'
                    comp.UIAxes1.Position = comp.plotArea(2,:);
                    comp.UIAxes2.Position = comp.plotArea(3,:);
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes2.Visible = "on";
                    comp.UIAxes1.Toolbar.Visible = "on";
                    comp.UIAxes2.Toolbar.Visible = "on";
                    plot(comp.UIAxes1, comp.WindTurbine.Blade.Radius, comp.WindTurbine.Blade.Chord);
                    plot(comp.UIAxes2, comp.WindTurbine.Blade.Radius, comp.WindTurbine.Blade.Twist);
                    comp.UIAxes1.Title.String = "Blade geometry";
                    ylabel(comp.UIAxes1, "Chord [m]");
                    ylabel(comp.UIAxes2, "Twist [deg]");
                    xlabel(comp.UIAxes2, "Radius [m]");
                    diameter = sprintf('Rotor diameter = %4.1f m', 2*comp.WindTurbine.Blade.Radius(end));
                    text(comp.UIAxes1, 0.97, 0.95, diameter, Units="normalized", VerticalAlignment="top", HorizontalAlignment="right", BackgroundColor="w", EdgeColor="k", LineWidth=1);
                case 'Blade mass'
                    comp.UIAxes1.Position = comp.plotArea(1,:);
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes1.Toolbar.Visible = "on";
                    plot(comp.UIAxes1, comp.WindTurbine.Blade.Radius, comp.WindTurbine.Blade.Mass);
                    comp.UIAxes1.Title.String = "Blade mass density";
                    ylabel(comp.UIAxes1, "Mass density [kg/m]");
                    xlabel(comp.UIAxes1, "Radius [m]");
                    masses = sprintf('Blade mass = %2.0f t\nRotor mass (blades + hub) = %2.0f t', ...
                        trapz(comp.WindTurbine.Blade.Radius,comp.WindTurbine.Blade.Mass)/1e3, ...
                        comp.WindTurbine.Blade.Number*trapz(comp.WindTurbine.Blade.Radius,comp.WindTurbine.Blade.Mass)/1e3 + comp.WindTurbine.Nacelle.Hub.Mass/1e3);
                    text(comp.UIAxes1, 0.97, 0.95, masses, Units="normalized", VerticalAlignment="top", HorizontalAlignment="right", BackgroundColor="w", EdgeColor="k", LineWidth=1);
                case 'Blade stiffness'
                    comp.UIAxes1.Position = comp.plotArea(2,:);
                    comp.UIAxes2.Position = comp.plotArea(3,:);
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes2.Visible = "on";
                    comp.UIAxes1.Toolbar.Visible = "on";
                    comp.UIAxes2.Toolbar.Visible = "on";
                    plot(comp.UIAxes1, comp.WindTurbine.Blade.Radius, comp.WindTurbine.Blade.EIflap);
                    plot(comp.UIAxes2, comp.WindTurbine.Blade.Radius, comp.WindTurbine.Blade.EIedge);
                    comp.UIAxes1.Title.String = "Blade stiffness";
                    ylabel(comp.UIAxes1, "Flap. stiff. [N m^2]");
                    ylabel(comp.UIAxes2, "Edge. stiff. [N m^2]");
                    xlabel(comp.UIAxes2, "Radius [m]");
                case 'Tower'
                    comp.UIAxes1.Position = comp.plotArea(2,:);
                    comp.UIAxes2.Position = comp.plotArea(3,:);
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes2.Visible = "on";
                    comp.UIAxes1.Toolbar.Visible = "on";
                    comp.UIAxes2.Toolbar.Visible = "on";
                    plot(comp.UIAxes1, comp.WindTurbine.Tower.Height, comp.WindTurbine.Tower.Diameter);
                    plot(comp.UIAxes2, comp.WindTurbine.Tower.Height, comp.WindTurbine.Tower.WallThickness);
                    comp.UIAxes1.Title.String = "Tower dimensions";
                    ylabel(comp.UIAxes1, "Diameter [m]");
                    ylabel(comp.UIAxes2, "WallThickness [m]");
                    xlabel(comp.UIAxes2, "Height [m]");
                    mass = sprintf('Tower mass = %2.0f t', trapz(comp.WindTurbine.Tower.Height,comp.WindTurbine.Tower.Mass)/1e3);
                    text(comp.UIAxes1, 0.97, 0.95, mass, Units="normalized", VerticalAlignment="top", HorizontalAlignment="right", BackgroundColor="w", EdgeColor="k", LineWidth=1);
                case 'Torque control curve'
                    comp.UIAxes1.Position = comp.plotArea(1,:);
                    comp.UIAxes1.Toolbar.Visible = "on";
                    comp.UIAxes1.Visible = "on";
                    comp.UIAxes1.Title.String = "Torque control curve";
                    ylabel(comp.UIAxes1, "HSS torque [Nm]");
                    xlabel(comp.UIAxes1, "HSS rotational speed [rpm]");
                    OmegaA = comp.WindTurbine.Control.Torque.SpeedA;
                    OmegaB = comp.WindTurbine.Control.Torque.SpeedB;
                    OmegaC = comp.WindTurbine.Control.Torque.SpeedB2;
                    OmegaD = comp.WindTurbine.Control.Torque.SpeedC;
                    OmegaRegion2 = [OmegaB + 0:((OmegaC-OmegaB)/50):OmegaC, OmegaC];
                    TorqueRegion2 = comp.WindTurbine.Control.Torque.OptGain * (2*pi*OmegaRegion2/60).^2;
                    TorqueA = 0;
                    TorqueD = comp.WindTurbine.Control.Torque.Demanded;
                    Omega = [OmegaA, OmegaRegion2, OmegaD];
                    Torque = [TorqueA, TorqueRegion2, TorqueD];
                    OmegaIdeal = [0:(max(Omega)/50):1.1*max(Omega), 1.1*max(Omega)];
                    TorqueIdeal = comp.WindTurbine.Control.Torque.OptGain * (2*pi*OmegaIdeal/60).^2;
                    plot(comp.UIAxes1, OmegaIdeal, TorqueIdeal,'--');
                    hold(comp.UIAxes1, "on");
                    plot(comp.UIAxes1, Omega, Torque);
                    rated = sprintf('Rated power = %3.2f MW', comp.WindTurbine.SteadyOpResults.ratedPower/1000000);
                    text(comp.UIAxes1, 0.03, 0.95, rated, Units="normalized", VerticalAlignment="top", BackgroundColor="w", EdgeColor="k", LineWidth=1);
                    textString = sprintf('A\n%s\n', '\downarrow');
                    text(comp.UIAxes1, OmegaA, 0, textString, HorizontalAlignment="center", VerticalAlignment="bottom");
                    text(comp.UIAxes1, OmegaB, TorqueRegion2(1), 'B \rightarrow ', HorizontalAlignment="right", VerticalAlignment="middle");
                    text(comp.UIAxes1, OmegaC, TorqueRegion2(end), 'C \rightarrow ', HorizontalAlignment="right", VerticalAlignment="middle");
                    text(comp.UIAxes1, OmegaD, TorqueD, 'D \rightarrow ', HorizontalAlignment="right", VerticalAlignment="middle");
                    hold(comp.UIAxes1, "off");
                case 'Pitch: Kp, Ki and low-pass'
                    if comp.WindTurbine.Control.Pitch.Scheduled
                        comp.UIAxes1.Position = comp.plotArea(4,:);
                        comp.UIAxes2.Position = comp.plotArea(5,:);
                        comp.UIAxes3.Position = comp.plotArea(6,:);
                        comp.UIAxes1.Visible = "on";
                        comp.UIAxes2.Visible = "on";
                        comp.UIAxes3.Visible = "on";
                        comp.UIAxes1.Toolbar.Visible = "on";
                        comp.UIAxes2.Toolbar.Visible = "on";
                        comp.UIAxes3.Toolbar.Visible = "on";
                        plot(comp.UIAxes1, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.KpGS);
                        plot(comp.UIAxes2, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.KiGS);
                        plot(comp.UIAxes3, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.LowPassCutOffFreqGS);
                        comp.UIAxes1.Title.String = "Gains of PI controller and low-pass filter cut-off frequency";
                        ylabel(comp.UIAxes1, "Proportional gain Kp [-]");
                        ylabel(comp.UIAxes2, "Integral gain Ki [-]");
                        ylabel(comp.UIAxes3, "Cut-off frequency [rad/s]");
                        xlabel(comp.UIAxes3, "Pitch angle for scheduling [deg]");
                    else
                        comp.NogainschedulingappliedLabel.Visible = "on";
                    end
                case 'Pitch: Notch filter 1'
                    if comp.WindTurbine.Control.Pitch.Scheduled
                        comp.UIAxes1.Position = comp.plotArea(4,:);
                        comp.UIAxes2.Position = comp.plotArea(5,:);
                        comp.UIAxes3.Position = comp.plotArea(6,:);
                        comp.UIAxes1.Visible = "on";
                        comp.UIAxes2.Visible = "on";
                        comp.UIAxes3.Visible = "on";
                        comp.UIAxes1.Toolbar.Visible = "on";
                        comp.UIAxes2.Toolbar.Visible = "on";
                        comp.UIAxes3.Toolbar.Visible = "on";
                        plot(comp.UIAxes1, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.Notch_beta1GS);
                        plot(comp.UIAxes2, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.Notch_beta2GS);
                        plot(comp.UIAxes3, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.Notch_wnGS);
                        comp.UIAxes1.Title.String = "Settings notch filter 1";
                        ylabel(comp.UIAxes1, "β1 [rad/s]");
                        ylabel(comp.UIAxes2, "β2 [rad/s]");
                        ylabel(comp.UIAxes3, "ωn [rad/s]");
                        xlabel(comp.UIAxes3, "Pitch angle for scheduling [deg]");
                    else
                        comp.NogainschedulingappliedLabel.Visible = "on";
                    end
                case 'Pitch: Notch filter 2'
                    if comp.WindTurbine.Control.Pitch.Scheduled
                        comp.UIAxes1.Position = comp.plotArea(4,:);
                        comp.UIAxes2.Position = comp.plotArea(5,:);
                        comp.UIAxes3.Position = comp.plotArea(6,:);
                        comp.UIAxes1.Visible = "on";
                        comp.UIAxes2.Visible = "on";
                        comp.UIAxes3.Visible = "on";
                        comp.UIAxes1.Toolbar.Visible = "on";
                        comp.UIAxes2.Toolbar.Visible = "on";
                        comp.UIAxes3.Toolbar.Visible = "on";
                        plot(comp.UIAxes1, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.Notch2_beta1GS);
                        plot(comp.UIAxes2, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.Notch2_beta2GS);
                        plot(comp.UIAxes3, ScheduledPitchAngles, comp.WindTurbine.Control.Pitch.Notch2_wnGS);
                        comp.UIAxes1.Title.String = "Settings notch filter 2";
                        ylabel(comp.UIAxes1, "β1 [rad/s]");
                        ylabel(comp.UIAxes2, "β2 [rad/s]");
                        ylabel(comp.UIAxes3, "ωn [rad/s]");
                        xlabel(comp.UIAxes3, "Pitch angle for scheduling [deg]");
                    else
                        comp.NogainschedulingappliedLabel.Visible = "on";
                    end
            end
        end
        
    end
    
    methods (Access = private)
        
        function updateAerofoilDropDown(comp)
            currentAerofoil = comp.AerofoilDropDown.Value;
            comp.AerofoilDropDown.Items = string(comp.WindTurbine.Airfoil.Name);
            comp.AerofoilDropDown.ValueIndex = 1;
            if ~isempty(currentAerofoil) % The first time after a UI reset, no aerofoil is selected
                if sum(strcmp(comp.AerofoilDropDown.Items, currentAerofoil))
                    comp.AerofoilDropDown.Value = currentAerofoil;
                end
            end
        end
        
        function resetAxes(comp)
            cla(comp.UIAxes1);
            cla(comp.UIAxes2);
            cla(comp.UIAxes3);
            comp.UIAxes1.XLimMode = "auto";
            comp.UIAxes2.XLimMode = "auto";
            comp.UIAxes3.XLimMode = "auto";
            comp.UIAxes1.YLimMode = "auto";
            comp.UIAxes2.YLimMode = "auto";
            comp.UIAxes3.YLimMode = "auto";
            comp.UIAxes1.Visible = "off";
            comp.UIAxes2.Visible = "off";
            comp.UIAxes3.Visible = "off";
            comp.UIAxes1.Toolbar.Visible = "off";
            comp.UIAxes2.Toolbar.Visible = "off";
            comp.UIAxes3.Toolbar.Visible = "off";
            comp.UIAxes1.Title.String = "";
            ylabel(comp.UIAxes1, "");
            xlabel(comp.UIAxes1, "");
            comp.UIAxes2.Title.String = "";
            ylabel(comp.UIAxes2, "");
            xlabel(comp.UIAxes2, "");
            comp.UIAxes3.Title.String = "";
            ylabel(comp.UIAxes3, "");
            xlabel(comp.UIAxes3, "");
            legend(comp.UIAxes1, "off");
            legend(comp.UIAxes2, "off");
            legend(comp.UIAxes3, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % Set UIAxes positions, indexing then according to:
            % 1 = Single plot
            % 2 = Highest of 2 plots            
            % 3 = Lowest of 2 plots
            % 4 = Highest of 3 plots
            % 5 = Middle of 3 plots
            % 6 = Lowest of 3 plots
            plotHeight1 = comp.UIAxes1.Position(2) + comp.UIAxes1.Position(4) - comp.UIAxes3.Position(2);
            plotHeight2 = floor(plotHeight1 / 2) - 5;
            plotHeight3 = floor(plotHeight1 / 3) - 7;
            plotWidth = comp.UIAxes1.Position(3);
            plotCorner = comp.UIAxes3.Position(1:2);

            comp.plotArea(:,3) = ones(6,1)*plotWidth;

            comp.plotArea(1,1:2) = plotCorner;
            comp.plotArea(1,4) = plotHeight1;

            comp.plotArea(2,1:2) = plotCorner + [0 (plotHeight2 + 10)];
            comp.plotArea(2,4) = plotHeight2;
            comp.plotArea(3,1:2) = plotCorner;
            comp.plotArea(3,4) = plotHeight2;

            comp.plotArea(4,1:2) = plotCorner + [0 (2*plotHeight3 + 20)];
            comp.plotArea(4,4) = plotHeight3;
            comp.plotArea(5,1:2) = plotCorner + [0 (plotHeight3 + 10)];
            comp.plotArea(5,4) = plotHeight3;
            comp.plotArea(6,1:2) = plotCorner;
            comp.plotArea(6,4) = plotHeight3;

        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(comp, event)
            if comp.BestmatchwithlefttabCheckBox.Value
                switch comp.specsTab
                    case "Rotor"
                        comp.selectedRotorPlot = comp.DisplayDropDown.Value;
                    case "Rotor*"
                        comp.selectedRotorPlot = comp.DisplayDropDown.Value;
                    case "Aerofoils"
                        comp.selectedAerofoilPlot = comp.DisplayDropDown.Value;
                    case "Aerofoils*"
                        comp.selectedAerofoilPlot = comp.DisplayDropDown.Value;
                    case "Control 2"
                        comp.selectedControl2Plot = comp.DisplayDropDown.Value;
                    case "Control 2*"
                        comp.selectedControl2Plot = comp.DisplayDropDown.Value;
                end
            end
            plotSpecs(comp);            
        end

        % Value changed function: AerofoilDropDown
        function AerofoilDropDownValueChanged(comp, event)
            plotSpecs(comp);
        end

        % Value changed function: BestmatchwithlefttabCheckBox
        function BestmatchwithlefttabCheckBoxValueChanged(comp, event)
            if comp.BestmatchwithlefttabCheckBox.Value
                plotSpecs(comp);
            else
                currentPlot = comp.DisplayDropDown.Value;
                comp.DisplayDropDown.Items = comp.itemsAll;
                comp.DisplayDropDown.Value = currentPlot;
                updateAerofoilDropDown(comp);
                comp.selectedRotorPlot = 'Blade geometry';
                comp.selectedAerofoilPlot = 'Aerofoil polars';
                comp.selectedControl2Plot = 'Pitch: Kp, Ki and low-pass';
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.UIAxes3 = uiaxes(comp.PlotPanel);
            comp.UIAxes2 = uiaxes(comp.PlotPanel);
            comp.UIAxes1 = uiaxes(comp.PlotPanel);
            comp.NogainschedulingappliedLabel = uilabel(comp.PlotPanel);
            comp.NogainschedulingappliedLabel.Visible = 'off';
            comp.NogainschedulingappliedLabel.Text = 'No gain scheduling applied';
            comp.SelectionPanel = uipanel(comp.GridLayout);
            comp.GridLayout2 = uigridlayout(comp.SelectionPanel);
            comp.BestmatchwithlefttabCheckBox = uicheckbox(comp.GridLayout2);
            comp.BestmatchwithlefttabCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @BestmatchwithlefttabCheckBoxValueChanged, true);
            comp.BestmatchwithlefttabCheckBox.Text = 'Best match with left tab';
            comp.AerofoilDropDown = uidropdown(comp.GridLayout2);
            comp.AerofoilDropDown.Items = {};
            comp.AerofoilDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @AerofoilDropDownValueChanged, true);
            comp.AerofoilDropDown.Visible = 'off';
            comp.AerofoilDropDown.Value = {};
            comp.DisplayDropDown = uidropdown(comp.GridLayout2);
            comp.DisplayDropDown.Items = {};
            comp.DisplayDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DisplayDropDownValueChanged, true);
            comp.DisplayDropDown.Value = {};
            comp.AerofoilDropDownLabel = uilabel(comp.GridLayout2);
            comp.AerofoilDropDownLabel.Visible = 'off';
            comp.AerofoilDropDownLabel.Text = 'Aerofoil';
            comp.DisplayDropDownLabel = uilabel(comp.GridLayout2);
            comp.DisplayDropDownLabel.Text = 'Display';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end