classdef VisualAnalysis3D < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout           matlab.ui.container.GridLayout
        GridLayout2          matlab.ui.container.GridLayout
        SelectionPanel       matlab.ui.container.Panel
        GridLayout22         matlab.ui.container.GridLayout
        DisplayLabel         matlab.ui.control.Label
        DisplayDropDown      matlab.ui.control.DropDown
        SitingPanel          matlab.ui.container.Panel
        GridLayout21         matlab.ui.container.GridLayout
        RestoreButton        matlab.ui.control.Button
        SiteLabel            matlab.ui.control.Label
        ColourLabel          matlab.ui.control.Label
        StyleLabel           matlab.ui.control.Label
        TowerLabel           matlab.ui.control.Label
        BladeLabel           matlab.ui.control.Label
        SiteDropDown         matlab.ui.control.DropDown
        TowerColourDropDown  matlab.ui.control.DropDown
        TowerStyleDropDown   matlab.ui.control.DropDown
        BladeColourDropDown  matlab.ui.control.DropDown
        BladeStyleDropDown   matlab.ui.control.DropDown
        PlotPanel            matlab.ui.container.Panel
        UndockButton         matlab.ui.control.Button
        WindstreaksCheckBox  matlab.ui.control.CheckBox
        AnimateButton        matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Colour % Struct with values for different colours
        ColourArray
        Lighting
        WindTurbine
        animationTimer
        azimuth
        hasFocus
        mainApp
        previousDisplayDropDown
        previousTimerVal
        specsChanged
        undock
    end
    

    methods (Access = public)

        %% Managing access to the main figure (FASTTool) and the WindTurbine object

        function setHandles(comp, WindTurbine, app)
            comp.WindTurbine = WindTurbine;
            comp.mainApp = app;
        end

        %% Managing states and notifications (*) of changed data

        function resetChangeState(comp)
            comp.specsChanged = false;
        end

        function state = getChangeState(comp)
            state = comp.specsChanged;
        end

        %% Managing the display and extraction of data on the tab

        function resetUI(comp)
            comp.DisplayDropDown.ValueIndex = 1;
            comp.previousDisplayDropDown = 'Wind turbine';
            stop(comp.animationTimer);
            comp.AnimateButton.Text = "Animate";
            comp.azimuth = 0;
            comp.undock = false;
        end

        function setSpecs(comp, Data)
            comp.SiteDropDown.ValueIndex = Data.Appearance(1);
            comp.BladeStyleDropDown.ValueIndex = Data.Appearance(2);
            comp.BladeColourDropDown.ValueIndex = Data.Appearance(3);
            comp.TowerStyleDropDown.ValueIndex = Data.Appearance(4);
            comp.TowerColourDropDown.ValueIndex = Data.Appearance(5);

            applyEdits(comp)
        end

        function updateFocusStatus(comp, title)
            if strcmp(title, "3D") || strcmp(title, "3D*")
                comp.hasFocus = true;
            else
                comp.hasFocus = false;
            end
        end

        function updateRotation(comp)
            if ~(comp.hasFocus && strcmp(comp.DisplayDropDown.Value, "Wind turbine"))
                stop(comp.animationTimer);
                return;
            end

            % 'try' is used to avoid errors when this function is triggered
            % after the number of blades has been changed, but before the
            % plot has been (fully) updated with 'plotSpecs'. In such cases
            % the indices of the parts in the plot are not determined
            % correctly.
            try
                Tower = comp.WindTurbine.Tower;
                Nacelle = comp.WindTurbine.Nacelle;
                Blade = comp.WindTurbine.Blade;
                Control = comp.WindTurbine.Control;
                Drivetrain = comp.WindTurbine.Drivetrain;

                % Rotating parts
                if Blade.Number == 1
                    NParts = 3 + 1;
                else
                    NParts = Blade.Number + 1;
                end

                % Rotation matrices
                o = toc(comp.previousTimerVal) * Control.Torque.SpeedC * 2 * pi / (60 * Drivetrain.Gearbox.Ratio);
                comp.azimuth = comp.azimuth + o;
                comp.previousTimerVal = tic;
                t = Nacelle.Hub.ShaftTilt*pi/180;
                R = ...
                    [cos(t),  0,       sin(t); ...
                    0,       1,       0; ...
                    -sin(t),  0,       cos(t)] * ...
                    [1,       0,       0; ...
                    0,       cos(o), -sin(o); ...
                    0,       sin(o),  cos(o)] * ...
                    [cos(-t), 0,       sin(-t); ...
                    0,       1,       0; ...
                    -sin(-t), 0,       cos(-t)];

                Parts = get(comp.UIAxes, 'Children');
                for i = length(Parts)-2:-1:length(Parts)-11
                    streak = Parts(i);
                    set(streak,'Visible','off');
                end

                % Rotate blades
                for i = 1:NParts
                    Part = Parts(i);
                    N = size(get(Part,'XData'));
                    A = [...
                        reshape(get(Part,'XData'),1,[]); ...
                        reshape(get(Part,'YData'),1,[]); ...
                        reshape(get(Part,'ZData'),1,[]) - Tower.HubHeight];
                    A = R*A;
                    set(Part,...
                        'XData', reshape(A(1,:),N), ...
                        'YData', reshape(A(2,:),N), ...
                        'ZData', reshape(A(3,:),N) + Tower.HubHeight);
                end

                % Animate wind streaks
                if comp.WindstreaksCheckBox.Value
                    for i = length(Parts)-2:-1:length(Parts)-11
                        streak = Parts(i);
                        x = get(streak,'XData');
                        y = get(streak,'YData');
                        z = get(streak,'ZData');
                        s = 0.16*(0.75*10.*(z(end)/Tower.HubHeight).^0.25 + 5.6);
                        x = [x(2:end), x(end)+1.0*s*randn(1)+0.25*10*(z(end)/Tower.HubHeight)^0.25];
                        y = [y(2:end), y(end)+0.8*s*randn(1)];
                        z = [z(2:end), z(end)+0.5*s*randn(1)];
                        if x(end) > 50 || z(end) < 0
                            x = 0*x + (-2:0.5:2) .* 10.*(z/Tower.HubHeight).^0.25 - 50;
                            y = 0*y + (rand(1)-0.5)*2*Blade.Radius(end);
                            z = 0*z + (Tower.HubHeight + Blade.Radius(end))*rand(1);
                        end
                        set(streak,...
                            'XData', real(x), ...
                            'YData', real(y), ...
                            'ZData', real(z), ...
                            'Visible', 'on');
                    end
                end

                drawnow;
            catch
            end
        end

        function stopAnimation(comp)
            stop(comp.animationTimer);
        end
        
        function plotSpecs(comp)

            % Set turbine appearance
            Landscape = comp.WindTurbine.Appearance(1);
            BladeStyle = comp.WindTurbine.Appearance(2);
            BladeColour = comp.WindTurbine.Appearance(3);
            TowerStyle = comp.WindTurbine.Appearance(4);
            TowerColour = comp.WindTurbine.Appearance(5);
            BladePaint = comp.ColourArray(BladeColour,:);
            TowerPaint = comp.ColourArray(TowerColour,:);

            Tower = comp.WindTurbine.Tower;
            Nacelle = comp.WindTurbine.Nacelle;
            Blade = comp.WindTurbine.Blade;
            Airfoil = comp.WindTurbine.Airfoil;
            Control = comp.WindTurbine.Control;

            % Set axis
            if comp.undock
                comp.undock = false;
                plotFigure = figure();
                set(plotFigure, 'Name', 'Wind turbine plot')
                set(plotFigure, 'Name', 'Blade plot')
                set(plotFigure, 'Name', 'Nacelle plot')
                set(plotFigure, 'Name', 'Tower plot')
                axis off;
                plotAxes = plotFigure.CurrentAxes;
                % Set background when making FASTTool_image for App Details:
                % set(gcf, 'color', [0.8 0.8 0.8])
           else
               plotAxes = comp.UIAxes;
            end

            cla(plotAxes);

            view(plotAxes, -45,180/pi*atan(sin(pi/4)));
            light(plotAxes);
            lightangle(plotAxes, 0, 45);
            axis(plotAxes, "equal");
            hold(plotAxes, "on");

            switch comp.DisplayDropDown.Value
                case 'Wind turbine'

                    rotate3d(plotAxes, "on");

                    % Plot wind streaks
                    rng('shuffle');
                    t = -2:0.5:2;
                    N = 10;
                    for i = 1:N
                        y = (rand(1)-0.5)*2*Blade.Radius(end);
                        z = (Tower.HubHeight + Blade.Radius(end))*rand(1);
                        U = 10*(z/Tower.HubHeight)^0.25;
                        if comp.WindstreaksCheckBox.Value
                            plot3(plotAxes, U*t + 60*(rand(1)-0.5), 0*t+y, 0*t+z, "white", LineWidth = 0.25, Visible = "on");
                        else
                            plot3(plotAxes, U*t + 60*(rand(1)-0.5), 0*t+y, 0*t+z, "white", LineWidth = 0.25, Visible = "off");
                        end
                    end

                    % Plot landscape
                    switch Landscape
                        case 1 % Grassland

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Patch of grass
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [-2 -2 -2 -2 0 0 0 0]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [-5 -5 -5 -5 -2 -2 -2 -2]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            R = interp1(Tower.Height, Tower.Diameter/2, 2);
                            [x,y,z] = cylinder(R + [1,1,0],N);
                            z = 2*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [-5 Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 2 % Rough pasture

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Patch of grass
                            [x,y] = ndgrid(...
                                linspace(-L/2, L/2, 10), ...
                                linspace(-L/2, L/2, 10));
                            z = 5 * cos(x * pi/L) .* cos(y * pi/L);
                            for n = 1:5
                                i = randi(size(x,1));
                                j = randi(size(y,2));
                                z = z + randn(1) * cos((x-x(i,j)) * pi/L) .* cos((y-y(i,j)) * pi/L);
                            end
                            z = z - min(z(:));
                            base = z(sqrt(x.^2 + y.^2) <= Tower.Diameter(1));
                            if ~isempty(base)
                                z = z * 5/min(base(:));
                            end
                            z = z - 5;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:)-2, flip(z(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1)-2; flip(z(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:)-2, flip(z(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end)-2; flip(z(:,end))], ...
                                'g', FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            z_ = 0*z + min(z(:)) - 3;
                            surf(plotAxes, x,y,z_, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:)-2, flip(z_(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1)-2; flip(z_(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:)-2, flip(z_(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end)-2; flip(z_(:,end))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Scattered bushes
                            width = 2;
                            height = 2;
                            clearance = 5;
                            X = x;
                            Y = y;
                            Z = z;
                            for i = 2:size(X,1)-1
                                for j = 2:size(Y,2)-1

                                    draw = randi(2);
                                    if draw == 1
                                        if (sqrt(X(i,j)^2 + Y(i,j)^2) > Tower.Diameter/2 + clearance)

                                            [x,y,z] = sphere(8);
                                            x = X(i,j) + width*x + (rand(1)-0.5)*10 + 0.25*randn(size(x));
                                            y = Y(i,j) + width*y + (rand(1)-0.5)*10 + 0.25*randn(size(y));
                                            z = Z(i,j) + height*z + 0.15*height + 0.25*randn(size(z));
                                            TreeColor = comp.Colour.Green + 10/255*randn([1,3]);
                                            TreeColor(TreeColor < 0) = 0;
                                            TreeColor(TreeColor > 1) = 1;
                                            surf(plotAxes, x,y,z, ...
                                                FaceColor = TreeColor, ...
                                                EdgeColor = comp.Colour.EdgeColour, ...
                                                AmbientStrength = comp.Lighting.Nature(1), ...
                                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                                SpecularStrength = comp.Lighting.Nature(3), ...
                                                BackFaceLighting = "reverselit");

                                        end
                                    end
                                end
                            end

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            R = interp1(Tower.Height, Tower.Diameter/2, 2);
                            [x,y,z] = cylinder(R + [1,1,0],N);
                            z = 2*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [min(z_(:)),  Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 3 % Forest

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Patch of grass
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [-2 -2 -2 -2 0 0 0 0]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Green*0.9, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [-5 -5 -5 -5 -2 -2 -2 -2]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Trees
                            if comp.undock
                                N = 24;
                            else
                                N = 6;
                            end
                            width = 5;
                            height = 30;
                            clearance = 7.5;
                            [X,Y] = ndgrid(...
                                -(L/2-width/2):clearance:(L/2-width/2), ...
                                -(L/2-width/2):clearance:(L/2-width/2));
                            for i = 1:size(X,1)
                                for j = 1:size(Y,2)

                                    if sqrt(X(i,j)^2 + Y(i,j)^2) > Tower.Diameter/2 + clearance
                                        [x,y,z] = cylinder(0.5,4);
                                        dX = (rand(1) - 0.5) * width*3/4;
                                        dY = (rand(1) - 0.5) * width*3/4;
                                        x = x + X(i,j) + dX;
                                        y = y + Y(i,j) + dY;
                                        z = 4*z;
                                        surf(plotAxes, x,y,z, ...
                                            FaceColor = comp.Colour.Brown, ...
                                            EdgeColor = comp.Colour.EdgeColour, ...
                                            AmbientStrength = comp.Lighting.Nature(1), ...
                                            DiffuseStrength = comp.Lighting.Nature(2), ...
                                            SpecularStrength = comp.Lighting.Nature(3), ...
                                            BackFaceLighting = "reverselit");

                                        [x,y,z] = cylinder([0.6,0.9,0.8,1.0,0.8,0.9,0.7,0.8,0.5,0.6,0.3,0.4,0] * (width+randn(1))*3/4,N);
                                        x = x + X(i,j) + dX;
                                        y = y + Y(i,j) + dY;
                                        z = 4 + (height + 2*randn(1) - 4) * z;
                                        TreeColor = comp.Colour.Green + 10/255*randn([1,3]);
                                        TreeColor(TreeColor < 0) = 0;
                                        TreeColor(TreeColor > 1) = 1;
                                        surf(plotAxes, x,y,z, ...
                                            FaceColor = TreeColor, ...
                                            EdgeColor = comp.Colour.EdgeColour, ...
                                            AmbientStrength = comp.Lighting.Nature(1), ...
                                            DiffuseStrength = comp.Lighting.Nature(2), ...
                                            SpecularStrength = comp.Lighting.Nature(3), ...
                                            BackFaceLighting = "reverselit");

                                    end

                                end
                            end

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            R = interp1(Tower.Height, Tower.Diameter/2, 2);
                            [x,y,z] = cylinder(R + [1,1,0],N);
                            z = 2*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [-5 Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 4 % Hill

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Patch of grass
                            [x,y] = ndgrid(...
                                linspace(-L/2, L/2, 10), ...
                                linspace(-L/2, L/2, 10));
                            z = 15 * cos(x * pi/L) .* cos(y * pi/L);
                            for n = 1:5
                                i = randi(size(x,1));
                                j = randi(size(y,2));
                                z = z + 2*randn(1) * cos((x-x(i,j)) * pi/L) .* cos((y-y(i,j)) * pi/L);
                            end
                            z = z - min(z(:));
                            base = z(sqrt(x.^2 + y.^2) <= Tower.Diameter(1));
                            if ~isempty(base)
                                z = z * 15/min(base(:));
                            end
                            z = z - 15;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:)-2, flip(z(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1)-2; flip(z(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:)-2, flip(z(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end)-2; flip(z(:,end))], ...
                                'g', ...
                                FaceColor = comp.Colour.Green, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            z_ = 0*z + min(z(:)) - 3;
                            surf(plotAxes, x,y,z_, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:)-2, flip(z_(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1)-2; flip(z_(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:)-2, flip(z_(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end)-2; flip(z_(:,end))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            R = interp1(Tower.Height, Tower.Diameter/2, 2);
                            [x,y,z] = cylinder(R + [1,1,0],N);
                            z = 2*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [min(z_(:)),  Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 5 % Mountain

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Surface
                            [x,y] = ndgrid(...
                                linspace(-L/2, L/2, 10), ...
                                linspace(-L/2, L/2, 10));
                            z = 30 * cos(x * pi/L) .* cos(y * pi/2/L);
                            for n = 1:5
                                i = randi(size(x,1));
                                j = randi(size(y,2));
                                z = z + 5*randn(1) * cos((x-x(i,j)) * pi/L) .* cos((y-y(i,j)) * pi/L);
                            end
                            z = z - min(z(:));
                            base = z(sqrt(x.^2 + y.^2) <= Tower.Diameter(1));
                            if ~isempty(base)
                                z = z * 30/min(base(:));
                            end
                            z = z - 30;
                            surf(plotAxes, x,y,z, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            colormap(plotAxes, [...
                                interp1(0:0.5:1, [comp.Colour.Rock(1); comp.Colour.Rock(1); comp.Colour.White(1)], linspace(0,1,32))', ...
                                interp1(0:0.5:1, [comp.Colour.Rock(2); comp.Colour.Rock(2); comp.Colour.White(2)], linspace(0,1,32))', ...
                                interp1(0:0.5:1, [comp.Colour.Rock(3); comp.Colour.Rock(3); comp.Colour.White(3)], linspace(0,1,32))']);
                            clim(plotAxes, [-15 0]);

                            % Rock
                            z_ = 0*z + min(z(:)) - 3;
                            surf(plotAxes, x,y,z_, ...
                                FaceColor = comp.Colour.Rock, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:), flip(z_(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Rock, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1); flip(z_(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Rock, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:), flip(z_(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Rock, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end); flip(z_(:,end))], ...
                                'g', ...
                                FaceColor = comp.Colour.Rock, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            R = interp1(Tower.Height, Tower.Diameter/2, 2);
                            [x,y,z] = cylinder(R + [1,1,0],N);
                            z = 2*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [min(z_(:)),  Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 6 % Coastal

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Sea surface
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2 * 0.99; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2 * 0.99; ...
                                [-5 -5 -5 -5 0 0 0 0] - 0.5];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Sea, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                FaceAlpha = 0.5, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Land surface
                            [x,y] = ndgrid(...
                                linspace(-L/2, L/2, 10), ...
                                linspace(-L/2, L/2, 10));
                            z = 5 * cos((x - L*0.4) * pi/L) .* cos(y * pi/4/L);
                            for n = 1:5
                                i = randi(size(x,1));
                                j = randi(size(y,2));
                                z = z + randn(1) * cos((x-x(i,j)) * pi/L) .* cos((y-y(i,j)) * pi/L);
                            end
                            z = z - min(z(:));
                            base = z(sqrt(x.^2 + y.^2) <= Tower.Diameter(1));
                            if ~isempty(base)
                                z = z * 5/min(base(:));
                            end
                            z = z - 5;
                            surf(plotAxes, x,y,z, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            colormap(plotAxes, [...
                                interp1(0:1, [comp.Colour.Sand(1); comp.Colour.Green(1)], linspace(0,1,32))', ...
                                interp1(0:1, [comp.Colour.Sand(2); comp.Colour.Green(2)], linspace(0,1,32))', ...
                                interp1(0:1, [comp.Colour.Sand(3); comp.Colour.Green(3)], linspace(0,1,32))']);
                            clim(plotAxes, [-0.5 2]);

                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:)-2, flip(z(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Sand, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1)-2; flip(z(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Sand, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:)-2, flip(z(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Sand, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end)-2; flip(z(:,end))], ...
                                'g', ...
                                FaceColor = comp.Colour.Sand, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            z_ = 0*z + min(z(:)) - 3;
                            surf(plotAxes, x,y,z_, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x(1,:))], ...
                                [y(1,:), flip(y(1,:))], ...
                                [z(1,:)-2, flip(z_(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,1); flip(x(:,1))], ...
                                [y(:,1); flip(y(:,1))], ...
                                [z(:,1)-2; flip(z_(:,1))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x(end,:))], ...
                                [y(end,:), flip(y(end,:))], ...
                                [z(end,:)-2, flip(z_(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(:,end); flip(x(:,end))], ...
                                [y(:,end); flip(y(:,end))], ...
                                [z(:,end)-2; flip(z_(:,end))], ...
                                'g', ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            R = interp1(Tower.Height, Tower.Diameter/2, 2);
                            [x,y,z] = cylinder(R + [1,1,0],N);
                            z = 2*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [min(z_(:)),  Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 7 % Offshore

                            % Landscape size
                            L = max(60, Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Sea surface
                            depth = -20;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2 * 0.99; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2 * 0.99; ...
                                [depth depth depth depth 0 0 0 0] + 0.01];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Sea, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                FaceAlpha = 0.5, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Seabed
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [depth-2 depth-2 depth-2 depth-2 depth depth depth depth]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Sand, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [depth-5 depth-5 depth-5 depth-5 depth-2 depth-2 depth-2 depth-2]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Monopile
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            height = 10;
                            radius = max(Tower.Diameter(Tower.Height <= height))/2 + 0.1;
                            [x,y,z] = cylinder(radius,N);
                            z = depth + z*(height-depth);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder(radius + [0,1.5,1.5],N);
                            z = height + z*2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Bottom mount
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            [x,y,z] = cylinder(Tower.Diameter(1)/2 + [1,1,0],N);
                            z = 2*z + depth;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [depth-5,  Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                        case 8 % Far offshore

                            % Landscape size
                            L = max(60, 4.5 * Tower.Diameter(1) + 5);
                            W = max(L, Blade.Radius(end) + 1);

                            % Sea surface
                            depth = -40;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2 * 0.99; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2 * 0.99; ...
                                [depth depth depth depth 0 0 0 0] + 0.01];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Sea, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                FaceAlpha = 0.5, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Seabed
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [depth-2 depth-2 depth-2 depth-2 depth depth depth depth]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Sand, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Soil
                            vert = [[-1 1 1 -1 -1 1 1 -1] * L/2; ...
                                [-1 -1 1 1 -1 -1 1 1] * L/2; ...
                                [depth-5 depth-5 depth-5 depth-5 depth-2 depth-2 depth-2 depth-2]];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Brown, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Jacket
                            if comp.undock
                                N = 16 + 1;
                            else
                                N = 6 + 1;
                            end
                            height = 15;
                            radius = max(Tower.Diameter(Tower.Height <= height))/2;
                            n = interp1([0, 1], 5 + [2.5 0.5]*radius, [0, 0.3077, 0.5769, 0.8077, 1]);
                            z = [0, 0.3077, 0.5769, 0.8077, 1]*((height-7) - (depth+2)) + (depth + 2);
                            Jacket_Nodes = [...
                                [-1,-1, 1, 1]*n(1), ...
                                [-1,-1, 1, 1]*n(2), ...
                                [-1,-1, 1, 1]*n(3), ...
                                [-1,-1, 1, 1]*n(4), ...
                                [-1,-1, 1, 1]*n(5), 0,; ...
                                [-1, 1,-1, 1]*n(1), ...
                                [-1, 1,-1, 1]*n(2), ...
                                [-1, 1,-1, 1]*n(3), ...
                                [-1, 1,-1, 1]*n(4), ...
                                [-1, 1,-1, 1]*n(5), 0,; ...
                                [ 1, 1, 1, 1]*z(1), ...
                                [ 1, 1, 1, 1]*z(2), ...
                                [ 1, 1, 1, 1]*z(3), ...
                                [ 1, 1, 1, 1]*z(4), ...
                                [ 1, 1, 1, 1]*z(5), 15];
                            Jacket_Members = [...
                                [ 1, 2]; [ 2, 4]; [ 4, 3]; [ 3, 1]; ...
                                [ 1,17]; [ 2,18]; [ 3,19]; [ 4,20]; ...
                                [ 1, 6]; [ 2, 5]; [ 2, 8]; [ 4, 6]; [ 3, 8]; [ 4, 7]; [ 3, 5]; [ 1, 7]; ...
                                [ 5,10]; [ 6, 9]; [ 6,12]; [ 8,10]; [ 7,12]; [ 8,11]; [ 7, 9]; [ 5,11]; ...
                                [ 9,14]; [10,13]; [10,16]; [12,14]; [11,16]; [12,15]; [11,13]; [ 9,15]; ...
                                [13,18]; [14,17]; [14,20]; [16,18]; [15,20]; [16,19]; [15,17]; [13,19]; ...
                                [17,20]; [18,19]; [17,21]; [18,21]; [19,21]; [20,21]];
                            Jacket_Diameter = ones(size(Jacket_Members));
                            Jacket_Diameter(5:8,:) = 1.5;
                            Jacket_Diameter(43:46,1) = 1.5;
                            for i = 1:size(Jacket_Members,1)
                                n1 = Jacket_Members(i,1);
                                n2 = Jacket_Members(i,2);
                                r1 = Jacket_Nodes(:,n1);
                                r2 = Jacket_Nodes(:,n2);
                                azi = repmat(linspace(0,2*pi,N),[2,1]);
                                R = repmat(Jacket_Diameter(i,:)'/2,[1,N]);
                                t = [zeros(1,N); ones(1,N)];
                                n = (r2-r1)/norm(r2-r1);
                                v = rand(3,1);
                                e2 = n - v/dot(v,n);
                                e2 = e2/norm(e2);
                                e3 = cross(n,e2);
                                e3 = e3/norm(e3);
                                x = r1(1) + (r2(1)-r1(1))*t + R.*cos(azi)*e2(1) + R.*sin(azi)*e3(1);
                                y = r1(2) + (r2(2)-r1(2))*t + R.*cos(azi)*e2(2) + R.*sin(azi)*e3(2);
                                z = r1(3) + (r2(3)-r1(3))*t + R.*cos(azi)*e2(3) + R.*sin(azi)*e3(3);
                                surf(plotAxes, x,y,z, ...
                                    FaceColor = comp.Colour.Yellow, ...
                                    EdgeColor = comp.Colour.EdgeColour, ...
                                    AmbientStrength = comp.Lighting.Steel(1), ...
                                    DiffuseStrength = comp.Lighting.Steel(2), ...
                                    SpecularStrength = comp.Lighting.Steel(3), ...
                                    BackFaceLighting = "reverselit");
                            end

                            % Platform
                            vert = [[-1 1 1 -1 -1 1 1 -1] * (Jacket_Nodes(1,20) + 1); ...
                                [-1 -1 1 1 -1 -1 1 1] * (Jacket_Nodes(2,20) + 1); ...
                                [0 0 0 0 1 1 1 1]*2/3 + Jacket_Nodes(3,20)];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Nature(1), ...
                                DiffuseStrength = comp.Lighting.Nature(2), ...
                                SpecularStrength = comp.Lighting.Nature(3), ...
                                BackFaceLighting = "reverselit");

                            % Interface
                            if comp.undock
                                N = 50;
                            else
                                N = 12;
                            end
                            radius = max(Tower.Diameter(Tower.Height <= height))/2 + 0.01;
                            [x,y,z] = cylinder(radius,N);
                            z = height-7 + 5*z;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder(radius + [0,1.5,1.5],N);
                            z = height - 2 + z*2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Steel(1), ...
                                DiffuseStrength = comp.Lighting.Steel(2), ...
                                SpecularStrength = comp.Lighting.Steel(3), ...
                                BackFaceLighting = "reverselit");

                            % Bottom mount
                            if comp.undock
                                N = 16;
                            else
                                N = 6;
                            end
                            for i = 1:4
                                [x,y,z] = cylinder([2,2,1],N);
                                x = x + Jacket_Nodes(1,i);
                                y = y + Jacket_Nodes(2,i);
                                z = 2*z - 2 + Jacket_Nodes(3,i);
                                surf(plotAxes, x,y,z, ...
                                    FaceColor = comp.Colour.Grey, ...
                                    EdgeColor = comp.Colour.EdgeColour, ...
                                    AmbientStrength = comp.Lighting.Steel(1), ...
                                    DiffuseStrength = comp.Lighting.Steel(2), ...
                                    SpecularStrength = comp.Lighting.Steel(3), ...
                                    BackFaceLighting = "reverselit");
                            end

                            % Set limits
                            if ~comp.undock
                                xlim(plotAxes, [-L L]);
                                ylim(plotAxes, [-W W]);
                                zlim(plotAxes, [depth-5,  Tower.HubHeight + Blade.Radius(end) + 1]);
                            end

                    end

                    if ~comp.undock

                        % Create fixed number of tower elements (N = 21)
                        N = 21;
                        x = linspace(Tower.Height(1), Tower.Height(end), N);
                        Tower.Diameter = interp1(Tower.Height, Tower.Diameter, x);
                        Tower.Height = x;

                        % Create fixed number of blade elements (N = 21)
                        N = 21;
                        x = linspace(Blade.Radius(1), Blade.Radius(end), N);
                        Blade.Twist = interp1(Blade.Radius, Blade.Twist, x);
                        Blade.Chord = interp1(Blade.Radius, Blade.Chord, x);
                        Blade.PitchAxis = interp1(Blade.Radius, Blade.PitchAxis, x);
                        Blade.Thickness = interp1(Blade.Radius, Blade.Thickness, x);
                        Blade.NFoil = interp1(Blade.Radius, Blade.NFoil, x, 'nearest');
                        Blade.Radius = x;

                    end

                    % Plot tower
                    if comp.undock
                        N = 50 + 1;
                    else
                        N = 12 + 1;
                    end
                    s = Tower.Height/Tower.Height(end);
                    switch TowerStyle
                        case 1
                            paint = ones(size(s));
                        case 2
                            paint = zeros(size(s));
                            s = 0.05*round(20*s);
                            paint(s >= 0.2) = 1;
                            paint(s >= 0.4) = 0;
                            paint(s >= 0.6) = 1;
                            paint(s >= 0.8) = 0;
                            paint(s >= 1.0) = 1;
                        case 3
                            paint = zeros(size(s));
                            paint(s > 0.25) = 1;
                            paint(s > 0.30) = 0;
                        case 4
                            paint = (s > 0.8);
                        case 5
                            paint = zeros(size(s));
                            paint(Tower.Height <= 30) = 1;
                        case 6
                            paint = zeros(size(s));
                            n = length(s(Tower.Height <= 40));
                            paint(1:n) = linspace(1,0,n);
                        case 7
                            paint = [ones(1,size(s,2)); zeros(1,size(s,2))];
                            s = 0.05*round(20*s);
                            paint(1,s >= 0.1) = 0;
                            paint(1,s >= 0.2) = 1;
                            paint(1,s >= 0.3) = 0;
                            paint(1,s >= 0.4) = 1;
                            paint(1,s >= 0.5) = 0;
                            paint(1,s >= 0.6) = 1;
                            paint(1,s >= 0.7) = 0;
                            paint(1,s >= 0.8) = 1;
                            paint(1,s >= 0.9) = 0;
                            paint(1,s >= 1.0) = 1;
                            paint(2,s >= 0.1) = 1;
                            paint(2,s >= 0.2) = 0;
                            paint(2,s >= 0.3) = 1;
                            paint(2,s >= 0.4) = 0;
                            paint(2,s >= 0.5) = 1;
                            paint(2,s >= 0.6) = 0;
                            paint(2,s >= 0.7) = 1;
                            paint(2,s >= 0.8) = 0;
                            paint(2,s >= 0.9) = 1;
                            paint(2,s >= 1.0) = 0;
                            paint = [paint; paint];
                        case 8
                            paint = (s < 0.15);
                    end
                    if TowerStyle == 7
                        paint = kron(paint,ones(round(N/4),1));
                        paint = paint(1:N-1,:);
                        TowerColors = zeros([size(paint),3]);
                        TowerColors(:,:,1) = paint*TowerPaint(1) + (1-paint)*comp.Colour.White(1);
                        TowerColors(:,:,2) = paint*TowerPaint(2) + (1-paint)*comp.Colour.White(2);
                        TowerColors(:,:,3) = paint*TowerPaint(3) + (1-paint)*comp.Colour.White(3);
                        TowerColors = permute(TowerColors,[2,1,3]);
                    else
                        TowerColors = kron(paint(:),TowerPaint) + kron((1-paint(:)),comp.Colour.White);
                        TowerColors = repmat(TowerColors,[1,1,N]);
                        TowerColors = permute(TowerColors,[1,3,2]);
                    end

                    if Landscape == 8
                        Tower.Height(Tower.Height <= height) = height - 5;
                    end
                    r = repmat(Tower.Diameter(:)/2,[1,N]);
                    azi = repmat(linspace(0,2*pi,N),[length(Tower.Diameter),1]);
                    x = r.*cos(azi);
                    y = r.*sin(azi);
                    z = repmat(Tower.Height(:),[1,N]);
                    x = [x; x(end,:)];
                    y = [y; y(end,:)];
                    z = [z; 0*z(end,:) + Tower.HubHeight];
                    TowerColors = [TowerColors; TowerColors(end,:,:)];
                    TowerColors = [TowerColors, TowerColors(:,end,:)];
                    surf(plotAxes, x,y,z, ...
                        CData = TowerColors, ...
                        FaceColor = "texturemap", ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = comp.Lighting.Glassfiber(1), ...
                        DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                        SpecularStrength = comp.Lighting.Glassfiber(3), ...
                        BackFaceLighting = "reverselit");

                    % Plot nacelle
                    if comp.undock
                        N = 50 + 1;
                    else
                        N = 20 + 1;
                    end
                    t = Nacelle.Hub.ShaftTilt*pi/180;
                    Ry = [cos(t), 0, sin(t); ...
                        0,      1, 0; ...
                        -sin(t), 0, cos(t)];
                    switch Nacelle.Housing.Type
                        case 1

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z)) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Tower.HubHeight + Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                        case 2

                            Nx = 12;
                            R = Nacelle.Housing.Diameter/2;
                            r = repmat(R*sin(linspace(0,pi/2,Nx)),[N,1]);
                            azi = repmat(linspace(0,2*pi,N)',[1,Nx]);
                            x = repmat(cos(linspace(0,pi/2,Nx)),[N,1]) * (Nacelle.Housing.Length-0.4) + 0.3 - Nacelle.Hub.Overhang  + 0.5*Blade.Chord(1);
                            y = r.*cos(azi);
                            z = r.*sin(azi);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),N,Nx);
                            y = reshape(A(2,:),N,Nx);
                            z = reshape(A(3,:),N,Nx) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 1.5;
                            dz = Tower.HubHeight + Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9 1]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),4,N);
                            y = reshape(A(2,:),4,N);
                            z = reshape(A(3,:),4,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                        case 3

                            [x,y,z] = cylinder([1 2/3]*Nacelle.Housing.Diameter/2,N-1);
                            z = z * (Nacelle.Housing.Length-0.4) + 0.3 - Nacelle.Hub.Overhang  + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),2,N);
                            y = reshape(A(2,:),2,N);
                            z = reshape(A(3,:),2,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Tower.HubHeight + Nacelle.Housing.Diameter/2*0.85;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9 1]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),4,N);
                            y = reshape(A(2,:),4,N);
                            z = reshape(A(3,:),4,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                        case 4

                            [x,y,z] = cylinder([1 1]*Nacelle.Housing.Diameter/2,N-1);
                            z = z * (Nacelle.Housing.Length-0.4) + 0.3 - Nacelle.Hub.Overhang  + 0.5*Blade.Chord(1);
                            dx = max(z(:));
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),2,N);
                            y = reshape(A(2,:),2,N);
                            z = reshape(A(3,:),2,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder([1 1]*Tower.Diameter(end)/2,N-1);
                            z = dx - z * dx;
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),2,N);
                            y = reshape(A(2,:),2,N);
                            z = reshape(A(3,:),2,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = sphere(N-1);
                            x = x * Tower.Diameter(end)/2;
                            y = y * Tower.Diameter(end)/2;
                            z = z * Tower.Diameter(end)/2 + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder([1 0.9 0.9 1]*min(Blade.Chord(1), Tower.Diameter(end)/2),N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),4,N);
                            y = reshape(A(2,:),4,N);
                            z = reshape(A(3,:),4,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                        case 5

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z)) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Tower.HubHeight + Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            x = [0, 0, 2, 2, 0, 0; 2, 2, 3.5, 3.5, 2, 2] + 0.5*Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = [1, 1, 1, -1, -1, -1; 1, 1, 1, -1, -1, -1] * Nacelle.Housing.Diameter/2;
                            z = [0.1, 0.1, 1, 1, 0.1, 0.1; 0.1, 0.1, 1, 1, 0.1, 0.1] * Nacelle.Housing.Diameter * 3/4;
                            y(:,2:3) = y(:,2:3) + 0.25;
                            y(:,4:5) = y(:,4:5) - 0.25;
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(y));
                            z = reshape(A(3,:),size(z)) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            x = [0, 0, 2, 2, 0, 0; 2, 2, 3.5, 3.5, 2, 2] + 0.5*Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = [1, 1, 1, -1, -1, -1; 1, 1, 1, -1, -1, -1] * Nacelle.Housing.Diameter/2 + 0.05*[0, -1, -1, 1, 1, 0; 0, -1, -1, 1, 1, 0];
                            z = [0.1, 0.1, 1, 1, 0.1, 0.1; 0.1, 0.1, 1, 1, 0.1, 0.1] * Nacelle.Housing.Diameter * 3/4 + 0.05*[0, 0, -1, -1, 0, 0; 0, 0, -1, -1, 0, 0];
                            y(:,2:3) = y(:,2:3) + 0.25;
                            y(:,4:5) = y(:,4:5) - 0.25;
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(y));
                            z = reshape(A(3,:),size(z)) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                        case 6

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z)) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Tower.HubHeight + Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + (Nacelle.Housing.Length-1.5);
                            dz = 0.5 + Tower.HubHeight + Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 2 + dx*cos(t); ...
                                [-1 -1 1 1 -1 -1 1 1] * 3/2; ...
                                [-1 -1 -1 -1 0 0 0 0] * 0.25 + dz - dx*sin(t)];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            x = repmat([-1, 1, 1, -1, -1] * 2 + dx*cos(t), [2,1]);
                            y = repmat([1, 1, -1, -1, 1] * 3/2, [2,1]);
                            z = [zeros(1,5); ones(1,5)] * 0.25 + dz - dx*sin(t);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder(0.1,4);
                            x = x + dx*cos(t);
                            z = -z - 0.25 + dz  - dx*sin(t);
                            for i = [-1, 1]
                                for j = [-1, 1]
                                    surf(plotAxes, x+i,y+j,z, ...
                                        FaceColor = comp.Colour.Grey, ...
                                        EdgeColor = comp.Colour.EdgeColour, ...
                                        AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                        DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                        SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                        BackFaceLighting = "reverselit");
                                end
                            end

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                        case 7

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z)) + Tower.HubHeight;
                            z(2,[1,4,17]) = Tower.HubHeight + 0.9*Nacelle.Housing.Diameter/2;
                            z(2,2:3) = Tower.HubHeight + Nacelle.Housing.Diameter/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            x = [-1-sqrt(2), -1, 1, 1+sqrt(2), 1+sqrt(2), 1, -1, -1-sqrt(2), -1-sqrt(2)] + 1+sqrt(2);
                            y = [1, 1+sqrt(2), 1+sqrt(2), 1, -1, -1-sqrt(2), -1-sqrt(2), -1, 1];
                            x = repmat(x,[2,1])*2.5 - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 1;
                            y = repmat(y,[2,1])*2.5;
                            z = [zeros(1,9); ones(1,9)]*0.5 + Tower.HubHeight + Nacelle.Housing.Diameter/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 1 + (1+sqrt(2))*2.5;
                            x = ([0, 0, 0, 0; 1, 1, 1, 1] - 0.5) * 0.5 + dx;
                            y = [0, 1, 0, 0; 0, 1, 0, 0];
                            z = [-1, 0, 0, -1; -1, 0, 0, -1] + Tower.HubHeight + Nacelle.Housing.Diameter/2;
                            if Nacelle.Housing.Diameter < 10
                                for i = [-1 1]
                                    for j = [-1 1]
                                        surf(plotAxes, x + 1.5*i,y*j + Nacelle.Housing.Diameter/2*j,z, ...
                                            FaceColor = comp.Colour.Lightgrey, ...
                                            EdgeColor = comp.Colour.EdgeColour, ...
                                            AmbientStrength = comp.Lighting.Steel(1), ...
                                            DiffuseStrength = comp.Lighting.Steel(2), ...
                                            SpecularStrength = comp.Lighting.Steel(3), ...
                                            BackFaceLighting = "reverselit");
                                    end
                                end
                            end
                            h = 0.75;
                            q = linspace(0,2*pi,25);
                            x = [(5+2/3*h)*cos(q); 5*cos(q)];
                            y = [(5+2/3*h)*sin(q); 5*sin(q)];
                            z = zeros(size(x));
                            x = x + dx;
                            z = z+0.01 + 0.5 + Tower.HubHeight + Nacelle.Housing.Diameter/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");
                            x = [1, 1, 0, 0, 1, 1, -1, -1, 0, 0, -1, -1, 1]*2 + [0, 0, h/2, h/2, 0, 0, 0, 0, -h/2, -h/2, 0, 0, 0];
                            y = [-1, -1, -1, 1, 1, 1, 1, 1, 1, -1, -1, -1, -1]*2 + [0, h, h, -h, -h, 0, 0, -h, -h, h, h, 0, 0];
                            z = zeros(size(x));
                            x = x + dx;
                            z = z+0.01 + 0.5 + Tower.HubHeight + Nacelle.Housing.Diameter/2;
                            patch(plotAxes, x,y,z, ...
                                'g', ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N) + Tower.HubHeight;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = comp.Lighting.Glassfiber(1), ...
                                DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                                SpecularStrength = comp.Lighting.Glassfiber(3), ...
                                BackFaceLighting = "reverselit");

                    end

                    % Plot nose cone
                    if Nacelle.Housing.Type == 4
                        R = min(Blade.Chord(1)*3/4, Tower.Diameter(end)/2);
                    else
                        R = Nacelle.Housing.Diameter/2;
                    end

                    switch Nacelle.Hub.Type
                        case 1

                            if comp.undock
                                Nx = 50 + 1;
                            else
                                Nx = 10 + 1;
                            end
                            r = repmat(R*sin(linspace(0,pi/2,Nx)),[N,1]);
                            azi = repmat(linspace(0,2*pi,N)',[1,Nx]);
                            x = 0.5*Blade.Chord(1) - repmat(cos(linspace(0,pi/2,Nx)),[N,1])*Nacelle.Hub.Length - Nacelle.Hub.Overhang;
                            y = r.*cos(azi);
                            z = r.*sin(azi);

                        case 2

                            Nx = 4;
                            [x_,y_,z_] = cylinder([1, 0.99]*R,N-1);
                            x = z_'; y = x_'; z = y_';
                            x = [x * 0.9, x(:,end), x(:,end)];
                            y = [y, y(:,end) * 0.9, y(:,end) * 0];
                            z = [z, z(:,end) * 0.9, z(:,end) * 0];
                            x = 0.5*Blade.Chord(1) - x*Nacelle.Hub.Length - Nacelle.Hub.Overhang;

                        case 3

                            Nx = 3;
                            [x_,y_,z_] = cylinder([1, 0.99]*R,N-1);
                            x = z_'; y = x_'; z = y_';
                            x = [x*0.5*Blade.Chord(1)/Nacelle.Hub.Length, x(:,end)];
                            y = [y, y(:,end) * 0];
                            z = [z, z(:,end) * 0];
                            x = 0.5*Blade.Chord(1) - x*Nacelle.Hub.Length - Nacelle.Hub.Overhang;

                        case 4

                            if comp.undock
                                Nx = 50 + 1;
                            else
                                Nx = 10 + 1;
                            end
                            rho = (R^2 + Nacelle.Hub.Length^2)/(2*R);
                            x = linspace(0,Nacelle.Hub.Length,Nx);
                            r = repmat(sqrt(rho^2 - (Nacelle.Hub.Length-x).^2) + R - rho,[N,1]);
                            azi = repmat(linspace(0,2*pi,N)',[1,Nx]);
                            x = repmat(x-Nacelle.Hub.Length,[N,1]) + 0.5*Blade.Chord(1) - Nacelle.Hub.Overhang;
                            y = r.*cos(azi);
                            z = r.*sin(azi);

                    end
                    A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                    A = Ry * A;
                    x = reshape(A(1,:),N,Nx);
                    y = reshape(A(2,:),N,Nx);
                    z = reshape(A(3,:),N,Nx) + Tower.HubHeight;
                    surf(plotAxes, x,y,z, ...
                        FaceColor = comp.Colour.Lightergrey, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = comp.Lighting.Glassfiber(1), ...
                        DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                        SpecularStrength = comp.Lighting.Glassfiber(3), ...
                        BackFaceLighting = "reverselit");

                    % Blade cross-section
                    if comp.undock
                        N = 10;
                    else
                        N = 40;
                    end
                    x = [];
                    y = [];
                    for i = 1:length(Blade.NFoil)
                        x = [x; ([Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1:N:end), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1)] - Blade.PitchAxis(i)) * Blade.Chord(i)];
                    end
                    for i = 1:length(Blade.NFoil)
                        t_u = max(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:200));
                        t_l = min(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,200:end));
                        t(i) = t_u - t_l;
                    end
                    for i = 1:length(Blade.NFoil)
                        y = [y; -1*[Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:N:end), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1)] * Blade.Thickness(i)/t(i)];
                    end
                    z = repmat(Blade.Radius(:),[1,size(x,2)]);

                    % Plot blade
                    for i = 1:length(Blade.NFoil)
                        t = pi/2 - (Blade.Twist(i) + Control.Pitch.Fine) * pi/180;
                        Rz = [cos(t),-sin(t), 0; ...
                            sin(t), cos(t), 0; ...
                            0,      0,      1];
                        A = Rz * [x(i,:); y(i,:); z(i,:)];
                        x(i,:) = A(1,:);
                        y(i,:) = A(2,:);
                        z(i,:) = A(3,:);
                    end
                    x = [x(1,:); x; 0.5*x(end,:)];
                    y = [y(1,:); y; 0*y(end,:)];
                    z = [0*z(1,:); z; 0.01 + z(end,:)];
                    o = 2*pi/Blade.Number;
                    t = Nacelle.Hub.ShaftTilt*pi/180;
                    Ry = [cos(t), 0, sin(t); ...
                        0,      1, 0; ...
                        -sin(t), 0, cos(t)];
                    c = -Blade.Cone*pi/180;
                    Rc = [cos(c), 0, sin(c); ...
                        0,      1, 0; ...
                        -sin(c), 0, cos(c)];
                    N = size(x);

                    s = Blade.Radius/Blade.Radius(end);
                    switch BladeStyle
                        case 1
                            paint = ones(size(s));
                        case 2
                            paint = zeros(size(s));
                            paint(s > 0.25) = 1;
                            paint(s > 0.50) = 0;
                            paint(s > 0.75) = 1;
                        case 3
                            paint = zeros(size(s));
                            paint(s > 0.125) = 1;
                            paint(s > 0.250) = 0;
                            paint(s > 0.375) = 1;
                            paint(s > 0.500) = 0;
                            paint(s > 0.625) = 1;
                            paint(s > 0.750) = 0;
                            paint(s > 0.875) = 1;
                        case 4
                            paint = (s > 0.8);
                        case 5
                            paint = zeros(size(s));
                            paint(s > 0.650) = 1;
                            paint(s > 0.750) = 0;
                            paint(s > 0.875) = 1;
                        case 6
                            paint = zeros(size(s));
                            paint(s > 0.70) = 1;
                            paint(s > 0.75) = 0;
                            paint(s > 0.80) = 1;
                        case 7
                            paint = zeros(2,size(s,2));
                            paint(1,s > 0.75) = 0;
                            paint(1,s > 0.80) = 1;
                            paint(1,s > 0.85) = 0;
                            paint(1,s > 0.90) = 1;
                            paint(2,s > 0.75) = 1;
                            paint(2,s > 0.80) = 0;
                            paint(2,s > 0.85) = 1;
                            paint(2,s > 0.90) = 0;
                            paint = [paint; paint];
                        case 8
                            paint = (s < 0.15);
                    end
                    if BladeStyle == 7
                        paint = kron(paint,ones(round((N(2)+1)/4),1));
                        paint = paint(1:N(2),:);
                        BladeColors = zeros([size(paint),3]);
                        BladeColors(:,:,1) = paint*BladePaint(1) + (1-paint)*comp.Colour.White(1);
                        BladeColors(:,:,2) = paint*BladePaint(2) + (1-paint)*comp.Colour.White(2);
                        BladeColors(:,:,3) = paint*BladePaint(3) + (1-paint)*comp.Colour.White(3);
                        BladeColors = permute(BladeColors,[2,1,3]);
                    else
                        BladeColors = kron(paint(:),BladePaint) + kron((1-paint(:)),comp.Colour.White);
                        BladeColors = repmat(BladeColors,[1,1,N(2)]);
                        BladeColors = permute(BladeColors,[1,3,2]);
                    end

                    if Blade.Number == 1
                        do = pi/2 + comp.azimuth;
                    else
                        do = pi - pi/Blade.Number + comp.azimuth;
                    end

                    for B = 1:Blade.Number
                        Rx = [1, 0,      0; ...
                            0, cos(B*o + do),-sin(B*o + do); ...
                            0, sin(B*o + do), cos(B*o + do)];
                        A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        xB = reshape(A(1,:),N);
                        yB = reshape(A(2,:),N);
                        zB = reshape(A(3,:),N) + Tower.HubHeight;
                        surf(plotAxes, xB,yB,zB, ...
                            CData = BladeColors, ...
                            FaceColor = "texturemap", ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = comp.Lighting.Glassfiber(1), ...
                            DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                            SpecularStrength = comp.Lighting.Glassfiber(3), ...
                            BackFaceLighting = "reverselit");
                    end

                    if Blade.Number == 1

                        [x,y,z] = cylinder(Blade.Chord(1)/2*[2/3 1/3], N(2)-1);
                        N = size(x);
                        z = -1*z*Blade.Radius(end)/4;
                        A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        x = reshape(A(1,:),N);
                        y = reshape(A(2,:),N);
                        z = reshape(A(3,:),N) + Tower.HubHeight;
                        surf(plotAxes, x,y,z, ...
                            FaceColor = comp.Colour.White, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = comp.Lighting.Glassfiber(1), ...
                            DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                            SpecularStrength = comp.Lighting.Glassfiber(3), ...
                            BackFaceLighting = "reverselit");

                        [x,y,z] = sphere(N(2)-1);
                        x = x * Blade.Chord(1)/4;
                        y = y * Blade.Chord(1)/2;
                        z = z * Blade.Chord(1)/4 - Blade.Radius(end)/4;
                        N = size(x);
                        A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        x = reshape(A(1,:),N);
                        y = reshape(A(2,:),N);
                        z = reshape(A(3,:),N) + Tower.HubHeight;
                        surf(plotAxes, x,y,z, ...
                            FaceColor = BladePaint, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = comp.Lighting.Glassfiber(1), ...
                            DiffuseStrength = comp.Lighting.Glassfiber(2), ...
                            SpecularStrength = comp.Lighting.Glassfiber(3), ...
                            BackFaceLighting = "reverselit");

                    end

                case 'Blade'

                    % Smooth blade Thickness
                    t = zeros(size(Blade.Radius));
                    for i = 1:length(Blade.NFoil)
                        t_u = max(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:200) * Blade.Chord(i));
                        t_l = min(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,200:end) * Blade.Chord(i));
                        t(i) = t_u - t_l;
                    end
                    Blade.Thickness = t;
                    s = 2.*round((length(Blade.NFoil)/5+1)/2)-1;
                    t = conv(t(:),ones(1,s)/sum(s),"same")./t(:);
                    t(1:(s-1)/2) = 1;
                    t(end-(s-1)/2:end) = 1;
                    Blade.Thickness = Blade.Thickness .* t;

                    % Smooth pitch axis
                    tref =  [1.000; 0.700; 0.405; 0.350; 0.300; 0.250; 0.210; 0.180];
                    Blade.PitchAxis = interp1(tref,[0.5; 0.45; 0.40; 0.375; 0.375; 0.375; 0.375; 0.375], Blade.Thickness./Blade.Chord, 'pchip');

                    % Blade cross-section
                    x = [];
                    y = [];
                    for i = 1:length(Blade.NFoil)
                        x = [x; ([Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,:), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(1,1)] - Blade.PitchAxis(i)) * Blade.Chord(i)];
                    end
                    for i = 1:length(Blade.NFoil)
                        t_u = max(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1:200));
                        t_l = min(Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,200:end));
                        t(i) = t_u - t_l;
                    end
                    for i = 1:length(Blade.NFoil)
                        y = [y; -1*[Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,:), Airfoil.Geometry{Blade.IFoil(Blade.NFoil(i))}(2,1)] * Blade.Thickness(i)/t(i)];
                    end
                    z = repmat(Blade.Radius(:),[1,size(x,2)]);

                    % Plot blade
                    for i = 1:length(Blade.NFoil)
                        t = pi/2 - Blade.Twist(i) * pi/180;
                        Rz = [cos(t),-sin(t), 0; ...
                            sin(t), cos(t), 0; ...
                            0,      0,      1];
                        A = Rz * [x(i,:); y(i,:); z(i,:)];
                        x(i,:) = A(1,:);
                        y(i,:) = A(2,:);
                        z(i,:) = A(3,:);
                    end
                    x = [x(1,:); x; 0.5*x(end,:)];
                    y = [y(1,:); y; 0*y(end,:)];
                    z = [0*z(1,:); z; 0.01 + z(end,:)];
                    Rx = [1, 0,       0; ...
                        0, cos(pi),-sin(pi); ...
                        0, sin(pi), cos(pi)];
                    Ry = [cos(pi/2), 0, sin(pi/2); ...
                        0,         1, 0; ...
                        -sin(pi/2), 0, cos(pi/2)];
                    A = Rx * Ry * [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                    N = size(x);

                    s = Blade.Radius/Blade.Radius(end);
                    switch BladeStyle
                        case 1
                            paint = ones(size(s));
                        case 2
                            paint = zeros(size(s));
                            paint(s > 0.25) = 1;
                            paint(s > 0.50) = 0;
                            paint(s > 0.75) = 1;
                        case 3
                            paint = zeros(size(s));
                            paint(s > 0.125) = 1;
                            paint(s > 0.250) = 0;
                            paint(s > 0.375) = 1;
                            paint(s > 0.500) = 0;
                            paint(s > 0.625) = 1;
                            paint(s > 0.750) = 0;
                            paint(s > 0.875) = 1;
                        case 4
                            paint = (s > 0.8);
                        case 5
                            paint = zeros(size(s));
                            paint(s > 0.650) = 1;
                            paint(s > 0.750) = 0;
                            paint(s > 0.875) = 1;
                        case 6
                            paint = zeros(size(s));
                            paint(s > 0.70) = 1;
                            paint(s > 0.75) = 0;
                            paint(s > 0.80) = 1;
                        case 7
                            paint = zeros(2,size(s,2));
                            paint(1,s > 0.80) = 0;
                            paint(1,s > 0.85) = 1;
                            paint(1,s > 0.90) = 0;
                            paint(1,s > 0.95) = 1;
                            paint(2,s > 0.80) = 1;
                            paint(2,s > 0.85) = 0;
                            paint(2,s > 0.90) = 1;
                            paint(2,s > 0.95) = 0;
                            paint = [paint; paint];
                        case 8
                            paint = (s < 0.15);
                    end
                    if BladeStyle == 7
                        paint = kron(paint,ones(round((N(2)+1)/4),1));
                        paint = paint(1:N(2),:);
                        BladeColours = zeros([size(paint),3]);
                        BladeColours(:,:,1) = paint*BladePaint(1) + (1-paint)*comp.Colour.White(1);
                        BladeColours(:,:,2) = paint*BladePaint(2) + (1-paint)*comp.Colour.White(2);
                        BladeColours(:,:,3) = paint*BladePaint(3) + (1-paint)*comp.Colour.White(3);
                        BladeColours = permute(BladeColours,[2,1,3]);
                    else
                        BladeColours = kron(paint(:),BladePaint) + kron((1-paint(:)),comp.Colour.White);
                        BladeColours = repmat(BladeColours,[1,1,N(2)]);
                        BladeColours = permute(BladeColours,[1,3,2]);
                    end

                    x = reshape(A(1,:),N);
                    y = reshape(A(2,:),N);
                    z = reshape(A(3,:),N);
                    surf(plotAxes, x,y,z, ...
                        CData = BladeColours, ...
                        FaceColor = "texturemap", ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    xlim(plotAxes, [min(x(:)) - 0.3, max(x(:))])
                    ylim(plotAxes, [min(y(:)), max(y(:))])
                    zlim(plotAxes, [min(z(:)), max(z(:))])

                    x_ = x;
                    y_ = 0.8*y .* (max(x(:)) - x)/max(x(:));
                    z_ = 0.8*z .* (max(x(:)) - x)/max(x(:));

                    surf(plotAxes, x_,y_,z_, ...
                        FaceColor = comp.Colour.Grey, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    patch(plotAxes, ...
                        [x(1,:), flip(x_(1,:))], ...
                        [y(1,:), flip(y_(1,:))], ...
                        [z(1,:), flip(z_(1,:))], ...
                        'g', ...
                        FaceColor = comp.Colour.White, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit")

                    if Blade.NFoil(1) == 1
                        R = 0.9/2*Blade.Chord(1);
                        r = 0.05/2*Blade.Chord(1);
                        [x_,y_,z_] = cylinder([r r],12);
                        for t = pi/15:pi/15:2*pi
                            x = 0.3*z_ - 0.1;
                            y = x_ + R*cos(t);
                            z = y_ + R*sin(t);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:), y(1,:), z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                        end
                    end

                    lighting(plotAxes, "gouraud");

                case 'Nacelle'
                    N = 200 + 1;
                    t = Nacelle.Hub.ShaftTilt*pi/180;
                    Ry = [cos(t), 0, sin(t); ...
                        0,      1, 0; ...
                        -sin(t), 0, cos(t)];
                    switch Nacelle.Housing.Type
                        case 1

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z));
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                        case 2

                            Nx = 50;
                            R = Nacelle.Housing.Diameter/2;
                            r = repmat(R*sin(linspace(0,pi/2,Nx)),[N,1]);
                            azi = repmat(linspace(0,2*pi,N)',[1,Nx]);
                            x = repmat(cos(linspace(0,pi/2,Nx)),[N,1]) * (Nacelle.Housing.Length-0.4) + 0.3 - Nacelle.Hub.Overhang  + 0.5*Blade.Chord(1);
                            y = r.*cos(azi);
                            z = r.*sin(azi);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),N,Nx);
                            y = reshape(A(2,:),N,Nx);
                            z = reshape(A(3,:),N,Nx);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 1.5;
                            dz = Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9 1]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),4,N);
                            y = reshape(A(2,:),4,N);
                            z = reshape(A(3,:),4,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                        case 3

                            [x,y,z] = cylinder([1 2/3]*Nacelle.Housing.Diameter/2,N-1);
                            z = z * (Nacelle.Housing.Length-0.4) + 0.3 - Nacelle.Hub.Overhang  + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),2,N);
                            y = reshape(A(2,:),2,N);
                            z = reshape(A(3,:),2,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Nacelle.Housing.Diameter/2*0.85;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9 1]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),4,N);
                            y = reshape(A(2,:),4,N);
                            z = reshape(A(3,:),4,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                        case 4

                            [x,y,z] = cylinder([1 1]*Nacelle.Housing.Diameter/2,N-1);
                            z = z * (Nacelle.Housing.Length-0.4) + 0.3 - Nacelle.Hub.Overhang  + 0.5*Blade.Chord(1);
                            dx = max(z(:));
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),2,N);
                            y = reshape(A(2,:),2,N);
                            z = reshape(A(3,:),2,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder([1 1]*Tower.Diameter(end)/2,N-1);
                            z = dx - z * dx;
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),2,N);
                            y = reshape(A(2,:),2,N);
                            z = reshape(A(3,:),2,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = sphere(N-1);
                            x = x * Tower.Diameter(end)/2;
                            y = y * Tower.Diameter(end)/2;
                            z = z * Tower.Diameter(end)/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder([1 0.9 0.9 1]*min(Blade.Chord(1), Tower.Diameter(end)/2),N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),4,N);
                            y = reshape(A(2,:),4,N);
                            z = reshape(A(3,:),4,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                        case 5

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z));
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            x = [0, 0, 2, 2, 2, 2, 0, 0; 2, 2, 3.5, 3.5, 3.5, 3.5, 2, 2] + 0.5*Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = [1, 1, 1, 0.9, -0.9, -1, -1, -1; 1, 1, 1, 0.9, -0.9, -1, -1, -1] * Nacelle.Housing.Diameter/2;
                            z = [0.1, 0.1, 0.9, 1, 1, 0.9, 0.1, 0.1; 0.1, 0.1, 0.9, 1, 1, 0.9, 0.1, 0.1] * Nacelle.Housing.Diameter * 3/4;
                            y(:,2:3) = y(:,2:3) + 0.25;
                            y(:,6:7) = y(:,6:7) - 0.25;
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(y));
                            z = reshape(A(3,:),size(z));
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            x_ = [0, 0, 2, 2, 2, 2, 0, 0; 2, 2, 3.5, 3.5, 3.5, 3.5, 2, 2] + 0.5*Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y_ = [1, 1, 1, 0.9, -0.9, -1, -1, -1; 1, 1, 1, 0.9, -0.9, -1, -1, -1] * Nacelle.Housing.Diameter/2 + 0.2*[0, -1, -1, -1, 1, 1, 1, 0; 0, -1, -1, -1, -1, 1, 1, 0];
                            z_ = [0.1, 0.1, 0.9, 1, 1, 0.9, 0.1, 0.1; 0.1, 0.1, 0.9, 1, 1, 0.9, 0.1, 0.1] * Nacelle.Housing.Diameter * 3/4 + 0.2*[0, 0, -1, -1, -1, -1, 0, 0; 0, 0, -1, -1, -1, -1, 0, 0];
                            y_(:,2:3) = y_(:,2:3) + 0.25;
                            y_(:,6:7) = y_(:,6:7) - 0.25;
                            A = [reshape(x_,1,[]); reshape(y_,1,[]); reshape(z_,1,[])];
                            A = Ry * A;
                            x_ = reshape(A(1,:),size(x_));
                            y_ = reshape(A(2,:),size(y_));
                            z_ = reshape(A(3,:),size(z_));
                            surf(plotAxes, x_,y_,z_, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(1,:), flip(x_(1,:))], ...
                                [y(1,:), flip(y_(1,:))], ...
                                [z(1,:), flip(z_(1,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, ...
                                [x(end,:), flip(x_(end,:))], ...
                                [y(end,:), flip(y_(end,:))], ...
                                [z(end,:), flip(z_(end,:))], ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                        case 6

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z));
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 0.25*Nacelle.Housing.Length;
                            dz = Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightergrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 1/2 * 0.8 + dx; ...
                                [-1 -1 1 1 -1 -1 1 1] * 1/2 * 0.8; ...
                                [-1 -1 -1 -1 1 1 1 1] * 1/4];
                            vert = Ry * vert;
                            vert(3,:) = vert(3,:) + dz+0.01;
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + (Nacelle.Housing.Length-1.5);
                            dz = 0.5 + Nacelle.Housing.Diameter/2*0.9;
                            vert = [[-1 1 1 -1 -1 1 1 -1] * 2 + dx*cos(t); ...
                                [-1 -1 1 1 -1 -1 1 1] * 3/2; ...
                                [-1 -1 -1 -1 0 0 0 0] * 0.25 + dz - dx*sin(t)];
                            faces = [1 2 6 5; 2 3 7 6; 3 4 8 7; 4 1 5 8; 1 2 3 4; 5 6 7 8];
                            patch(plotAxes, ...
                                Vertices = vert', ...
                                Faces = faces, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            x = repmat([-1, 1, 1, -1, -1] * 2 + dx*cos(t), [2,1]);
                            y = repmat([1, 1, -1, -1, 1] * 3/2, [2,1]);
                            z = [zeros(1,5); ones(1,5)] * 0.25 + dz - dx*sin(t);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Lightgrey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            [x,y,z] = cylinder(0.1,12);
                            x = x + dx*cos(t);
                            z = -z - 0.25 + dz  - dx*sin(t);
                            for i = [-1, 1]
                                for j = [-1, 1]
                                    surf(plotAxes, x+i,y+j,z, ...
                                        FaceColor = comp.Colour.Grey, ...
                                        EdgeColor = comp.Colour.EdgeColour, ...
                                        AmbientStrength = 0.5, ...
                                        DiffuseStrength = 0.5, ...
                                        SpecularStrength = 0.5, ...
                                        BackFaceLighting = "reverselit");
                                end
                            end

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                        case 7

                            x = [zeros(1,17); ones(1,17)] * Nacelle.Housing.Length - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1);
                            y = repmat([-1, -0.9, 0.9, 1, 1, 0.95, 0.95, 1, 1, 0.9, -0.9, -1, -1, -0.95, -0.95, -1, -1], [2,1]) * Nacelle.Housing.Diameter/2;
                            z = repmat([0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9], [2,1]) * Nacelle.Housing.Diameter/2;
                            x(2,:) = x(2,:) + 0.5*[0.9, 1, 1, 0.9, 0.1, 0.1, -0.1, -0.1, -0.9, -1, -1, -0.9, -0.1, -0.1, 0.1, 0.1, 0.9];
                            z(2,:) = 7/8*z(2,:);
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),size(x));
                            y = reshape(A(2,:),size(x));
                            z = reshape(A(3,:),size(z));
                            z(2,[1,4,17]) = 0.9*Nacelle.Housing.Diameter/2;
                            z(2,2:3) = Nacelle.Housing.Diameter/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.White, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            x = [-1-sqrt(2), -1, 1, 1+sqrt(2), 1+sqrt(2), 1, -1, -1-sqrt(2), -1-sqrt(2)] + 1+sqrt(2);
                            y = [1, 1+sqrt(2), 1+sqrt(2), 1, -1, -1-sqrt(2), -1-sqrt(2), -1, 1];
                            x = repmat(x,[2,1])*2.5 - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 1;
                            y = repmat(y,[2,1])*2.5;
                            z = [zeros(1,9); ones(1,9)]*0.5 + Nacelle.Housing.Diameter/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:),y(1,:),z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:),y(end,:),z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            dx = - Nacelle.Hub.Overhang + 0.3 + 0.5*Blade.Chord(1) + 1 + (1+sqrt(2))*2.5;
                            x = ([0, 0, 0, 0; 1, 1, 1, 1] - 0.5) * 0.5 + dx;
                            y = [0, 1, 0, 0; 0, 1, 0, 0];
                            z = [-1, 0, 0, -1; -1, 0, 0, -1] + Nacelle.Housing.Diameter/2;
                            if Nacelle.Housing.Diameter < 10
                                for i = [-1 1]
                                    for j = [-1 1]
                                        surf(plotAxes, x + 1.5*i,y*j + Nacelle.Housing.Diameter/2*j,z, ...
                                            FaceColor = comp.Colour.Lightgrey, ...
                                            EdgeColor = comp.Colour.EdgeColour, ...
                                            AmbientStrength = 0.5, ...
                                            DiffuseStrength = 0.5, ...
                                            SpecularStrength = 0.5, ...
                                            BackFaceLighting = "reverselit");
                                    end
                                end
                            end
                            h = 0.75;
                            q = linspace(0,2*pi,25);
                            x = [(5+2/3*h)*cos(q); 5*cos(q)];
                            y = [(5+2/3*h)*sin(q); 5*sin(q)];
                            z = zeros(size(x));
                            x = x + dx;
                            z = z+0.01 + 0.5 + Nacelle.Housing.Diameter/2;
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            x = [1, 1, 0, 0, 1, 1, -1, -1, 0, 0, -1, -1, 1]*2 + [0, 0, h/2, h/2, 0, 0, 0, 0, -h/2, -h/2, 0, 0, 0];
                            y = [-1, -1, -1, 1, 1, 1, 1, 1, 1, -1, -1, -1, -1]*2 + [0, h, h, -h, -h, 0, 0, -h, -h, h, h, 0, 0];
                            z = zeros(size(x));
                            x = x + dx;
                            z = z+0.01 + 0.5 + Nacelle.Housing.Diameter/2;
                            patch(plotAxes, x,y,z, ...
                                'g', ...
                                FaceColor = comp.Colour.Yellow, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                            [x,y,z] = cylinder([1 0.9 0.9]*Nacelle.Housing.Diameter/2,N-1);
                            z = z*0.3 - Nacelle.Hub.Overhang + 0.5*Blade.Chord(1);
                            A = [reshape(z,1,[]); reshape(x,1,[]); reshape(y,1,[])];
                            A = Ry * A;
                            x = reshape(A(1,:),3,N);
                            y = reshape(A(2,:),3,N);
                            z = reshape(A(3,:),3,N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");

                    end

                    % Plot nose cone
                    if Nacelle.Housing.Type == 4
                        R = min(Blade.Chord(1)*3/4, Tower.Diameter(end)/2);
                    else
                        R = Nacelle.Housing.Diameter/2;
                    end
                    switch Nacelle.Hub.Type
                        case 1

                            Nx = 50;
                            r = repmat(R*sin(linspace(0,pi/2,Nx)),[N,1]);
                            azi = repmat(linspace(0,2*pi,N)',[1,Nx]);
                            x = 0.5*Blade.Chord(1) - repmat(cos(linspace(0,pi/2,Nx)),[N,1])*Nacelle.Hub.Length - Nacelle.Hub.Overhang;
                            y = r.*cos(azi);
                            z = r.*sin(azi);

                        case 2

                            Nx = 4;
                            [x_,y_,z_] = cylinder([1, 0.99]*R,N-1);
                            x = z_'; y = x_'; z = y_';
                            x = [x * 0.9, x(:,end), x(:,end)];
                            y = [y, y(:,end) * 0.9, y(:,end) * 0];
                            z = [z, z(:,end) * 0.9, z(:,end) * 0];
                            x = 0.5*Blade.Chord(1) - x*Nacelle.Hub.Length - Nacelle.Hub.Overhang;

                        case 3

                            Nx = 3;
                            [x_,y_,z_] = cylinder([1, 0.99]*R,N-1);
                            x = z_'; y = x_'; z = y_';
                            x = [x*0.5*Blade.Chord(1)/Nacelle.Hub.Length, x(:,end)];
                            y = [y, y(:,end) * 0];
                            z = [z, z(:,end) * 0];
                            x = 0.5*Blade.Chord(1) - x*Nacelle.Hub.Length - Nacelle.Hub.Overhang;

                        case 4

                            Nx = 50;
                            rho = (R^2 + Nacelle.Hub.Length^2)/(2*R);
                            x = linspace(0,Nacelle.Hub.Length,Nx);
                            r = repmat(sqrt(rho^2 - (Nacelle.Hub.Length-x).^2) + R - rho,[N,1]);
                            azi = repmat(linspace(0,2*pi,N)',[1,Nx]);
                            x = repmat(x-Nacelle.Hub.Length,[N,1]) + 0.5*Blade.Chord(1) - Nacelle.Hub.Overhang;
                            y = r.*cos(azi);
                            z = r.*sin(azi);

                    end
                    A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                    A = Ry * A;
                    x = reshape(A(1,:),N,Nx);
                    y = reshape(A(2,:),N,Nx);
                    z = reshape(A(3,:),N,Nx);
                    surf(plotAxes, x,y,z, ...
                        FaceColor = comp.Colour.Lightergrey, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    % Plot blades
                    o = 2*pi/Blade.Number;
                    t = Nacelle.Hub.ShaftTilt*pi/180;
                    Ry = [cos(t), 0, sin(t); ...
                        0,      1, 0; ...
                        -sin(t), 0, cos(t)];
                    c = -Blade.Cone*pi/180;
                    Rc = [cos(c), 0, sin(c); ...
                        0,      1, 0; ...
                        -sin(c), 0, cos(c)];
                    if Blade.Number == 1
                        do = pi/2;
                    else
                        do = pi - pi/Blade.Number;
                    end
                    for B = 1:Blade.Number
                        Rx = [1, 0,      0; ...
                            0, cos(B*o + do),-sin(B*o + do); ...
                            0, sin(B*o + do), cos(B*o + do)];
                        [x,y,z] = cylinder(Blade.Chord(1)/2,400);
                        z = z*(R+1/3);
                        A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        N = size(x);
                        x = reshape(A(1,:),N);
                        y = reshape(A(2,:),N);
                        z = reshape(A(3,:),N);
                        surf(plotAxes, x,y,z, ...
                            FaceColor = comp.Colour.White, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        [x_,y_,z_] = cylinder(0.8*Blade.Chord(1)/2,400);
                        z_ = R + z_*1/3;
                        if Nacelle.Hub.Type == 2
                            z_(1,:) = z_(1,:) + 0.1;
                        end
                        A = [reshape(x_,1,[]); reshape(y_,1,[]); reshape(z_,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        N = size(x_);
                        x_ = reshape(A(1,:),N);
                        y_ = reshape(A(2,:),N);
                        z_ = reshape(A(3,:),N);
                        surf(plotAxes, x_,y_,z_, ...
                            FaceColor = comp.Colour.White, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        patch(plotAxes, ...
                            [x(end,:), flip(x_(end,:))], ...
                            [y(end,:), flip(y_(end,:))], ...
                            [z(end,:), flip(z_(end,:))], ...
                            'g', ...
                            FaceColor = comp.Colour.Lightergrey, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        patch(plotAxes, x_(1,:), y_(1,:), z_(1,:), ...
                            'g', ...
                            FaceColor = comp.Colour.Lightgrey, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        R_ = 0.9/2*Blade.Chord(1);
                        r = 0.05/2*Blade.Chord(1);
                        [x_,y_,z_] = cylinder([r r],12);
                        for q = pi/15:pi/15:2*pi
                            x = x_ + R_*cos(q);
                            y = y_ + R_*sin(q);
                            z = 0.3*z_ + R + 1/3;
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Rc * A;
                            A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                            A = Ry * Rx * A;
                            N = size(x_);
                            x = reshape(A(1,:),N);
                            y = reshape(A(2,:),N);
                            z = reshape(A(3,:),N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(end,:), y(end,:), z(end,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                        end

                    end

                    if Blade.Number == 1

                        c = Blade.Cone*pi/180;
                        Rc = [cos(c), 0, sin(c); ...
                            0,      1, 0; ...
                            -sin(c), 0, cos(c)];
                        do = do + pi;
                        Rx = [1, 0,      0; ...
                            0, cos(B*o + do),-sin(B*o + do); ...
                            0, sin(B*o + do), cos(B*o + do)];
                        [x,y,z] = cylinder(Blade.Chord(1)/2*2/3,400);
                        z = z*(R+1/3);
                        A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        N = size(x);
                        x = reshape(A(1,:),N);
                        y = reshape(A(2,:),N);
                        z = reshape(A(3,:),N);
                        surf(plotAxes, x,y,z, ...
                            FaceColor = comp.Colour.White, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        [x_,y_,z_] = cylinder(0.8*Blade.Chord(1)/2*2/3,400);
                        z_ = z_*1/3 + R;
                        if Nacelle.Hub.Type == 2
                            z_(1,:) = z_(1,:) + 0.1;
                        end
                        A = [reshape(x_,1,[]); reshape(y_,1,[]); reshape(z_,1,[])];
                        A = Rc * A;
                        A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                        A = Ry * Rx * A;
                        N = size(x_);
                        x_ = reshape(A(1,:),N);
                        y_ = reshape(A(2,:),N);
                        z_ = reshape(A(3,:),N);
                        surf(plotAxes, x_,y_,z_, ...
                            FaceColor = comp.Colour.White, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        patch(plotAxes, ...
                            [x(end,:), flip(x_(end,:))], ...
                            [y(end,:), flip(y_(end,:))], ...
                            [z(end,:), flip(z_(end,:))], ...
                            'g', ...
                            FaceColor = comp.Colour.Lightergrey, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        patch(plotAxes, x_(1,:), y_(1,:), z_(1,:), ...
                            'g', ...
                            FaceColor = comp.Colour.Lightgrey, ...
                            EdgeColor = comp.Colour.EdgeColour, ...
                            AmbientStrength = 0.5, ...
                            DiffuseStrength = 0.5, ...
                            SpecularStrength = 0.5, ...
                            BackFaceLighting = "reverselit");

                        R_ = 0.9/2*Blade.Chord(1) * 2/3;
                        r = 0.05/2*Blade.Chord(1) * 2/3;
                        [x_,y_,z_] = cylinder([r r],12);
                        for q = pi/15:pi/15:2*pi
                            x = x_ + R_*cos(q);
                            y = y_ + R_*sin(q);
                            z = 0.3*z_ + R + 1/3;
                            A = [reshape(x,1,[]); reshape(y,1,[]); reshape(z,1,[])];
                            A = Rc * A;
                            A(1,:) = A(1,:) - Nacelle.Hub.Overhang;
                            A = Ry * Rx * A;
                            N = size(x_);
                            x = reshape(A(1,:),N);
                            y = reshape(A(2,:),N);
                            z = reshape(A(3,:),N);
                            surf(plotAxes, x,y,z, ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                            patch(plotAxes, x(1,:), y(1,:), z(1,:), ...
                                'g', ...
                                FaceColor = comp.Colour.Grey, ...
                                EdgeColor = comp.Colour.EdgeColour, ...
                                AmbientStrength = 0.5, ...
                                DiffuseStrength = 0.5, ...
                                SpecularStrength = 0.5, ...
                                BackFaceLighting = "reverselit");
                        end

                    end

                    % Tower
                    [x,y,z] = cylinder(Tower.Diameter(end)/2,400);
                    z = -z*(Nacelle.Housing.Diameter/2 + 1);
                    surf(plotAxes, x,y,z, ...
                        FaceColor = comp.Colour.White, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    [x_,y_,z_] = cylinder(0.95*Tower.Diameter(end)/2,400);
                    z_ = -z_*(Nacelle.Housing.Diameter/2 + 1);
                    surf(plotAxes, x_,y_,z_, ...
                        FaceColor = comp.Colour.White, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    patch(plotAxes, ...
                        [x(end,:), flip(x_(end,:))], ...
                        [y(end,:), flip(y_(end,:))], ...
                        [z(end,:), flip(z_(end,:))], ...
                        'g', ...
                        FaceColor = comp.Colour.Lightergrey, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    patch(plotAxes, x_(end,:), y_(end,:), z_(end,:) + 2/3, ...
                        'g', ...
                        FaceColor = comp.Colour.Lightgrey, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                case 'Tower'
                    view(plotAxes, 0,180/pi*atan(sin(pi/4)));

                    xlim(plotAxes, [-0.5 0.5] * max(Tower.Diameter));
                    ylim(plotAxes, [-0.5 0.5] * max(Tower.Diameter));
                    zlim(plotAxes, [min(Tower.Height) max(Tower.Height)]);

                    N = 200 + 1;
                    s = Tower.Height/Tower.Height(end);
                    switch TowerStyle
                        case 1
                            paint = ones(size(s));
                        case 2
                            paint = zeros(size(s));
                            s = 0.05*round(20*s);
                            paint(s >= 0.2) = 1;
                            paint(s >= 0.4) = 0;
                            paint(s >= 0.6) = 1;
                            paint(s >= 0.8) = 0;
                            paint(s >= 1.0) = 1;
                        case 3
                            paint = zeros(size(s));
                            paint(s > 0.25) = 1;
                            paint(s > 0.31) = 0;
                        case 4
                            paint = (s > 0.8);
                        case 5
                            paint = zeros(size(s));
                            paint(Tower.Height <= 30) = 1;
                        case 6
                            paint = zeros(size(s));
                            n = length(s(Tower.Height <= 40));
                            paint(1:n) = linspace(1,0,n);
                        case 7
                            paint = [ones(1,size(s,2)); zeros(1,size(s,2))];
                            s = 0.05*round(20*s);
                            paint(1,s >= 0.1) = 0;
                            paint(1,s >= 0.2) = 1;
                            paint(1,s >= 0.3) = 0;
                            paint(1,s >= 0.4) = 1;
                            paint(1,s >= 0.5) = 0;
                            paint(1,s >= 0.6) = 1;
                            paint(1,s >= 0.7) = 0;
                            paint(1,s >= 0.8) = 1;
                            paint(1,s >= 0.9) = 0;
                            paint(1,s >= 1.0) = 1;
                            paint(2,s >= 0.1) = 1;
                            paint(2,s >= 0.2) = 0;
                            paint(2,s >= 0.3) = 1;
                            paint(2,s >= 0.4) = 0;
                            paint(2,s >= 0.5) = 1;
                            paint(2,s >= 0.6) = 0;
                            paint(2,s >= 0.7) = 1;
                            paint(2,s >= 0.8) = 0;
                            paint(2,s >= 0.9) = 1;
                            paint(2,s >= 1.0) = 0;
                            paint = [paint; paint];
                        case 8
                            paint = (s < 0.15);
                    end
                    if TowerStyle == 7
                        paint = kron(paint,ones(round(N/4),1));
                        paint = paint(1:N-1,:);
                        TowerColours = zeros([size(paint),3]);
                        TowerColours(:,:,1) = paint*TowerPaint(1) + (1-paint)*comp.Colour.White(1);
                        TowerColours(:,:,2) = paint*TowerPaint(2) + (1-paint)*comp.Colour.White(2);
                        TowerColours(:,:,3) = paint*TowerPaint(3) + (1-paint)*comp.Colour.White(3);
                        TowerColours = permute(TowerColours,[2,1,3]);
                    else
                        TowerColours = kron(paint(:),TowerPaint) + kron((1-paint(:)),comp.Colour.White);
                        TowerColours = repmat(TowerColours,[1,1,N]);
                        TowerColours = permute(TowerColours,[1,3,2]);
                    end

                    r = repmat(Tower.Diameter(:)/2,[1,N]);
                    azi = repmat(linspace(0,2*pi,N),[length(Tower.Diameter),1]);
                    x = r.*cos(azi);
                    y = r.*sin(azi);
                    z = repmat(Tower.Height(:),[1,N]);

                    TowerColours = [TowerColours, TowerColours(:,end,:)];
                    surf(plotAxes, x,y,z, ...
                        CData = TowerColours, ...
                        FaceColor = "texturemap", ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    for i = 1:length(Tower.Height)
                        x_(i,:) = x(i,:) * (Tower.Diameter(i) - 2*Tower.WallThickness(i))/Tower.Diameter(i);
                        y_(i,:) = y(i,:) * (Tower.Diameter(i) - 2*Tower.WallThickness(i))/Tower.Diameter(i);
                    end
                    z_ = z;

                    surf(plotAxes, x_,y_,z_, ...
                        FaceColor = comp.Colour.Grey, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    patch(plotAxes, ...
                        [x(1,:), flip(x_(1,:))], ...
                        [y(1,:), flip(y_(1,:))], ...
                        [z(1,:), flip(z_(1,:))], ...
                        'g', ...
                        FaceColor = comp.Colour.White, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

                    patch(plotAxes, ...
                        [x(end,:), flip(x_(end,:))], ...
                        [y(end,:), flip(y_(end,:))], ...
                        [z(end,:), flip(z_(end,:))], ...
                        'g', ...
                        FaceColor = comp.Colour.White, ...
                        EdgeColor = comp.Colour.EdgeColour, ...
                        AmbientStrength = 0.5, ...
                        DiffuseStrength = 0.5, ...
                        SpecularStrength = 0.5, ...
                        BackFaceLighting = "reverselit");

            end
            if strcmp(comp.AnimateButton.Text, "Stop") && ~strcmp(comp.animationTimer.Running, "on")
                start(comp.animationTimer);
            end
        end
    end
    
    methods (Access = private)
        
        function applyEdits(comp)
            comp.WindTurbine.Appearance = ...
                [comp.SiteDropDown.ValueIndex; ...
                comp.BladeStyleDropDown.ValueIndex; ...
                comp.BladeColourDropDown.ValueIndex; ...
                comp.TowerStyleDropDown.ValueIndex; ...
                comp.TowerColourDropDown.ValueIndex];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.Colour.EdgeColour = 'none';
            comp.Colour.White = [255, 255, 255]/255;
            comp.Colour.Lightergrey = [200, 200, 200]/255;
            comp.Colour.Lightgrey = [174, 174, 174]/255;
            comp.Colour.Grey = [120, 120, 120]/255;
            comp.Colour.Red = [189, 112, 82]/255;
            comp.Colour.Brown = [146, 95, 54]/255;
            comp.Colour.Green = [194, 184, 101]/255;
            comp.Colour.Yellow = [249, 178, 51]/255;
            comp.Colour.Blue = [0 165, 213]/255;
            comp.Colour.Orange = [245, 162, 77]/255;
            comp.Colour.Rock = 0.5*(comp.Colour.Grey + comp.Colour.Brown);
            comp.Colour.Sand = [223, 208, 165]/255;
            comp.Colour.Sea = [77, 142, 150]/255;
            comp.ColourArray = [...
                comp.Colour.White; ...
                comp.Colour.Lightergrey; ...
                comp.Colour.Lightgrey; ...
                comp.Colour.Grey; ...
                comp.Colour.Red; ...
                comp.Colour.Green; ...
                comp.Colour.Yellow; ...
                comp.Colour.Blue; ...
                comp.Colour.Orange; ...
                comp.Colour.Brown; ...
                comp.Colour.Rock; ...
                comp.Colour.Sand; ...
                comp.Colour.Sea];

            comp.Lighting.Steel = [0.8, 0.5, 0.9];
            comp.Lighting.Glassfiber = [0.7, 0.7, 0.2];
            comp.Lighting.Nature = [0.9, 0.2, 0.0];

            comp.animationTimer = timer(...
                StartDelay = 0.02, ...
                Period = 0.02, ...
                ExecutionMode = "fixedSpacing", ...
                TimerFcn = @(~,~) comp.updateRotation);                
        end

        % Value changed function: DisplayDropDown
        function DisplayDropDownValueChanged(comp, event)
            if strcmp(comp.DisplayDropDown.Value, "Wind turbine")
                comp.WindstreaksCheckBox.Visible = "on";
                comp.AnimateButton.Visible = "on";
            else
                comp.WindstreaksCheckBox.Visible = "off";
                comp.AnimateButton.Visible = "off";
            end
            plotSpecs(comp);
        end

        % Value changed function: BladeColourDropDown, BladeStyleDropDown, 
        % ...and 3 other components
        function TabDataChanged(comp, event)
            applyEdits(comp);
            comp.specsChanged = true;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(comp, event)
            [Data] = getRestoredata(comp.mainApp);
            setSpecs(comp, Data);
            comp.specsChanged = false;
            updateForChangedSpecs(comp.mainApp);            
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(comp, event)
            if strcmp(comp.AnimateButton.Text, "Animate")
                comp.previousTimerVal = tic;
                start(comp.animationTimer);
                comp.AnimateButton.Text = "Stop";
            else
                stop(comp.animationTimer);
                comp.AnimateButton.Text = "Animate";
            end
        end

        % Button pushed function: UndockButton
        function UndockButtonPushed(comp, event)
            comp.undock = true;
            plotSpecs(comp);
        end

        % Value changed function: WindstreaksCheckBox
        function WindstreaksCheckBoxValueChanged(comp, event)
            plotSpecs(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 590 670];
            comp.GridLayout = uigridlayout(comp);
            comp.PlotPanel = uipanel(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.PlotPanel);
            comp.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            comp.UIAxes.Visible = 'off';
            comp.AnimateButton = uibutton(comp.PlotPanel, 'push');
            comp.AnimateButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AnimateButtonPushed, true);
            comp.AnimateButton.Tooltip = {'Animate rotation at rated conditions'};
            comp.AnimateButton.Text = 'Animate';
            comp.WindstreaksCheckBox = uicheckbox(comp.PlotPanel);
            comp.WindstreaksCheckBox.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @WindstreaksCheckBoxValueChanged, true);
            comp.WindstreaksCheckBox.Text = 'Wind streaks';
            comp.UndockButton = uibutton(comp.PlotPanel, 'push');
            comp.UndockButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UndockButtonPushed, true);
            comp.UndockButton.Text = 'Undock';
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.SitingPanel = uipanel(comp.GridLayout2);
            comp.SitingPanel.Title = 'Siting';
            comp.GridLayout21 = uigridlayout(comp.SitingPanel);
            comp.BladeStyleDropDown = uidropdown(comp.GridLayout21);
            comp.BladeStyleDropDown.Items = {'Full colour', 'Striped 1', 'Striped 2', 'Tips 1', 'Tips 2', 'Tips 3', 'Checkered', 'Roots'};
            comp.BladeStyleDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.BladeStyleDropDown.Value = 'Full colour';
            comp.BladeColourDropDown = uidropdown(comp.GridLayout21);
            comp.BladeColourDropDown.Items = {'White', 'Light grey', 'Grey', 'Black', 'Red', 'Green', 'Yellow', 'Blue', 'Orange', 'Brown', 'Rock', 'Sand', 'Sea'};
            comp.BladeColourDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.BladeColourDropDown.Value = 'White';
            comp.TowerStyleDropDown = uidropdown(comp.GridLayout21);
            comp.TowerStyleDropDown.Items = {'Full colour', 'Striped', 'Single stripe', 'Top', 'Treeline', 'Treeline gradient', 'Checkered', 'Base'};
            comp.TowerStyleDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.TowerStyleDropDown.Value = 'Full colour';
            comp.TowerColourDropDown = uidropdown(comp.GridLayout21);
            comp.TowerColourDropDown.Items = {'White', 'Light grey', 'Grey', 'Black', 'Red', 'Green', 'Yellow', 'Blue', 'Orange', 'Brown', 'Rock', 'Sand', 'Sea'};
            comp.TowerColourDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.TowerColourDropDown.Value = 'White';
            comp.SiteDropDown = uidropdown(comp.GridLayout21);
            comp.SiteDropDown.Items = {'Grassland', 'Rough Pasture', 'Forest', 'Hill', 'Mountain', 'Coastal', 'Offshore', 'Far offshore'};
            comp.SiteDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @TabDataChanged, true);
            comp.SiteDropDown.Value = 'Grassland';
            comp.BladeLabel = uilabel(comp.GridLayout21);
            comp.BladeLabel.Text = 'Blade';
            comp.TowerLabel = uilabel(comp.GridLayout21);
            comp.TowerLabel.Text = 'Tower';
            comp.StyleLabel = uilabel(comp.GridLayout21);
            comp.StyleLabel.Text = 'Style';
            comp.ColourLabel = uilabel(comp.GridLayout21);
            comp.ColourLabel.Text = 'Colour';
            comp.SiteLabel = uilabel(comp.GridLayout21);
            comp.SiteLabel.Text = 'Site';
            comp.RestoreButton = uibutton(comp.GridLayout21, 'push');
            comp.RestoreButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RestoreButtonPushed, true);
            comp.RestoreButton.Tooltip = {'Restore data from the project file'};
            comp.RestoreButton.Text = 'Restore';
            comp.SelectionPanel = uipanel(comp.GridLayout2);
            comp.GridLayout22 = uigridlayout(comp.SelectionPanel);
            comp.DisplayDropDown = uidropdown(comp.GridLayout22);
            comp.DisplayDropDown.Items = {'Wind turbine', 'Blade', 'Nacelle', 'Tower'};
            comp.DisplayDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DisplayDropDownValueChanged, true);
            comp.DisplayDropDown.Value = 'Wind turbine';
            comp.DisplayLabel = uilabel(comp.GridLayout22);
            comp.DisplayLabel.Text = 'Display';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end