classdef AirfoilDesign_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AirfoilDesign             matlab.ui.Figure
        panelStaticProperties     matlab.ui.container.Panel
        AirfoilPerformance_TableSize_slider  matlab.ui.control.Slider
        AirfoilPerformance_Table  matlab.ui.control.Table
        text3                     matlab.ui.control.Label
        AirfoilPerformance_TableSize_textbox  matlab.ui.control.EditField
        AirfoilPerformance_EditCells_checkbox  matlab.ui.control.CheckBox
        PlotProperties            matlab.ui.control.Button
        Apply                     matlab.ui.control.Button
        Cancel                    matlab.ui.control.Button
        panelAirfoilCollection    matlab.ui.container.Panel
        AirfoilCollection         matlab.ui.control.ListBox
        NewAirfoil                matlab.ui.control.Button
        DeleteAirfoil             matlab.ui.control.Button
        panelDynamicStall         matlab.ui.container.Panel
        StallAngle1_textbox       matlab.ui.control.EditField
        text18                    matlab.ui.control.Label
        StallAngle2_textbox       matlab.ui.control.EditField
        text19                    matlab.ui.control.Label
        text20                    matlab.ui.control.Label
        CnSlope_textbox           matlab.ui.control.EditField
        text21                    matlab.ui.control.Label
        CritCn1_textbox           matlab.ui.control.EditField
        CritCn2_textbox           matlab.ui.control.EditField
        text22                    matlab.ui.control.Label
        text23                    matlab.ui.control.Label
        text24                    matlab.ui.control.Label
        text29                    matlab.ui.control.Label
        panelAirfoilCoordinates   matlab.ui.container.Panel
        AirfoilCoordinates_TableSize_slider  matlab.ui.control.Slider
        AirfoilCoordinates_Table  matlab.ui.control.Table
        text28                    matlab.ui.control.Label
        AirfoilCoordinates_TableSize_textbox  matlab.ui.control.EditField
        AirfoilCoordinates_EditCells_checkbox  matlab.ui.control.CheckBox
        PlotShape                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        WindTurbineDesign_App % Main window
    end
    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.AirfoilPerformance_Table.ColumnFormat = {'numeric' 'numeric' 'numeric' []};
            app.AirfoilCoordinates_Table.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.AirfoilCoordinates_Table.ColumnFormat = {'numeric' 'numeric'};
        end
        
        function AirfoilCollection_Callback_wrapper(app, event)
            AirfoilCollection_Callback(app,event);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function AirfoilDesign_OpeningFcn(app, wtdMainApp, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            app.WindTurbineDesign_App = wtdMainApp;
            
            % Get input
            handles.Airfoil = varargin{1};
            handles.Blade = varargin{2};
            handles.Input = varargin;
            
            % Update input fields
            set(handles.AirfoilCollection, 'String', handles.Airfoil.Name)
            
            % Selected airfoil
            i = get(handles.AirfoilCollection,'Value');
            handles.CurrentAirfoil = i;
            
            % Update stall characteristics
            set(handles.StallAngle1_textbox,'String',num2str(handles.Airfoil.StallAngle1(i)));
            set(handles.StallAngle2_textbox,'String',num2str(handles.Airfoil.StallAngle2(i)));
            set(handles.CnSlope_textbox,'String',num2str(handles.Airfoil.CnSlope(i)));
            set(handles.CritCn1_textbox,'String',num2str(handles.Airfoil.CritCn1(i)));
            set(handles.CritCn2_textbox,'String',num2str(handles.Airfoil.CritCn2(i)));
            
            % Update tables
            set(handles.AirfoilPerformance_Table, 'Data', ...
               [num2cell(handles.Airfoil.Alpha{i}), ...
                num2cell(handles.Airfoil.Cl{i}), ...
                num2cell(handles.Airfoil.Cd{i}), ...
                num2cell(handles.Airfoil.Cm{i})]);
            set(handles.AirfoilCoordinates_Table, 'Data', ...
               [num2cell(handles.Airfoil.Geometry{i}(1,:)'), ...
                num2cell(handles.Airfoil.Geometry{i}(2,:)')]);
            set(handles.AirfoilPerformance_TableSize_textbox, 'String', length(handles.Airfoil.Alpha{i}));
            set(handles.AirfoilPerformance_TableSize_slider, 'Value', length(handles.Airfoil.Alpha{i}));
            set(handles.AirfoilCoordinates_TableSize_textbox, 'String', length(handles.Airfoil.Geometry{i}(1,:)));
            set(handles.AirfoilCoordinates_TableSize_slider, 'Value', length(handles.Airfoil.Geometry{i}(1,:)));
            
            % Update airfoil plot
            %DrawAirfoil(handles)
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Halt window
            uiwait(handles.AirfoilDesign);
        end

        % Value changed function: AirfoilCollection
        function AirfoilCollection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Get table data
            app.AirfoilPerformance_Table = get(handles.AirfoilPerformance_Table, 'Data');
            app.AirfoilCoordinates_Table = get(handles.AirfoilCoordinates_Table, 'Data');
            
            % Find invalid cells
            for i = 1:size(app.AirfoilPerformance_Table,1)
                for j = 1:size(app.AirfoilPerformance_Table,2)
                    invalid(i,j) = ...
                        isempty(cell2mat(app.AirfoilPerformance_Table(i,j))) + ...
                        sum(isnan(cell2mat(app.AirfoilPerformance_Table(i,j))));
                end
            end
            
            % Fill vectors
            Alpha = [];
            Cl = [];
            Cd = [];
            Cm = [];
            for i = 1:size(app.AirfoilPerformance_Table,1)
                if sum(invalid(i,:)) == 0
                    Alpha = [Alpha; app.AirfoilPerformance_Table{i,1}];
                    Cl = [Cl; app.AirfoilPerformance_Table{i,2}];
                    Cd = [Cd; app.AirfoilPerformance_Table{i,3}];
                    Cm = [Cm; app.AirfoilPerformance_Table{i,4}];
                end
            end
            
            % Find invalid cells
            for i = 1:size(app.AirfoilCoordinates_Table,1)
                for j = 1:size(app.AirfoilCoordinates_Table,2)
                    invalid(i,j) = ...
                        isempty(cell2mat(app.AirfoilCoordinates_Table(i,j))) + ...
                        sum(isnan(cell2mat(app.AirfoilCoordinates_Table(i,j))));
                end
            end
            
            % Fill vectors
            Geometry = [];
            for i = 1:size(app.AirfoilCoordinates_Table,1)
                if sum(invalid(i,:)) == 0
                    Geometry = [Geometry, cell2mat(app.AirfoilCoordinates_Table(i,:))'];
                end
            end
            
            % Update values
            i = handles.CurrentAirfoil;
            handles.Airfoil.Alpha{i} = Alpha;
            handles.Airfoil.Cl{i} = Cl;
            handles.Airfoil.Cd{i} = Cd;
            handles.Airfoil.Cm{i} = Cm;
            handles.Airfoil.Geometry{i} = Geometry;
            handles.Airfoil.StallAngle1(i) = str2double(get(handles.StallAngle1_textbox,'String'));
            handles.Airfoil.StallAngle2(i) = str2double(get(handles.StallAngle2_textbox,'String'));
            handles.Airfoil.CnSlope(i) = str2double(get(handles.CnSlope_textbox,'String'));
            handles.Airfoil.CritCn1(i) = str2double(get(handles.CritCn1_textbox,'String'));
            handles.Airfoil.CritCn2(i) = str2double(get(handles.CritCn2_textbox,'String'));
            
            % Selected airfoil
            i = get(hObject,'Value');
            handles.CurrentAirfoil = i;
            
            % Update stall characteristics
            set(handles.StallAngle1_textbox,'String',num2str(handles.Airfoil.StallAngle1(i)));
            set(handles.StallAngle2_textbox,'String',num2str(handles.Airfoil.StallAngle2(i)));
            set(handles.CnSlope_textbox,'String',num2str(handles.Airfoil.CnSlope(i)));
            set(handles.CritCn1_textbox,'String',num2str(handles.Airfoil.CritCn1(i)));
            set(handles.CritCn2_textbox,'String',num2str(handles.Airfoil.CritCn2(i)));
            
            % Update tables
            set(handles.AirfoilPerformance_Table, 'Data', ...
               [num2cell(handles.Airfoil.Alpha{i}), ...
                num2cell(handles.Airfoil.Cl{i}), ...
                num2cell(handles.Airfoil.Cd{i}), ...
                num2cell(handles.Airfoil.Cm{i})]);
            set(handles.AirfoilCoordinates_Table, 'Data', ...
               [num2cell(handles.Airfoil.Geometry{i}(1,:)'), ...
                num2cell(handles.Airfoil.Geometry{i}(2,:)')]);
            set(handles.AirfoilPerformance_TableSize_textbox, 'String', length(handles.Airfoil.Alpha{i}));
            set(handles.AirfoilPerformance_TableSize_slider, 'Value', length(handles.Airfoil.Alpha{i}));
            set(handles.AirfoilCoordinates_TableSize_textbox, 'String', length(handles.Airfoil.Geometry{i}(1,:)));
            set(handles.AirfoilCoordinates_TableSize_slider, 'Value', length(handles.Airfoil.Geometry{i}(1,:)));
            
            % Update airfoil plot
            %DrawAirfoil(handles)
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: 
        % AirfoilCoordinates_EditCells_checkbox
        function AirfoilCoordinates_EditCells_checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if get(hObject, 'Value') == 1
                set(handles.AirfoilCoordinates_Table, 'ColumnEditable', [true true]);
            else
                set(handles.AirfoilCoordinates_Table, 'ColumnEditable', [false false]);
            end
        end

        % Value changed function: 
        % AirfoilCoordinates_TableSize_slider
        function AirfoilCoordinates_TableSize_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            rows = get(hObject, 'Value');
            Table = get(handles.AirfoilCoordinates_Table, 'Data');
            if rows < 2
                set(hObject, 'Value', 2);
            else
                if rows < size(Table,1)
                    Table = Table(1:rows,:);
                    set(handles.AirfoilCoordinates_Table, 'Data', Table);
                elseif rows > size(Table,1)
                    Table{rows,size(Table,2)} = [];
                    set(handles.AirfoilCoordinates_Table, 'Data', Table);
                end
                set(handles.AirfoilCoordinates_TableSize_textbox, 'String', int2str(rows));
            end
        end

        % Value changed function: 
        % AirfoilCoordinates_TableSize_textbox
        function AirfoilCoordinates_TableSize_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            rows = ceil(str2double(get(hObject, 'String')));
            Table = get(handles.AirfoilCoordinates_Table, 'Data');
            if rows < 2
                rows = 2;
            elseif rows > get(handles.AirfoilCoordinates_TableSize_slider, 'Max')
                rows = get(handles.AirfoilCoordinates_TableSize_slider, 'Max');
            elseif isnan(rows)
                rows = size(Table,1);
            end
            if rows < size(Table,1)
                Table = Table(1:rows,:);
                set(handles.AirfoilCoordinates_Table, 'Data', Table);
            elseif rows > size(Table,1)
                Table{rows,size(Table,2)} = [];
                set(handles.AirfoilCoordinates_Table, 'Data', Table);
            end
            set(hObject, 'String', int2str(rows));
            set(handles.AirfoilCoordinates_TableSize_slider, 'Value', rows);
        end

        % Cell selection callback: AirfoilCoordinates_Table
        function AirfoilCoordinates_Table_CellSelectionCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            handles.Selection = eventdata.Indices;
            guidata(hObject, handles);
        end

        % Close request function: AirfoilDesign
        function AirfoilDesign_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            button = questdlg('Save changes?');
            if strcmp(button, 'Yes')
                handles.Save = true;
            elseif strcmp(button, 'No')
                handles.Save = false;
            end
            guidata(hObject, handles);
            uiresume(hObject);
            delete(app);
        end

        % Value changed function: 
        % AirfoilPerformance_EditCells_checkbox
        function AirfoilPerformance_EditCells_checkbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if get(hObject, 'Value') == 1
                set(handles.AirfoilPerformance_Table, 'ColumnEditable', [true true true true]);
            else
                set(handles.AirfoilPerformance_Table, 'ColumnEditable', [false false false false]);
            end
        end

        % Value changed function: 
        % AirfoilPerformance_TableSize_slider
        function AirfoilPerformance_TableSize_slider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            rows = get(hObject, 'Value');
            Table = get(handles.AirfoilPerformance_Table, 'Data');
            if rows < 2
                set(hObject, 'Value', 2);
            else
                if rows < size(Table,1)
                    Table = Table(1:rows,:);
                    set(handles.AirfoilPerformance_Table, 'Data', Table);
                elseif rows > size(Table,1)
                    Table{rows,size(Table,2)} = [];
                    set(handles.AirfoilPerformance_Table, 'Data', Table);
                end
                set(handles.AirfoilPerformance_TableSize_textbox, 'String', int2str(rows));
            end
        end

        % Value changed function: 
        % AirfoilPerformance_TableSize_textbox
        function AirfoilPerformance_TableSize_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            rows = ceil(str2double(get(hObject, 'String')));
            Table = get(handles.AirfoilPerformance_Table, 'Data');
            if rows < 2
                rows = 2;
            elseif rows > get(handles.AirfoilPerformance_TableSize_slider, 'Max')
                rows = get(handles.AirfoilPerformance_TableSize_slider, 'Max');
            elseif isnan(rows)
                rows = size(Table,1);
            end
            if rows < size(Table,1)
                Table = Table(1:rows,:);
                set(handles.AirfoilPerformance_Table, 'Data', Table);
            elseif rows > size(Table,1)
                Table{rows,size(Table,2)} = [];
                set(handles.AirfoilPerformance_Table, 'Data', Table);
            end
            set(hObject, 'String', int2str(rows));
            set(handles.AirfoilPerformance_TableSize_slider, 'Value', rows);
        end

        % Cell selection callback: AirfoilPerformance_Table
        function AirfoilPerformance_Table_CellSelectionCallback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            handles.Selection = eventdata.Indices;
            guidata(hObject, handles);
        end

        % Button pushed function: Apply
        function Apply_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.Save = true;
            guidata(hObject, handles);
            uiresume(handles.AirfoilDesign);
        end

        % Button pushed function: Cancel
        function Cancel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.Save = false;
            guidata(hObject, handles);
            uiresume(handles.AirfoilDesign);
        end

        % Value changed function: CnSlope_textbox
        function CnSlope_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if isnan(str2double(get(hObject,'String')))
                set(hObject, 'String', num2str(handles.CnSlope))
            end
            handles.CnSlope = str2double(get(hObject,'String'));
            guidata(hObject, handles);
        end

        % Value changed function: CritCn1_textbox
        function CritCn1_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if isnan(str2double(get(hObject,'String')))
                set(hObject, 'String', num2str(handles.CritCn1))
            end
            handles.CritCn1 = str2double(get(hObject,'String'));
            guidata(hObject, handles);
        end

        % Value changed function: CritCn2_textbox
        function CritCn2_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if isnan(str2double(get(hObject,'String')))
                set(hObject, 'String', num2str(handles.CritCn2))
            end
            handles.CritCn2 = str2double(get(hObject,'String'));
            guidata(hObject, handles);
        end

        % Button pushed function: DeleteAirfoil
        function DeleteAirfoil_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            
            % Prevent deletion of base airfoils
            if length(handles.Airfoil.Name) <= 8
                errordlg('Cannot delete base airfoils.');
            else
            
                % Delete airfoil
                i = get(handles.AirfoilCollection,'Value');
                handles.Airfoil.Name(i) = [];
                handles.Airfoil.Geometry(i) = [];
                handles.Airfoil.Alpha(i) = [];
                handles.Airfoil.Cl(i) = [];
                handles.Airfoil.Cd(i) = [];
                handles.Airfoil.Cm(i) = [];
                handles.Airfoil.StallAngle1(i) = [];
                handles.Airfoil.StallAngle2(i) = [];
                handles.Airfoil.CnSlope(i) = [];
                handles.Airfoil.CritCn1(i) = [];
                handles.Airfoil.CritCn2(i) = [];
                handles.Blade.IFoil(handles.Blade.IFoil == i) = i - 1;
            
                % Update list
                set(handles.AirfoilCollection,'String',handles.Airfoil.Name);
                if i > length(handles.Airfoil.Name)
                    set(handles.AirfoilCollection,'Value',i-1);
                end
            
                % Update handles structure
                guidata(hObject, handles);
                AirfoilCollection_Callback_wrapper(app, event);
            
            end
        end

        % Button pushed function: NewAirfoil
        function NewAirfoil_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); 
            
            
            % Prompt for new airfoil name
            name = inputdlg('Enter airfoil name:');
            
            if ~isempty(name)
            
                % Make room in airfoil structure
                i = length(handles.Airfoil.Name) + 1;
                handles.Airfoil.Name{i} = name{1};
                handles.Airfoil.Geometry{i} = [1,0,1;0,0,0];
                handles.Airfoil.Alpha{i} = [-180;0;180];
                handles.Airfoil.Cl{i} = [0;0;0];
                handles.Airfoil.Cd{i} = [0;0;0];
                handles.Airfoil.Cm{i} = [0;0;0];
                handles.Airfoil.StallAngle1(i) = 0;
                handles.Airfoil.StallAngle2(i) = 0;
                handles.Airfoil.CnSlope(i) = 0;
                handles.Airfoil.CritCn1(i) = 0;
                handles.Airfoil.CritCn2(i) = 0;
            
                % Update list
                list = get(handles.AirfoilCollection,'String');
                list{i} = name{1};
                set(handles.AirfoilCollection,'String',list);
                set(handles.AirfoilCollection,'Value',i);
            
                % Update handles structure
                guidata(hObject, handles);
                AirfoilCollection_Callback_wrapper(app, event);
            
                % Update airfoil plot
                %DrawAirfoil(handles)
            
            end
        end

        % Button pushed function: PlotProperties
        function PlotProperties_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Plot settings
            Plot = figure();
            set(Plot, 'Name', 'Airfoil polars')
            
            % Lift curve
            subplot(2,1,1)
            hold on
            plot([-180 180], [0 0], '--', 'Color', [0.5 0.5 0.5], 'LineWidth', 0.25)
            alpha = cell2mat(handles.AirfoilPerformance_Table.Data(:,1));
            Cl = cell2mat(handles.AirfoilPerformance_Table.Data(:,2));
            Cd = cell2mat(handles.AirfoilPerformance_Table.Data(:,3));
            Cm = cell2mat(handles.AirfoilPerformance_Table.Data(:,4));
            Cl_plot = plot(alpha, Cl);
            Cd_plot = plot(alpha, Cd);
            Cm_plot = plot(alpha, Cm);
            set(gca, ...
                'XMinorTick', 'on', ...
                'YMinorTick', 'on', ...
                'XTick', -180:30:180, ...
                'Box', 'on', ...
                'Layer', 'top', ...
                'Fontsize', 8);
            xlim([-180 180])
            legend([Cl_plot, Cd_plot, Cm_plot], {'Cl'; 'Cd'; 'Cm'}, 'Location', 'Best', 'Color', 'none', 'Box', 'off');
            xlabel('Angle of attack [deg]')
            ylabel('[-]')
            
            subplot(2,1,2)
            hold on
            Cl = Cl(alpha > -30);
            Cd = Cd(alpha > -30);
            alpha = alpha(alpha > -30);
            Cl = Cl(alpha < 30);
            Cd = Cd(alpha < 30);
            alpha = alpha(alpha < 30);
            
            hold on
            plot([0 0.025], [0 0], '--', 'Color', [0.5 0.5 0.5], 'LineWidth', 0.25)
            plot(Cd,Cl)
            hold off
            set(gca, ...
                'XMinorTick', 'on', ...
                'YMinorTick', 'on', ...
                'Box', 'on', ...
                'Layer', 'top', ...
                'Fontsize', 8);
            xlim([0 0.025])
            xlabel('Cd [-]')
            ylabel('Cl [-]')
        end

        % Button pushed function: PlotShape
        function PlotShape_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot settings
            Plot = figure();
            set(Plot, 'Name', 'Airfoil shape')
            
            % Plot settings
            %axis(handles.AirfoilPlot);
            %cla reset;
            set(gca, ...
                'XTick', 0.0:0.1:1.0, ...
                'YTick', -0.5:0.1:0.5, ...
                'XMinorTick', 'on', ...
                'YMinorTick', 'on', ...
                'Box', 'on', ...
                'Layer', 'top', ...
                'Fontsize', 8);
            axis equal
            xlim([-0.1 1.1])
            ylim([-0.6 0.6])
            
            % Plot shape
            x = cell2mat(handles.AirfoilCoordinates_Table.Data(:,1));
            y = cell2mat(handles.AirfoilCoordinates_Table.Data(:,2));
            hold on
            plot([-0.1 1.1], [0 0], '--', 'Color', [0.5 0.5 0.5], 'LineWidth', 0.25)
            patch(x, y, [0 165, 213]/255, 'EdgeColor', 0.5*[0 165, 213]/255, 'LineWidth', 1)
            hold off
        end

        % Value changed function: StallAngle1_textbox
        function StallAngle1_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if isnan(str2double(get(hObject,'String')))
                set(hObject, 'String', num2str(handles.StallAngle1))
            end
            handles.StallAngle1 = str2double(get(hObject,'String'));
            guidata(hObject, handles);
        end

        % Value changed function: StallAngle2_textbox
        function StallAngle2_textbox_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if isnan(str2double(get(hObject,'String')))
                set(hObject, 'String', num2str(handles.StallAngle2))
            end
            handles.StallAngle2 = str2double(get(hObject,'String'));
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AirfoilDesign = uifigure('Visible', 'off');
            app.AirfoilDesign.Name = 'Airfoil design';
            app.AirfoilDesign.CloseRequestFcn = createCallbackFcn(app, @AirfoilDesign_CloseRequestFcn, true);
            app.AirfoilDesign.Tag = 'AirfoilDesign';
            app.panelStaticProperties = uipanel(app.AirfoilDesign);
            app.panelStaticProperties.Title = 'Static properties';
            app.panelStaticProperties.Tag = 'uipanel1';
            app.AirfoilPerformance_TableSize_slider = uislider(app.panelStaticProperties);
            app.AirfoilPerformance_TableSize_slider.Limits = [1 1001];
            app.AirfoilPerformance_TableSize_slider.MajorTicks = [];
            app.AirfoilPerformance_TableSize_slider.ValueChangedFcn = createCallbackFcn(app, @AirfoilPerformance_TableSize_slider_Callback, true);
            app.AirfoilPerformance_TableSize_slider.MinorTicks = [];
            app.AirfoilPerformance_TableSize_slider.Tag = 'AirfoilPerformance_TableSize_slider';
            app.AirfoilPerformance_TableSize_slider.Value = 49;
            app.AirfoilPerformance_Table = uitable(app.panelStaticProperties);
            app.AirfoilPerformance_Table.ColumnName = {'α [deg]'; 'Cl [-]'; 'Cd [-]'; 'Cm [-]'};
            app.AirfoilPerformance_Table.RowName = '';
            app.AirfoilPerformance_Table.ColumnEditable = [false false false false];
            app.AirfoilPerformance_Table.CellSelectionCallback = createCallbackFcn(app, @AirfoilPerformance_Table_CellSelectionCallback, true);
            app.AirfoilPerformance_Table.Tag = 'AirfoilPerformance_Table';
            app.text3 = uilabel(app.panelStaticProperties);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Rows:';
            app.AirfoilPerformance_TableSize_textbox = uieditfield(app.panelStaticProperties, 'text');
            app.AirfoilPerformance_TableSize_textbox.ValueChangedFcn = createCallbackFcn(app, @AirfoilPerformance_TableSize_textbox_Callback, true);
            app.AirfoilPerformance_TableSize_textbox.Tag = 'AirfoilPerformance_TableSize_textbox';
            app.AirfoilPerformance_EditCells_checkbox = uicheckbox(app.panelStaticProperties);
            app.AirfoilPerformance_EditCells_checkbox.ValueChangedFcn = createCallbackFcn(app, @AirfoilPerformance_EditCells_checkbox_Callback, true);
            app.AirfoilPerformance_EditCells_checkbox.Tag = 'AirfoilPerformance_EditCells_checkbox';
            app.AirfoilPerformance_EditCells_checkbox.Text = 'Edit cells';
            app.PlotProperties = uibutton(app.AirfoilDesign, 'push');
            app.PlotProperties.ButtonPushedFcn = createCallbackFcn(app, @PlotProperties_Callback, true);
            app.PlotProperties.Tag = 'PlotProperties';
            app.PlotProperties.Text = 'Plot properties';
            app.Apply = uibutton(app.AirfoilDesign, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @Apply_Callback, true);
            app.Apply.Tag = 'Apply';
            app.Apply.Text = 'Apply';
            app.Cancel = uibutton(app.AirfoilDesign, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @Cancel_Callback, true);
            app.Cancel.Tag = 'Cancel';
            app.Cancel.Text = 'Cancel';
            app.panelAirfoilCollection = uipanel(app.AirfoilDesign);
            app.panelAirfoilCollection.Title = 'Airfoil collection';
            app.panelAirfoilCollection.Tag = 'uipanel4';
            app.AirfoilCollection = uilistbox(app.panelAirfoilCollection);
            app.AirfoilCollection.Items = {'Listbox'};
            app.AirfoilCollection.ValueChangedFcn = createCallbackFcn(app, @AirfoilCollection_Callback, true);
            app.AirfoilCollection.Tag = 'AirfoilCollection';
            app.AirfoilCollection.Value = 'Listbox';
            app.NewAirfoil = uibutton(app.panelAirfoilCollection, 'push');
            app.NewAirfoil.ButtonPushedFcn = createCallbackFcn(app, @NewAirfoil_Callback, true);
            app.NewAirfoil.Tag = 'NewAirfoil';
            app.NewAirfoil.Text = 'New';
            app.DeleteAirfoil = uibutton(app.panelAirfoilCollection, 'push');
            app.DeleteAirfoil.ButtonPushedFcn = createCallbackFcn(app, @DeleteAirfoil_Callback, true);
            app.DeleteAirfoil.Tag = 'DeleteAirfoil';
            app.DeleteAirfoil.Text = 'Delete';
            app.panelDynamicStall = uipanel(app.AirfoilDesign);
            app.panelDynamicStall.Title = 'Dynamic stall';
            app.panelDynamicStall.Tag = 'uipanel5';
            app.StallAngle1_textbox = uieditfield(app.panelDynamicStall, 'text');
            app.StallAngle1_textbox.ValueChangedFcn = createCallbackFcn(app, @StallAngle1_textbox_Callback, true);
            app.StallAngle1_textbox.Tag = 'StallAngle1_textbox';
            app.StallAngle1_textbox.Tooltip = 'Positive stall angle [deg]';
            app.StallAngle1_textbox.Value = 'Edit Text';
            app.text18 = uilabel(app.panelDynamicStall);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Crit. Cn at neg. α:';
            app.StallAngle2_textbox = uieditfield(app.panelDynamicStall, 'text');
            app.StallAngle2_textbox.ValueChangedFcn = createCallbackFcn(app, @StallAngle2_textbox_Callback, true);
            app.StallAngle2_textbox.Tag = 'StallAngle2_textbox';
            app.StallAngle2_textbox.Tooltip = 'Negative stall angle [deg]';
            app.StallAngle2_textbox.Value = 'Edit Text';
            app.text19 = uilabel(app.panelDynamicStall);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Stall angle at pos. α:';
            app.text20 = uilabel(app.panelDynamicStall);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Stall angle at neg. α:';
            app.CnSlope_textbox = uieditfield(app.panelDynamicStall, 'text');
            app.CnSlope_textbox.ValueChangedFcn = createCallbackFcn(app, @CnSlope_textbox_Callback, true);
            app.CnSlope_textbox.Tag = 'CnSlope_textbox';
            app.CnSlope_textbox.Tooltip = 'Cn slope [1/rad]';
            app.CnSlope_textbox.Value = 'Edit Text';
            app.text21 = uilabel(app.panelDynamicStall);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Crit. Cn at pos. α:';
            app.CritCn1_textbox = uieditfield(app.panelDynamicStall, 'text');
            app.CritCn1_textbox.ValueChangedFcn = createCallbackFcn(app, @CritCn1_textbox_Callback, true);
            app.CritCn1_textbox.Tag = 'CritCn1_textbox';
            app.CritCn1_textbox.Tooltip = 'Critical value of Cn at leading edge separation. [-] It should be extracted from airfoil data at a given Mach and Reynolds number. It can be calculated from the static value of Cn at either the break in the pitching moment or the loss of chord force at the onset of stall. It is close to the condition of maximum lift of the airfoil at low Mach numbers.';
            app.CritCn1_textbox.Value = 'Edit Text';
            app.CritCn2_textbox = uieditfield(app.panelDynamicStall, 'text');
            app.CritCn2_textbox.ValueChangedFcn = createCallbackFcn(app, @CritCn2_textbox_Callback, true);
            app.CritCn2_textbox.Tag = 'CritCn2_textbox';
            app.CritCn2_textbox.Tooltip = 'As above for negative angles [-]';
            app.CritCn2_textbox.Value = 'Edit Text';
            app.text22 = uilabel(app.panelDynamicStall);
            app.text22.Tag = 'text22';
            app.text22.Text = 'dCn/dα slope:';
            app.text23 = uilabel(app.panelDynamicStall);
            app.text23.Tag = 'text23';
            app.text23.Text = '1/rad';
            app.text24 = uilabel(app.panelDynamicStall);
            app.text24.Tag = 'text24';
            app.text24.Text = 'deg';
            app.text29 = uilabel(app.panelDynamicStall);
            app.text29.Tag = 'text29';
            app.text29.Text = 'deg';
            app.panelAirfoilCoordinates = uipanel(app.AirfoilDesign);
            app.panelAirfoilCoordinates.Title = 'Airfoil coordinates';
            app.panelAirfoilCoordinates.Tag = 'uipanel6';
            app.AirfoilCoordinates_TableSize_slider = uislider(app.panelAirfoilCoordinates);
            app.AirfoilCoordinates_TableSize_slider.Limits = [1 1001];
            app.AirfoilCoordinates_TableSize_slider.MajorTicks = [];
            app.AirfoilCoordinates_TableSize_slider.ValueChangedFcn = createCallbackFcn(app, @AirfoilCoordinates_TableSize_slider_Callback, true);
            app.AirfoilCoordinates_TableSize_slider.MinorTicks = [];
            app.AirfoilCoordinates_TableSize_slider.Tag = 'AirfoilCoordinates_TableSize_slider';
            app.AirfoilCoordinates_TableSize_slider.Value = 49;
            app.AirfoilCoordinates_Table = uitable(app.panelAirfoilCoordinates);
            app.AirfoilCoordinates_Table.ColumnName = {'x/c [-]'; 'y/c [-]'};
            app.AirfoilCoordinates_Table.RowName = '';
            app.AirfoilCoordinates_Table.ColumnEditable = [false false];
            app.AirfoilCoordinates_Table.CellSelectionCallback = createCallbackFcn(app, @AirfoilCoordinates_Table_CellSelectionCallback, true);
            app.AirfoilCoordinates_Table.Tag = 'AirfoilCoordinates_Table';
            app.text28 = uilabel(app.panelAirfoilCoordinates);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Rows:';
            app.AirfoilCoordinates_TableSize_textbox = uieditfield(app.panelAirfoilCoordinates, 'text');
            app.AirfoilCoordinates_TableSize_textbox.ValueChangedFcn = createCallbackFcn(app, @AirfoilCoordinates_TableSize_textbox_Callback, true);
            app.AirfoilCoordinates_TableSize_textbox.Tag = 'AirfoilCoordinates_TableSize_textbox';
            app.AirfoilCoordinates_EditCells_checkbox = uicheckbox(app.panelAirfoilCoordinates);
            app.AirfoilCoordinates_EditCells_checkbox.ValueChangedFcn = createCallbackFcn(app, @AirfoilCoordinates_EditCells_checkbox_Callback, true);
            app.AirfoilCoordinates_EditCells_checkbox.Tag = 'AirfoilCoordinates_EditCells_checkbox';
            app.AirfoilCoordinates_EditCells_checkbox.Text = 'Edit cells';
            app.PlotShape = uibutton(app.AirfoilDesign, 'push');
            app.PlotShape.ButtonPushedFcn = createCallbackFcn(app, @PlotShape_Callback, true);
            app.PlotShape.Tag = 'PlotShape';
            app.PlotShape.Text = 'Plot shape';
            % Show the figure after all components are created
            app.AirfoilDesign.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AirfoilDesign_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AirfoilDesign)

                % Execute the startup function
                runStartupFcn(app, @(app)AirfoilDesign_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.AirfoilDesign)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AirfoilDesign)
        end
    end
end